classdef differenceRangeA1_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        MakerandomdataButton           matlab.ui.control.Button
        Datagroup2TextArea             matlab.ui.control.TextArea
        Datagroup2TextAreaLabel        matlab.ui.control.Label
        Datagroup1TextArea             matlab.ui.control.TextArea
        Datagroup1TextAreaLabel        matlab.ui.control.Label
        textOut                        matlab.ui.control.TextArea
        RuntestButton                  matlab.ui.control.Button
        InstructionsButton             matlab.ui.control.Button
        ResultsPanel                   matlab.ui.container.Panel
        StandardrangeofdifferencesEditField  matlab.ui.control.EditField
        StandardrangeofdifferencesEditFieldLabel  matlab.ui.control.Label
        StandarderrorofdifferenceEditField  matlab.ui.control.EditField
        StandarderrorofdifferenceEditFieldLabel  matlab.ui.control.Label
        ObserveddifferenceEditField    matlab.ui.control.EditField
        ObserveddifferenceEditFieldLabel  matlab.ui.control.Label
        ModelRangeEditField            matlab.ui.control.EditField
        ModelRangeEditFieldLabel       matlab.ui.control.Label
        SetmodelprecisionPanel         matlab.ui.container.Panel
        NumberofpositivehitsEditField  matlab.ui.control.NumericEditField
        NumberofpositivehitsEditFieldLabel  matlab.ui.control.Label
        TestvaluesperstandarderrorEditField  matlab.ui.control.NumericEditField
        TestvaluesperstandarderrorEditFieldLabel  matlab.ui.control.Label
        RepititionspertestvalueEditField  matlab.ui.control.NumericEditField
        RepititionspertestvalueEditFieldLabel  matlab.ui.control.Label
        TestoptionsPanel               matlab.ui.container.Panel
        ResetDefaultsButton            matlab.ui.control.Button
        ConvertresultstopercentCheckBox  matlab.ui.control.CheckBox
        AssumenormalityCheckBox        matlab.ui.control.CheckBox
        Confidencefraction95CIEditField  matlab.ui.control.NumericEditField
        Confidencefraction95CIEditFieldLabel  matlab.ui.control.Label
        ax2                            matlab.ui.control.UIAxes
        ax1                            matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        
        function results = resetDefaults(app)
            global globDR
                     app.Confidencefraction95CIEditField.Value = globDR.default.confidenceFraction;
                     app.AssumenormalityCheckBox.Value = globDR.default.useMean;
                     app.ConvertresultstopercentCheckBox.Value = globDR.default.convertToPercent;
                     app.NumberofpositivehitsEditField.Value = globDR.default.hitNum;
                     app.RepititionspertestvalueEditField.Value = globDR.default.reps;
                     app.TestvaluesperstandarderrorEditField.Value = globDR.default.testResolution;
                     app.textOut.Value = 'Ready for data entry';
        end
        
        function dat = parseDataIn(app,datStr)
            
            dat = [];
            for i = 1:length(datStr)
                dat = [dat str2num(datStr{i})];
            end

        end
        
        function results = updateAx1(app)
            global globDR
            app.ax1.reset;
            app.ax1.NextPlot ='add';
            scatter(app.ax1,globDR.dat1*0+1,globDR.dat1,'k')
            scatter(app.ax1,globDR.dat2*0+2,globDR.dat2,'k')
            app.ax1.XLim = [0 3];
            app.ax1.XTickLabel = {'' '1' '2' ''};

            drawnow
        end
        
        function results = updateResults(app)
            global globDR

            res = globDR.result;

            app.StandardrangeofdifferencesEditField.Value = num2str(res.standardDifferenceRange);
            app.StandarderrorofdifferenceEditField.Value = num2str(res.standardErrorOfDifference);
            app.ObserveddifferenceEditField.Value = num2str(res.observedDifference);
            app.ModelRangeEditField.Value = num2str(res.bestRange); 

            cla(app.ax2);
            app.ax2.NextPlot = 'add';
            plot(app.ax2,res.testedDifferences,res.hitsForDifferences,'k')
            maxDif = max(res.hitsForDifferences);
            plot(app.ax2,[res.bestRange(1) res.bestRange(1)],[0 maxDif],'r')
            plot(app.ax2,[res.bestRange(2) res.bestRange(2)],[0 maxDif],'r')
            app.ax2.YLim = [0 maxDif];


        end
    end
    
    methods (Access = public)

        function results = updateAx1_public(app)
            global globDR

            cla(app.ax1)
            app.ax1.NextPlot ='add';
            scatter(app.ax1,globDR.dat1*0+1,globDR.dat1,'k')
            scatter(app.ax1,globDR.dat2*0+2,globDR.dat2,'b')
            app.ax1.XLim = [0 3];
            app.ax1.XTickLabel = {'' '1' '2' ''};

            drawnow
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global globDR

            globDR.app = app;

            default.confidenceFraction = 0.95;
            default.convertToPercent = 1;
            default.useMean = 1;
            default.testResolution = 40; % Number of tests values per standard error of the differences
            default.reps = 100000; % number of repetitions per search value
            default.hitNum = 100000; % number of hits included in final range
            default.showProcess = 0;         

            globDR.param = default;
            globDR.default = default;

            resetDefaults(app)


            globDR.dat1 = [];
            globDR.dat2 = [];


        end

        % Button pushed function: ResetDefaultsButton
        function resetDefaultsCallback(app, event)
            resetDefaults(app)
        end

        % Value changed function: Datagroup1TextArea
        function dat1_callback(app, event)
            global globDR
            value = app.Datagroup1TextArea.Value;
            
            dat = parseDataIn(app,value);
            globDR.dat1 = dat;
            app.textOut.Value = sprintf('Group One = %s',num2str(dat));

            updateAx1(app)

        end

        % Value changed function: Datagroup2TextArea
        function dataGroup2_callback(app, event)
            global globDR

            value = app.Datagroup2TextArea.Value;
            dat = parseDataIn(app,value);
            globDR.dat2 = dat;
            app.textOut.Value = sprintf('Group Two =  %s',num2str(dat));

            updateAx1(app)

        end

        % Button pushed function: RuntestButton
        function runTest(app, event)
            global globDR


            globDR.param.confidenceFraction = app.Confidencefraction95CIEditField.Value;
            globDR.param.convertToPercent = app.ConvertresultstopercentCheckBox.Value;
            globDR.param.useMean = app.AssumenormalityCheckBox.Value;
            globDR.param.testResolution = app.TestvaluesperstandarderrorEditField.Value;
            globDR.param.hitNum = app.NumberofpositivehitsEditField.Value;
            globDR.param.reps = app.RepititionspertestvalueEditField.Value;
            

            app.textOut.Value = 'Running Model'; drawnow
            startTime = tic;
            res = differenceRangeA1(globDR.dat1,globDR.dat2,globDR.param);
            runTime = toc(startTime);
            runTime = runTime/60;
            globDR.result = res;
            
            app.textOut.Value = sprintf('Finished Running Model in %0.2f minutes',runTime);
            drawnow
            updateResults(app)
            
        end

        % Button pushed function: MakerandomdataButton
        function openRandomDataWindow(app, event)
            app.textOut.Value = 'Create data for testing model';
            makeRandomData_app
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ax1 = uiaxes(app.UIFigure);
            app.ax1.XTick = [];
            app.ax2 = uiaxes(app.UIFigure);
            title(app.ax2, 'Title')
            xlabel(app.ax2, 'Percent Difference')
            zlabel(app.ax2, 'Z')
            app.TestoptionsPanel = uipanel(app.UIFigure);
            app.TestoptionsPanel.Title = 'Test options';
            app.Confidencefraction95CIEditFieldLabel = uilabel(app.TestoptionsPanel);
            app.Confidencefraction95CIEditFieldLabel.Text = 'Confidence fraction (95%CI)';
            app.Confidencefraction95CIEditField = uieditfield(app.TestoptionsPanel, 'numeric');
            app.Confidencefraction95CIEditField.Value = 0.95;
            app.AssumenormalityCheckBox = uicheckbox(app.TestoptionsPanel);
            app.AssumenormalityCheckBox.Text = 'Assume normality';
            app.ConvertresultstopercentCheckBox = uicheckbox(app.TestoptionsPanel);
            app.ConvertresultstopercentCheckBox.Text = 'Convert results to percent';
            app.ConvertresultstopercentCheckBox.Value = true;
            app.ResetDefaultsButton = uibutton(app.TestoptionsPanel, 'push');
            app.ResetDefaultsButton.ButtonPushedFcn = createCallbackFcn(app, @resetDefaultsCallback, true);
            app.ResetDefaultsButton.Text = 'Reset Defaults';
            app.SetmodelprecisionPanel = uipanel(app.UIFigure);
            app.SetmodelprecisionPanel.Title = 'Set model precision';
            app.RepititionspertestvalueEditFieldLabel = uilabel(app.SetmodelprecisionPanel);
            app.RepititionspertestvalueEditFieldLabel.Text = 'Repititions per test value';
            app.RepititionspertestvalueEditField = uieditfield(app.SetmodelprecisionPanel, 'numeric');
            app.RepititionspertestvalueEditField.ValueDisplayFormat = '%.0f';
            app.RepititionspertestvalueEditField.Value = 100000;
            app.TestvaluesperstandarderrorEditFieldLabel = uilabel(app.SetmodelprecisionPanel);
            app.TestvaluesperstandarderrorEditFieldLabel.Text = 'Test values per standard error';
            app.TestvaluesperstandarderrorEditField = uieditfield(app.SetmodelprecisionPanel, 'numeric');
            app.TestvaluesperstandarderrorEditField.ValueDisplayFormat = '%.0f';
            app.TestvaluesperstandarderrorEditField.Value = 40;
            app.NumberofpositivehitsEditFieldLabel = uilabel(app.SetmodelprecisionPanel);
            app.NumberofpositivehitsEditFieldLabel.Text = 'Number of positive hits';
            app.NumberofpositivehitsEditField = uieditfield(app.SetmodelprecisionPanel, 'numeric');
            app.NumberofpositivehitsEditField.ValueDisplayFormat = '%.0f';
            app.NumberofpositivehitsEditField.Value = 100000;
            app.ResultsPanel = uipanel(app.UIFigure);
            app.ResultsPanel.Title = 'Results';
            app.ModelRangeEditFieldLabel = uilabel(app.ResultsPanel);
            app.ModelRangeEditFieldLabel.Text = 'Model Range';
            app.ModelRangeEditField = uieditfield(app.ResultsPanel, 'text');
            app.ObserveddifferenceEditFieldLabel = uilabel(app.ResultsPanel);
            app.ObserveddifferenceEditFieldLabel.Text = 'Observed difference';
            app.ObserveddifferenceEditField = uieditfield(app.ResultsPanel, 'text');
            app.StandarderrorofdifferenceEditFieldLabel = uilabel(app.ResultsPanel);
            app.StandarderrorofdifferenceEditFieldLabel.Text = 'Standard error of difference';
            app.StandarderrorofdifferenceEditField = uieditfield(app.ResultsPanel, 'text');
            app.StandardrangeofdifferencesEditFieldLabel = uilabel(app.ResultsPanel);
            app.StandardrangeofdifferencesEditFieldLabel.Text = 'Standard range of differences';
            app.StandardrangeofdifferencesEditField = uieditfield(app.ResultsPanel, 'text');
            app.InstructionsButton = uibutton(app.UIFigure, 'push');
            app.InstructionsButton.Text = 'Instructions';
            app.RuntestButton = uibutton(app.UIFigure, 'push');
            app.RuntestButton.ButtonPushedFcn = createCallbackFcn(app, @runTest, true);
            app.RuntestButton.Text = 'Run test';
            app.textOut = uitextarea(app.UIFigure);
            app.Datagroup1TextAreaLabel = uilabel(app.UIFigure);
            app.Datagroup1TextAreaLabel.Text = 'Data group 1';
            app.Datagroup1TextArea = uitextarea(app.UIFigure);
            app.Datagroup1TextArea.ValueChangedFcn = createCallbackFcn(app, @dat1_callback, true);
            app.Datagroup2TextAreaLabel = uilabel(app.UIFigure);
            app.Datagroup2TextAreaLabel.Text = 'Data group 2';
            app.Datagroup2TextArea = uitextarea(app.UIFigure);
            app.Datagroup2TextArea.ValueChangedFcn = createCallbackFcn(app, @dataGroup2_callback, true);
            app.MakerandomdataButton = uibutton(app.UIFigure, 'push');
            app.MakerandomdataButton.ButtonPushedFcn = createCallbackFcn(app, @openRandomDataWindow, true);
            app.MakerandomdataButton.Text = 'Make random data';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = differenceRangeA1_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end