classdef GraphPointToPoint_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                      matlab.ui.Figure
        uipanel_renderInfluence      matlab.ui.container.Panel
        pushbutton_render            matlab.ui.control.Button
        togglebutton_normalizeOUT    matlab.ui.control.StateButton
        togglebutton_normalizeIN     matlab.ui.control.StateButton
        togglebutton_groupOUT        matlab.ui.control.StateButton
        togglebutton_groupIN         matlab.ui.control.StateButton
        pushbutton_printGraph        matlab.ui.control.Button
        uitable_con                  matlab.ui.control.Table
        uipanel_distanceFunction     matlab.ui.container.Panel
        togglebutton_allowNonCell    matlab.ui.control.StateButton
        edit_dFunc                   matlab.ui.control.EditField
        text11                       matlab.ui.control.Label
        pushbutton_applyFunction     matlab.ui.control.Button
        popupmenu_pickFunction       matlab.ui.control.DropDown
        textOut                      matlab.ui.control.EditField
        text3                        matlab.ui.control.Label
        pushbutton_makeAllSM         matlab.ui.control.Button
        uipanel_ptsOUT               matlab.ui.container.Panel
        pushbutton_clearOUTpts       matlab.ui.control.Button
        togglebutton_getProbePoints  matlab.ui.control.StateButton
        pushbutton7                  matlab.ui.control.Button
        pushbutton_addFullArbor      matlab.ui.control.Button
        pushbutton_addCellBody       matlab.ui.control.Button
        popupmenu_addSynGroupOUT     matlab.ui.control.DropDown
        text9                        matlab.ui.control.Label
        text8                        matlab.ui.control.Label
        listbox_outGroups            matlab.ui.control.ListBox
        edit_customOUT               matlab.ui.control.TextArea
        uipanel_selectPtsIn          matlab.ui.container.Panel
        pushbutton_clearINpts        matlab.ui.control.Button
        togglebutton_getINpts        matlab.ui.control.StateButton
        pushbutton_addINlist         matlab.ui.control.Button
        edit_customIN                matlab.ui.control.TextArea
        popupmenu_addSynGroupIN      matlab.ui.control.DropDown
        text7                        matlab.ui.control.Label
        text6                        matlab.ui.control.Label
        text5                        matlab.ui.control.Label
        listbox_inGroups             matlab.ui.control.ListBox
        uipanel_selectCell           matlab.ui.container.Panel
        cellText                     matlab.ui.control.EditField
        text4                        matlab.ui.control.Label
        popupmenu_volume             matlab.ui.control.DropDown
        edit_selectCell              matlab.ui.control.EditField
        pushbutton_makeSM            matlab.ui.control.Button
        axes1                        matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        function cellText_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to cellText (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function deselectToggleUI(app, handles, hObject)
            
            
            tag = get(hObject,'Tag');
            hList = {'togglebutton_getINpts','togglebutton_getProbePoints'};
            
            for i = 1:length(hList)
                if ~strcmp(tag,hList{i})
                    set(eval(['handles.' hList{i}]),'Value',0);
                    eval([hList{i} '_Callback(handles.' hList{i} ',[],handles)']);
                end
            end
        end
        
        function txt = displayCoordinates(app, ~, info)
            global glob globSM
            
            X = info.Position(2);
            Y = info.Position(1);
            Z = info.Position(3);
            %c_info = getCursorInfo(glob.dcm)
            
            anc2sub = (glob.em.res/ 1000)./ glob.em.dsRes;
            allPos2 = [X Y Z] ./ anc2sub;
            VASTpos = sprintf('%.0f  %.0f  %.0f',allPos2(end,2),allPos2(end,1),allPos2(end,3));
            disp(VASTpos)
            
            anc2um =  1./ glob.em.dsRes;
            umPos = [X Y Z] ;
            umPosStr = sprintf('%.0f \r%.0f \r%.0f um',umPos(end,2),umPos(end,1),umPos(end,3));
            
            set(globSM.h.textOut,'String',VASTpos);
            tag = get(info.Target,'Tag');
            
            txt = umPosStr;
            
            clipboard('copy',umPos)
            
            globSM.OUT.editPts = cat(1,globSM.OUT.editPts,umPos);
            
            strs = num2str(globSM.OUT.editPts,'%0.2f ');
            set(globSM.h.edit_customOUT,'String',strs);
        end
        
        function txt = displayCoordinates2(app, ~, info)
            global glob globSM
            
            X = info.Position(2);
            Y = info.Position(1);
            Z = info.Position(3);
            %c_info = getCursorInfo(glob.dcm)
            
            anc2sub = (glob.em.res/ 1000)./ glob.em.dsRes;
            allPos2 = [X Y Z] ./ anc2sub;
            VASTpos = sprintf('%.0f  %.0f  %.0f',allPos2(end,2),allPos2(end,1),allPos2(end,3));
            disp(VASTpos)
            
            anc2um =  1./ glob.em.dsRes;
            umPos = [X Y Z];
            umPosStr = sprintf('%.0f \r%.0f \r%.0f um',umPos(end,2),umPos(end,1),umPos(end,3));
            
            set(globSM.h.textOut,'String',VASTpos);
            tag = get(info.Target,'Tag');
            
            txt = umPosStr;
            
            clipboard('copy',umPos)
            
            globSM.IN.editPts = cat(1,globSM.IN.editPts,umPos);
            
            strs = num2str(globSM.IN.editPts,'%0.2f ');
            set(globSM.h.edit_customIN,'String',strs);
        end
        
        function updateGraphs(app)
            
            global globSM
            
            handles = globSM.h;
            
            if isfield(globSM,'ptp')
            ptp = globSM.ptp;
            
            gIN = get(handles.togglebutton_groupIN,'Value');
            gOUT = get(handles.togglebutton_groupOUT,'Value');
            
            nIN = get(handles.togglebutton_normalizeIN,'Value');
            nOUT = get(handles.togglebutton_normalizeOUT,'Value');
            
            %%Assign groups to graph positions
            for i = 1:length(ptp.indIN)
                for p = 1:length(ptp.indIN{i})
                    rowG{ptp.indIN{i}(p)} = sprintf('%d.%d',i,p);
                end
            end
            for i = 1:length(ptp.indOUT)
                for p = 1:length(ptp.indOUT{i})
                    colG{ptp.indOUT{i}(p)} = sprintf('%d.%d',i,p);
                end
            end
            colG2 = [1:length(ptp.indOUT)];
            rowG2 = [1:length(ptp.indIN)];
            
            if gIN & gOUT  %% select grouping or not
                con = ptp.conG;
                colG = colG2;
                rowG = rowG2;
            elseif gIN
                con = ptp.conGin;
                rowG = rowG2;
            elseif gOUT
                con = ptp.conGout;
                colG = colG2;
            else
                con = ptp.con;
            end
            
            if nIN & nOUT  %% select grouping or not
                con = con./ sum(con(:));
            elseif nIN
                con = con./ repmat(sum(con,2),[1 size(con,2)]);
            elseif nOUT
                con = con./ repmat(sum(con,1),[size(con,1) 1]);
            else
                con = con;
            end
            
            set(globSM.h.uitable_con,'Data',con)
            set(globSM.h.uitable_con,'RowName',rowG);
            set(globSM.h.uitable_con,'ColumnName',colG);
            
            showCon = con;
            colormap(globSM.h.axes1,jet(256))
            showCon = showCon*255/max(showCon(:));
            image(globSM.h.axes1,showCon)
            % set(globSM.h.axes1,'Xticklabel',colG)
            % set(globSM.h.axes1,'Yticklabel',rowG)
            end
        end
        
        function updateSMfig(app)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            global glob globSM
            
            h = globSM.h;
            
            %%update cell dir
            globSM.smDir = [glob.dir.Volumes globSM.vol '\Analysis\SMs\'];
            fileName = sprintf('sm_cid%d.mat',globSM.pickCID);
            globSM.smFileName = fileName;
            if exist([globSM.smDir globSM.smFileName],'file')
                str = 'Analysis SM file found';
            else
                str = 'No anslysis SM file found';
            end
            set(h.cellText,'String',str);
            
            
            %%update synList
            str = {glob.syn.g.name};
            set(h.popupmenu_addSynGroupIN,'String',str);
            set(h.popupmenu_addSynGroupOUT,'String',str);
            
            %%update point groups
            if globSM.IN.num
                    for n = 1:globSM.IN.num
                INnames{n} = globSM.IN.g(n).dat.name;
                    end
            else
                INnames = {};
            end
            
            set(h.listbox_inGroups,'String',INnames);
            set(h.listbox_inGroups,'Value',1);
            
            if globSM.OUT.num
                for n = 1:globSM.OUT.num
                    OUTnames{n} = globSM.OUT.g(n).dat.name;
                end
            else
                OUTnames = {};
            end
            set(h.listbox_outGroups,'String',OUTnames);
            set(h.listbox_outGroups,'Value',1);
            
            
            %%Custom pts
            strs = num2str(globSM.OUT.editPts,'%0.2f ');
            set(globSM.h.edit_customOUT,'String',strs);
            
            strs = num2str(globSM.IN.editPts,'%0.2f ');
            set(globSM.h.edit_customIN,'String',strs);
            
            %%Distance Function
            
            set(h.edit_dFunc,'String',globSM.dFunc)
            
            updateGraphs(app)
        end
        
        % Update components that require runtime configuration
        function addRuntimeConfigurations(app)
            
            % Set component properties that require runtime configuration
            app.textOut.DeleteFcn = createCallbackFcn(app, @textOut_DeleteFcn, true);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function GraphPointToPoint_OpeningFcn(app, varargin)
            % Add runtime required configuration - Added by Migration Tool
            addRuntimeConfigurations(app);
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to GraphPointToPoint (see VARARGIN)
            
            % Choose default command line output for GraphPointToPoint
            handles.output = hObject;
            
            % Update handles structure
            guidata(hObject, handles);
            
            % UIWAIT makes GraphPointToPoint wait for user response (see UIRESUME)
            % uiwait(handles.figure1);
            global glob globSM
            
            
            globSM.h = handles;
            
            IN.num = 0;
            OUT.num = 0;
            OUT.editPts = [];
            IN.editPts = [];
            globSM.IN = IN;
            globSM.OUT = OUT;
            
            
            strs = get(handles.popupmenu_pickFunction,'String');
            globSM.dFunc = strs{1};
            
            updateSMfig(app)
        end

        % Value changed function: edit_dFunc
        function edit_dFunc_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edit_dFunc (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edit_dFunc as text
            %        str2double(get(hObject,'String')) returns contents of edit_dFunc as a double
            
            global globSM
            
            str = get(hObject,'String');
            globSM.dFunc = str;
        end

        % Value changed function: edit_selectCell
        function edit_selectCell_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edit_selectCell (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edit_selectCell as text
            %        str2double(get(hObject,'String')) returns contents of edit_selectCell as a double
            
            global glob globSM
            
            str = get(hObject,'String');
            cid = str2num(str);
            if ~isempty(cid)
                   globSM.pickCID = cid;
            end
            
            updateSMfig(app)
        end

        % Value changed function: listbox_inGroups
        function listbox_inGroups_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to listbox_inGroups (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns listbox_inGroups contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from listbox_inGroups
            
            global globSM
            
            val = get(hObject,'Value');
            
            keep = setdiff([1:globSM.IN.num],val);
            
            globSM.IN.g = globSM.IN.g(keep);
            globSM.IN.num = length(globSM.IN.g);
            
            set(hObject,'Value',1)
            updateSMfig(app)
        end

        % Value changed function: listbox_outGroups
        function listbox_outGroups_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to listbox_outGroups (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns listbox_outGroups contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from listbox_outGroups
            
            global globSM
            
            val = get(hObject,'Value');
            
            keep = setdiff([1:globSM.OUT.num],val);
            globSM.OUT.g = globSM.OUT.g(keep);
            globSM.OUT.num = length(globSM.OUT.g);
            updateSMfig(app)
        end

        % Value changed function: popupmenu_addSynGroupIN
        function popupmenu_addSynGroupIN_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to popupmenu_addSynGroupIN (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns popupmenu_addSynGroupIN contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from popupmenu_addSynGroupIN
            
            global glob globSM
            
            strs = get(hObject,'String');
            val = get(hObject,'Value');
            str = strs{val};
            
            globSM.IN.num = globSM.IN.num + 1;
            g.dat = glob.syn.g(val);
            g.type = 'synGroup';
            globSM.IN.g(globSM.IN.num) = g;
            updateSMfig(app)
        end

        % Value changed function: popupmenu_addSynGroupOUT
        function popupmenu_addSynGroupOUT_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to popupmenu_addSynGroupOUT (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns popupmenu_addSynGroupOUT contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from popupmenu_addSynGroupOUT


            global glob globSM
            
            strs = get(hObject,'String');
            val = get(hObject,'Value');
            str = strs{val};
            
            globSM.OUT.num = globSM.OUT.num + 1;
            g.dat = glob.syn.g(val);
            g.type = 'synGroup';
            globSM.OUT.g(globSM.OUT.num) = g;
            updateSMfig(app)
        end

        % Value changed function: popupmenu_pickFunction
        function popupmenu_pickFunction_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to popupmenu_pickFunction (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns popupmenu_pickFunction contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from popupmenu_pickFunction
            
            global globSM
            
            strs = get(hObject,'String');
            val = get(hObject,'Value');
            str = strs{val};
            
            globSM.dFunc = str;
            updateSMfig(app)
        end

        % Value changed function: popupmenu_volume
        function popupmenu_volume_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to popupmenu_volume (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns popupmenu_volume contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from popupmenu_volume
            
            
            global glob globSM
            
            strs = get(hObject,'String');
            val = get(hObject,'Value');
            str = strs{val};
            globSM.vol = str;
            glob.NA.MPN = [glob.dir.Volumes str '\Merge\'];
            makeMPNcnv
        end

        % Button pushed function: pushbutton7
        function pushbutton7_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton7 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            global globSM
            globSM.OUT.num = globSM.OUT.num + 1;
            g.dat.pos = globSM.OUT.editPts;
            g.type = 'pts';
            g.dat.name = 'custom list';
            globSM.OUT.g(globSM.OUT.num) = g;
            updateSMfig(app)
        end

        % Button pushed function: pushbutton_addINlist
        function pushbutton_addINlist_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton_addINlist (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            global globSM
            globSM.IN.num = globSM.IN.num + 1;
            g.dat.pos = globSM.IN.editPts;
            g.type = 'pts';
            g.dat.name = 'custom list';
            globSM.IN.g(globSM.IN.num) = g;
            updateSMfig(app)
        end

        % Button pushed function: pushbutton_applyFunction
        function pushbutton_applyFunction_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton_applyFunction (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            global glob globSM
            
            
            if globSM.IN.num & globSM.OUT.num
                set(handles.textOut,'BackgroundColor',[.7 1 .7])
                set(handles.textOut,'String','Analyzing point lists');
                pause(.01)
                globSM.ptp = smPt2Pt;
                set(handles.textOut,'BackgroundColor',[1 1 1])
                set(handles.textOut,'String','Finished point Lists');
            else
                set(handles.textOut,'BackgroundColor',[1 .7 .7])
                 set(handles.textOut,'String','Did not find in and out point lists');
                pause(1)
                set(handles.textOut,'BackgroundColor',[1 1 1])
            end
            
            get(handles.edit_dFunc,'backgroundcolor')
            updateGraphs(app);
        end

        % Button pushed function: pushbutton_clearINpts
        function pushbutton_clearINpts_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton_clearINpts (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            
            global globSM
            
            globSM.IN.editPts = [];
            updateSMfig(app)
        end

        % Button pushed function: pushbutton_clearOUTpts
        function pushbutton_clearOUTpts_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton_clearOUTpts (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            global globSM
            
            globSM.OUT.editPts = [];
            updateSMfig(app)
        end

        % Button pushed function: pushbutton_makeAllSM
        function pushbutton_makeAllSM_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton_makeAllSM (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            
            globSM.figH = figure
            runAllSM
            close(globSM.figH)
            
            edit_selectCell_Callback(handles.selectCell, [], handles)
        end

        % Button pushed function: pushbutton_makeSM
        function pushbutton_makeSM_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton_makeSM (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            
            global glob globSM
            
            globSM.figH = figure;
            makeSM(globSM.pickCID)
            pause(.01)
            close(globSM.figH)
            
            updateSMfig(app)
        end

        % Button pushed function: pushbutton_printGraph
        function pushbutton_printGraph_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton_printGraph (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            updateGraphs(app)
        end

        % Callback function
        function textOut_DeleteFcn(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
                'bye'
        end

        % Value changed function: togglebutton_getINpts
        function togglebutton_getINpts_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to togglebutton_getINpts (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of togglebutton_getINpts
            
            global glob globSM
            
            val = get(hObject,'Value');
            
            % try
            %     glob.dcm.Enable = 'off'
            %     %glob.dcm.delete
            % end
            
            if val
                deselectToggleUI(app, handles, hObject)
                glob.dcm = datacursormode(glob.handles.figure1);
                glob.dcm.UpdateFcn = @app.displayCoordinates2;
            
                set(glob.dcm,'DisplayStyle','datatip',...
                    'SnapToDataVertex','off','Enable','on')
            else
                rotate3d(glob.handles.figure1)
                delete(findall(glob.handles.figure1,'Type','hggroup'))
            end
        end

        % Value changed function: togglebutton_getProbePoints
        function togglebutton_getProbePoints_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to togglebutton_getProbePoints (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of togglebutton_getProbePoints
            global glob globSM
            
            val = get(hObject,'Value');
            
            % try
            %     glob.dcm.Enable = 'off'
            %     %glob.dcm.delete
            % end
            
            if val
                deselectToggleUI(app, handles, hObject)
                glob.dcm = datacursormode(glob.handles.figure1);
                glob.dcm.UpdateFcn = @app.displayCoordinates;
            
                set(glob.dcm,'DisplayStyle','datatip',...
                    'SnapToDataVertex','off','Enable','on')
            else
                rotate3d(glob.handles.figure1)
                delete(findall(glob.handles.figure1,'Type','hggroup'))
            end
        end

        % Value changed function: togglebutton_groupIN
        function togglebutton_groupIN_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to togglebutton_groupIN (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of togglebutton_groupIN
            updateGraphs(app)
        end

        % Value changed function: togglebutton_groupOUT
        function togglebutton_groupOUT_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to togglebutton_groupOUT (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of togglebutton_groupOUT
            
            updateGraphs(app)
        end

        % Value changed function: togglebutton_normalizeIN
        function togglebutton_normalizeIN_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to togglebutton_normalizeIN (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of togglebutton_normalizeIN
            updateGraphs(app)
        end

        % Value changed function: togglebutton_normalizeOUT
        function togglebutton_normalizeOUT_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to togglebutton_normalizeOUT (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of togglebutton_normalizeOUT
            
            updateGraphs(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'GraphPointToPoint';
            app.figure1.Tag = 'figure1';
            app.axes1 = uiaxes(app.figure1);
            app.axes1.Tag = 'axes1';
            app.uipanel_selectCell = uipanel(app.figure1);
            app.uipanel_selectCell.Title = 'Select cell';
            app.uipanel_selectCell.Tag = 'uipanel_selectCell';
            app.pushbutton_makeSM = uibutton(app.uipanel_selectCell, 'push');
            app.pushbutton_makeSM.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_makeSM_Callback, true);
            app.pushbutton_makeSM.Tag = 'pushbutton_makeSM';
            app.pushbutton_makeSM.Text = 'Analyze Skeleton';
            app.edit_selectCell = uieditfield(app.uipanel_selectCell, 'text');
            app.edit_selectCell.ValueChangedFcn = createCallbackFcn(app, @edit_selectCell_Callback, true);
            app.edit_selectCell.Tag = 'edit_selectCell';
            app.edit_selectCell.Value = '1033';
            app.popupmenu_volume = uidropdown(app.uipanel_selectCell);
            app.popupmenu_volume.Items = {'Analysis', 'AprilMerge', 'juneTest', 'medRes'};
            app.popupmenu_volume.ValueChangedFcn = createCallbackFcn(app, @popupmenu_volume_Callback, true);
            app.popupmenu_volume.Tag = 'popupmenu_volume';
            app.popupmenu_volume.Value = 'Analysis';
            app.text4 = uilabel(app.uipanel_selectCell);
            app.text4.Tag = 'text4';
            app.text4.Text = 'Select Volume';
            app.cellText = uieditfield(app.uipanel_selectCell, 'text');
            app.cellText.Tag = 'cellText';
            app.cellText.Value = 'Cell data not found';
            app.uipanel_selectPtsIn = uipanel(app.figure1);
            app.uipanel_selectPtsIn.Title = 'Select Points IN';
            app.uipanel_selectPtsIn.Tag = 'uipanel_selectPtsIn';
            app.listbox_inGroups = uilistbox(app.uipanel_selectPtsIn);
            app.listbox_inGroups.Items = {};
            app.listbox_inGroups.ValueChangedFcn = createCallbackFcn(app, @listbox_inGroups_Callback, true);
            app.listbox_inGroups.Tag = 'listbox_inGroups';
            app.listbox_inGroups.Value = {};
            app.text5 = uilabel(app.uipanel_selectPtsIn);
            app.text5.Tag = 'text5';
            app.text5.Text = 'Points IN groups';
            app.text6 = uilabel(app.uipanel_selectPtsIn);
            app.text6.Tag = 'text6';
            app.text6.Text = 'Add synapse groups';
            app.text7 = uilabel(app.uipanel_selectPtsIn);
            app.text7.Tag = 'text7';
            app.text7.Text = 'Add custom point list';
            app.popupmenu_addSynGroupIN = uidropdown(app.uipanel_selectPtsIn);
            app.popupmenu_addSynGroupIN.Items = {};
            app.popupmenu_addSynGroupIN.ValueChangedFcn = createCallbackFcn(app, @popupmenu_addSynGroupIN_Callback, true);
            app.popupmenu_addSynGroupIN.Tag = 'popupmenu_addSynGroupIN';
            app.popupmenu_addSynGroupIN.Value = {};
            app.edit_customIN = uitextarea(app.uipanel_selectPtsIn);
            app.edit_customIN.Tag = 'edit_customIN';
            app.pushbutton_addINlist = uibutton(app.uipanel_selectPtsIn, 'push');
            app.pushbutton_addINlist.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_addINlist_Callback, true);
            app.pushbutton_addINlist.Tag = 'pushbutton_addINlist';
            app.pushbutton_addINlist.Text = 'Make custom point group';
            app.togglebutton_getINpts = uibutton(app.uipanel_selectPtsIn, 'state');
            app.togglebutton_getINpts.ValueChangedFcn = createCallbackFcn(app, @togglebutton_getINpts_Callback, true);
            app.togglebutton_getINpts.Tag = 'togglebutton_getINpts';
            app.togglebutton_getINpts.Text = 'Get Probe Points';
            app.pushbutton_clearINpts = uibutton(app.uipanel_selectPtsIn, 'push');
            app.pushbutton_clearINpts.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_clearINpts_Callback, true);
            app.pushbutton_clearINpts.Tag = 'pushbutton_clearINpts';
            app.pushbutton_clearINpts.Text = 'Clear';
            app.uipanel_ptsOUT = uipanel(app.figure1);
            app.uipanel_ptsOUT.Title = 'Select Points OUT';
            app.uipanel_ptsOUT.Tag = 'uipanel_ptsOUT';
            app.edit_customOUT = uitextarea(app.uipanel_ptsOUT);
            app.edit_customOUT.Tag = 'edit_customOUT';
            app.listbox_outGroups = uilistbox(app.uipanel_ptsOUT);
            app.listbox_outGroups.Items = {};
            app.listbox_outGroups.ValueChangedFcn = createCallbackFcn(app, @listbox_outGroups_Callback, true);
            app.listbox_outGroups.Tag = 'listbox_outGroups';
            app.listbox_outGroups.Value = {};
            app.text8 = uilabel(app.uipanel_ptsOUT);
            app.text8.Tag = 'text8';
            app.text8.Text = 'Points OUT groups';
            app.text9 = uilabel(app.uipanel_ptsOUT);
            app.text9.Tag = 'text9';
            app.text9.Text = 'Add synapse groups';
            app.popupmenu_addSynGroupOUT = uidropdown(app.uipanel_ptsOUT);
            app.popupmenu_addSynGroupOUT.Items = {};
            app.popupmenu_addSynGroupOUT.ValueChangedFcn = createCallbackFcn(app, @popupmenu_addSynGroupOUT_Callback, true);
            app.popupmenu_addSynGroupOUT.Tag = 'popupmenu_addSynGroupOUT';
            app.popupmenu_addSynGroupOUT.Value = {};
            app.pushbutton_addCellBody = uibutton(app.uipanel_ptsOUT, 'push');
            app.pushbutton_addCellBody.Tag = 'pushbutton_addCellBody';
            app.pushbutton_addCellBody.Text = 'Add Cell Body';
            app.pushbutton_addFullArbor = uibutton(app.uipanel_ptsOUT, 'push');
            app.pushbutton_addFullArbor.Tag = 'pushbutton_addFullArbor';
            app.pushbutton_addFullArbor.Text = 'Add Full Arbor';
            app.pushbutton7 = uibutton(app.uipanel_ptsOUT, 'push');
            app.pushbutton7.ButtonPushedFcn = createCallbackFcn(app, @pushbutton7_Callback, true);
            app.pushbutton7.Tag = 'pushbutton7';
            app.pushbutton7.Text = 'Make custom point group';
            app.togglebutton_getProbePoints = uibutton(app.uipanel_ptsOUT, 'state');
            app.togglebutton_getProbePoints.ValueChangedFcn = createCallbackFcn(app, @togglebutton_getProbePoints_Callback, true);
            app.togglebutton_getProbePoints.Tag = 'togglebutton_getProbePoints';
            app.togglebutton_getProbePoints.Text = 'Get Probe Points';
            app.pushbutton_clearOUTpts = uibutton(app.uipanel_ptsOUT, 'push');
            app.pushbutton_clearOUTpts.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_clearOUTpts_Callback, true);
            app.pushbutton_clearOUTpts.Tag = 'pushbutton_clearOUTpts';
            app.pushbutton_clearOUTpts.Text = 'Clear';
            app.pushbutton_makeAllSM = uibutton(app.figure1, 'push');
            app.pushbutton_makeAllSM.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_makeAllSM_Callback, true);
            app.pushbutton_makeAllSM.Tag = 'pushbutton_makeAllSM';
            app.pushbutton_makeAllSM.Text = 'Analyze All Skeletons';
            app.text3 = uilabel(app.figure1);
            app.text3.Tag = 'text3';
            app.text3.Text = 'May take some time';
            app.textOut = uieditfield(app.figure1, 'text');
            app.textOut.Tag = 'textOut';
            app.textOut.Value = 'Ready';
            app.uipanel_distanceFunction = uipanel(app.figure1);
            app.uipanel_distanceFunction.Title = 'Distance Function';
            app.uipanel_distanceFunction.Tag = 'uipanel_distanceFunction';
            app.popupmenu_pickFunction = uidropdown(app.uipanel_distanceFunction);
            app.popupmenu_pickFunction.Items = {'w = 1', 'w = d', 'w = 1./(1+d)', 'w = 1./(1+d^2)', 'fixed radius length constant'};
            app.popupmenu_pickFunction.ValueChangedFcn = createCallbackFcn(app, @popupmenu_pickFunction_Callback, true);
            app.popupmenu_pickFunction.Tag = 'popupmenu_pickFunction';
            app.popupmenu_pickFunction.Value = 'w = 1';
            app.pushbutton_applyFunction = uibutton(app.uipanel_distanceFunction, 'push');
            app.pushbutton_applyFunction.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_applyFunction_Callback, true);
            app.pushbutton_applyFunction.Tag = 'pushbutton_applyFunction';
            app.pushbutton_applyFunction.Text = 'Apply Function';
            app.text11 = uilabel(app.uipanel_distanceFunction);
            app.text11.Tag = 'text11';
            app.text11.Text = 'weight vs distance + radius function';
            app.edit_dFunc = uieditfield(app.uipanel_distanceFunction, 'text');
            app.edit_dFunc.ValueChangedFcn = createCallbackFcn(app, @edit_dFunc_Callback, true);
            app.edit_dFunc.Tag = 'edit_dFunc';
            app.togglebutton_allowNonCell = uibutton(app.uipanel_distanceFunction, 'state');
            app.togglebutton_allowNonCell.Tag = 'togglebutton_allowNonCell';
            app.togglebutton_allowNonCell.Text = 'Single Cell Paths';
            app.togglebutton_allowNonCell.Value = true;
            app.uitable_con = uitable(app.figure1);
            app.uitable_con.Tag = 'uitable_con';
            app.pushbutton_printGraph = uibutton(app.figure1, 'push');
            app.pushbutton_printGraph.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_printGraph_Callback, true);
            app.pushbutton_printGraph.Tag = 'pushbutton_printGraph';
            app.pushbutton_printGraph.Text = 'Print Graph';
            app.togglebutton_groupIN = uibutton(app.figure1, 'state');
            app.togglebutton_groupIN.ValueChangedFcn = createCallbackFcn(app, @togglebutton_groupIN_Callback, true);
            app.togglebutton_groupIN.Tag = 'togglebutton_groupIN';
            app.togglebutton_groupIN.Text = 'Group IN';
            app.togglebutton_groupOUT = uibutton(app.figure1, 'state');
            app.togglebutton_groupOUT.ValueChangedFcn = createCallbackFcn(app, @togglebutton_groupOUT_Callback, true);
            app.togglebutton_groupOUT.Tag = 'togglebutton_groupOUT';
            app.togglebutton_groupOUT.Text = 'Group OUT';
            app.togglebutton_normalizeIN = uibutton(app.figure1, 'state');
            app.togglebutton_normalizeIN.ValueChangedFcn = createCallbackFcn(app, @togglebutton_normalizeIN_Callback, true);
            app.togglebutton_normalizeIN.Tag = 'togglebutton_normalizeIN';
            app.togglebutton_normalizeIN.Text = 'Normalize IN';
            app.togglebutton_normalizeOUT = uibutton(app.figure1, 'state');
            app.togglebutton_normalizeOUT.ValueChangedFcn = createCallbackFcn(app, @togglebutton_normalizeOUT_Callback, true);
            app.togglebutton_normalizeOUT.Tag = 'togglebutton_normalizeOUT';
            app.togglebutton_normalizeOUT.Text = 'Normalize OUT';
            app.uipanel_renderInfluence = uipanel(app.figure1);
            app.uipanel_renderInfluence.Title = 'Render influence';
            app.uipanel_renderInfluence.Tag = 'uipanel_renderInfluence';
            app.pushbutton_render = uibutton(app.uipanel_renderInfluence, 'push');
            app.pushbutton_render.Tag = 'pushbutton_render';
            app.pushbutton_render.Text = 'Render';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GraphPointToPoint_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)GraphPointToPoint_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end