classdef cellTable_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                 matlab.ui.Figure
        pushbutton_copyTable    matlab.ui.control.Button
        text4                   matlab.ui.control.Label
        text3                   matlab.ui.control.Label
        text2                   matlab.ui.control.Label
        popupmenu_addCellGroup  matlab.ui.control.DropDown
        uitable1                matlab.ui.control.Table
        edit_fields             matlab.ui.control.TextArea
        pushbutton_updateTable  matlab.ui.control.Button
        edit_cellList           matlab.ui.control.TextArea
    end

    
    methods (Access = private)
        function updateTable(app)
            
            
            global glob globTable tis
            
            
            h = globTable.handles;
            set(h.popupmenu_addCellGroup,'String',{glob.g.name});
            set(h.edit_cellList,'String',num2str(globTable.cids))
            
            allRes = [];
            
            c = 1;
            
            cellList = globTable.cids;
            
            
            typeNames = tis.cells.type.typeNames';
            typeNames = {'unassigned' typeNames{:}};
            tNum = length(typeNames);
            
            clear field
            field{1,1} = 'cid' ;
            field{2,1} = 'total syn';
            field{3,1} = 'total INPUTS';
            field(4:3+tNum,1) = typeNames;
            L = length(field);
            field{L+1,1} = 'total OUTPUTS';
            field(L+2:L+1+tNum,1) = typeNames;
            
            for c = 1:length(cellList);
                targ = cellList(c);
            
            
                isPreToTarg = find(tis.syn.post == targ);
                isPostToTarg = find(tis.syn.pre == targ);
            
                %pos = tis.syn.synPosDS(isSyn,[2 1 3]) * glob.em.dsRes(1);
                preClass = tis.syn.preClass(isPreToTarg)+1;
                postClass = tis.syn.postClass(isPostToTarg)+1;
                preSynType = tis.syn.synType(isPreToTarg);
                postSynType = tis.syn.synType(isPostToTarg);


                preHist = hist(preClass,1:length(typeNames));
                postHist = hist(postClass,1:length(typeNames));
            
                res(1,c) = targ;
                res(2,c) =  length(isPreToTarg) + length(isPostToTarg);
                res(3,c) = length(isPreToTarg);
                res(4:3+tNum,c) = preHist';
                res(L+1,c) = length(isPostToTarg);
                res(L+2: L+1 + tNum,c) =postHist';
            
            
            end
            
            has = sum(res,2);
            has(1:2) = 1;
            
            useField = field(has>0);
            useRes = res(has>0,:);
            resStr = num2str(useRes,'%d   ');
            rs = size(resStr,2);
            
            tab = char(' ');
            for i = 1:length(useField);
                tab(i,1:length(useField{i})) = useField{i};
                tab(i,21:20 + rs) = resStr(i,:);
            end
            
            globTable.useRes = useRes;
            globTable.tabStr = tab;
            
            set(globTable.handles.edit_fields,'String',useField(2:end));
            
            set(globTable.handles.uitable1,'RowName',useField(2:end));
            set(globTable.handles.uitable1,'ColumnName',cellList);
            set(globTable.handles.uitable1,'Data',useRes(2:end,:));
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function cellTable_OpeningFcn(app, varargin)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to cellTable (see VARARGIN)
            
            % Choose default command line output for cellTable
            handles.output = hObject;
            
            % Update handles structure
            guidata(hObject, handles);
            
            % UIWAIT makes cellTable wait for user response (see UIRESUME)
            % uiwait(handles.figure1);
            global globTable glob
            
            globTable.handles = handles;
            
            globTable.cids = glob.pickCID;
            
            
            updateTable(app)
        end

        % Value changed function: edit_cellList
        function edit_cellList_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edit_cellList (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edit_cellList as text
            %        str2double(get(hObject,'String')) returns contents of edit_cellList as a double
            
            global globTable
            
            str = get(hObject,'String');
            val = str2num(str);
            
            if ~isempty(val)
                globTable.cids = val;
            else
                'input needs numbers'
                set(hObject,'String','input needs numbers')
                pause(2)
                set(hObject,'String',num2str(globTable.cids))
            end
        end

        % Value changed function: popupmenu_addCellGroup
        function popupmenu_addCellGroup_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to popupmenu_addCellGroup (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns popupmenu_addCellGroup contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from popupmenu_addCellGroup
            
            global glob globTable
            
            val = get(hObject,'Value');
            globTable.cids = glob.g(val).cid;
            
            updateTable(app)
        end

        % Button pushed function: pushbutton_copyTable
        function pushbutton_copyTable_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton_copyTable (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            global globTable
            updateTable(app)
            
            clipboard('copy',globTable.useRes)
        end

        % Button pushed function: pushbutton_updateTable
        function pushbutton_updateTable_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton_updateTable (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            updateTable(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'cellTable';
            app.figure1.Tag = 'figure1';
            app.edit_cellList = uitextarea(app.figure1);
            app.edit_cellList.ValueChangedFcn = createCallbackFcn(app, @edit_cellList_Callback, true);
            app.edit_cellList.Tag = 'edit_cellList';
            app.pushbutton_updateTable = uibutton(app.figure1, 'push');
            app.pushbutton_updateTable.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_updateTable_Callback, true);
            app.pushbutton_updateTable.Tag = 'pushbutton_updateTable';
            app.pushbutton_updateTable.Text = 'Update Table';
            app.edit_fields = uitextarea(app.figure1);
            app.edit_fields.Tag = 'edit_fields';
            app.edit_fields.Value = {'Edit Text'};
            app.uitable1 = uitable(app.figure1);
            app.uitable1.Tag = 'uitable1';
            app.popupmenu_addCellGroup = uidropdown(app.figure1);
            app.popupmenu_addCellGroup.Items = {};
            app.popupmenu_addCellGroup.ValueChangedFcn = createCallbackFcn(app, @popupmenu_addCellGroup_Callback, true);
            app.popupmenu_addCellGroup.Tag = 'popupmenu_addCellGroup';
            app.popupmenu_addCellGroup.Value = {};
            app.text2 = uilabel(app.figure1);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Choose cell group';
            app.text3 = uilabel(app.figure1);
            app.text3.Tag = 'text3';
            app.text3.Text = 'Cells';
            app.text4 = uilabel(app.figure1);
            app.text4.Tag = 'text4';
            app.text4.Text = 'Fields';
            app.pushbutton_copyTable = uibutton(app.figure1, 'push');
            app.pushbutton_copyTable.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_copyTable_Callback, true);
            app.pushbutton_copyTable.Tag = 'pushbutton_copyTable';
            app.pushbutton_copyTable.Text = 'Copy Table to Clipboard';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = cellTable_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)cellTable_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end