classdef datLinkFig_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                      matlab.ui.Figure
        ImportDocdatatoCelNavButton  matlab.ui.control.Button
        ChooseVolumeDropDown         matlab.ui.control.DropDown
        ChooseVolumeDropDownLabel    matlab.ui.control.Label
        textOut                      matlab.ui.control.EditField
        data2Tis                     matlab.ui.control.Button
        text5                        matlab.ui.control.Label
        edit_fileDestination         matlab.ui.control.EditField
        pushbutton_makeDataFile      matlab.ui.control.Button
        edit_aliasSheet              matlab.ui.control.TextArea
        text3                        matlab.ui.control.Label
        edit_dataSheet               matlab.ui.control.TextArea
        text2                        matlab.ui.control.Label
    end


    methods (Access = private)
        function [fileName, GID] = parseSheetAddress(app, datStr)

            datStr = datStr{1};
            slash = regexp(datStr,'/');
            fileName = datStr(slash(end-1)+1:slash(end)-1);
            gidE  = regexp(datStr,'gid=');
            GID = datStr(gidE(end)+4:end);
        end

        function popupmenu1_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to popupmenu1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called

            % Hint: popupmenu controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end


        function results = loadSheetDat(app)

            global glob
            try
                load([glob.NA.export.datDir 'dat.mat'])
                glob.NA.export.googleLinks = dat.googleLinks;

                https =  'https://docs.google.com/spreadsheets/d/';
                datPath = sprintf('%s%s/edit#gid=%s',https,dat.googleLinks.datSheet,dat.googleLinks.datGID);
                aliasPath = sprintf('%s%s/edit#gid=%s',https,dat.googleLinks.datSheet,dat.googleLinks.aliasGID);

                glob.NA.export.aliasPath = aliasPath;
                glob.NA.export.datPath = datPath;

                app.edit_aliasSheet.Value = aliasPath;
                app.edit_dataSheet.Value = datPath;
            catch err
                app.textOut.Value = 'No dat.mat found';
            end

        end

        function results = setVolumeDir(app)

            global glob

            MPN = glob.NA.MPN;

            strs = app.ChooseVolumeDropDown.Items;
            val = app.ChooseVolumeDropDown.Value;
            activeVolume = strs{val};

            if strcmp(activeVolume, 'Main')
                dsp('Select volume for dat')
                glob.NA.export.datDir = MPN;
            else
                glob.NA.export.datDir = [MPN activeVolume '\Merge\'];
            end

            app.edit_fileDestination.Value = glob.NA.export.datDir;
            glob.NA.export.volume = activeVolume;

        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function datLinkFig_OpeningFcn(app, varargin)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>

            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to datLinkFig (see VARARGIN)

            % Choose default command line output for datLinkFig
            handles.output = hObject;

            % Update handles structure
            guidata(hObject, handles);


            global glob

            activeVolume = glob.vol.activeName;
            volumes = glob.vol.names;
            app.ChooseVolumeDropDown.Items = volumes;
            app.ChooseVolumeDropDown.ItemsData = 1:length(volumes);
            app.ChooseVolumeDropDown.Value = find(strcmp(volumes,activeVolume),1);

            glob.NA.export.volume = activeVolume;


            setVolumeDir(app)
            loadSheetDat(app)

            app.textOut.Value = 'Ready';


        end

        % Button pushed function: data2Tis
        function data2Tis_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to data2Tis (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            global glob tis

            app.textOut.Value = 'Making tis.mat';
            pause(.01)

            load([glob.NA.export.datDir 'dat.mat'])
            load([glob.NA.export.datDir 'obI.mat'])
            binder.obI = obI;
            binder.dat = dat;

            tis = makeTis(binder);
            fvDir = [glob.dir.Volumes glob.NA.export.volume '\Analysis\fvLibrary\'];
            save([fvDir 'tis.mat'],'tis');
            app.textOut.Value = 'Finished making tis.mat. Updating CellNav.';
            updateCellNavGlob
            app.textOut.Value = 'Finished updating CellNav.  Reselection of volume may be necessary.';

        end

        % Button pushed function: pushbutton_makeDataFile
        function pushbutton_makeDataFile_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            app.textOut.Value = 'Making dat file from Google document';
            pause(.01)

            % hObject    handle to pushbutton_makeDataFile (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            global glob

            datStr = app.edit_dataSheet.Value;
            [dat.googleLinks.datSheet dat.googleLinks.datGID] ...
                = parseSheetAddress(app, datStr);

            datStr = app.edit_aliasSheet.Value;
            [a  dat.googleLinks.aliasGID]  = parseSheetAddress(app, datStr);

            dat = parseGoogleDat(dat)
            save([glob.NA.export.datDir 'dat.mat'],'dat')

            app.textOut.Value = 'Done making dat.mat';
        end

        % Value changed function: ChooseVolumeDropDown
        function ChooseVolumeDropDown_function(app, event)
            value = app.ChooseVolumeDropDown.Value;
            strs = app.ChooseVolumeDropDown.Items;
            str = strs{value};

            setVolumeDir(app)
            loadSheetDat(app)

            app.textOut.Value = sprintf('ready to get data for volume %s',str);


        end

        % Button pushed function: ImportDocdatatoCelNavButton
        function fullImport(app, event)
            app.textOut.Value = 'Making dat file from Google document';
            pause(.01)

            % hObject    handle to pushbutton_makeDataFile (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            global glob

            datStr = app.edit_dataSheet.Value;
            [dat.googleLinks.datSheet dat.googleLinks.datGID] ...
                = parseSheetAddress(app, datStr);

            datStr = app.edit_aliasSheet.Value;
            [a  dat.googleLinks.aliasGID]  = parseSheetAddress(app, datStr);

            dat = parseGoogleDat(dat)
            save([glob.NA.export.datDir 'dat.mat'],'dat')

            app.textOut.Value = 'Done making dat.mat';


            %%Make tis

            app.textOut.Value = 'Making tis.mat';
            pause(.01)

            load([glob.NA.export.datDir 'dat.mat'])
            load([glob.NA.export.datDir 'obI.mat'])
            binder.obI = obI;
            binder.dat = dat;

            tis = makeTis(binder);
            fvDir = [glob.dir.Volumes glob.NA.export.volume '\Analysis\fvLibrary\'];
            save([fvDir 'tis.mat'],'tis');
            app.textOut.Value = 'Finished making tis.mat. Updating CellNav.';
            updateCellNavGlob
            app.textOut.Value = 'Finished updating CellNav.  Reselection of volume may be necessary.';


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'datLinkFig';
            app.figure1.Tag = 'figure1';
            app.text2 = uilabel(app.figure1);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Data Sheet';
            app.edit_dataSheet = uitextarea(app.figure1);
            app.edit_dataSheet.Tag = 'edit_dataSheet';
            app.text3 = uilabel(app.figure1);
            app.text3.Tag = 'text3';
            app.text3.Text = 'Alias Sheet';
            app.edit_aliasSheet = uitextarea(app.figure1);
            app.edit_aliasSheet.Tag = 'edit_aliasSheet';
            app.pushbutton_makeDataFile = uibutton(app.figure1, 'push');
            app.pushbutton_makeDataFile.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_makeDataFile_Callback, true);
            app.pushbutton_makeDataFile.Tag = 'pushbutton_makeDataFile';
            app.pushbutton_makeDataFile.Text = 'Make Data Link File (only)';
            app.edit_fileDestination = uieditfield(app.figure1, 'text');
            app.edit_fileDestination.Tag = 'edit_fileDestination';
            app.edit_fileDestination.Value = 'Y:\Active\morganLab\karlsRetina\CellNavLibrary_IxQ\Volumes\';
            app.text5 = uilabel(app.figure1);
            app.text5.Tag = 'text5';
            app.text5.Text = {'Data File'; 'Destination'};
            app.data2Tis = uibutton(app.figure1, 'push');
            app.data2Tis.ButtonPushedFcn = createCallbackFcn(app, @data2Tis_Callback, true);
            app.data2Tis.Tag = 'data2Tis';
            app.data2Tis.Text = 'Import Linked Data To Library (only)';
            app.textOut = uieditfield(app.figure1, 'text');
            app.ChooseVolumeDropDownLabel = uilabel(app.figure1);
            app.ChooseVolumeDropDownLabel.Text = 'Choose Volume';
            app.ChooseVolumeDropDown = uidropdown(app.figure1);
            app.ChooseVolumeDropDown.ValueChangedFcn = createCallbackFcn(app, @ChooseVolumeDropDown_function, true);
            app.ImportDocdatatoCelNavButton = uibutton(app.figure1, 'push');
            app.ImportDocdatatoCelNavButton.ButtonPushedFcn = createCallbackFcn(app, @fullImport, true);
            app.ImportDocdatatoCelNavButton.Text = 'Import Doc data to CelNav';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = datLinkFig_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)datLinkFig_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end