classdef editSkeleton_AppFig < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                        matlab.ui.Figure
        uitoolbar1                     matlab.ui.container.Toolbar
        uitoggletool1                  matlab.ui.container.toolbar.ToggleTool
        uitoggletool2                  matlab.ui.container.toolbar.ToggleTool
        uitoggletool3                  matlab.ui.container.toolbar.ToggleTool
        uitoggletool4                  matlab.ui.container.toolbar.ToggleTool
        uitoggletool5                  matlab.ui.container.toolbar.ToggleTool
        ConditionSkeletonPanel         matlab.ui.container.Panel
        ShowerrorsButton               matlab.ui.control.StateButton
        ConditionButton                matlab.ui.control.Button
        SkeletonpropertiesPanel        matlab.ui.container.Panel
        RemapButton                    matlab.ui.control.Button
        colordisconnectedCheckBox      matlab.ui.control.CheckBox
        colorbranchesCheckBox          matlab.ui.control.CheckBox
        radiusEditField                matlab.ui.control.EditField
        radiusEditFieldLabel           matlab.ui.control.Label
        randColCheckBox                matlab.ui.control.CheckBox
        zcropper_uibuttongroup         matlab.ui.container.ButtonGroup
        uipanel_nodes                  matlab.ui.container.Panel
        togglebutton_nepOriginal       matlab.ui.control.StateButton
        pushbutton_nodeDown            matlab.ui.control.Button
        pushbutton_nodeUp              matlab.ui.control.Button
        edit_nodes                     matlab.ui.control.EditField
        Xmax                           matlab.ui.control.Slider
        Xmin                           matlab.ui.control.Slider
        togglebutton_edit              matlab.ui.control.StateButton
        uipanel_file                   matlab.ui.container.Panel
        pushbutton_makeSM_2            matlab.ui.control.Button
        pushbutton_saveCustom          matlab.ui.control.Button
        pushbutton_saveToLibrary       matlab.ui.control.Button
        pushbutton_loadCustom          matlab.ui.control.Button
        pushbutton_makeSM              matlab.ui.control.Button
        edit_saveName                  matlab.ui.control.EditField
        text4                          matlab.ui.control.Label
        edit_saveFolder                matlab.ui.control.EditField
        pushbutton_folder              matlab.ui.control.Button
        uipanel6                       matlab.ui.container.Panel
        pushbutton_origin              matlab.ui.control.StateButton
        pushbutton_XY                  matlab.ui.control.Button
        pushbutton_XZ                  matlab.ui.control.Button
        pushbutton_YZ                  matlab.ui.control.Button
        togglebutton_XYZ               matlab.ui.control.StateButton
        uipanel5                       matlab.ui.container.Panel
        togglebutton_Reset             matlab.ui.control.Button
        text3                          matlab.ui.control.Label
        edit_winSize                   matlab.ui.control.EditField
        togglebutton_hideWin           matlab.ui.control.StateButton
        pushbutton_center              matlab.ui.control.StateButton
        pushbutton_clip                matlab.ui.control.Button
        Zmin                           matlab.ui.control.Slider
        Zmax                           matlab.ui.control.Slider
        Ymax                           matlab.ui.control.Slider
        Ymin                           matlab.ui.control.Slider
        textOut                        matlab.ui.control.TextArea
        uipanel4                       matlab.ui.container.Panel
        pushbutton_clear               matlab.ui.control.Button
        load_pushbutton                matlab.ui.control.Button
        text2                          matlab.ui.control.Label
        edit_CID                       matlab.ui.control.EditField
        uipanel_pick                   matlab.ui.container.Panel
        togglebutton_clearPick         matlab.ui.control.StateButton
        pickNode_togglebutton          matlab.ui.control.StateButton
        pickEdge_togglebutton          matlab.ui.control.StateButton
        show_uipanel                   matlab.ui.container.Panel
        togglebutton_showEdges_2       matlab.ui.control.StateButton
        togglebutton13_2               matlab.ui.control.StateButton
        togglebutton12_2               matlab.ui.control.StateButton
        togglebutton_showVerts         matlab.ui.control.StateButton
        togglebutton19                 matlab.ui.control.StateButton
        togglebutton18                 matlab.ui.control.StateButton
        togglebutton_showNodes         matlab.ui.control.StateButton
        togglebutton16                 matlab.ui.control.StateButton
        togglebutton15                 matlab.ui.control.StateButton
        togglebutton_showEdges         matlab.ui.control.StateButton
        togglebutton13                 matlab.ui.control.StateButton
        togglebutton12                 matlab.ui.control.StateButton
        togglebutton_showRadiusMinus   matlab.ui.control.StateButton
        togglebutton_showRadiusPlus    matlab.ui.control.StateButton
        togglebutton_showSurface       matlab.ui.control.StateButton
        togglebutton_showSurfaceMinus  matlab.ui.control.StateButton
        togglebutton_showSurfacePlus   matlab.ui.control.StateButton
        togglebutton_showRad           matlab.ui.control.StateButton
        remove_pushbutton              matlab.ui.control.Button
        Branch_uibuttongroup           matlab.ui.container.ButtonGroup
        clear_pushbutton               matlab.ui.control.Button
        merge_pushbutton               matlab.ui.control.Button
        backup_pushbutton              matlab.ui.control.Button
        add_pushbutton                 matlab.ui.control.Button
        start_pushbutton               matlab.ui.control.Button
        brushPanel                     matlab.ui.container.Panel
        surfaceToNode_pushbutton       matlab.ui.control.Button
        brushToEdge_pushbutton         matlab.ui.control.Button
        brushNode_togglebutton         matlab.ui.control.StateButton
        mainAx                         matlab.ui.control.UIAxes
    end


    methods (Access = private)
        function addPtToBranch(app)
            global globES

            if globES.branch.on
                if isfield(globES.data, 'newPt')
                    if length(globES.data.newPt) == 3
                        globES.branch.nodes = cat(1,globES.branch.nodes,globES.data.newPt(:)');
                        globES.branch.rads = cat(1,globES.branch.rads,globES.data.newRad);
                    end
                end
            end
            showBranch(app)
            globES.branch
        end

        function brushEdge_togglebutton_Callback(app, hObject, eventdata, handles)
            % hObject    handle to brushEdge_togglebutton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hint: get(hObject,'Value') returns toggle state of brushEdge_togglebutton


            val = get(hObject,'Value');

            if val
                makeBrush(app)
            else
                deleteBrush(app)
            end
        end

        function clearAxis(app)

            global globES

            p = fields(globES.p);
            for i = 1:length(p)
                try
                    eval(sprintf('globES.p.%s.delete;',p{i}));
                    globES.p = rmfield(globES.p,p{i});
                end
            end
        end

        function deleteBrush(app)
            global glob globES

            brush(globES.fig,'off')
            try set(globES.brushOb,'enable','off'),catch,end
            brush(globES.fig,'off')
            try set(globES.brushOb,'enable','off'); end
            try delete(globES.p.newPt), end
            try delete(globES.p.skelPt), end
        end

        function deselectToggleUI(app, handles, hObject)

            tag = get(hObject,'Tag');
            hList = {'pickNode_togglebutton', 'pickEdge_togglebutton',...
                'pickSurface_togglebutton'};

            for i = 1:length(hList)
                if ~strcmp(tag,hList{i})
                    try
                        set(eval(['handles.' hList{i}]),'Value',0);
                        % eval([hList{i} '_Callback(handles.' hList{i} ',[],handles)']);
                    end
                end
            end
        end

        function txt = displayCoordinates(app, ~, info)
            global glob globES
            X = info.Position(2);%!dim
            Y = info.Position(1);
            Z = info.Position(3);
            %c_info = getCursorInfo(glob.dcm)

            anc2sub = (glob.em.res/ 1000);

            umPos = [X Y Z] ;
            umPosStr = sprintf('%.0f  %.0f  %.0f',umPos(end,2),umPos(end,1),umPos(end,3));
            disp(umPos)

            VASTpos = round([Y X Z] ./ anc2sub);
            VASTposStr = sprintf('%.0f \r%.0f \r%.0f um',umPos(end,2),umPos(end,1),umPos(end,3));

            set(globES.h.textOut,'String',VASTpos);
            tag = get(info.Target,'Tag');

            txt = umPosStr;

            clipboard('copy',VASTpos)
        end

        function file_popupmenu_Callback(app, hObject, eventdata, handles)
            % hObject    handle to file_popupmenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: contents = cellstr(get(hObject,'String')) returns file_popupmenu contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from file_popupmenu

            global glob globES

            strs = get(hObject,'String');
            val = get(hObject,'Value');
            str = strs{val};

            if strcmp(str,'Load CID')
                set(handles.textOut,'String','Loading')
                loadCID(app)
                set(handles.textOut,'String','Finished loading')

            elseif strcmp(str,'Load Target Skeleton')
                set(handles.textOut,'String','Loading')
                [TFN TPN] = uigetfile(globES.f.saveDir);
                loadCID(app, [TPN '/' TFN])
                set(handles.textOut,'String',sprintf('Finished loading %s',TFN))

            elseif strcmp(str,'Save')
                set(handles.textOut,'String','Saving')
                nep = makeNep(app);
                fileName = sprintf('%snep_%d.mat',globES.f.useFvDir,globES.pickCID);
                save(globES.data.fileName,'nep');
                set(handles.textOut,'String',sprintf('Finished saving %s',fileName))
            elseif strcmp(str,'Save As')
                set(handles.textOut,'String','Saving')
                fileName = sprintf('%s%s.mat',globES.f.saveDir,globES.f.saveName);
                nep = makeNep(app);
                save(fileName,'nep')
                set(handles.textOut,'String',sprintf('Finished saving %s',fileName))
            end
        end

        function file_popupmenu_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to file_popupmenu (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called

            % Hint: popupmenu controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end

        function goToNode(app, num)
            global globES

            if globES.view.showOriginal
                nep = globES.data.autoNep;
            else
                nep = globES.data.currentNep;
            end

            nodes = unique(nep.bones(num).edges(:));
            pos = nep.pos(nodes,:);
            edges = nep.bones(num).edges;

            meanPos = mean(pos,1);

            ePos1 = nep.pos(edges(:,1),:);
            ePos2 = nep.pos(edges(:,2),:);

            X = meanPos(2);%!dim
            Y = meanPos(1);
            Z = meanPos(3);
            %c_info = getCursorInfo(glob.dcm)

            [a b] = view;plot3
            yl = get(globES.ax,'YLim');
            xl = get(globES.ax,'XLim');
            zl = get(globES.ax,'ZLim');
            yr = diff(yl)/2;
            xr = diff(xl)/2;
            zr = diff(zl)/2;
            set(globES.ax,'XLim',[X-xr X+xr],...
                'YLim',[Y-yr Y+yr],'ZLim',[Z-zr Z+zr]);
            view([a b]);

            try delete(globES.p.goToNode); catch, end
            globES.p.goToNode = scatter3(globES.ax,pos(:,2),pos(:,1),pos(:,3),50,...
                'o','markeredgecolor',[1 .4 0],'markerfacealpha',0,'linewidth',2);

            try delete(globES.p.goToEdge); catch, end
            globES.p.goToEdge = plot3(globES.ax,[ePos1(:,2) ePos2(:,2)]',...
                [ePos1(:,1) ePos2(:,1)]',[ePos1(:,3) ePos2(:,3)]',...
                'color',[1 .5 0],'linewidth',2);
        end

        function goToNode2(app, num)

            global globES

            pos = globES.data.pos(num,:);

            X = pos(2);%!dim
            Y = pos(1);
            Z = pos(3);
            %c_info = getCursorInfo(glob.dcm)

            [a b] = view;
            yl = get(globES.ax,'YLim');
            xl = get(globES.ax,'XLim');
            zl = get(globES.ax,'ZLim');
            yr = diff(yl)/2;
            xr = diff(xl)/2;
            zr = diff(zl)/2;
            set(globES.ax,'XLim',[X-xr X+xr],...
                'YLim',[Y-yr Y+yr],'ZLim',[Z-zr Z+zr]);
            view([a b]);

            try delete(globES.p.goToNode); end
            %globES.p.goToNode = scatter3(globES.ax,X,Y,Z,300,'r','o','filled','markerfacealpha',.3)
            globES.p.goToNode = scatter3(globES.ax,X,Y,Z,700,'r','o','markerfacealpha',0)
        end

        function loadCID(app, nepFileName)

            global glob globES

            try   delete(globES.p.skel), end
            try    delete(globES.p.plotSkel), end;
            try    delete(globES.p.scatterSkel), end;
            try   delete(globES.p.surface), end;
            try   delete(globES.p.rad), end;
            try   delete(globES.p.radConst), end;
            try   delete(globES.p.vert), end;
            try   delete(globES.p.seed), end;
            try   delete(globES.p.cell), end;
            try   delete(globES.p.volPatch), end;


            fileName = sprintf('%s%d.mat',globES.f.useFvDir,globES.pickCID);
            fv = loadFV(fileName);
            %globSE.p.surface = renderFVnav(fv,[0 .8 0],.2)
            globES.data.vert = fv.vertices;
            if exist(fileName,'file')
                fv = loadFV(fileName);
                fv.vertices = fv.vertices(:,[1 2 3]) ;
                try delete(globES.p.volPatch); end
                globES.p.volPatch = renderFVnavAx(globES.ax,fv,[1 1 1],.6);
                globES.p.volPatch.FaceAlpha = globES.view.surfAlph;
                set(globES.h.togglebutton_showSurface,'Value',1)

                %globES.p.fullPatch =  renderFVnav(fv,[1 1 1],.2); %% show full patch outside of window

                cellRange = cat(1, min(fv.vertices,[],1), max(fv.vertices,[],1));
                cellRange = cellRange(:,[3 2 1]);
                %    scatter3(globES.ax,cellRange(1,1),cellRange(1,2),cellRange(1,3),'*','r')
                %   scatter3(globES.ax,cellRange(2,1),cellRange(2,2),cellRange(2,3),'*','r')
                globES.view.cellRange = cellRange;
                globES.view.window = cellRange;
                app.textOut.Value = 'Found volume';
            else
                app.textOut.Value = 'Did not find volume.'
            end
            try  globES.p.cell.FaceAlpha = .25; end
            pause(.01)


            if exist('nepFileName','var')
                fileName = nepFileName;
            else
                fileName = sprintf('%snep_%d.mat',globES.f.useFvDir,globES.pickCID);
            end


            if 1 % show skel with edges and nodes
                if exist(fileName,'file')
                    %fv = loadFV(fileName);
                    app.textOut.Value = 'Found skeleton.';
                    load(fileName)

                else

                    smDir = [glob.dir.Volumes  glob.vol.activeName '\Analysis\SMs\'];
                    smFileName = sprintf('%ssmx_cid%d.mat',smDir,globES.pickCID);
                    if exist(smFileName,'file')
                        load(smFileName,'nep');
                        save(fileName,'nep');
                    end
                end

                if exist('nep','var')
                    globES.data.currentNep = nep;
                    if isfield(nep,'autoNep')
                        globES.data.autoNep = nep.autoNep;
                    else
                        globES.data.autoNep = nep;
                    end
                    hold on
                    pos = nep.pos;
                    globES.data.fileName = fileName;
                    edges = nep.edges;
                    globES.data.pos = pos;
                    globES.data.edges = edges;
                    globES.data.seedNode = nep.seedNode;
                    globES.data.seedPos = pos(nep.seedNode,:);
                    globES.data.rads = nep.nodeRad;
                    globES.data.fv = nep.fv;
                    %         try delete(globES.p.nepFV), end
                    %         globES.p.nepFV = renderFV(nep.fv,[1 1 1],.3);

                    globES.data.bones = nep.bones;
                    globES.view.pickNumMax = length(nep.bones);
                    globES.data.autoPos = pos;

                    showScatterSkel(app)
                    mapSkeleton(app)
                    if app.togglebutton_showEdges.Value
                        showEdges(app)
                    end
                    if ~app.togglebutton_showNodes.Value
                        globES.p.scatterSkel.Visible = 'off';

                    end
                    if ~app.togglebutton_showEdges_2.Value
                        globES.p.radConst.Visible = 'off';
                    end

                    globES.p.seed = scatter3(globES.ax,globES.data.seedPos(2),globES.data.seedPos(1),...
                        globES.data.seedPos(3),300,'g','o')
                else
                    app.textOut.Value = 'Did not find skeleton.'
                end
            end

            %%Show points across surface of cell. needed for selection
            showVerts(app)
            pause(.01)

            %%Check radius
            radVal = get(globES.h.radius_togglebutton,'Value');
            if radVal
                showRad(app)
            else
                try delete(globES.p.rad), end
            end

            globES.f.saveName = sprintf('nep_%d_edit',globES.pickCID);
            set(globES.h.edit_saveName,'String',globES.f.saveName);
        end

        function makeBrush(app)
            global glob globES

            globES.brushOb = brush(globES.fig);
            set(globES.brushOb,'enable','on')
        end

        function nep = makeNep(app)

            global globES

            seedPos = globES.data.seedPos;
            edges = globES.data.edges;
            nodes = globES.data.pos; % will go wrong if gaps
            rads = globES.data.rads;

            nodePos = globES.data.pos;
            dists = getDist(nodePos,seedPos);

            clear nep
            nep.seedNode = find(dists==min(dists));
            nep.pos = nodePos;
            nep.nodes = [1:size(nodePos,1)];
            nep.edges = edges;
            nep.edgeRad  = mean(rads(edges),2);
            nep.nodeRad = rads;

            nep = groupNepEdges(nep);
            %showRadSurf(nep.pos,nep.edges, nep.nodeRad)

            nep = edgeGroup2bones(nep);
            nep = smoothRad(nep,.2);
            nep.fv = globES.data.fv;
            nep.props = getNepProps(nep);
            nep.autoNep = globES.data.autoNep;
            globES.data.currentNep = nep;
        end

        function txt = pickEdge(app, ~, info)
            global globES


            globES.data.skelSelect = [];
            globES.data.skelPt = [];

            tag = info.Target.Tag;
            if strcmp(tag,'plotSkel')

                ePos = [info.Target.XData; info.Target.YData;info.Target.ZData];
                ePos = ePos([2 1 3],:);
                pos = globES.data.pos;
                n1 = find(sum(abs(pos - repmat(ePos(:,1)',[size(pos,1) 1])),2)==0,1);
                n2 = find(sum(abs(pos - repmat(ePos(:,2)',[size(pos,1) 1])),2)==0,1);

                edges = globES.data.edges;
                dE1 = edges - n1;
                dE2 = edges - n2;
                hit = find(~dE1(:,1) & ~dE2(:,2));
                if isempty(hit)
                    hit = find(~dE1(:,2) & ~dE2(:,1));
                end

                globES.pick.edgeID = [globES.pick.edgeID hit];
                globES.pick.edgePos = cat(3,globES.pick.edgePos, [pos(n1,:); pos(n2,:)]);
                %     edgePos = permute(globES.pick.edgePos,[3 1 2]);
                %     globES.pick.edgePos1 = edgePos(:,:,2);
                %     globES.pick.edgePos2 = edgePos(:,:,1);
                %     globES.pick.edgePos3 = edgePos(:,:,3);
                % refreshdata
                %     drawnow

            elseif strcmp(tag,'scatSkel')
                meanPt = info.Position([2 1 3]);
                pos = globES.data.pos;
                dists = sqrt((pos(:,1)-meanPt(1)).^2 + (pos(:,2)-meanPt(2)).^2 + ...
                    (pos(:,3) - meanPt(3)).^2);
                targ =  find(dists == 0,1);

                globES.data.newPt = meanPt;
                globES.data.newRad = globES.data.rads(targ);
                globES.pick.nodeID = [globES.pick.nodeID targ];
                globES.pick.nodePos = meanPt;
            end

            txt = sprintf('picked edges =  %s, nodes = %s',...
                num2str(globES.pick.edgeID),num2str(globES.pick.nodeID));
        end

        function txt = pickWindowCenter(app, ~, info)
            global globES
            X = info.Position(2);
            Y = info.Position(1);
            Z = info.Position(3);
            %c_info = getCursorInfo(glob.dcm)

            str = sprintf('X = %0.1f, Y = %0.1f, Z = %0.1f',X,Y,Z);

            set(globES.h.textOut,'String',str);
            tag = get(info.Target,'Tag');

            txt = str;

            rad = globES.view.winWidth/2;

            globES.view.window = [Y-rad X-rad Z-rad; Y+rad X+rad Z+rad]; %!dim
            if globES.h.togglebutton_hideWin.Value
                showWindow(app)
            end

            if 1 % Move to origin
                X = info.Position(1);%!dim
                Y = info.Position(2);
                Z = info.Position(3);
                %c_info = getCursorInfo(glob.dcm)

                [a b] = view;
                yl = get(globES.ax,'YLim');
                xl = get(globES.ax,'XLim');
                zl = get(globES.ax,'ZLim');
                yr = diff(yl)/2;
                xr = diff(xl)/2;
                zr = diff(zl)/2;
                set(globES.ax,'XLim',[X-xr X+xr],...
                    'YLim',[Y-yr Y+yr],'ZLim',[Z-zr Z+zr]);
                view([a b]);
            end


            globES.dcm.UpdateFcn = [];
            set(globES.dcm,'Enable','off')
            updateCenter(app) %%Show clipped
        end

        function popupmenu2_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to popupmenu2 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called

            % Hint: popupmenu controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end

        function txt = setOrigin(app, ~, info)

            global globES
            X = info.Position(1);%!dim
            Y = info.Position(2);
            Z = info.Position(3);
            %c_info = getCursorInfo(glob.dcm)

            [a b] = view;
            yl = get(globES.ax,'YLim');
            xl = get(globES.ax,'XLim');
            zl = get(globES.ax,'ZLim');
            yr = diff(yl)/2;
            xr = diff(xl)/2;
            zr = diff(zl)/2;
            set(globES.ax,'XLim',[X-xr X+xr],...
                'YLim',[Y-yr Y+yr],'ZLim',[Z-zr Z+zr]);
            view([a b]);
            txt = 'origin'
        end

        function showBranch(app)

            global globES

            n = globES.branch.nodes;

            if globES.branch.on
                set(globES.h.Branch_uibuttongroup,'BackgroundColor',[1 1 0.70]);
            else
                set(globES.h.Branch_uibuttongroup,'BackgroundColor',[.94 .94 .94]);
            end

            try delete(globES.p.branchNodes), end
            if size(n,1)>0
                globES.p.branchNodes = scatter3(globES.ax,n(:,2),n(:,1),n(:,3),100,'o','c','filled');
            end


            try delete(globES.p.branchEdges), end
            if size(n,1)>1
                globES.p.branchEdges = plot3(globES.ax,[n(1:end-1,2) n(2:end,2)],...
                    [n(1:end-1,1) n(2:end,1)],[n(1:end-1,3) n(2:end,3)],'c','linewidth',4);
            end


            try delete(globES.p.branchRad), end

            posR = globES.branch.nodes;
            L = size(posR,1);
            edgeR = cat(2,[1:L-1]', [2:L]');
            radR = globES.branch.rads;
            nodeColR = [1 1 0];
            alph = 1;

            %posR(:,1) = posR(:,1) * -1;
            if L>1
                %[fvRad globES.p.branchRad] = showRadSurf_cn(posR(:,[2 1 3]),edgeR,radR,nodeColR,alph,globES.fig)
                [fvRad globES.p.branchRad] = showRadPts_cn(posR,edgeR,radR,nodeColR,alph)

            elseif L==1
                posR = cat(1,posR,posR+.1);
                edgeR = [1 2];
                radR = [radR radR];
                [fvRad globES.p.branchRad] = showRadSurf_cn(posR,edgeR,radR,nodeColR,alph,globES.fig)

            end
        end

        function showEdges(app)

            global globES

            pos = globES.data.pos;
            edges = globES.data.edges;

            if globES.view.clip
                [isPos isEdge] = winFilt(app, globES.view.window,pos,edges);
                %pos = pos(isPos,:);
                edges = edges(isEdge,:);
            end


            try delete(globES.p.plotSkel); catch, end
            set(globES.ax,'Clipping','off')
            globES.p.plotSkel = plot3(globES.ax,[pos(edges(:,1),2) pos(edges(:,2),2)]',...
                [pos(edges(:,1),1) pos(edges(:,2),1)]',...
                [pos(edges(:,1),3) pos(edges(:,2),3)]','color',[0 .8 0],'linewidth',2);
            set(globES.p.plotSkel,'Tag','plotSkel')
        end

        function showRad(app)

            global globES

            try delete(globES.p.rad), end

            posR = globES.data.pos;
            edgeR = globES.data.edges;
            radR = globES.data.rads;
            nodeNum = length(radR);
            nodeColR = ones(nodeNum,3);
            alph = .4;

            [fvRad globES.p.rad] = showRadSurf_cn(posR(:,[2 1 3]),edgeR,radR,[1 0 0],alph,globES.fig);
        end

        function showScatterSkel(app)

            global globES

            pos = globES.data.pos;
            globES.data.nodeInd = 1:size(pos,1);
            if globES.view.clip
                [isPos isEdge] = winFilt(app, globES.view.window,pos);
                globES.data.nodeInd = find(isPos);
                pos = pos(isPos,:);
            end


            try delete(globES.p.scatterSkel); catch, end
            set(globES.ax,'Clipping','Off');
            globES.p.scatterSkel = scatter3(globES.ax,pos(:,2),pos(:,1),pos(:,3),30,...
                'o','markeredgecolor',[0 .2 0],'markerfacecolor',[0 1 0]);
            set(globES.p.scatterSkel,'Tag','scatSkel')
        end

        function showVerts(app)

            global globES

            %%Make scatter vertices for selection

            vert = globES.data.vert;
            dsamp = 10; % 1 = a few surface spots.  1000= ~all
            v = round(vert*dsamp);
            maxV = ceil(max(v,[],1));
            ind = sub2ind(maxV,v(:,1),v(:,2),v(:,3));
            uInd = unique(ind);
            [y x z] = ind2sub(maxV,uInd);
            v = [y x z] * 1/dsamp;
            v = v(:,[3 2 1]); %!dim
            %v = vert; %!!!!!!

            globES.data.vertInd = 1:size(v,1);
            if globES.view.clip
                [isPos isEdge] = winFilt(app, globES.view.window,v(:,[2 1 3])); %!dim
                globES.data.vertInd = find(isPos);
                v = v(isPos,:);
            end


            set(globES.ax, 'NextPlot', 'add');
            try delete(globES.p.vert); end
            set(globES.ax,'Clipping','Off');
            globES.p.vert = scatter3(globES.ax,v(:,1),v(:,2),v(:,3),5,...
                'b','filled','MarkerEdgeAlpha',0,'MarkerFaceAlpha',0); %!dim
            %set(globES.p.vert,'visible','off')
            set(globES.p.vert,'Tag','vert')
        end

        function showWindow(app)

            global globES


            win = globES.view.window';

            cornPts = [1 2 3; 1 2 6; 1 5 3; 1 5 6;...
                4 2 3; 4 2 6; 4 5 3; 4 5 6];
            cornPos = win(cornPts);

            cornFac = [3 1 2 4 ; 6 5 7 8;1 2 6 5; 3 4 8 7; 2 4 8 6 ; 5 1 3 7];

            fv.vertices = cornPos;
            fv.faces = cornFac;

            try delete(globES.p.win); end
            globES.p.win = patch(globES.ax,fv,'facecolor',[1 0 0],'facealpha',.07,...
                'edgealpha',1,'edgecolor','r');
            globES.p.win.FaceColor = 'flat';
            globES.p.win.FaceVertexCData = [ 1 0 0;1 0 0; 0 1 0; 0 1 0; 0 0 1; 0 0 1];
        end

        function updateCenter(app)
            global globES

            set(globES.h.pushbutton_center,'Value',0)
            try delete(findall(globES.fig,'Type','hggroup')); end

            if 1 % do clip
                %pushbutton_clip_Callback

                updateShow(app)

                %hide surface
                pos = globES.p.volPatch.Vertices;
                [isPos isEdge] = winFilt(app, globES.view.window,pos(:,[2 1 3])); %!dim

                globES.p.volPatch.AlphaDataMapping = 'none';
                globES.p.volPatch.FaceAlpha = 'flat';
                vAlph = ones(size(globES.p.volPatch.Vertices,1),1) * globES.view.surfAlph;
                vAlph(~isPos) = 0.04;
                globES.p.volPatch.FaceVertexAlphaData = vAlph;
                pause(.01)
            end
        end

        function updatePlots(app)

            showEdges(app)
            showScatterSkel(app)
        end

        function updateSkel(app)

            global globES
            %
            % pos = globES.data.pos;
            % edges = globES.data.edges;
            %
            % % show skel with edges and nodes
            % hold on
            % try delete(globES.p.scatterSkel); catch, end
            % globES.p.scatterSkel = scatter3(pos(:,2),pos(:,1),pos(:,3),5,'o',...
            %     'w','filled');
            % try delete(globES.p.plotSkel); catch, end
            % globES.p.plotSkel = plot3([pos(edges(:,1),2) pos(edges(:,2),2)]',...
            %     [pos(edges(:,1),1) pos(edges(:,2),1)]',...
            %     [pos(edges(:,1),3) pos(edges(:,2),3)]','color',[1 0 0],'linewidth',1);
            % set(gca,'Clipping','Off');
            % set(globES.p.plotSkel,'Tag','plotSkel')
            % set(globES.p.scatterSkel,'Tag','scatSkel')

            globES.branch.nodes = [];
            globES.branch.rads = [];
            globES.branch.on = 0;

%             showEdges(app)
%             showScatterSkel(app)
%             showBranch(app)
        end

        function useBrush(app, varargin)

            global globES

            skelSelect = globES.p.scatterSkel.BrushData;
            %globES.p.plotSkel.BrushData
            vertSelect = globES.p.vert.BrushData>0;
            sum(vertSelect)
            sum(skelSelect)
            % set(globES.p.vert,'Visible','on')
            %
            Ys = globES.p.vert.YData(vertSelect);
            Xs = globES.p.vert.XData(vertSelect);
            Zs = globES.p.vert.ZData(vertSelect);
            %
            %
            globES.data.newPt = [mean(Ys) mean(Xs) mean(Zs)];
            %  pause(1)
            %  brush off
            %  pause(1)
            % globES.p.newPt = scatter3(globES.ax,...
            %     Xs,Ys,Zs,200,'*','markerlinewidth',4);


            %set(globES.ax, 'NextPlot', 'add')
            %vertSel = scatter3(globES.ax,Xs,Ys,Zs,200,'*');
            pause(.01)
            %brush(globES.fig,'off')
            %set(globES.brushOb,'Enable','off')

            %rotate3d(globES.fig)


            %   set(globES.brushOb,'ActionPostCallback',...
            %         @(ohf,s) useBrush(ohf,s), 'enable','off')

            'bark'
        end

        function [isPos, isEdge] = winFilt(app, win, pos, edges)

            if ~exist('edges','var')
                edges = [];
            end
            %!dim
            pos = pos(:,[2 1 3]);
            isPos = (pos(:,1) >= win(1,1)) & (pos(:,1) <= win(2,1)) & ...
                (pos(:,2) >= win(1,2)) & (pos(:,2) <= win(2,2)) & ...
                (pos(:,3) >= win(1,3)) & (pos(:,3) <= win(2,3));

            edgeHit = isPos(edges);

            isEdge = sum(edgeHit,2)==2;
        end

        function resetInteractions(app, event)
            % This function resets the states of the toggle tools that
            % impact user interactions.  It also resets the figure interactions.

            % Find all tools to reset.  Exclude the tool associated
            % with the event.
            interactiveTools = [app.uitoggletool1, app.uitoggletool2, app.uitoggletool3, app.uitoggletool4, app.uitoggletool5];
            interactiveTools(event.Source == interactiveTools) = [];

            % Set the state of the tools to 'off'.
            [interactiveTools.State] = deal('off');

            % Set figure interactions to 'off'.
            rotate3d(globES.ax, 'off');
            pan(globES.ax, 'off');
            zoom(globES.ax,'off');
        end


        function results = showRadConst(app,const)


            global globES

            try delete(globES.p.radConst), end

            if ~exist('const','var'), const = 0.1,end
            posR = globES.data.pos;
            edgeR = globES.data.edges;
            radR = globES.data.rads * 0 + const;
            nodeNum = length(radR);
            nodeColR = ones(nodeNum,3);
            alph = globES.view.edgeAlph;

            [fvRad globES.p.radConst] = showRadSurf_cn(posR(:,[2 1 3]),edgeR,radR,[1 0 0],alph,globES.fig);

        end

        function results = updateShow(app)

            if app.togglebutton_showNodes.Value
                showScatterSkel(app)
            end
            if app.togglebutton_showVerts.Value
                globES.p.vert.MarkerFaceAlpha = .6;
            end
            if app.togglebutton_showEdges.Value
                showEdges(app)
            end
            if app.togglebutton_showEdges_2.Value
                try globES.p.radConst.Visible = 'on'
                catch ME
                    mapSkeleton(app);
                end
            end
            %               if app.togglebutton_showSurface.Value
            %                 showSurf(app)
            %             end
            if app.togglebutton_showRad.Value
                showRad(app)
            end
            
            showBranch(app)
            
            drawnow
        end

        function results = mapSkeleton(app)
            global globES

            app.textOut.Value = 'Mapping skeleton'; drawnow

            radConst = str2num(app.radiusEditField.Value)
            edgeR = globES.data.edges;
            posR = globES.data.pos;
            radR = globES.data.rads * 0 + radConst;
            nodeNum = length(radR);
            nodeColR = ones(nodeNum,3);
            alph = globES.view.edgeAlph;


            if app.colorbranchesCheckBox.Value
                makeNep(app)
                nep = globES.data.currentNep;
                if 0 % color each bone (branch point to branch point)
                    %                     nep = groupNepEdges(nep)
                    %                     edgeGroups = unique(nep.groupEdges);
                    %                     groupCol = hsv(length(edgeGroups));
                    %                     if app.randColCheckBox.Value
                    %                         groupCol = groupCol(randperm(length(edgeGroups)),:);
                    %                     end
                    %                     edgeCol = groupCol(nep.groupEdges,:);
                    %
                    %                     for i = max(edgeGroups):-1:min(edgeGroups)
                    %                         edgeG = edgeR(nep.groupEdges==edgeGroups(i),:);
                    %                         changeNodes = edgeG(:);
                    %                         nodeColR(edgeG(:),:) = repmat(groupCol(i,:),[length(changeNodes) 1]);
                    %                     end


                else % color each tip path by length priority
                    
                    nep = globES.data.currentNep;
                    nep = sortNodesByLength(nep);
                    if app.colordisconnectedCheckBox.Value
                        tipID = nep.group;
                    else
                        tipID = nep.tipID;
                    end
                    tipID = tipID-min(tipID)+1;
                    groupCol = hsv(max(tipID));
                    if app.randColCheckBox.Value
                        groupCol = groupCol(randperm(size(groupCol,1)),:);
                    end
                    nodeColR = groupCol(tipID,:);

                end

            end

            try delete(globES.p.radConst),end
            [fvRad globES.p.radConst] = showRadSurf_cn(posR(:,[2 1 3]),edgeR,radR,nodeColR,alph,globES.fig);

            app.textOut.Value = 'Finished mapping skeleton'; drawnow


        end

        function results = condition(app)
            global globES
            
            app.textOut.Value = 'Conditioning skeleton...'; drawnow
            makeNep(app)
            nep = globES.data.currentNep;
            nep = edges2predFix(nep);
            startGroups = length(nep.seeds);
            nep = uniteSkel(nep);%% fix breaks in skeleton
            nep= removeRecursive(nep);
            nep = edges2predFix(nep);
            globES.data.edges = nep.edges;
            globES.data.currentNep = nep;
            
            %% show problems
            loopNodes = nep.loopHits;
            try delete(globES.p.loopNodes); end
            if~isempty(loopNodes)
                pos = nep.pos;
                globES.p.loopNodes = scatter3(globES.ax,pos(loopNodes,2),...
                    pos(loopNodes,1),pos(loopNodes,3),100,'w','linewidth',10);
            end
            updateShow(app)
            
            report = sprintf('Found %d objects to bridge and %d loop points',startGroups -1,length(loopNodes));
            app.textOut.Value = report;
        end
            
        
        function results = makeFig(app)

            global globES
            
            try close(globES.fig); end
            globES.fig = figure;
            globES.fig.Position = [513 54 1020 731];
            globES.ax = gca;
            mainAx = globES.ax;
            z = zoom(globES.fig);
            setAxes3DPanAndZoomStyle(z,mainAx,'limits')
            set(globES.ax,'Clipping','Off');
            set(mainAx,'color',[0 0 0])
            set(mainAx,'visible','off')
            set(mainAx,'View',[0 0])
            set(mainAx,'CameraPositionMode','auto')
            set(mainAx,'CameraTargetMode','auto')
            set(mainAx,'CameraViewAngleMode','auto')

            daspect([1,1,1]);
            view(3); axis tight
            set(gcf,'color',[0 0 0]);

            l = lightangle(145,45) ;


            set(mainAx,'color',[0 0 0])
            set(globES.fig,'color',[0 0 0])

            %set(gcf,'color',[0 0 0])
            set(mainAx,'visible','on')
            set(mainAx, 'NextPlot', 'add')
        
        end


        function results = showErrors(app)

            global globES

            if app.ShowerrorsButton.Value
                try globES.p.loopNodes.Visible = 'on';  end
            else
                try  globES.p.loopNodes.Visible = 'off';end
            end


        end
    
end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function editSkeleton_OpeningFcn(app, varargin)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>

            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to editSkeleton (see VARARGIN)

            % Choose default command line output for editSkeleton
            handles.output = hObject;

            % Update handles structure
            guidata(hObject, handles);

            % UIWAIT makes editSkeleton wait for user response (see UIRESUME)
            % uiwait(handles.figure1);

            global glob globES
            clear globES
            global globES

            globES.h = handles;
            
            %%Set up figure
            makeFig(app)
            globES.fig.Visible = 'off';

            %%Set variables
            globES.branch.on = 0;
            globES.branch.nodes = [];
            globES.branch.rads = [];
            globES.branch.edges = [];
            globES.view.clip = 0;
            globES.view.surfAlph = .2;
            globES.view.edgeAlph = .9;
            globES.view.winWidth = 10;
            globES.view.pickNode = 1;
            set(handles.edit_winSize,'String',num2str(globES.view.winWidth))
            globES.view.showOriginal = 0;

            try
                globES.pickCID = glob.pickCID
            catch err
                globES.pickCID = [];
            end


            %%Set up files
            try globES.f.useFvDir = glob.useFvDir;
            catch
                globES.f.useFvDir = [];
            end

            if ~isempty(globES.f.useFvDir);
                slashes = regexp(globES.f.useFvDir,'\');
                globES.f.saveDir = [globES.f.useFvDir(1:slashes(end-1)) 'editSkel\'];
                if ~exist(globES.f.saveDir),mkdir(globES.f.saveDir);end
            end

            set(handles.edit_CID,'String',num2str(globES.pickCID))
            set(handles.edit_saveFolder,'String',globES.f.saveDir);
            globES.f.saveName = sprintf('nep_%d_edit',globES.pickCID);

            set(handles.edit_saveName,'String',globES.f.saveName);
        end

        % Value changed function: Xmax
        function Xmax_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>


            global globES

            val = get(hObject,'Value');
            globES.view.window(2,3) = globES.view.cellRange(1,3) + ...
                diff(globES.view.cellRange(:,3)) * val;

            %Fix opposite slider
            val2 = get(handles.Xmin,'Value');
            if val2>val
                set(handles.Xmin,'Value',val)
                globES.view.window(1,3) =globES.view.cellRange(1,3) + ...
                    diff(globES.view.cellRange(:,3)) * val;
            end


            showWindow(app)
        end

        % Value changed function: Xmin
        function Xmin_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>


            global globES

            val = get(hObject,'Value');

            globES.view.window(1,3) = globES.view.cellRange(1,3) + ...
                diff(globES.view.cellRange(:,3)) * val;

            %Fix opposite slider
            val2 = get(handles.Xmax,'Value');
            if val2<val
                set(handles.Xmax,'Value',val)
                globES.view.window(2,3) =globES.view.cellRange(1,3) + ...
                    diff(globES.view.cellRange(:,3)) * val;
            end


            showWindow(app)
        end

        % Value changed function: Ymax
        function Ymax_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            global globES

            val = get(hObject,'Value');
            globES.view.window(2,1) =globES.view.cellRange(1,1) + ...
                diff(globES.view.cellRange(:,1)) * val;

            %Fix opposite slider
            val2 = get(handles.Ymin,'Value');
            if val2>val
                set(handles.Ymin,'Value',val)
                globES.view.window(1,1) =globES.view.cellRange(1,1) + ...
                    diff(globES.view.cellRange(:,1)) * val;
            end


            showWindow(app)
        end

        % Value changed function: Ymin
        function Ymin_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            global globES

            val = get(hObject,'Value');
            globES.view.window(1,1) =globES.view.cellRange(1,1) + ...
                diff(globES.view.cellRange(:,1)) * val;

            %Fix opposite slider
            val2 = get(handles.Ymax,'Value');
            if val2<val
                set(handles.Ymax,'Value',val)
                globES.view.window(2,1) =globES.view.cellRange(1,1) + ...
                    diff(globES.view.cellRange(:,1)) * val;
            end

            showWindow(app)
        end

        % Value changed function: Zmax
        function Zmax_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            global globES

            val = get(hObject,'Value');
            globES.view.window(2,2) = globES.view.cellRange(1,2) + ...
                diff(globES.view.cellRange(:,2)) * val;


            %Fix opposite slider
            val2 = get(handles.Zmin,'Value');
            if val2>val
                set(handles.Zmin,'Value',val)
                globES.view.window(1,2) =globES.view.cellRange(1,2) + ...
                    diff(globES.view.cellRange(:,2)) * val;
            end


            showWindow(app)
        end

        % Value changed function: Zmin
        function Zmin_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            global globES

            val = get(hObject,'Value');
            globES.view.window(1,2) = globES.view.cellRange(1,2) + ...
                diff(globES.view.cellRange(:,2)) * val;

            %Fix opposite slider
            val2 = get(handles.Zmax,'Value');
            if val2<val
                set(handles.Zmax,'Value',val)
                globES.view.window(2,2) =globES.view.cellRange(1,2) + ...
                    diff(globES.view.cellRange(:,2)) * val;
            end
            showWindow(app)
        end

        % Button pushed function: add_pushbutton
        function add_pushbutton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to add_pushbutton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)


            addPtToBranch(app)
        end

        % Button pushed function: backup_pushbutton
        function backup_pushbutton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to backup_pushbutton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            global globES

            n = globES.branch.nodes;
            if size(n,1)>0
                n = n(1:end-1,:);
                globES.branch.nodes = n;
            end
            showBranch(app)
        end

        % Value changed function: brushNode_togglebutton
        function brushNode_togglebutton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to brushNode_togglebutton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hint: get(hObject,'Value') returns toggle state of brushNode_togglebutton
            global globES

            deselectToggleUI(app, handles, hObject)
            val = get(hObject,'Value');

            globES.pick.edgeID = [];
            globES.pick.edgePos = [];
            globES.pick.nodeID = [];
            globES.pick.nodePos = [];
            globES.skelSelect = [];
            globES.skelPt = [];


            if val
                makeBrush(app)
            else
                try globES.p.scatterSkel.BrushData = globES.p.scatterSkel.BrushData * 0; end
                try globES.p.skelPt.BrushData = globES.p.skelPt.BrushData * 0; end
                try globES.p.vert.BrushData = globES.p.vert.BrushData * 0 ; end
                deleteBrush(app)
            end
        end

        % Button pushed function: brushToEdge_pushbutton
        function brushToEdge_pushbutton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to brushToEdge_pushbutton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            global globES

            vertSelect = find(globES.p.scatterSkel.BrushData);
            sum(vertSelect)
            Ys = globES.p.scatterSkel.YData(vertSelect);
            Xs = globES.p.scatterSkel.XData(vertSelect);
            Zs = globES.p.scatterSkel.ZData(vertSelect);
            rads = globES.data.rads(vertSelect);

            ids = find(vertSelect);

            set(globES.h.brushNode_togglebutton,'value',0)
            deleteBrush(app)

            meanPt = [mean(Ys) mean(Xs) mean(Zs)];
            dists = sqrt((Ys-meanPt(1)).^2 + (Xs-meanPt(2)).^2 + ...
                (Zs - meanPt(3)).^2);
            targ =  find(dists == min(dists),1);
            useNode = vertSelect(targ);

            newPt = [Ys(targ) Xs(targ) Zs(targ)];
            globES.data.newPt = newPt;
            globES.data.newRad = rads(ids(targ));
            %set(globES.brushOb,'Enable','Off')

            hold on
            if ~isempty(newPt)
                try delete(globES.p.newPt), end
                globES.p.newPt = scatter3(globES.ax,...
                    newPt(:,2),newPt(:,1),newPt(:,3),1000,'*','markeredgecolor','y',...
                    'linewidth',4);
                pause(.01)

                skelPt = [Ys(:) Xs(:) Zs(:)];
                globES.data.skelPt = skelPt;
                globES.data.skelSelect = vertSelect;
                set(globES.brushOb,'Enable','Off')
                try delete(globES.p.skelPt), end
                globES.p.skelPt = scatter3(globES.ax,...
                    skelPt(:,2),skelPt(:,1),skelPt(:,3),300,'o','filled',...
                    'markerfacecolor','y','markeredgecolor','y');
                pause(.01)


                if globES.branch.on
                    addPtToBranch(app)
                    set(globES.h.brushNode_togglebutton,'value',1)
                    makeBrush(app)
                end

            else
                set(handles.textOut,'String','No skeleton points selected');
            end
        end

        % Button pushed function: clear_pushbutton
        function clear_pushbutton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to clear_pushbutton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            global globES

            globES.branch.nodes = [];
            globES.branch.rads = [];
            globES.branch.on = 0;

            showBranch(app)
        end

        % Value changed function: edit_CID
        function edit_CID_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to edit_CID (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of edit_CID as text
            %        str2double(get(hObject,'String')) returns contents of edit_CID as a double
            global globES

            str = get(hObject,'String');
            num = str2num(str);
            globES.pickCID = num;
        end

        % Value changed function: edit_nodes
        function edit_nodes_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to edit_nodes (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of edit_nodes as text
            %        str2double(get(hObject,'String')) returns contents of edit_nodes as a double

            global globES

            str = get(hObject,'String');
            num = str2num(str);

            if ~isempty(num)

                if num<1
                    num = 1;
                elseif num > globES.view.pickNumMax
                    num = globES.view.pickNumMax;
                end
                globES.view.pickNum = num;
                goToNode(app, num)
            else
                set(hObject,'String','Must be number')
                pause(1)
                set(hObject,'String','')
            end
        end

        % Value changed function: edit_saveName
        function edit_saveName_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to edit_saveName (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of edit_saveName as text
            %        str2double(get(hObject,'String')) returns contents of edit_saveName as a double
            global globES

            globES.f.saveName = get(hObject,'String')
        end

        % Value changed function: edit_winSize
        function edit_winSize_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to edit_winSize (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: get(hObject,'String') returns contents of edit_winSize as text
            %        str2double(get(hObject,'String')) returns contents of edit_winSize as a double
            global globES

            str = get(hObject,'String');
            num = str2num(str);
            if ~isempty(num)
                globES.view.winWidth = num;
            end
        end

        % Button pushed function: load_pushbutton
        function load_pushbutton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            global globES
            makeFig(app)
            cla(globES.ax)
            loadCID(app)
        end

        % Button pushed function: merge_pushbutton
        function merge_pushbutton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to merge_pushbutton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            global globES

            pos = globES.data.pos;
            edges = globES.data.edges;
            rads = globES.data.rads;

            L = size(pos,1);
            n = globES.branch.nodes;
            nL = size(n,1);

            difY = pos(:,1) - n(:,1)';
            difX = pos(:,2) - n(:,2)';
            difZ = pos(:,3) - n(:,3)';

            difA = (difY==0) & (difX == 0) & (difZ == 0);
            [sID bID] = find(difA);
            changeID = setdiff([1:nL],bID);
            newID = [1:length(changeID)]+L;
            branchID = zeros(1,nL);
            branchID(changeID) = newID;
            branchID(bID) = sID;

            pos(newID,:) = n(changeID,:);
            rads(newID) = globES.branch.rads(changeID);
            newEdges = [branchID(1:end-1)' branchID(2:end)'];
            edges = cat(1,edges,newEdges);

            globES.data.pos = pos;
            globES.data.edges = edges;
            globES.data.rads = rads;


            showEdges(app)
            showScatterSkel(app)

            globES.branch.nodes = [];
            globES.branch.rads = [];
            globES.branch.on = 0;
            globES.pick.edgeID = [];
            globES.pick.nodeID = [];

            showBranch(app)
        end

        % Value changed function: pickEdge_togglebutton
        function pickEdge_togglebutton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to pickEdge_togglebutton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)


            % Hint: get(hObject,'Value') returns toggle state of pickNode_togglebutton
            global glob globES

            deselectToggleUI(app, handles, hObject)

            val = get(hObject,'Value');


            globES.pick.edgeID = [];
            globES.pick.edgePos = [];
            globES.pick.nodeID = [];
            globES.pick.nodePos = [];
            globES.skelSelect = [];
            globES.skelPt = [];

            if val
                delete(findall(globES.fig,'Type','hggroup'));
                %try delete(globES.dcm);  end
                %deselectToggleUI(handles, hObject)
                globES.dcm = datacursormode(globES.fig);
                globES.dcm.UpdateFcn = @app.pickEdge;
                set(globES.dcm,'DisplayStyle','window',...
                    'SnapToDataVertex','off','Enable','on');

                set(globES.p.vert,'Visible','Off')
                set(globES.p.volPatch,'Visible','Off')


            else
                rotate3d(globES.fig);
                delete(findall(handles.figure1,'Type','hggroup'));
                set(globES.p.vert,'Visible','On')
                set(globES.p.volPatch,'Visible','On')
            end
        end

        % Value changed function: pickNode_togglebutton
        function pickNode_togglebutton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to pickNode_togglebutton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hint: get(hObject,'Value') returns toggle state of pickNode_togglebutton
            global glob globES

            deselectToggleUI(app, handles, hObject)

            val = get(hObject,'Value');

            globES.pick.edgeID = [];
            globES.pick.edgePos = [];
            globES.pick.nodeID = [];
            globES.pick.nodePos = [];
            globES.skelSelect = [];
            globES.skelPt = [];

            if val
                delete(findall(globES.fig,'Type','hggroup'));
                %try delete(globES.dcm);  end
                %deselectToggleUI(handles, hObject)
                globES.dcm = datacursormode(globES.fig);
                globES.dcm.UpdateFcn = @app.pickEdge;
                set(globES.dcm,'DisplayStyle','window',...
                    'SnapToDataVertex','off','Enable','on');

                try set(globES.p.vert,'Visible','Off'), end
                try set(globES.p.volPatch,'Visible','Off'), end
                try set(globES.p.plotSkel,'Visible','Off'), end
                try set(globES.p.radConst,'Visible','Off'), end


            else
                rotate3d(globES.fig);
                delete(findall(globES.fig,'Type','hggroup'));
                try set(globES.p.vert,'Visible','On'), end
                try set(globES.p.volPatch,'Visible','On'), end
                try set(globES.p.plotSkel,'Visible','On'), end
                try set(globES.p.radConst,'Visible','On'), end

            end
        end

        % Button pushed function: pushbutton_XY
        function pushbutton_XY_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to pushbutton_XY (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global globES
            view(globES.ax,[0 270])
        end

        % Button pushed function: pushbutton_XZ
        function pushbutton_XZ_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to pushbutton_XZ (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global globES
            view(globES.ax,[0 0 ])
        end

        % Button pushed function: pushbutton_YZ
        function pushbutton_YZ_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to pushbutton_YZ (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global globES
            view(globES.ax,[90 1])
        end

        % Value changed function: pushbutton_center
        function pushbutton_center_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to pushbutton_center (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hint: get(hObject,'Value') returns toggle state of pushbutton_center
            global globES

            val = get(hObject,'Value');

            if val
                deselectToggleUI(app, handles, hObject)
                try delete(findall(globES.fig,'Type','hggroup')); end
                %datacursormode(globES.fig,'on')
                globES.dcm = datacursormode(globES.fig);
                globES.dcm.UpdateFcn = @app.pickWindowCenter;
                set(globES.dcm,'DisplayStyle','window',...
                    'SnapToDataVertex','off','Enable','on')
            else
                try set(globES.dcm,'Enable','off'), end
                rotate3d(globES.fig)
                delete(findall(globES.fig,'Type','hggroup'))
            end
        end

        % Button pushed function: pushbutton_clear
        function pushbutton_clear_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to pushbutton_clear (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            clearAxis(app)
        end

        % Button pushed function: pushbutton_clip
        function pushbutton_clip_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to pushbutton_clip (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hint: get(hObject,'Value') returns toggle state of pushbutton_clip

            global globES

            globES.view.clip = 1;

            updateShow(app)

            %hide surface
            pos = globES.p.volPatch.Vertices;
            [isPos isEdge] = winFilt(app, globES.view.window,pos(:,[2 1 3])); %!dim

            globES.p.volPatch.AlphaDataMapping = 'none';
            globES.p.volPatch.FaceAlpha = 'flat';
            vAlph = ones(size(globES.p.volPatch.Vertices,1),1) * globES.view.surfAlph;
            vAlph(~isPos) = 0.04;
            globES.p.volPatch.FaceVertexAlphaData = vAlph;
            set(globES.p.win,'Visible','Off')
            set(globES.h.togglebutton_hideWin,'Value',0);
        end

        % Button pushed function: pushbutton_folder
        function pushbutton_folder_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to pushbutton_folder (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            global globES

            globES.f.saveDir = uigetdir(globES.f.saveDir);
        end

        % Button pushed function: pushbutton_loadCustom
        function pushbutton_loadCustom_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to pushbutton_loadCustom (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            global glob globES

            set(handles.textOut,'String','Loading')
            [TFN TPN] = uigetfile(globES.f.saveDir);
            loadCID(app, [TPN '/' TFN])
            set(handles.edit_saveName,'String',TFN);
            set(handles.textOut,'String',sprintf('Finished loading %s',TFN))
        end

        % Button pushed function: pushbutton_makeSM
        function pushbutton_makeSM_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to pushbutton_makeSM (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            global glob globES

            set(handles.textOut,'String',sprintf('Skeletonizing cell %d',globES.pickCID))
            tempFig = figure;

            makeVolMPNcnv
            sm = makeSM(globES.pickCID);

            load('MPN.mat')
            smDir = [WPN 'SMs\'];
            libDir = [WPN 'fvLibrary\'];
            dSMs = dir([smDir '*.mat']);
            nams = { dSMs.name};

            %showNepBones(sm.nep);
            %showRadSurf(sm.nep.pos,sm.nep.edges,sm.nep.nodeRad,[0 1 0],.4)
            if isfield(sm,'nep')
                if ~isempty(sm.nep)
                    filename = sprintf('%snep_%d.mat',libDir,sm.cid);
                    nep = sm.nep;
                    save(filename,'nep')

                    clf
                    fv = showRadSurf_cnv(sm.nep.pos,sm.nep.edges,sm.nep.nodeRad*0+.1,[1 1 1],1,tempFig);
                    pause(.01)
                    filename = sprintf('%sskelFV_%d.mat',libDir,sm.cid);
                    save(filename,'fv')
                    
                end
            end
            close(tempFig)

            loadCID(app)

            set(handles.textOut,'String',sprintf('Finished skeletonizing cell %d',globES.pickCID))
        end

        % Button pushed function: pushbutton_nodeDown
        function pushbutton_nodeDown_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to pushbutton_nodeDown (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hint: get(hObject,'Value') returns toggle state of pushbutton_nodeDown
            global globES

            num = globES.view.pickNum - 1;

            if num<1
                num = 1;
            elseif num > globES.view.pickNumMax
                num = globES.view.pickNumMax;
            end
            goToNode(app, num)
            globES.view.pickNum = num;
            set(globES.h.edit_nodes,'String',num2str(num));
        end

        % Button pushed function: pushbutton_nodeUp
        function pushbutton_nodeUp_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to pushbutton_nodeUp (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hint: get(hObject,'Value') returns toggle state of pushbutton_nodeUp
            global globES

            num = globES.view.pickNum + 1;

            if num<1
                num = 1;
            elseif num > globES.view.pickNumMax
                num = globES.view.pickNumMax;
            end
            goToNode(app, num)
            globES.view.pickNum = num;
            set(globES.h.edit_nodes,'String',num2str(num));
        end

        % Value changed function: pushbutton_origin
        function pushbutton_origin_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to pushbutton_origin (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global globES

            val = get(hObject,'Value');
            if val
                try delete(globES.p.win);end
                set(globES.h.togglebutton_hideWin,'Value',0);
                deselectToggleUI(app, handles, hObject)
                globES.dcm = datacursormode(globES.fig);
                globES.dcm.UpdateFcn = @app.setOrigin;

                set(globES.dcm,'DisplayStyle','datatip',...
                    'SnapToDataVertex','off','Enable','on')
            else
                rotate3d(globES.fig)
                delete(findall(globES.fig,'Type','hggroup'))
            end

            pause(.01)
        end

        % Button pushed function: pushbutton_saveCustom
        function pushbutton_saveCustom_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to pushbutton_saveCustom (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            global glob globES

            set(handles.textOut,'String','Saving')
            fileName = sprintf('%s%s.mat',globES.f.saveDir,globES.f.saveName);
            nep = makeNep(app);
            save(fileName,'nep')
            set(handles.textOut,'String',sprintf('Finished saving %s',fileName))
        end

        % Button pushed function: pushbutton_saveToLibrary
        function pushbutton_saveToLibrary_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to pushbutton_saveToLibrary (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global glob globES

            set(handles.textOut,'String','Saving')
            nep = makeNep(app);
            fileName = sprintf('%snep_%d.mat',globES.f.useFvDir,globES.pickCID);
            save(fileName,'nep');
            set(handles.textOut,'String',sprintf('Finished saving %s',fileName))
        end

        % Value changed function: togglebutton_showRad
        function togglebutton_showRad_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to radius_togglebutton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hint: get(hObject,'Value') returns toggle state of radius_togglebutton

            global globES

            val = get(hObject,'Value');

            try delete(globES.p.rad), end

            if val
                showRad(app)
            end
        end

        % Button pushed function: remove_pushbutton
        function remove_pushbutton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to remove_pushbutton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            global globES

            pos = globES.data.pos;
            edges = globES.data.edges;
            rads = globES.data.rads;


            if ~isempty(globES.pick.edgeID)
                edges = edges(setdiff([1:size(edges,1)],globES.pick.edgeID),:);
                globES.data.edges = edges;
                globES.pick.edgeID = [];
            end

            if isfield(globES.data,'skelSelect')
                skelSelect = globES.data.skelSelect;
                skelSelect = globES.data.nodeInd(skelSelect);
            else
                skelSelect = [];
            end

            if ~isempty(globES.pick.nodeID)
                skelSelect = [skelSelect globES.pick.nodeID];
                globES.pick.nodeID = [];
            end

            if ~isempty(skelSelect)
                L = size(pos,1);
                n = pos(skelSelect,:);
                nL = size(n,1);
                keepID = setdiff(1:size(pos,1),skelSelect);
                pos = pos(keepID,:);
                newID = 1:length(keepID);
                lookUpT = zeros(L,1);
                lookUpT(keepID) = newID;
                edges = lookUpT(edges);
                cutEdges = sum(edges==0,2);
                edges = edges(~cutEdges,:);
                rads = rads(keepID);

                globES.data.pos = pos;
                globES.data.edges = edges;
                globES.data.rads = rads;

                try delete(globES.p.skelPt), end
                try delete(globES.p.newPt), end
                globES.data.skelSelect = [];
                globES.data.skelPt = [];
            end

            updateSkel(app)
            updateShow(app)
            deleteBrush(app)
        end

        % Button pushed function: start_pushbutton
        function start_pushbutton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to start_pushbutton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            global globES


            if ~globES.branch.on | isempty(~globES.branch.nodes)
                globES.branch.on = 1;

                if isfield(globES.data, 'newPt')
                    if length(globES.data.newPt) == 3
                        globES.branch.nodes = globES.data.newPt(:)';
                        globES.branch.rads = globES.data.newRad;
                    end
                end
                showBranch(app)

            else
                set(handles.textOut,'String','Must clear previous branch before starting a new one');
            end
            globES.branch
        end

        % Button pushed function: surfaceToNode_pushbutton
        function surfaceToNode_pushbutton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to surfaceToNode_pushbutton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            global globES

            vertSelect = globES.p.vert.BrushData>0;
            if sum(vertSelect)
                Ys = globES.p.vert.YData(vertSelect);
                Xs = globES.p.vert.XData(vertSelect);
                Zs = globES.p.vert.ZData(vertSelect);

                set(globES.h.brushNode_togglebutton,'value',0)
                deleteBrush(app)

                newPt = [mean(Ys) mean(Xs) mean(Zs)];
                dists = sqrt((Ys-newPt(1)).^2 + (Xs-newPt(2)).^2 + (Zs-newPt(3)).^2);
                newRad = mean(dists);

                globES.data.newPt = newPt;
                globES.data.newRad = newRad;
                set(globES.brushOb,'Enable','Off')
                %  pause(1)
                %  brush off
                %  pause(1)
                try delete(globES.p.newPt), end
                globES.p.newPt = scatter3(globES.ax,...
                    newPt(:,2),newPt(:,1),newPt(:,3),200,'*','y','linewidth',4);
                drawnow

                if globES.branch.on
                    addPtToBranch(app)
                    set(globES.h.brushNode_togglebutton,'value',1)
                    makeBrush(app)
                end

            else
                set(handles.textOut,'String','No selection detected')
            end
        end

        % Callback function
        function togglebutton_Files_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to togglebutton_Files (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hint: get(hObject,'Value') returns toggle state of togglebutton_Files


            val = get(hObject,'Value');
            if val
                set(handles.uipanel_file,'Visible','On');
            else
                set(handles.uipanel_file,'Visible','Off');
            end
        end

        % Button pushed function: togglebutton_Reset
        function togglebutton_Reset_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to togglebutton_Reset (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            global globES

            globES.view.window = globES.view.cellRange;
            showWindow(app)
            pushbutton_clip_Callback(hObject,eventdata)
        end

        % Value changed function: togglebutton_XYZ
        function togglebutton_XYZ_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to togglebutton_XYZ (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hint: get(hObject,'Value') returns toggle state of togglebutton_XYZ

            global globES

            val = get(hObject,'Value');

            % try
            %     glob.dcm.Enable = 'off'
            %     %glob.dcm.delete
            % end

            if val
                deselectToggleUI(app, handles, hObject)
                globES.dcm = datacursormode(globES.fig);
                globES.dcm.UpdateFcn = @app.displayCoordinates;

                set(globES.dcm,'DisplayStyle','datatip',...
                    'SnapToDataVertex','off','Enable','on')
            else
                rotate3d(globES.fig)
                delete(findall(globES.fig,'Type','hggroup'))
            end
        end

        % Value changed function: togglebutton_clearPick
        function togglebutton_clearPick_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to togglebutton_clearPick (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hint: get(hObject,'Value') returns toggle state of togglebutton_clearPick

            global globES

            globES.pick.edgeID = [];
            globES.pick.edgePos = [];
            globES.pick.nodeID = [];
            globES.pick.nodePos = [];
            info.Target.Tag = 'clear';
            pickEdge(app, 'none',info)
        end

        % Value changed function: togglebutton_edit
        function togglebutton_edit_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to togglebutton_edit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hint: get(hObject,'Value') returns toggle state of togglebutton_edit

            val = get(hObject,'Value');
            if val
                set(handles.brushPanel,'Visible','Off')
                set(handles.remove_pushbutton,'Visible','Off')
                set(handles.Branch_uibuttongroup,'Visible','Off')
                set(handles.uipanel_pick,'Visible','Off')
            else
                set(handles.brushPanel,'Visible','On')
                set(handles.remove_pushbutton,'Visible','On')
                set(handles.Branch_uibuttongroup,'Visible','On')
                set(handles.uipanel_pick,'Visible','On')
            end
        end

        % Value changed function: togglebutton_hideWin
        function togglebutton_hideWin_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to togglebutton_hideWin (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hint: get(hObject,'Value') returns toggle state of togglebutton_hideWin

            global globES

            val = get(hObject,'Value');
            if val
                showWindow(app);
            else
                try delete(globES.p.win);end
            end
        end

        % Value changed function: togglebutton_nepOriginal
        function togglebutton_nepOriginal_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to togglebutton_nepOriginal (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hint: get(hObject,'Value') returns toggle state of togglebutton_nepOriginal

            global globES

            val = get(hObject,'Value');
            if val
                globES.view.showOriginal = 1;
            else
                globES.view.showOriginal = 0;

            end
        end

        % Callback function
        function togglebutton_nodes_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to togglebutton_nodes (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hint: get(hObject,'Value') returns toggle state of togglebutton_nodes

            global globES

            try delete(globES.p.goToNode); end
            val = get(hObject,'Value');

            if val
                set(globES.h.uipanel_nodes,'Visible','On')
            else
                set(globES.h.uipanel_nodes,'Visible','Off')
                try delete(globES.p.goToNode); catch, end
                try delete(globES.p.goToEdge); catch, end
            end
        end

        % Value changed function: togglebutton_showEdges
        function togglebutton_showEdges_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to togglebutton_showEdges (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hint: get(hObject,'Value') returns toggle state of togglebutton_showEdges

            global globES

            val = get(hObject,'Value');

            try globES.p.plotSkel.delete;end
            if val
                showEdges(app)
            end
        end

        % Value changed function: togglebutton_showNodes
        function togglebutton_showNodes_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to togglebutton_showNodes (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hint: get(hObject,'Value') returns toggle state of togglebutton_showNodes

            global globES

            val = get(hObject,'Value');

            try globES.p.scatterSkel.delete;end
            if val
                showScatterSkel(app)
            end
        end

        % Value changed function: togglebutton_showRadiusMinus
        function togglebutton_showRadiusMinus_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to togglebutton_showRadiusMinus (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hint: get(hObject,'Value') returns toggle state of togglebutton_showRadiusMinus

            global globES

            if isfield(globES.p,'rad')
                val = globES.p.rad.FaceAlpha;
                val = max(0,val-.1);

                globES.p.rad.FaceAlpha = val;
            end
        end

        % Value changed function: togglebutton_showRadiusPlus
        function togglebutton_showRadiusPlus_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to togglebutton_showRadiusPlus (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hint: get(hObject,'Value') returns toggle state of togglebutton_showRadiusPlus

            global globES


            try val = globES.p.rad.FaceAlpha;
            catch
                showRad(app);
                globES.p.rad.FaceAlpha = 0;
                val = globES.p.rad.FaceAlpha;
            end
            val = min(1,val+.1);
            globES.p.rad.FaceAlpha = val;
            set(handles.radius_togglebutton,'Value',1)
        end

        % Value changed function: togglebutton_showSurfaceMinus
        function togglebutton_showSurfaceMinus_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to togglebutton_showSurfaceMinus (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hint: get(hObject,'Value') returns toggle state of togglebutton_showSurfaceMinus
            global globES

            if isfield(globES.p,'volPatch')
                globES.view.surfAlph = max(0,globES.view.surfAlph - .1);
                globES.p.volPatch.FaceAlpha = globES.view.surfAlph ;
            end
        end

        % Value changed function: togglebutton_showSurfacePlus
        function togglebutton_showSurfacePlus_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to togglebutton_showSurfacePlus (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hint: get(hObject,'Value') returns toggle state of togglebutton_showSurfacePlus
            global globES


            if isfield(globES.p,'volPatch')
                globES.view.surfAlph = min(1,globES.view.surfAlph +.1);
                globES.p.volPatch.FaceAlpha = globES.view.surfAlph ;
            end
        end

        % Value changed function: togglebutton_showSurface
        function togglebutton_showSurface_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to togglebutton_showSurface (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hint: get(hObject,'Value') returns toggle state of togglebutton_showSurface
            global globES

            val = get(hObject,'Value');
            if isfield(globES.p,'volPatch')
                if val
                    globES.p.volPatch.Visible = 'on';
                else
                    globES.p.volPatch.Visible = 'off';
                end
            end
        end

        % Value changed function: togglebutton_showVerts
        function togglebutton_showVerts_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to togglebutton_showVerts (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hint: get(hObject,'Value') returns toggle state of togglebutton_showVerts

            global globES

            val = get(hObject,'Value');

            if val
                globES.p.vert.MarkerFaceAlpha = .6;
            else
                globES.p.vert.MarkerFaceAlpha = 0;
            end
        end

        % Callback function
        function togglebutton_show_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to togglebutton_show (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hint: get(hObject,'Value') returns toggle state of togglebutton_show
            val = get(hObject,'Value')
            if val
                set(handles.show_uipanel,'Visible','On')
            else
                set(handles.show_uipanel,'Visible','Off')
            end
        end

        % Callback function
        function togglebutton_window_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to togglebutton_window (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hint: get(hObject,'Value') returns toggle state of togglebutton_window

            global globES
            h = handles;

            val = get(hObject,'Value')

            if val
                set(h.uipanel5,'Visible','on')
                set(h.Ymin,'Visible','on')
                set(h.Xmin,'Visible','on')
                set(h.Zmin,'Visible','on')
                set(h.Ymax,'Visible','on')
                set(h.Xmax,'Visible','on')
                set(h.Zmax,'Visible','on')
            else
                set(h.uipanel5,'Visible','off')
                set(h.Ymin,'Visible','off')
                set(h.Xmin,'Visible','off')
                set(h.Zmin,'Visible','off')
                set(h.Ymax,'Visible','off')
                set(h.Xmax,'Visible','off')
                set(h.Zmax,'Visible','off')
            end
        end

        % Clicked callback: uitoggletool5
        function uitoggletool5_ClickedCallback(app, event)
            % Reset the states of interactive tools and reset all figure
            % interactions.
            app.resetInteractions(event);

            % Enable or disable rotation based on the
            % tool's current state.
            state = app.uitoggletool5.State;
            rotate3d(globES.fig, char(state));
        end

        % Clicked callback: uitoggletool4
        function uitoggletool4_ClickedCallback(app, event)
            % Reset the states of interactive tools and reset all figure
            % interactions.
            app.resetInteractions(event);

            % Enable or disable data tips based on the
            % tool's current state.
            state = app.uitoggletool4.State;
            datacursormode(globES.fig, char(state));
        end

        % Clicked callback: uitoggletool3
        function uitoggletool3_ClickedCallback(app, event)
            % Reset the states of interactive tools and reset all figure
            % interactions.
            app.resetInteractions(event);

            % Enable or disable pan based on the
            % tool's current state.
            state = app.uitoggletool3.State;
            pan(globES.fig, char(state));
        end

        % Clicked callback: uitoggletool2
        function uitoggletool2_ClickedCallback(app, event)
            % Reset the states of interactive tools and reset all figure
            % interactions.
            app.resetInteractions(event);

            % Enable or disable zoom-out based on the
            % tool's current state.
            state = app.uitoggletool2.State;
            zoomModeObject = zoom(globES.fig);
            if state
                zoomModeObject.Direction = 'out';
                zoomModeObject.Enable = 'on';
            else
                zoomModeObject.Enable = 'off';
            end
        end

        % Clicked callback: uitoggletool1
        function uitoggletool1_ClickedCallback(app, event)
            % Reset the states of interactive tools and reset all figure
            % interactions.
            app.resetInteractions(event);

            % Enable or disable zoom-in based on the
            % tool's current state.
            state = app.uitoggletool1.State;
            zoomModeObject = zoom(globES.fig);
            if state
                zoomModeObject.Direction = 'in';
                zoomModeObject.Enable = 'on';
            else
                zoomModeObject.Enable = 'off';
            end
        end

        % Value changed function: togglebutton_showEdges_2
        function showEdgeRadConst(app, event)
            global globES
            value = app.togglebutton_showEdges_2.Value;

            if value
                try globES.p.radConst.Visible = 'on'
                catch ME
                    mapSkeleton(app);
                end
            else
                try globES.p.radConst.Visible = 'off';end
            end

        end

        % Callback function
        function colorize(app, event)
            global globES

            mapSkeleton(app)

        end

        % Value changed function: randColCheckBox
        function changeRandCol(app, event)
            value = app.randColCheckBox.Value;
            mapSkeleton(app)

        end

        % Value changed function: radiusEditField
        function changeRadius(app, event)
            value = app.radiusEditField.Value;
            mapSkeleton(app)
        end

        % Value changed function: colorbranchesCheckBox
        function colorBranches(app, event)
            value = app.colorbranchesCheckBox.Value;
            mapSkeleton(app)

        end

        % Value changed function: togglebutton12_2
        function plusAlphEdges(app, event)
            value = app.togglebutton12_2.Value;
            global globES
            if isfield(globES.p,'radConst')
                globES.view.edgeAlph = min(1,globES.view.edgeAlph +.1);
                globES.p.radConst.FaceAlpha = globES.view.edgeAlph ;
            end
        end

        % Value changed function: togglebutton13_2
        function minusAlphEdge(app, event)
            value = app.togglebutton13_2.Value;
            global globES
            if isfield(globES.p,'radConst')
                globES.view.edgeAlph = max(0,globES.view.edgeAlph -.1);
                globES.p.radConst.FaceAlpha = globES.view.edgeAlph ;
            end
        end

        % Button pushed function: ConditionButton
        function pushCondition(app, event)

            condition(app)

        end

        % Value changed function: colordisconnectedCheckBox
        function colorDisconnected(app, event)
            value = app.colordisconnectedCheckBox.Value;
            mapSkeleton(app)

        end

        % Value changed function: ShowerrorsButton
        function showErrorsButton(app, event)
            value = app.ShowerrorsButton.Value;
            showErrors(app)
        end

        % Button pushed function: RemapButton
        function remap(app, event)
            mapSkeleton(app)
        end

        % Button pushed function: pushbutton_makeSM_2
        function updateSMbutton(app, event)
             % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to pushbutton_makeSM (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            global glob globES

            tempFig = figure;

            makeVolMPNcnv
            condition(app)
            
            set(handles.textOut,'String',sprintf('Updating SM with nep for cell %d',globES.pickCID)), drawnow
            nep = globES.data.currentNep;
            nep.history = sprintf('Edited in cellNav on %s',datestr(clock));
            sm = updateSMwithNep(globES.pickCID,nep);
            close(tempFig)
            set(handles.textOut,'String',sprintf('Finished updating SM %d',globES.pickCID)), drawnow
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'editSkeleton';
            app.figure1.Tag = 'figure1';
            app.uitoolbar1 = uitoolbar(app.figure1);
            app.uitoolbar1.Tag = 'uitoolbar1';
            app.uitoggletool1 = uitoggletool(app.uitoolbar1);
            app.uitoggletool1.Tag = 'uitoggletool1';
            app.uitoggletool1.Tooltip = 'Zoom In';
            app.uitoggletool1.ClickedCallback = createCallbackFcn(app, @uitoggletool1_ClickedCallback, true);
            app.uitoggletool1.Icon = 'uitoggletool1_image.png';
            app.uitoggletool2 = uitoggletool(app.uitoolbar1);
            app.uitoggletool2.Tag = 'uitoggletool2';
            app.uitoggletool2.Tooltip = 'Zoom Out';
            app.uitoggletool2.ClickedCallback = createCallbackFcn(app, @uitoggletool2_ClickedCallback, true);
            app.uitoggletool2.Icon = 'uitoggletool2_image.png';
            app.uitoggletool3 = uitoggletool(app.uitoolbar1);
            app.uitoggletool3.Tag = 'uitoggletool3';
            app.uitoggletool3.Tooltip = 'Pan';
            app.uitoggletool3.ClickedCallback = createCallbackFcn(app, @uitoggletool3_ClickedCallback, true);
            app.uitoggletool3.Icon = 'uitoggletool3_image.png';
            app.uitoggletool4 = uitoggletool(app.uitoolbar1);
            app.uitoggletool4.Tag = 'uitoggletool4';
            app.uitoggletool4.Tooltip = 'Data Tips';
            app.uitoggletool4.ClickedCallback = createCallbackFcn(app, @uitoggletool4_ClickedCallback, true);
            app.uitoggletool4.Icon = 'uitoggletool4_image.png';
            app.uitoggletool5 = uitoggletool(app.uitoolbar1);
            app.uitoggletool5.Tag = 'uitoggletool5';
            app.uitoggletool5.Tooltip = 'Rotate 3D';
            app.uitoggletool5.ClickedCallback = createCallbackFcn(app, @uitoggletool5_ClickedCallback, true);
            app.uitoggletool5.Icon = 'uitoggletool5_image.png';
            app.mainAx = uiaxes(app.figure1);
            app.mainAx.Tag = 'mainAx';
            app.brushPanel = uipanel(app.figure1);
            app.brushPanel.Title = 'Brush';
            app.brushPanel.Tag = 'brushPanel';
            app.brushNode_togglebutton = uibutton(app.brushPanel, 'state');
            app.brushNode_togglebutton.ValueChangedFcn = createCallbackFcn(app, @brushNode_togglebutton_Callback, true);
            app.brushNode_togglebutton.Tag = 'brushNode_togglebutton';
            app.brushNode_togglebutton.Text = 'Toggle Brush';
            app.brushToEdge_pushbutton = uibutton(app.brushPanel, 'push');
            app.brushToEdge_pushbutton.ButtonPushedFcn = createCallbackFcn(app, @brushToEdge_pushbutton_Callback, true);
            app.brushToEdge_pushbutton.Tag = 'brushToEdge_pushbutton';
            app.brushToEdge_pushbutton.Text = 'Skel Nodes';
            app.surfaceToNode_pushbutton = uibutton(app.brushPanel, 'push');
            app.surfaceToNode_pushbutton.ButtonPushedFcn = createCallbackFcn(app, @surfaceToNode_pushbutton_Callback, true);
            app.surfaceToNode_pushbutton.Tag = 'surfaceToNode_pushbutton';
            app.surfaceToNode_pushbutton.Text = 'Surface';
            app.Branch_uibuttongroup = uibuttongroup(app.figure1);
            app.Branch_uibuttongroup.Title = 'Branch';
            app.Branch_uibuttongroup.Tag = 'Branch_uibuttongroup';
            app.start_pushbutton = uibutton(app.Branch_uibuttongroup, 'push');
            app.start_pushbutton.ButtonPushedFcn = createCallbackFcn(app, @start_pushbutton_Callback, true);
            app.start_pushbutton.Tag = 'start_pushbutton';
            app.start_pushbutton.Text = 'Start';
            app.add_pushbutton = uibutton(app.Branch_uibuttongroup, 'push');
            app.add_pushbutton.ButtonPushedFcn = createCallbackFcn(app, @add_pushbutton_Callback, true);
            app.add_pushbutton.Tag = 'add_pushbutton';
            app.add_pushbutton.Text = 'Add';
            app.backup_pushbutton = uibutton(app.Branch_uibuttongroup, 'push');
            app.backup_pushbutton.ButtonPushedFcn = createCallbackFcn(app, @backup_pushbutton_Callback, true);
            app.backup_pushbutton.Tag = 'backup_pushbutton';
            app.backup_pushbutton.Text = 'Backup';
            app.merge_pushbutton = uibutton(app.Branch_uibuttongroup, 'push');
            app.merge_pushbutton.ButtonPushedFcn = createCallbackFcn(app, @merge_pushbutton_Callback, true);
            app.merge_pushbutton.Tag = 'merge_pushbutton';
            app.merge_pushbutton.Text = 'Merge';
            app.clear_pushbutton = uibutton(app.Branch_uibuttongroup, 'push');
            app.clear_pushbutton.ButtonPushedFcn = createCallbackFcn(app, @clear_pushbutton_Callback, true);
            app.clear_pushbutton.Tag = 'clear_pushbutton';
            app.clear_pushbutton.Text = 'Clear';
            app.remove_pushbutton = uibutton(app.figure1, 'push');
            app.remove_pushbutton.ButtonPushedFcn = createCallbackFcn(app, @remove_pushbutton_Callback, true);
            app.remove_pushbutton.Tag = 'remove_pushbutton';
            app.remove_pushbutton.Text = {'Remove '; 'Highlighted'};
            app.show_uipanel = uipanel(app.figure1);
            app.show_uipanel.Title = 'Show';
            app.show_uipanel.Tag = 'show_uipanel';
            app.togglebutton_showRad = uibutton(app.show_uipanel, 'state');
            app.togglebutton_showRad.ValueChangedFcn = createCallbackFcn(app, @togglebutton_showRad_Callback, true);
            app.togglebutton_showRad.Tag = 'radius_togglebutton';
            app.togglebutton_showRad.Text = 'Radius';
            app.togglebutton_showSurfacePlus = uibutton(app.show_uipanel, 'state');
            app.togglebutton_showSurfacePlus.ValueChangedFcn = createCallbackFcn(app, @togglebutton_showSurfacePlus_Callback, true);
            app.togglebutton_showSurfacePlus.Tag = 'togglebutton_showSurfacePlus';
            app.togglebutton_showSurfacePlus.Text = '+';
            app.togglebutton_showSurfaceMinus = uibutton(app.show_uipanel, 'state');
            app.togglebutton_showSurfaceMinus.ValueChangedFcn = createCallbackFcn(app, @togglebutton_showSurfaceMinus_Callback, true);
            app.togglebutton_showSurfaceMinus.Tag = 'togglebutton_showSurfaceMinus';
            app.togglebutton_showSurfaceMinus.Text = '-';
            app.togglebutton_showSurface = uibutton(app.show_uipanel, 'state');
            app.togglebutton_showSurface.ValueChangedFcn = createCallbackFcn(app, @togglebutton_showSurface_Callback, true);
            app.togglebutton_showSurface.Tag = 'togglebutton_showSurface';
            app.togglebutton_showSurface.Text = 'Surface';
            app.togglebutton_showSurface.Value = true;
            app.togglebutton_showRadiusPlus = uibutton(app.show_uipanel, 'state');
            app.togglebutton_showRadiusPlus.ValueChangedFcn = createCallbackFcn(app, @togglebutton_showRadiusPlus_Callback, true);
            app.togglebutton_showRadiusPlus.Tag = 'togglebutton_showRadiusPlus';
            app.togglebutton_showRadiusPlus.Text = '+';
            app.togglebutton_showRadiusMinus = uibutton(app.show_uipanel, 'state');
            app.togglebutton_showRadiusMinus.ValueChangedFcn = createCallbackFcn(app, @togglebutton_showRadiusMinus_Callback, true);
            app.togglebutton_showRadiusMinus.Tag = 'togglebutton_showRadiusMinus';
            app.togglebutton_showRadiusMinus.Text = '-';
            app.togglebutton12 = uibutton(app.show_uipanel, 'state');
            app.togglebutton12.Tag = 'togglebutton12';
            app.togglebutton12.Text = '+';
            app.togglebutton13 = uibutton(app.show_uipanel, 'state');
            app.togglebutton13.Tag = 'togglebutton13';
            app.togglebutton13.Text = '-';
            app.togglebutton_showEdges = uibutton(app.show_uipanel, 'state');
            app.togglebutton_showEdges.ValueChangedFcn = createCallbackFcn(app, @togglebutton_showEdges_Callback, true);
            app.togglebutton_showEdges.Tag = 'togglebutton_showEdges';
            app.togglebutton_showEdges.Text = 'Plots';
            app.togglebutton15 = uibutton(app.show_uipanel, 'state');
            app.togglebutton15.Tag = 'togglebutton15';
            app.togglebutton15.Text = '+';
            app.togglebutton16 = uibutton(app.show_uipanel, 'state');
            app.togglebutton16.Tag = 'togglebutton16';
            app.togglebutton16.Text = '-';
            app.togglebutton_showNodes = uibutton(app.show_uipanel, 'state');
            app.togglebutton_showNodes.ValueChangedFcn = createCallbackFcn(app, @togglebutton_showNodes_Callback, true);
            app.togglebutton_showNodes.Tag = 'togglebutton_showNodes';
            app.togglebutton_showNodes.Text = 'Nodes';
            app.togglebutton18 = uibutton(app.show_uipanel, 'state');
            app.togglebutton18.Tag = 'togglebutton18';
            app.togglebutton18.Text = '+';
            app.togglebutton19 = uibutton(app.show_uipanel, 'state');
            app.togglebutton19.Tag = 'togglebutton19';
            app.togglebutton19.Text = '-';
            app.togglebutton_showVerts = uibutton(app.show_uipanel, 'state');
            app.togglebutton_showVerts.ValueChangedFcn = createCallbackFcn(app, @togglebutton_showVerts_Callback, true);
            app.togglebutton_showVerts.Tag = 'togglebutton_showVerts';
            app.togglebutton_showVerts.Text = 'Verts';
            app.togglebutton12_2 = uibutton(app.show_uipanel, 'state');
            app.togglebutton12_2.ValueChangedFcn = createCallbackFcn(app, @plusAlphEdges, true);
            app.togglebutton12_2.Tag = 'togglebutton12';
            app.togglebutton12_2.Text = '+';
            app.togglebutton13_2 = uibutton(app.show_uipanel, 'state');
            app.togglebutton13_2.ValueChangedFcn = createCallbackFcn(app, @minusAlphEdge, true);
            app.togglebutton13_2.Tag = 'togglebutton13';
            app.togglebutton13_2.Text = '-';
            app.togglebutton_showEdges_2 = uibutton(app.show_uipanel, 'state');
            app.togglebutton_showEdges_2.ValueChangedFcn = createCallbackFcn(app, @showEdgeRadConst, true);
            app.togglebutton_showEdges_2.Tag = 'togglebutton_showEdges';
            app.togglebutton_showEdges_2.Text = 'Edges';
            app.togglebutton_showEdges_2.Value = true;
            app.uipanel_pick = uipanel(app.figure1);
            app.uipanel_pick.Title = 'Pick';
            app.uipanel_pick.Tag = 'uipanel_pick';
            app.pickEdge_togglebutton = uibutton(app.uipanel_pick, 'state');
            app.pickEdge_togglebutton.ValueChangedFcn = createCallbackFcn(app, @pickEdge_togglebutton_Callback, true);
            app.pickEdge_togglebutton.Tag = 'pickEdge_togglebutton';
            app.pickEdge_togglebutton.Text = 'Edge';
            app.pickNode_togglebutton = uibutton(app.uipanel_pick, 'state');
            app.pickNode_togglebutton.ValueChangedFcn = createCallbackFcn(app, @pickNode_togglebutton_Callback, true);
            app.pickNode_togglebutton.Tag = 'pickNode_togglebutton';
            app.pickNode_togglebutton.Text = 'Node';
            app.togglebutton_clearPick = uibutton(app.uipanel_pick, 'state');
            app.togglebutton_clearPick.ValueChangedFcn = createCallbackFcn(app, @togglebutton_clearPick_Callback, true);
            app.togglebutton_clearPick.Tag = 'togglebutton_clearPick';
            app.togglebutton_clearPick.Text = 'X';
            app.uipanel4 = uipanel(app.figure1);
            app.uipanel4.Title = 'Load';
            app.uipanel4.Tag = 'uipanel4';
            app.edit_CID = uieditfield(app.uipanel4, 'text');
            app.edit_CID.ValueChangedFcn = createCallbackFcn(app, @edit_CID_Callback, true);
            app.edit_CID.Tag = 'edit_CID';
            app.text2 = uilabel(app.uipanel4);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Pick Cell';
            app.load_pushbutton = uibutton(app.uipanel4, 'push');
            app.load_pushbutton.ButtonPushedFcn = createCallbackFcn(app, @load_pushbutton_Callback, true);
            app.load_pushbutton.Tag = 'load_pushbutton';
            app.load_pushbutton.Text = 'Load';
            app.pushbutton_clear = uibutton(app.uipanel4, 'push');
            app.pushbutton_clear.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_clear_Callback, true);
            app.pushbutton_clear.Tag = 'pushbutton_clear';
            app.pushbutton_clear.Text = 'Clear';
            app.textOut = uitextarea(app.figure1);
            app.textOut.Tag = 'textOut';
            app.Ymin = uislider(app.figure1);
            app.Ymin.Limits = [0 1];
            app.Ymin.MajorTicks = [];
            app.Ymin.ValueChangedFcn = createCallbackFcn(app, @Ymin_Callback, true);
            app.Ymin.MinorTicks = [];
            app.Ymin.Tag = 'Ymin';
            app.Ymin.Visible = 'off';
            app.Ymax = uislider(app.figure1);
            app.Ymax.Limits = [0 1];
            app.Ymax.MajorTicks = [];
            app.Ymax.ValueChangedFcn = createCallbackFcn(app, @Ymax_Callback, true);
            app.Ymax.MinorTicks = [];
            app.Ymax.Tag = 'Ymax';
            app.Ymax.Visible = 'off';
            app.Ymax.Value = 1;
            app.Zmax = uislider(app.figure1);
            app.Zmax.Limits = [0 1];
            app.Zmax.MajorTicks = [];
            app.Zmax.ValueChangedFcn = createCallbackFcn(app, @Zmax_Callback, true);
            app.Zmax.MinorTicks = [];
            app.Zmax.Tag = 'Zmax';
            app.Zmax.Visible = 'off';
            app.Zmax.Value = 1;
            app.Zmin = uislider(app.figure1);
            app.Zmin.Limits = [0 1];
            app.Zmin.MajorTicks = [];
            app.Zmin.ValueChangedFcn = createCallbackFcn(app, @Zmin_Callback, true);
            app.Zmin.MinorTicks = [];
            app.Zmin.Tag = 'Zmin';
            app.Zmin.Visible = 'off';
            app.uipanel5 = uipanel(app.figure1);
            app.uipanel5.Title = 'Window';
            app.uipanel5.Tag = 'uipanel5';
            app.pushbutton_clip = uibutton(app.uipanel5, 'push');
            app.pushbutton_clip.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_clip_Callback, true);
            app.pushbutton_clip.Tag = 'pushbutton_clip';
            app.pushbutton_clip.Text = 'Clip';
            app.pushbutton_center = uibutton(app.uipanel5, 'state');
            app.pushbutton_center.ValueChangedFcn = createCallbackFcn(app, @pushbutton_center_Callback, true);
            app.pushbutton_center.Tag = 'pushbutton_center';
            app.pushbutton_center.Text = 'Center';
            app.togglebutton_hideWin = uibutton(app.uipanel5, 'state');
            app.togglebutton_hideWin.ValueChangedFcn = createCallbackFcn(app, @togglebutton_hideWin_Callback, true);
            app.togglebutton_hideWin.Tag = 'togglebutton_hideWin';
            app.togglebutton_hideWin.Text = 'Show';
            app.togglebutton_hideWin.Value = true;
            app.edit_winSize = uieditfield(app.uipanel5, 'text');
            app.edit_winSize.ValueChangedFcn = createCallbackFcn(app, @edit_winSize_Callback, true);
            app.edit_winSize.Tag = 'edit_winSize';
            app.text3 = uilabel(app.uipanel5);
            app.text3.Tag = 'text3';
            app.text3.Text = 'Size';
            app.togglebutton_Reset = uibutton(app.uipanel5, 'push');
            app.togglebutton_Reset.ButtonPushedFcn = createCallbackFcn(app, @togglebutton_Reset_Callback, true);
            app.togglebutton_Reset.Tag = 'togglebutton_Reset';
            app.togglebutton_Reset.Text = 'Reset';
            app.uipanel6 = uipanel(app.figure1);
            app.uipanel6.Title = 'Navigate';
            app.uipanel6.Tag = 'uipanel6';
            app.togglebutton_XYZ = uibutton(app.uipanel6, 'state');
            app.togglebutton_XYZ.ValueChangedFcn = createCallbackFcn(app, @togglebutton_XYZ_Callback, true);
            app.togglebutton_XYZ.Tag = 'togglebutton_XYZ';
            app.togglebutton_XYZ.Text = 'get XYZ';
            app.pushbutton_YZ = uibutton(app.uipanel6, 'push');
            app.pushbutton_YZ.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_YZ_Callback, true);
            app.pushbutton_YZ.Tag = 'pushbutton_YZ';
            app.pushbutton_YZ.Text = 'YZ';
            app.pushbutton_XZ = uibutton(app.uipanel6, 'push');
            app.pushbutton_XZ.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_XZ_Callback, true);
            app.pushbutton_XZ.Tag = 'pushbutton_XZ';
            app.pushbutton_XZ.Text = 'XZ';
            app.pushbutton_XY = uibutton(app.uipanel6, 'push');
            app.pushbutton_XY.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_XY_Callback, true);
            app.pushbutton_XY.Tag = 'pushbutton_XY';
            app.pushbutton_XY.Text = 'XY';
            app.pushbutton_origin = uibutton(app.uipanel6, 'state');
            app.pushbutton_origin.ValueChangedFcn = createCallbackFcn(app, @pushbutton_origin_Callback, true);
            app.pushbutton_origin.Tag = 'pushbutton_origin';
            app.pushbutton_origin.Text = 'Set Origin';
            app.uipanel_file = uipanel(app.figure1);
            app.uipanel_file.Title = 'Files';
            app.uipanel_file.Tag = 'uipanel_file';
            app.pushbutton_folder = uibutton(app.uipanel_file, 'push');
            app.pushbutton_folder.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_folder_Callback, true);
            app.pushbutton_folder.Tag = 'pushbutton_folder';
            app.pushbutton_folder.Text = 'Folder';
            app.edit_saveFolder = uieditfield(app.uipanel_file, 'text');
            app.edit_saveFolder.Tag = 'edit_saveFolder';
            app.edit_saveFolder.Value = 'Edit Text';
            app.text4 = uilabel(app.uipanel_file);
            app.text4.Tag = 'text4';
            app.text4.Text = 'File Name';
            app.edit_saveName = uieditfield(app.uipanel_file, 'text');
            app.edit_saveName.ValueChangedFcn = createCallbackFcn(app, @edit_saveName_Callback, true);
            app.edit_saveName.Tag = 'edit_saveName';
            app.pushbutton_makeSM = uibutton(app.uipanel_file, 'push');
            app.pushbutton_makeSM.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_makeSM_Callback, true);
            app.pushbutton_makeSM.Tag = 'pushbutton_makeSM';
            app.pushbutton_makeSM.Text = 'Auto Skel';
            app.pushbutton_loadCustom = uibutton(app.uipanel_file, 'push');
            app.pushbutton_loadCustom.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_loadCustom_Callback, true);
            app.pushbutton_loadCustom.Tag = 'pushbutton_loadCustom';
            app.pushbutton_loadCustom.Text = 'Load Custom';
            app.pushbutton_saveToLibrary = uibutton(app.uipanel_file, 'push');
            app.pushbutton_saveToLibrary.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_saveToLibrary_Callback, true);
            app.pushbutton_saveToLibrary.Tag = 'pushbutton_saveToLibrary';
            app.pushbutton_saveToLibrary.Text = 'Save To Library';
            app.pushbutton_saveCustom = uibutton(app.uipanel_file, 'push');
            app.pushbutton_saveCustom.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_saveCustom_Callback, true);
            app.pushbutton_saveCustom.Tag = 'pushbutton_saveCustom';
            app.pushbutton_saveCustom.Text = 'Save To Custom';
            app.pushbutton_makeSM_2 = uibutton(app.uipanel_file, 'push');
            app.pushbutton_makeSM_2.ButtonPushedFcn = createCallbackFcn(app, @updateSMbutton, true);
            app.pushbutton_makeSM_2.Tag = 'pushbutton_makeSM';
            app.pushbutton_makeSM_2.Text = 'Update cell map (SM) ';
            app.togglebutton_edit = uibutton(app.figure1, 'state');
            app.togglebutton_edit.ValueChangedFcn = createCallbackFcn(app, @togglebutton_edit_Callback, true);
            app.togglebutton_edit.Tag = 'togglebutton_edit';
            app.togglebutton_edit.Text = 'Edit';
            app.Xmin = uislider(app.figure1);
            app.Xmin.Limits = [0 1];
            app.Xmin.MajorTicks = [];
            app.Xmin.ValueChangedFcn = createCallbackFcn(app, @Xmin_Callback, true);
            app.Xmin.MinorTicks = [];
            app.Xmin.Tag = 'Xmin';
            app.Xmin.Visible = 'off';
            app.Xmax = uislider(app.figure1);
            app.Xmax.Limits = [0 1];
            app.Xmax.MajorTicks = [];
            app.Xmax.ValueChangedFcn = createCallbackFcn(app, @Xmax_Callback, true);
            app.Xmax.MinorTicks = [];
            app.Xmax.Tag = 'Xmax';
            app.Xmax.Visible = 'off';
            app.Xmax.Value = 1;
            app.uipanel_nodes = uipanel(app.figure1);
            app.uipanel_nodes.Title = 'Nodes';
            app.uipanel_nodes.Tag = 'uipanel_nodes';
            app.edit_nodes = uieditfield(app.uipanel_nodes, 'text');
            app.edit_nodes.ValueChangedFcn = createCallbackFcn(app, @edit_nodes_Callback, true);
            app.edit_nodes.Tag = 'edit_nodes';
            app.pushbutton_nodeUp = uibutton(app.uipanel_nodes, 'push');
            app.pushbutton_nodeUp.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_nodeUp_Callback, true);
            app.pushbutton_nodeUp.Tag = 'pushbutton_nodeUp';
            app.pushbutton_nodeUp.Text = '+';
            app.pushbutton_nodeDown = uibutton(app.uipanel_nodes, 'push');
            app.pushbutton_nodeDown.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_nodeDown_Callback, true);
            app.pushbutton_nodeDown.Tag = 'pushbutton_nodeDown';
            app.pushbutton_nodeDown.Text = '-';
            app.togglebutton_nepOriginal = uibutton(app.uipanel_nodes, 'state');
            app.togglebutton_nepOriginal.ValueChangedFcn = createCallbackFcn(app, @togglebutton_nepOriginal_Callback, true);
            app.togglebutton_nepOriginal.Tag = 'togglebutton_nepOriginal';
            app.togglebutton_nepOriginal.Text = 'Original';
            app.zcropper_uibuttongroup = uibuttongroup(app.figure1);
            app.zcropper_uibuttongroup.Title = 'Z-Cropper';
            app.zcropper_uibuttongroup.Tag = 'zcropper_uibuttongroup';
            app.SkeletonpropertiesPanel = uipanel(app.figure1);
            app.SkeletonpropertiesPanel.Title = 'Skeleton properties';
            app.randColCheckBox = uicheckbox(app.SkeletonpropertiesPanel);
            app.randColCheckBox.ValueChangedFcn = createCallbackFcn(app, @changeRandCol, true);
            app.randColCheckBox.Text = 'randCol';
            app.randColCheckBox.Value = true;
            app.radiusEditFieldLabel = uilabel(app.SkeletonpropertiesPanel);
            app.radiusEditFieldLabel.Text = 'radius';
            app.radiusEditField = uieditfield(app.SkeletonpropertiesPanel, 'text');
            app.radiusEditField.ValueChangedFcn = createCallbackFcn(app, @changeRadius, true);
            app.radiusEditField.Value = '0.05';
            app.colorbranchesCheckBox = uicheckbox(app.SkeletonpropertiesPanel);
            app.colorbranchesCheckBox.ValueChangedFcn = createCallbackFcn(app, @colorBranches, true);
            app.colorbranchesCheckBox.Text = 'color branches';
            app.colorbranchesCheckBox.Value = true;
            app.colordisconnectedCheckBox = uicheckbox(app.SkeletonpropertiesPanel);
            app.colordisconnectedCheckBox.ValueChangedFcn = createCallbackFcn(app, @colorDisconnected, true);
            app.colordisconnectedCheckBox.Text = 'color disconnected';
            app.RemapButton = uibutton(app.SkeletonpropertiesPanel, 'push');
            app.RemapButton.ButtonPushedFcn = createCallbackFcn(app, @remap, true);
            app.RemapButton.Text = 'Remap';
            app.ConditionSkeletonPanel = uipanel(app.figure1);
            app.ConditionSkeletonPanel.Title = 'Condition Skeleton';
            app.ConditionButton = uibutton(app.ConditionSkeletonPanel, 'push');
            app.ConditionButton.ButtonPushedFcn = createCallbackFcn(app, @pushCondition, true);
            app.ConditionButton.Text = 'Condition';
            app.ShowerrorsButton = uibutton(app.ConditionSkeletonPanel, 'state');
            app.ShowerrorsButton.ValueChangedFcn = createCallbackFcn(app, @showErrorsButton, true);
            app.ShowerrorsButton.Text = 'Show errors';
            app.ShowerrorsButton.Value = true;
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = editSkeleton_AppFig(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)editSkeleton_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end