classdef moveVolume_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        PickVolumeDropDown       matlab.ui.control.DropDown
        PickVolumeDropDownLabel  matlab.ui.control.Label
        ZScaleEditField          matlab.ui.control.NumericEditField
        ZScaleEditFieldLabel     matlab.ui.control.Label
        PickGroupDropDown        matlab.ui.control.DropDown
        PickGroupDropDownLabel   matlab.ui.control.Label
        YXScaleEditField         matlab.ui.control.NumericEditField
        YXScaleEditFieldLabel    matlab.ui.control.Label
        ResetButton              matlab.ui.control.Button
        SaveButton               matlab.ui.control.Button
        ExecuteButton            matlab.ui.control.Button
        PitchEditField           matlab.ui.control.NumericEditField
        PitchEditFieldLabel      matlab.ui.control.Label
        RollEditField            matlab.ui.control.NumericEditField
        RollEditFieldLabel       matlab.ui.control.Label
        ZshiftEditField          matlab.ui.control.NumericEditField
        ZshiftEditFieldLabel     matlab.ui.control.Label
        XshiftEditField          matlab.ui.control.NumericEditField
        XshiftEditFieldLabel     matlab.ui.control.Label
        YshiftEditField          matlab.ui.control.NumericEditField
        YshiftEditFieldLabel     matlab.ui.control.Label
    end


    methods (Access = private)

        function results = readUI(app)
            global globMV
            globMV.Xshift = app.XshiftEditField.Value;
            globMV.Yshift = app.YshiftEditField.Value;
            globMV.Zshift = app.ZshiftEditField.Value;
            globMV.YXscale = app.YXScaleEditField.Value;
            globMV.Zscale = app.ZScaleEditField.Value;
            globMV.Roll = app.RollEditField.Value;
            globMV.Pitch = app.PitchEditField.Value;

        end

        function results = func2(app)

        end

        function results = showG(app)

            global globMV glob

            try
            for i = 1:length(globMV.p.cell)
                delete(globMV.p.cell(i))
            end
            end

            globMV.alpha = .3;
            cids = globMV.g.cid;
            for i = 1:length(globMV.fv1)
                if ~isempty(globMV.fv1(i).faces)
                globMV.p.cell(i) = renderFVnav(globMV.fv1(i),[1 .4 1],globMV.alpha,...
                    cids(i),globMV.tform);
                end
            end
            drawnow

        end
        
        function results = func4(app)
            
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            global glob globMV

            gNames = {glob.g.name};
            app.PickGroupDropDown.Items = gNames;
            app.PickGroupDropDown.ItemsData = [1:length(gNames)];

            A = [1 0 0 0; 0 1 0 0; 0 0 1 0; 0 0 0 1];
            globMV.tform = affinetform3d(A);

            app.PickVolumeDropDown.Items = glob.vol.libNames;
            app.PickVolumeDropDown.ItemsData = 1:length(glob.vol.libNames);


        end

        % Button pushed function: ExecuteButton
        function executeCallback(app, event)
            global glob globMV

            readUI(app)


            pts1 = [ 1 1 1; 1 1 10; 1 10 1; 10 1 1; 1 10 10; 10 10 10; 10 1 10];
            pc1 = pointCloud(pts1);
            yxS = globMV.YXscale;
            zS = globMV.Zscale;
            A  = [      yxS 0 0 globMV.Yshift; ...
                        0 yxS 0 globMV.Xshift; ...
                        0 0 zS globMV.Zshift; ...
                        0 0 0 1];
            tform = affinetform3d(A);

            globMV.A = A;
            globMV.tform = tform;


            showG(app)


        end

        % Value changed function: PickGroupDropDown
        function pickGroupCallback(app, event)

            global glob globMV

            gID = app.PickGroupDropDown.Value;
            glob.g(gID)
            globMV.g = glob.g(gID);


            %% get points

            idxs = globMV.g.idx;
            cids = globMV.g.cid;

            if ~isempty(idxs)
                fileName = sprintf('%s%d.mat',glob.useFvDir,idxs(1));
                globMV.fv1 = loadFV(fileName);

                for i = 2:length(idxs)
                    fileName = sprintf('%s%d.mat',glob.useFvDir,idxs(i));
                    globMV.fv1(i)  = loadFV(fileName);
                end
            else

                globMV.fv1 = [];
            end

            showG(app)

        end

        % Button pushed function: ResetButton
        function resetVals(app, event)
            

            app.XshiftEditField.Value = 0;
            app.YshiftEditField.Value = 0;
            app.ZshiftEditField.Value = 0;
            app.YXScaleEditField.Value = 1;
            app.ZScaleEditField.Value = 1;
            app.RollEditField.Value = 0;
            app.PitchEditField.Value = 0;

        end

        % Button pushed function: SaveButton
        function saveTform(app, event)
            global glob globMV

            volTransform = globMV.tform; %globTranslate.vTran;
            
            volID = app.PickVolumeDropDown.Value;


            glob.vol.tforms{volID} = volTransform;
            
            save([glob.dir.Volumes glob.vol.names{volID} '\volTransform.mat'],'volTransform');
            save([glob.dir.Volumes glob.vol.names{volID} '\Analysis\fvLibrary\volTransform.mat'],'volTransform');


        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            
            global glob

            try
                for i = 1:length(globMV.p.cell)
                    delete(globMV.p.cell(i))
                end
            end

            
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.YshiftEditFieldLabel = uilabel(app.UIFigure);
            app.YshiftEditFieldLabel.Text = 'Y shift';
            app.YshiftEditField = uieditfield(app.UIFigure, 'numeric');
            app.XshiftEditFieldLabel = uilabel(app.UIFigure);
            app.XshiftEditFieldLabel.Text = 'X shift';
            app.XshiftEditField = uieditfield(app.UIFigure, 'numeric');
            app.ZshiftEditFieldLabel = uilabel(app.UIFigure);
            app.ZshiftEditFieldLabel.Text = 'Z shift';
            app.ZshiftEditField = uieditfield(app.UIFigure, 'numeric');
            app.RollEditFieldLabel = uilabel(app.UIFigure);
            app.RollEditFieldLabel.Text = 'Roll';
            app.RollEditField = uieditfield(app.UIFigure, 'numeric');
            app.PitchEditFieldLabel = uilabel(app.UIFigure);
            app.PitchEditFieldLabel.Text = 'Pitch';
            app.PitchEditField = uieditfield(app.UIFigure, 'numeric');
            app.ExecuteButton = uibutton(app.UIFigure, 'push');
            app.ExecuteButton.ButtonPushedFcn = createCallbackFcn(app, @executeCallback, true);
            app.ExecuteButton.Text = 'Execute';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @saveTform, true);
            app.SaveButton.Text = 'Save';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @resetVals, true);
            app.ResetButton.Text = 'Reset';
            app.YXScaleEditFieldLabel = uilabel(app.UIFigure);
            app.YXScaleEditFieldLabel.Text = 'YX Scale';
            app.YXScaleEditField = uieditfield(app.UIFigure, 'numeric');
            app.YXScaleEditField.Value = 1;
            app.PickGroupDropDownLabel = uilabel(app.UIFigure);
            app.PickGroupDropDownLabel.Text = 'Pick Group';
            app.PickGroupDropDown = uidropdown(app.UIFigure);
            app.PickGroupDropDown.ValueChangedFcn = createCallbackFcn(app, @pickGroupCallback, true);
            app.ZScaleEditFieldLabel = uilabel(app.UIFigure);
            app.ZScaleEditFieldLabel.Text = 'Z Scale';
            app.ZScaleEditField = uieditfield(app.UIFigure, 'numeric');
            app.ZScaleEditField.Value = 1;
            app.PickVolumeDropDownLabel = uilabel(app.UIFigure);
            app.PickVolumeDropDownLabel.Text = 'Pick Volume';
            app.PickVolumeDropDown = uidropdown(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = moveVolume_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end