classdef customGroup_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                  matlab.ui.Figure
        text4                    matlab.ui.control.Label
        edit1                    matlab.ui.control.EditField
        pushbuttonUpdate         matlab.ui.control.Button
        pushbuttonCreateGroup    matlab.ui.control.Button
        text3                    matlab.ui.control.Label
        text2                    matlab.ui.control.Label
        popupmenuSubtractGroups  matlab.ui.control.DropDown
        listboxSubtractGroups    matlab.ui.control.ListBox
        popupmenuAddGroups       matlab.ui.control.DropDown
        listboxAddGroups         matlab.ui.control.ListBox
    end

    
    methods (Access = private)
        function showCellGroup(app, v)
            
            global glob globCG tis
            
            
            val = tis.cells.cids(glob.g(v).idx);
            
            if glob.g(v).show
            
                try
                    for r = 1:length(glob.g(v).patch)
                        delete(glob.g(v).patch(r))
                    end
                end
            
                for i = 1:length(val)
                    fileName = sprintf('%s%d.mat',glob.useFvDir,val(i));
                    fv = loadFV(fileName);;
                    glob.g(v).patch(i) = renderFVnav(fv,glob.g(v).col(i,:),glob.g(v).alph,val(i));
                    pause(.01)
                end
            else
                glob.g(v).patch = [];
            end
        end
        
        function updateCG(app)
            
            global glob globCG
            
            globCG.g = glob.g;
            gNames = {globCG.g.name};
            
            if length(gNames)>0
                app.popupmenuAddGroups.Items = gNames;
                app.popupmenuAddGroups.ItemsData = 1:length(gNames);
                app.popupmenuSubtractGroups.Items = gNames;
                app.popupmenuSubtractGroups.ItemsData = 1:length(gNames);
            end
        end
        
        function updateGroups(app)
            
            %newVal = max(min(G,length(groupNames)),1);
            global glob globOb
            groupNames ={glob.g.name};
            if length(groupNames)<1;
                groupNames = {' '};
            end

            glob.app.renderGroups_popUp.Items = groupNames;
            glob.app.renderGroups_popUp.ItemsData = 1:length(groupNames);
            glob.app.renderGroups_popUp.Value = length(groupNames);

            glob.app.popupmenu_synPreGroup.Items = groupNames;
            glob.app.popupmenu_synPreGroup.ItemsData = 1:length(groupNames);
            glob.app.popupmenu_synPreGroup.Value = length(groupNames);

            glob.app.popupmenu_synPostGroup.Items = groupNames;
            glob.app.popupmenu_synPostGroup.ItemsData = 1:length(groupNames);
            glob.app.popupmenu_synPostGroup.Value = length(groupNames);

            if length(glob.g)
                L = glob.app.renderGroups_popUp.Value;
                glob.app.subColor_popUp.Value = glob.g(L).colIdx;
                glob.app.subAlpha_popUp.Value = glob.g(L).alphIdx;
                glob.app.togglebutton_showGroup.Value = glob.g(L).show;
            end
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function customGroup_OpeningFcn(app, varargin)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to customGroup (see VARARGIN)
            
            % Choose default command line output for customGroup
            handles.output = hObject;
            
            % Update handles structure
            guidata(hObject, handles);
            
            % UIWAIT makes customGroup wait for user response (see UIRESUME)
            % uiwait(handles.figure1);
            
            global glob globCG
            
            globCG.h = handles;
            globCG.addG = glob.defaultG;
            globCG.subG = glob.defaultG;
            globCG.newName = '';
            
            updateCG(app)
        end

        % Value changed function: edit1
        function edit1_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edit1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edit1 as text
            %        str2double(get(hObject,'String')) returns contents of edit1 as a double
            
            global glob globCG
            str = get(hObject,'String');
            
            globCG.newName = str;
        end

        % Value changed function: listboxAddGroups
        function listboxAddGroups_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to listboxAddGroups (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns listboxAddGroups contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from listboxAddGroups
            
            global glob globCG
            
            val = get(hObject,'Value')+1;
            
            keep = setdiff(1:length(globCG.addG),val);
            globCG.addG = globCG.addG(keep);
            addNames = {globCG.addG(2:end).name};
            set(hObject,'Value',1);
            set(hObject,'String',addNames);
        end

        % Value changed function: listboxSubtractGroups
        function listboxSubtractGroups_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to listboxSubtractGroups (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns listboxSubtractGroups contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from listboxSubtractGroups
            
            global glob globCG
            
            val = get(hObject,'Value')+1;
            
            keep = setdiff(1:length(globCG.subG),val);
            globCG.subG = globCG.subG(keep);
            subNames = {globCG.subG(2:end).name};
            set(hObject,'Value',1);
            set(hObject,'String',subNames);
        end

        % Value changed function: popupmenuAddGroups
        function popupmenuAddGroups_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to popupmenuAddGroups (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns popupmenuAddGroups contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from popupmenuAddGroups
            
            
            global glob globCG
            
            val = app.popupmenuAddGroups.Value;
            
            L = length(globCG.addG);
            for i = 1:length(val)
                globCG.addG(L+i) = globCG.g(val(i));
            end
            
            addNames = {globCG.addG(2:end).name};
            app.listboxAddGroups.Items = addNames;
            app.listboxAddGroups.ItemsData = 1:length(addNames);
            
        end

        % Value changed function: popupmenuSubtractGroups
        function popupmenuSubtractGroups_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to popupmenuSubtractGroups (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns popupmenuSubtractGroups contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from popupmenuSubtractGroups
            
            global glob globCG
            
            val = app.popupmenuSubtractGroups.Value;
            
            L = length(globCG.subG);
            for i = 1:length(val)
                globCG.subG(L+i) = globCG.g(val(i));
            end
            
            subNames = {globCG.subG(2:end).name};
            set(globCG.h.listboxSubtractGroups,'String',subNames);
        end

        % Button pushed function: pushbuttonCreateGroup
        function pushbuttonCreateGroup_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbuttonCreateGroup (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)


            global glob globCG
            
            
            newGroup = glob.defaultG;
            
            aIdx = [];
            aCid = [];
            aCol = [];
            
            for i = 2:length(globCG.addG)
                aIdx = [aIdx; globCG.addG(i).idx(:)];
                aCid = [aCid; globCG.addG(i).cid(:)];
                aCol = cat(1,aCol,globCG.addG(i).col);
            end
            
            sIdx = [globCG.subG.idx];
            
            uIdx = unique(setdiff(aIdx,sIdx));
            
            [a keepIdx] = intersect(aIdx,uIdx);
            
            newGroup.idx = aIdx(keepIdx);
            newGroup.cid = aCid(keepIdx);
            newGroup.col = aCol(keepIdx,:);
            newGroup.show = get(glob.handles.togglebutton_showGroup,'Value');
            
            v = length(glob.g)+1;
            
            newGroup.name = sprintf('g%d - %s',v,globCG.newName);
            glob.g(v) = newGroup;
            
            showCellGroup(app, v)
            updateGroups(app)
        end

        % Button pushed function: pushbuttonUpdate
        function pushbuttonUpdate_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbuttonUpdate (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            updateCG(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'customGroup';
            app.figure1.Tag = 'figure1';
            app.listboxAddGroups = uilistbox(app.figure1);
            app.listboxAddGroups.Items = {};
            app.listboxAddGroups.Multiselect = 'on';
            app.listboxAddGroups.ValueChangedFcn = createCallbackFcn(app, @listboxAddGroups_Callback, true);
            app.listboxAddGroups.Tag = 'listboxAddGroups';
            app.listboxAddGroups.Value = {};
            app.popupmenuAddGroups = uidropdown(app.figure1);
            app.popupmenuAddGroups.Items = {};
            app.popupmenuAddGroups.ValueChangedFcn = createCallbackFcn(app, @popupmenuAddGroups_Callback, true);
            app.popupmenuAddGroups.Tag = 'popupmenuAddGroups';
            app.popupmenuAddGroups.Value = {};
            app.listboxSubtractGroups = uilistbox(app.figure1);
            app.listboxSubtractGroups.Items = {};
            app.listboxSubtractGroups.ValueChangedFcn = createCallbackFcn(app, @listboxSubtractGroups_Callback, true);
            app.listboxSubtractGroups.Tag = 'listboxSubtractGroups';
            app.listboxSubtractGroups.Value = {};
            app.popupmenuSubtractGroups = uidropdown(app.figure1);
            app.popupmenuSubtractGroups.Items = {};
            app.popupmenuSubtractGroups.ValueChangedFcn = createCallbackFcn(app, @popupmenuSubtractGroups_Callback, true);
            app.popupmenuSubtractGroups.Tag = 'popupmenuSubtractGroups';
            app.popupmenuSubtractGroups.Value = {};
            app.text2 = uilabel(app.figure1);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Add Groups';
            app.text3 = uilabel(app.figure1);
            app.text3.Tag = 'text3';
            app.text3.Text = 'Subtract Groups';
            app.pushbuttonCreateGroup = uibutton(app.figure1, 'push');
            app.pushbuttonCreateGroup.ButtonPushedFcn = createCallbackFcn(app, @pushbuttonCreateGroup_Callback, true);
            app.pushbuttonCreateGroup.Tag = 'pushbuttonCreateGroup';
            app.pushbuttonCreateGroup.Text = 'Create New Group';
            app.pushbuttonUpdate = uibutton(app.figure1, 'push');
            app.pushbuttonUpdate.ButtonPushedFcn = createCallbackFcn(app, @pushbuttonUpdate_Callback, true);
            app.pushbuttonUpdate.Tag = 'pushbuttonUpdate';
            app.pushbuttonUpdate.Text = 'Update CELL Groups (popup menu)';
            app.edit1 = uieditfield(app.figure1, 'text');
            app.edit1.ValueChangedFcn = createCallbackFcn(app, @edit1_Callback, true);
            app.edit1.Tag = 'edit1';
            app.text4 = uilabel(app.figure1);
            app.text4.Tag = 'text4';
            app.text4.Text = 'Name';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = customGroup_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)customGroup_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end