classdef meshAlignment < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        useGroupText                matlab.ui.control.TextArea
        GroupstouseforalignmentTextAreaLabel  matlab.ui.control.Label
        FindalignmentButton         matlab.ui.control.Button
        MaximumshiftEditField       matlab.ui.control.NumericEditField
        MaximumshiftEditFieldLabel  matlab.ui.control.Label
        AddgrouptouseforalignmentDropDown  matlab.ui.control.DropDown
        AddgrouptouseforalignmentDropDownLabel  matlab.ui.control.Label
    end

    
    methods (Access = private)
        
        function meshAlignment_OpeningFcn(app, varargin)
            'hi'
        end
        
        function results = func2(app)
            
        end
    end
    
    methods (Access = public)
        
        function results = func3(app)
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global glob globMA

            groupNames = {glob.g.name};
            app.AddgrouptouseforalignmentDropDown.Items = groupNames;
            app.AddgrouptouseforalignmentDropDown.ItemsData = 1:length(groupNames);

            globMA.groupList = [];

        end

        % Drop down opening function: AddgrouptouseforalignmentDropDown
        function AddgrouptouseforalignmentDropDownOpening(app, event)
            
            global glob

            groupNames = {glob.g.name};
            app.AddgrouptouseforalignmentDropDown.Items = groupNames;
            app.AddgrouptouseforalignmentDropDown.ItemsData = 1:length(groupNames);

        end

        % Value changed function: AddgrouptouseforalignmentDropDown
        function AddgrouptouseforalignmentDropDownValueChanged(app, event)
            value = app.AddgrouptouseforalignmentDropDown.Value;
            global glob globMA

            groupList = globMA.groupList;
            groupList = [groupList value];
            globMA.groupList = groupList;
            useGroupNames = {glob.g(groupList).name};
            groupStr = sprintf('%s\n',useGroupNames{:})
            app.useGroupText.Value = groupStr;

        end

        % Button pushed function: FindalignmentButton
        function findAlignment_button(app, event)
            global glob globMA

            maxShiftValue = app.MaximumshiftEditField.Value;
            maxShiftNum = str2num(maxShiftValue);
            if isempty(maxShiftNum)
                maxShiftNum = 1;
                app.MaximumshiftEditField.Value = num2str(maxShiftNum);
            end
            globMA.maxShift = maxShiftNum;
            
            meshAlignment_forCellNav
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.AddgrouptouseforalignmentDropDownLabel = uilabel(app.UIFigure);
            app.AddgrouptouseforalignmentDropDownLabel.Text = 'Add group to use for alignment';
            app.AddgrouptouseforalignmentDropDown = uidropdown(app.UIFigure);
            app.AddgrouptouseforalignmentDropDown.DropDownOpeningFcn = createCallbackFcn(app, @AddgrouptouseforalignmentDropDownOpening, true);
            app.AddgrouptouseforalignmentDropDown.ValueChangedFcn = createCallbackFcn(app, @AddgrouptouseforalignmentDropDownValueChanged, true);
            app.MaximumshiftEditFieldLabel = uilabel(app.UIFigure);
            app.MaximumshiftEditFieldLabel.Text = 'Maximum shift';
            app.MaximumshiftEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaximumshiftEditField.Value = 1;
            app.FindalignmentButton = uibutton(app.UIFigure, 'push');
            app.FindalignmentButton.ButtonPushedFcn = createCallbackFcn(app, @findAlignment_button, true);
            app.FindalignmentButton.Text = 'Find alignment';
            app.GroupstouseforalignmentTextAreaLabel = uilabel(app.UIFigure);
            app.GroupstouseforalignmentTextAreaLabel.Text = 'Groups to use for alignment';
            app.useGroupText = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = meshAlignment

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end