classdef skeletonizeCells_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        UpdateSWCandSynonlyButton       matlab.ui.control.Button
        NeuronModelPanel                matlab.ui.container.Panel
        PresetsDropDown                 matlab.ui.control.DropDown
        PresetsDropDownLabel            matlab.ui.control.Label
        magRadius                       matlab.ui.control.EditField
        RadiuscorrectionfactorLabel     matlab.ui.control.Label
        VewNeuronresultButton           matlab.ui.control.Button
        e_passEditField                 matlab.ui.control.EditField
        Dendriticreversale_passEditFieldLabel  matlab.ui.control.Label
        g_passEditField                 matlab.ui.control.EditField
        Passiveconductanceg_pasEditFieldLabel  matlab.ui.control.Label
        cmEditField                     matlab.ui.control.EditField
        CapacitanceuFcm2EditFieldLabel  matlab.ui.control.Label
        RaEditField                     matlab.ui.control.EditField
        AxialresistanceRaOhmcmEditFieldLabel  matlab.ui.control.Label
        tauEditField                    matlab.ui.control.EditField
        SynapticstimulationtauEditFieldLabel  matlab.ui.control.Label
        gmaxEditField                   matlab.ui.control.EditField
        SynapsestimulationgmaxEditFieldLabel  matlab.ui.control.Label
        reversalPotentialEditField      matlab.ui.control.EditField
        SynapsereversalpotentialLabel   matlab.ui.control.Label
        ExportexperimentinformationButton  matlab.ui.control.Button
        PreparegroupforNeuronsm2nrn_cidmatButton  matlab.ui.control.Button
        CloseButton                     matlab.ui.control.Button
        UpdateSMfolderButton            matlab.ui.control.Button
        makeSMrunskeletonizeandcelldescriptionsButton  matlab.ui.control.Button
        SelectGroupDropDown             matlab.ui.control.DropDown
        SelectGroupDropDownLabel        matlab.ui.control.Label
        textOut                         matlab.ui.control.EditField
    end

    
    methods (Access = private)
        
        function results = fillPresets(app)
            
            pick = app.PresetsDropDown.Value;
            p = neuronParameters(pick)
            app.RaEditField.Value = num2str(p.Ra);
            app.cmEditField.Value = num2str(p.cm);
            app.g_passEditField.Value = num2str(p.Rm);

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startUp_opening(app)
            global glob globSC
            
            globSC.g = glob.g;
            gNam = {globSC.g.name};
            app.SelectGroupDropDown.Items = gNam;
            app.SelectGroupDropDown.ItemsData = 1:length(gNam);
            
            p = neuronParameters(0);
            app.PresetsDropDown.Items = p;
            app.PresetsDropDown.ItemsData = 1:length(p);
            app.PresetsDropDown.Value = 1;

            p = neuronParameters(1);
            fillPresets(app);

            globSC.fig = figure;
            globSC.ax = gca;
            
            globSC.fig.Position = [ 431   102   824   636];
            
            app.textOut.Value = 'Ready.'
        end

        % Button pushed function: UpdateSMfolderButton
        function updateNepFolderButton(app, event)
            global glob globSC
            
            load('MPN.mat')
            smDir = [WPN 'SMs\'];
            dSMs = dir([smDir '*.mat']);
            nams = { dSMs.name};

            for i = 1:length(nams)
                
                nam = nams{i};
                str = sprintf('saving %s, %d of %d',nam,i,length(nams));
                app.textOut.Value = str; drawnow
                load([smDir nam])
                cid = str2num(nam(7:end-4));
                smxName = sprintf('smx_cid%d.mat',cid);
                save([WPN 'SMs\' smxName],'-struct','sm','nep','syn',...
                    'cell','skel2skel','syn2Skel','-v7.3');
            end

                app.textOut.Value = 'Finished creating all smxs'; drawnow


           if 0 % create nep folder
            app.textOut.Value = 'Updating all available NEPs';
            makeVolMPNcnv
            nepDir = [WPN 'neps\'];
            
            if ~exist(nepDir,'dir'), mkdir(nepDir); end
            for i = 1:length(nams)

                app.textOut.Value = ...
                    sprintf('Extracting %d of %d',i,length(nams));
                drawnow

                nam = nams{i};
                load([smDir nam]);
                if isfield(sm,'nep')

                    nepName = ['nep_' nam(4:end)];
                    nep = sm.nep;
                    save([nepDir nepName],'nep')
                end

                app.textOut.Value = ...
                    sprintf('Finished extracting NEPs');
                drawnow
            end
           end


        end

        % Button pushed function: CloseButton
        function closeWindow(app, event)
            global globSC
            try close(globSC.fig),end
            delete(app)
        end

        % Button pushed function: 
        % makeSMrunskeletonizeandcelldescriptionsButton
        function makeSMs_button(app, event)
            
             global glob globSC
            
            gID = app.SelectGroupDropDown.Value
            g = globSC.g(gID);
            app.textOut.Value = 'Analyzing and Skeletonizing Cells'; drawnow
            makeVolMPNcnv
            goodJob = ones(length(g.idx),1);
            for i = 1:length(g.idx)
                cid = g.cid(i);
                app.textOut.Value = ...
                    sprintf('Analyzing cell %d, (%d of %d)',cid,i,length(g.idx));
                drawnow

                try 
                    makeVolMPNcnv
                    sm = makeSM(cid);
                    fileName = sprintf('%snep_%d.mat',glob.useFvDir,cid);
                    nep = sm.nep;
                    save(fileName,'nep')
                catch err
                    goodJob(i) = 0; 
                    app.textOut.Value = ...
                        sprintf('Failed to skeletonize %d, (%d of %d)',cid,i,length(g.idx));
                    drawnow
                    pause(1)
                end
            end
            app.textOut.Value = sprintf('Finished analyzing cells. %d%% success',mean(goodJob)*100); drawnow

            
        end

        % Button pushed function: PreparegroupforNeuronsm2nrn_cidmatButton
        function prepareNep_button(app, event)
           global glob globSC tis
                    makeVolMPNcnv

           G = app.SelectGroupDropDown.Value;
           if G < 2
               cids = tis.cids;
           else 
               cids = [globSC.g(G).cid];
           end
           if ~isempty(cids)
                prepareNeps(cids,app);
           else
               app.textOut.Value = 'Select group';
           end
        end

        % Button pushed function: UpdateSWCandSynonlyButton
        function updateSWCbutton(app, event)
         global glob globSC


            makeVolMPNcnv
            %condition(app)
           
            app.textOut.Value = sprintf('Updating SM '); drawnow
            for i = 1:length(globSC.pickCID)
                cid = globSC.pickCID(i);
                updateSMwithNep(cid);
                app.textOut.Value = sprintf('Finished updating cid %d (%d of %d) ',cid,i,length(globSC.pickCID)); drawnow

            end

        end

        % Value changed function: SelectGroupDropDown
        function groupDropDownChanged(app, event)
            value = app.SelectGroupDropDown.Value;
            global globSC
            
            globSC.gPick = value;
            globSC.pickCID = globSC.g(value).cid;
            
            app.textOut.Value = sprintf('Picked cells %s',num2str(globSC.pickCID));drawnow
            
        end

        % Button pushed function: ExportexperimentinformationButton
        function exportExperimentButton(app, event)
            app.textOut.Value = 'Exporting experiment'; drawnow
            cnvSetUpNrnExperiment(app)
            app.textOut.Value = 'Finished exporting experiment'; drawnow

        end

        % Button pushed function: VewNeuronresultButton
        function viewNeuronResultButton(app, event)
            app.textOut.Value = 'Showing result';
            cnvExaminNrnResults
        end

        % Value changed function: PresetsDropDown
        function pickDefault(app, event)
            value = app.PresetsDropDown.Value;
            fillPresets(app)
        end

        % Drop down opening function: PresetsDropDown
        function makeDefault(app, event)


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.textOut = uieditfield(app.UIFigure, 'text');
            app.SelectGroupDropDownLabel = uilabel(app.UIFigure);
            app.SelectGroupDropDownLabel.Text = 'Select Group''';
            app.SelectGroupDropDown = uidropdown(app.UIFigure);
            app.SelectGroupDropDown.ValueChangedFcn = createCallbackFcn(app, @groupDropDownChanged, true);
            app.makeSMrunskeletonizeandcelldescriptionsButton = uibutton(app.UIFigure, 'push');
            app.makeSMrunskeletonizeandcelldescriptionsButton.ButtonPushedFcn = createCallbackFcn(app, @makeSMs_button, true);
            app.makeSMrunskeletonizeandcelldescriptionsButton.Text = 'makeSM (run skeletonize and cell descriptions)';
            app.UpdateSMfolderButton = uibutton(app.UIFigure, 'push');
            app.UpdateSMfolderButton.ButtonPushedFcn = createCallbackFcn(app, @updateNepFolderButton, true);
            app.UpdateSMfolderButton.Text = 'Update SM folder';
            app.CloseButton = uibutton(app.UIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @closeWindow, true);
            app.CloseButton.Text = 'Close';
            app.NeuronModelPanel = uipanel(app.UIFigure);
            app.NeuronModelPanel.Title = 'Neuron Model';
            app.PreparegroupforNeuronsm2nrn_cidmatButton = uibutton(app.NeuronModelPanel, 'push');
            app.PreparegroupforNeuronsm2nrn_cidmatButton.ButtonPushedFcn = createCallbackFcn(app, @prepareNep_button, true);
            app.PreparegroupforNeuronsm2nrn_cidmatButton.Text = 'Prepare group for Neuron (sm2nrn_cid.mat)';
            app.ExportexperimentinformationButton = uibutton(app.NeuronModelPanel, 'push');
            app.ExportexperimentinformationButton.ButtonPushedFcn = createCallbackFcn(app, @exportExperimentButton, true);
            app.ExportexperimentinformationButton.Text = 'Export experiment information';
            app.SynapsereversalpotentialLabel = uilabel(app.NeuronModelPanel);
            app.SynapsereversalpotentialLabel.Text = 'Synapse reversal potential';
            app.reversalPotentialEditField = uieditfield(app.NeuronModelPanel, 'text');
            app.reversalPotentialEditField.Value = '0';
            app.SynapsestimulationgmaxEditFieldLabel = uilabel(app.NeuronModelPanel);
            app.SynapsestimulationgmaxEditFieldLabel.Text = 'Synapse stimulation gmax';
            app.gmaxEditField = uieditfield(app.NeuronModelPanel, 'text');
            app.gmaxEditField.Value = '0.8';
            app.SynapticstimulationtauEditFieldLabel = uilabel(app.NeuronModelPanel);
            app.SynapticstimulationtauEditFieldLabel.Text = 'Synaptic stimulation tau';
            app.tauEditField = uieditfield(app.NeuronModelPanel, 'text');
            app.tauEditField.Value = '0.1';
            app.AxialresistanceRaOhmcmEditFieldLabel = uilabel(app.NeuronModelPanel);
            app.AxialresistanceRaOhmcmEditFieldLabel.Text = 'Axial resistance (Ra) Ohm * cm';
            app.RaEditField = uieditfield(app.NeuronModelPanel, 'text');
            app.RaEditField.Value = '1';
            app.CapacitanceuFcm2EditFieldLabel = uilabel(app.NeuronModelPanel);
            app.CapacitanceuFcm2EditFieldLabel.Text = 'Capacitance (uF/cm2) ';
            app.cmEditField = uieditfield(app.NeuronModelPanel, 'text');
            app.cmEditField.Value = '1';
            app.Passiveconductanceg_pasEditFieldLabel = uilabel(app.NeuronModelPanel);
            app.Passiveconductanceg_pasEditFieldLabel.Text = 'Passive conductance (g_pas)';
            app.g_passEditField = uieditfield(app.NeuronModelPanel, 'text');
            app.g_passEditField.Value = '0.001';
            app.Dendriticreversale_passEditFieldLabel = uilabel(app.NeuronModelPanel);
            app.Dendriticreversale_passEditFieldLabel.Text = 'Dendritic reversal e_pass';
            app.e_passEditField = uieditfield(app.NeuronModelPanel, 'text');
            app.e_passEditField.Value = '-65';
            app.VewNeuronresultButton = uibutton(app.NeuronModelPanel, 'push');
            app.VewNeuronresultButton.ButtonPushedFcn = createCallbackFcn(app, @viewNeuronResultButton, true);
            app.VewNeuronresultButton.Text = 'Vew Neuron result';
            app.RadiuscorrectionfactorLabel = uilabel(app.NeuronModelPanel);
            app.RadiuscorrectionfactorLabel.Text = 'Radius correction factor';
            app.magRadius = uieditfield(app.NeuronModelPanel, 'text');
            app.magRadius.Value = '1.5';
            app.PresetsDropDownLabel = uilabel(app.NeuronModelPanel);
            app.PresetsDropDownLabel.Text = 'Presets';
            app.PresetsDropDown = uidropdown(app.NeuronModelPanel);
            app.PresetsDropDown.DropDownOpeningFcn = createCallbackFcn(app, @makeDefault, true);
            app.PresetsDropDown.ValueChangedFcn = createCallbackFcn(app, @pickDefault, true);
            app.UpdateSWCandSynonlyButton = uibutton(app.UIFigure, 'push');
            app.UpdateSWCandSynonlyButton.ButtonPushedFcn = createCallbackFcn(app, @updateSWCbutton, true);
            app.UpdateSWCandSynonlyButton.Text = 'Update SWC and Syn only';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = skeletonizeCells_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startUp_opening)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end