classdef transformVolume_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                   matlab.ui.Figure
        edit_voxSize2             matlab.ui.control.EditField
        edit_voxSize1             matlab.ui.control.EditField
        text6                     matlab.ui.control.Label
        popupmenu_registraton     matlab.ui.control.DropDown
        popupmenu_transform       matlab.ui.control.DropDown
        pushbutton_runTransform   matlab.ui.control.Button
        pushbutton_saveTransform  matlab.ui.control.Button
        text5                     matlab.ui.control.Label
        popupmenu_Method01        matlab.ui.control.DropDown
        text4                     matlab.ui.control.Label
        text3                     matlab.ui.control.Label
        text2                     matlab.ui.control.Label
        popupmenu_chooseVolume    matlab.ui.control.DropDown
        edit_showTransform        matlab.ui.control.TextArea
        edit_pts2                 matlab.ui.control.TextArea
        edit_pts1                 matlab.ui.control.TextArea
        axes1                     matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function transformVolume_OpeningFcn(app, varargin)
            % Ensure that the app appears on screen when run
            movegui(app.figure1, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to transformVolume (see VARARGIN)
            
            % Choose default command line output for transformVolume
            handles.output = hObject;
            
            % Update handles structure
            guidata(hObject, handles);
        end

        % Button pushed function: pushbutton_runTransform
        function pushbutton_runTransform_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton_runTransform (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            global globTranslate
            
            pts1str = get(handles.edit_pts1,'String');
            pts2str = get(handles.edit_pts2,'String');
            vol = get(handles.popupmenu_chooseVolume,'String');
            meth1str = get(handles.popupmenu_Method01,'String');
            meth1val = get(handles.popupmenu_Method01,'Value');
            meth1 = meth1str{meth1val};
            
            transStr = get(handles.popupmenu_transform,'String');
            transVal = get(handles.popupmenu_transform,'Value');
            transType = transStr{transVal};
            
            regTypeVal = get(handles.popupmenu_registraton,'Value');
            regTypeStr = get(handles.popupmenu_registraton,'String');
            regType = regTypeStr{regTypeVal};
            
            vox1 = str2num(get(handles.edit_voxSize1,'String'));
            vox2 = str2num(get(handles.edit_voxSize2,'String'));
            
            
            pts1 = zeros(size(pts1str,1),3);
            for i = 1:size(pts1str)
                line = pts1str(i,:);
                nums = regexp(line,'\d');
                new = find(nums(2:end)-nums(1:end-1)>1);
                X = str2num(line(nums(1):nums(new(1)))) * vox1(1)/1000;
                Y = str2num(line(nums(new(1)+1):nums(new(2)))) * vox1(2)/1000;
                Z = str2num(line(nums(new(2)+1):nums(end))) * vox1(3)/1000;
                pts1(i,:) = [Y X Z];
            end
            
            pts2 = zeros(size(pts2str,1),3);
            for i = 1:size(pts2str)
                line = pts2str(i,:);
                nums = regexp(line,'\d');
                new = find(nums(2:end)-nums(1:end-1)>1);
                X = str2num(line(nums(1):nums(new(1)))) * vox2(1)/1000;
                Y = str2num(line(nums(new(1)+1):nums(new(2)))) * vox2(2)/1000;
                Z = str2num(line(nums(new(2)+1):nums(end))) * vox2(3)/1000;
                pts2(i,:) = [Y X Z];
            end


            pc1 = pointCloud(pts1);
            pc2 = pointCloud(pts2);
            tformStr = 'transform rendered in red';
            if sum(regexp(regType,'pcregistericp'))
                tform = pcregistericp(pc2,pc1,'Metric',meth1,'Extrapolate',true);
                pc3 = pctransform(pc2,tform);
                pts3 = pc3.Location;
                vTran.type = 'pc';
                vTran.tform = tform;
                tformStr = num2str(tform,' %0.2f ');
            
            
            elseif sum(regexp(regType,'pcregistercpd'))
                tform = pcregistercpd(pc2,pc1,'Transform',transType,'InteractionSigma',.01);
                vTran.type = 'pc';
                vTran.tform = tform;
            
            elseif sum(regexp(regType,'fitgeotrans'))
                tform = fitgeotrans(pts2,pts1,transType);
                pc3 = pctransform(pc2,tform);
                pts3 = pc3.Location;
                vTran.type = 'pc';
                vTran.tform = tform;
            
            elseif sum(regexp(regType,'absor'))
                regParam = absor(pts2',pts1','doScale',1,'doTrans',1);
                %tform = maketform('affine',regParam.M);
                tform = rotm2tform(regParam.M)
                pc3 = pctransform(pc2,tform);
                pts3 = pc3.Location;
                vTran.type = 'pc';
                vTran.tform = tform;
            else
                tform1 = estimateGeometricTransform(pts1(:,1:2),pts2(:,1:2),transType);
                pts3a = pts2;
                pts3a(:,1:2) = transformPointsInverse(tform1,pts2(:,1:2));
            
                tform2 = estimateGeometricTransform(pts1(:,2:3),pts3a(:,2:3),transType);
                pts3b = pts3a;
                pts3b(:,2:3) = transformPointsInverse(tform2,pts3b(:,2:3));
            
                pts3 = pts3b;
            
                vTran.type = 'YX XZ';
                vTran.tform1 = tform1;
                vTran.tform2 = tform2;
                tformStr = num2str(cat(1,tform1.T,tform2.T),'      %0.2f      ');
            
                %T = maketform('composite',T1,T2);
                %pts3 = transformPointsInverse(tform,pts2);
            
            end
            
            scatter3(handles.axes1,pts1(:,1),pts1(:,2),pts1(:,3),100,'o','k','MarkerFaceAlpha',1)
            hold on
            scatter3(handles.axes1,pts2(:,1),pts2(:,2),pts2(:,3),50,'o','b','filled','MarkerFaceAlpha',.2)
            scatter3(handles.axes1,pts3(:,1),pts3(:,2),pts3(:,3),50,'o','r','filled','MarkerFaceAlpha',.6)
            hold off
            rotate3d on
            
            set(handles.edit_showTransform,'String',tformStr);
            
            globTranslate.vTran = vTran;
        end

        % Button pushed function: pushbutton_saveTransform
        function pushbutton_saveTransform_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton_saveTransform (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            global glob globTranslate
            
            volTransform = globTranslate.vTran;
            volID = get(handles.popupmenu_chooseVolume,'Value');
            glob.vol.tforms{volID} = volTransform;
            
            save([glob.dir.Volumes glob.vol.names{volID} '\volTransform.mat'],'volTransform');
            save([glob.dir.Volumes glob.vol.names{volID} '\Analysis\fvLibrary\volTransform.mat'],'volTransform');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'transformVolume';
            app.figure1.Tag = 'figure1';
            app.axes1 = uiaxes(app.figure1);
            app.axes1.Tag = 'axes1';
            app.edit_pts1 = uitextarea(app.figure1);
            app.edit_pts1.Tag = 'edit_pts1';
            app.edit_pts2 = uitextarea(app.figure1);
            app.edit_pts2.Tag = 'edit_pts2';
            app.edit_showTransform = uitextarea(app.figure1);
            app.edit_showTransform.Tag = 'edit_showTransform';
            app.popupmenu_chooseVolume = uidropdown(app.figure1);
            app.popupmenu_chooseVolume.Items = {'Analysis', 'AprilMerge', 'LibraryToGo', 'juneTest', 'medRes'};
            app.popupmenu_chooseVolume.Tag = 'popupmenu_chooseVolume';
            app.popupmenu_chooseVolume.Value = 'Analysis';
            app.text2 = uilabel(app.figure1);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Volume to Transform';
            app.text3 = uilabel(app.figure1);
            app.text3.Tag = 'text3';
            app.text3.Text = 'Points in Static Volume';
            app.text4 = uilabel(app.figure1);
            app.text4.Tag = 'text4';
            app.text4.Text = 'Points in Moving Volume';
            app.popupmenu_Method01 = uidropdown(app.figure1);
            app.popupmenu_Method01.Items = {'pointToPoint'};
            app.popupmenu_Method01.Tag = 'popupmenu_Method01';
            app.popupmenu_Method01.Value = 'pointToPoint';
            app.text5 = uilabel(app.figure1);
            app.text5.Tag = 'text5';
            app.text5.Text = 'Transform Options';
            app.pushbutton_saveTransform = uibutton(app.figure1, 'push');
            app.pushbutton_saveTransform.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_saveTransform_Callback, true);
            app.pushbutton_saveTransform.Tag = 'pushbutton_saveTransform';
            app.pushbutton_saveTransform.Text = {'Save Transform '; 'to Volume'};
            app.pushbutton_runTransform = uibutton(app.figure1, 'push');
            app.pushbutton_runTransform.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_runTransform_Callback, true);
            app.pushbutton_runTransform.Tag = 'pushbutton_runTransform';
            app.pushbutton_runTransform.Text = 'Run Transform';
            app.popupmenu_transform = uidropdown(app.figure1);
            app.popupmenu_transform.Items = {'Nonrigid', 'Rigid', 'Affine'};
            app.popupmenu_transform.Tag = 'popupmenu_transform';
            app.popupmenu_transform.Value = 'Affine';
            app.popupmenu_registraton = uidropdown(app.figure1);
            app.popupmenu_registraton.Items = {'YX XZ', 'pcregistericp - For unpaired point clouds', 'pcregistercpd', 'fitgeotrans', 'absor'};
            app.popupmenu_registraton.Tag = 'popupmenu_registraton';
            app.popupmenu_registraton.Value = 'YX XZ';
            app.text6 = uilabel(app.figure1);
            app.text6.Tag = 'text6';
            app.text6.Text = 'Registration Type';
            app.edit_voxSize1 = uieditfield(app.figure1, 'text');
            app.edit_voxSize1.Tag = 'edit_voxSize1';
            app.edit_voxSize1.Value = '4 4 40';
            app.edit_voxSize2 = uieditfield(app.figure1, 'text');
            app.edit_voxSize2.Tag = 'edit_voxSize2';
            app.edit_voxSize2.Value = '20 20 40';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = transformVolume_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)transformVolume_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end