classdef cellNavGuide_8_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1           matlab.ui.Figure
        mainAx            matlab.ui.control.UIAxes
        edit1             matlab.ui.control.EditField
        textCID           matlab.ui.control.Label
        popupCellType     matlab.ui.control.DropDown
        listCIDs          matlab.ui.control.ListBox
        prevCell          matlab.ui.control.Button
        nextCell          matlab.ui.control.Button
        displayToggle     matlab.ui.container.Panel
        toggleBipolars    matlab.ui.control.StateButton
        toggleScaleBar    matlab.ui.control.StateButton
        toggleBorders     matlab.ui.control.StateButton
        panelView         matlab.ui.container.Panel
        viewXY            matlab.ui.control.Button
        viewXZ            matlab.ui.control.Button
        viewYZ            matlab.ui.control.Button
        raiseLight        matlab.ui.control.Button
        textCellType      matlab.ui.control.Label
        edit2             matlab.ui.control.EditField
        textRefCell       matlab.ui.control.Label
        panelConnections  matlab.ui.container.Panel
        pushPreCell       matlab.ui.control.StateButton
        pushPostCells     matlab.ui.control.StateButton
        pushPreSyn        matlab.ui.control.StateButton
        pushPostSyn       matlab.ui.control.StateButton
        pushShrinkMarker  matlab.ui.control.Button
        pushGrowMarker    matlab.ui.control.Button
        panelGetPos       matlab.ui.container.Panel
        pushDataTip       matlab.ui.control.StateButton
        textXYZ           matlab.ui.control.Label
        textOut           matlab.ui.control.Label
        textOutput        matlab.ui.control.Label
    end

    
    % Properties for app components created in addRuntimeConfigurations
    properties (Access = public)
        uitoolbar1 matlab.ui.container.Toolbar
        uiDataTip  matlab.ui.container.toolbar.ToggleTool
    end
    
    methods (Access = private)
        function txt = displayCoordinates(app, ~, info)
            global glob
                X = info.Position(2);
                Y = info.Position(1);
                Z = info.Position(3);
                %c_info = getCursorInfo(glob.dcm)
            
                anc2sub = (glob.em.res/ 1000)./ glob.em.dsRes;
                allPos2 = [X Y Z] ./ anc2sub;
                VASTpos = sprintf('%.0f  %.0f  %.0f',allPos2(end,2),allPos2(end,1),allPos2(end,3));
                disp(VASTpos)
            
                anc2um =  1./ glob.em.dsRes;
                umPos = [X Y Z] ./ anc2um;
                umPosStr = sprintf('%.0f \r%.0f \r%.0f um',umPos(end,2),umPos(end,1),umPos(end,3));
            
                set(glob.handles.textXYZ,'String',VASTpos)
            
                txt = umPosStr;
            
                clipboard('copy',VASTpos)
        end
        
        function showPickedCell(app)
            
            global glob tis
            
            glob.pickCID = tis.cids(glob.pickIdx);
            set(glob.handles.edit1,'String',num2str(glob.pickCID))
            
            pushPostSyn_Callback(glob.handles.pushPostSyn)
            pushPreSyn_Callback(glob.handles.pushPreSyn)
            pushPostCells_Callback(glob.handles.pushPostCells)
            pushPreCell_Callback(glob.handles.pushPreCell)
            
            
            fileName = sprintf('%s%d.mat',glob.fvDir,glob.pickCID);
            load(fileName);
            %     set(handles.mainAx)
            %     patch(handles.mainAx,fv);
            
            try
                glob.p.cell.delete
            end
            glob.p.cell = renderFVnav(fv,[1 1 1],1);
            axis off
            pause(.01)
        end
        
        function showText(app, textStr)
            global glob
            set(glob.handles.textOut,'String',textStr );
        end
        
        % Update components that require runtime configuration
        function addRuntimeConfigurations(app)
            
            % Load data for component configuration
            componentData = load('cellNavGuide_8_App.mat');
            
            % Create uitoolbar1
            app.uitoolbar1 = uitoolbar(app.figure1);
            app.uitoolbar1.Tag = 'uitoolbar1';
            
            % Create uiDataTip
            app.uiDataTip = uitoggletool(app.uitoolbar1);
            app.uiDataTip.CData = componentData.uiDataTip.CData;
            app.uiDataTip.Tag = 'uiDataTip';
            app.uiDataTip.Tooltip = 'Data Tips';
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function cellNavGuide_8_OpeningFcn(app, varargin)
            % Add runtime required configuration - Added by Migration Tool
            addRuntimeConfigurations(app);
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to cellNavGuide_8 (see VARARGIN)
            
            % Choose default command line output for cellNavGuide_8
            handles.output = hObject;
            
            % Update handles structure
            guidata(hObject, handles);
            
            % UIWAIT makes cellNavGuide_8 wait for user response (see UIRESUME)
            % uiwait(handles.figure1);
            
            global glob tis
            
            glob.p = [];
            load('MPN.mat')
            clear gca
            load([MPN 'obI.mat'])
            load([MPN 'dsObj.mat'])
            load([WPN 'tis.mat'])
            
            glob.MPN = MPN;
            glob.WPN = WPN;
            
            glob.fvDir = [WPN 'fvLibrary\'];
            glob.handles = handles;
            glob.pickIdx = 1;
            glob.light = lightangle(0,45) ;
            glob.param.markerSize = 100;
            glob.fvRes = 0.1;
            glob.em = obI.em;
        end

        % Value changed function: edit1
        function edit1_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edit1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edit1 as text
            %        str2double(get(hObject,'String')) returns contents of edit1 as a double
            global glob
            
            str = get(hObject,'string')
            val = str2num(str{1});
            
            glob.pickCID = val;
            showPickedCell(app)
        end

        % Value changed function: edit2
        function edit2_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edit2 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edit2 as text
            %        str2double(get(hObject,'String')) returns contents of edit2 as a double
            
            global glob tis
            
            str = get(hObject,'string')
            val = str2num(str{1});
            
            glob.pickCIDref = val;
            
            fileName = sprintf('%s%d.mat',glob.fvDir,glob.pickCIDref);
            load(fileName);
            
            try
                glob.p.refCell.delete
            end
            glob.p.refCell = renderFVnav(fv,[1 0 0],1);
        end

        % Value changed function: listCIDs
        function listCIDs_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to listCIDs (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns listCIDs contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from listCIDs
            
            global glob tis
            
            val = get(hObject,'Value');
            glob.pickIdx = glob.listCellidx(val);
            glob.pickCID = tis.cids(glob.pickIdx);
            showPickedCell(app)
        end

        % Button pushed function: nextCell
        function nextCell_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to nextCell (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            global glob tis
            
            glob.pickIdx = min(glob.pickIdx + 1, length(glob.listCellidx));
            glob.pickCID = tis.cids(glob.pickIdx);
            set(handles.listCIDs,'Value',glob.pickIdx)
            
            showPickedCell(app)
        end

        % Value changed function: popupCellType
        function popupCellType_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to popupCellType (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns popupCellType contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from popupCellType
            
            global glob tis
            
            
            val = get(hObject,'Value');
            
            if val == 1
                glob.listCellidx = 1:glob.cellNum;
            else
                typeID =glob.typeIDs(val);
                glob.listCellidx = find(tis.cells.type.typeID == typeID);
            
            end
            
            set(handles.listCIDs,'String',glob.cellStr(glob.listCellidx))
            glob.pickIdx = 1;
        end

        % Button pushed function: prevCell
        function prevCell_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to prevCell (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global glob tis
            glob.pickIdx = max(glob.pickIdx - 1, 1);
            glob.pickCID = tis.cids(glob.pickIdx);
            set(handles.listCIDs,'Value',glob.pickIdx)
            showPickedCell(app)
        end

        % Value changed function: pushDataTip
        function pushDataTip_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushDataTip (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of pushDataTip
            global glob
            
            val = get(hObject,'Value');
            
            % try
            %     glob.dcm.Enable = 'off'
            %     %glob.dcm.delete
            % end
            
            if val
            
                glob.dcm = datacursormode(handles.figure1);
                glob.dcm.UpdateFcn = @app.displayCoordinates;
            
                set(glob.dcm,'DisplayStyle','datatip',...
                    'SnapToDataVertex','off','Enable','on')
            else
              rotate3d(handles.figure1)
              delete(findall(handles.figure1,'Type','hggroup'))
            end
        end

        % Button pushed function: pushGrowMarker
        function pushGrowMarker_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushGrowMarker (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            
            global glob
            
            glob.param.markerSize = glob.param.markerSize ^ 1.1;
            glob.param.markerSize = max(1,glob.param.markerSize);
            pushPostSyn_Callback(glob.handles.pushPostSyn)
            pushPreSyn_Callback(glob.handles.pushPreSyn)
        end

        % Value changed function: pushPostCells
        function pushPostCells_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushPostCells (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global glob tis
            
            val = get(hObject,'Value');
            
            try
                glob.p.postCells.delete
            end
            
            if val
            
                targ = glob.pickCID;
            
                isSyn = find(tis.syn.pre == targ);
                isPost = unique(tis.syn.post(isSyn));
                isPost = setdiff(isPost,0);
                showCells = isPost;
                vert = [];
                fac = [];
                for i = 1:length(showCells)
                    fileName = sprintf('%s%d.mat',glob.fvDir,showCells(i));
                    if exist(fileName,'file')
                        load(fileName);
                        fac = cat(1,fac,fv.faces+size(vert,1));
                        vert = cat(1,vert,fv.vertices);
                    end
                end
            
                fv.vertices = vert;
                fv.faces = fac;
                glob.p.postCells = renderFVnav(fv,[1 .4 .2],.6)
            
                    postText = sprintf('post to %d = %s',targ,num2str(isPost'));
                showText(app, postText);
            
            
            end
        end

        % Value changed function: pushPostSyn
        function pushPostSyn_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushPostSyn (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            global glob tis
            
            val = get(hObject,'Value');
            
            try glob.p.postSyn1.delete
            end
            try glob.p.postSyn2.delete
            end
            if val
            
                targ = glob.pickCID;
                isSyn = find(tis.syn.pre == targ);
                pos = tis.syn.synPosDS(isSyn,[2 1 3]);
                postClass = tis.syn.postClass(isSyn);
                col = repmat([1 0 0],[length(isSyn) 1]);
                isRGC = find(postClass==1);
                hold on
                glob.p.postSyn1 = scatter3(pos(:,1),pos(:,2),pos(:,3),glob.param.markerSize,'markerfacecolor','m',...
                    'marker','o','markeredgecolor','w');
                glob.p.postSyn2 = scatter3(pos(isRGC,1),pos(isRGC,2),pos(isRGC,3),glob.param.markerSize,'markerfacecolor','b',...
                    'marker','o','markeredgealpha',0);
                set(glob.p.postSyn1,'clipping','off')
                set(glob.p.postSyn2,'clipping','off')
            
                hold off
            
            
                preSyn = tis.syn.post(isSyn);
                %postText = sprintf('postSyn to %d = %s',targ,num2str(preSyn'));
            
                postText = sprintf('allPost = %d.  RGC post = %d',length(isSyn),length(isRGC));
            
                showText(app, postText);
            
            end
        end

        % Value changed function: pushPreCell
        function pushPreCell_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushPreCell (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global glob tis
            
            val = get(hObject,'Value');


            try
                glob.p.preCells.delete
            end
            
            
            if val
            
                targ = glob.pickCID;
            
                isSyn = find(tis.syn.post == targ);
                isPre = unique(tis.syn.pre(isSyn));
                isPre = setdiff(isPre,0);
                showCells = isPre;
                vert = [];
                fac = [];
                for i = 1:length(showCells)
                    fileName = sprintf('%s%d.mat',glob.fvDir,showCells(i));
                    if exist(fileName,'file')
                        load(fileName);
                        fac = cat(1,fac,fv.faces+size(vert,1));
                        vert = cat(1,vert,fv.vertices);
                    end
                end
            
                fv.vertices = vert;
                fv.faces = fac;
                glob.p.preCells = renderFVnav(fv,[.2 .4 1],.6)
            
                preText = sprintf('pre to %d = %s',targ,num2str(isPre'));
                showText(app, preText);
            
            
            end
        end

        % Value changed function: pushPreSyn
        function pushPreSyn_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushPreSyn (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            global glob tis
            
            val = get(hObject,'Value');
            
            try glob.p.preSyn1.delete
            end
            try glob.p.preSyn2.delete
            end
            if val
            
                targ = glob.pickCID;
                isSyn = find(tis.syn.post == targ);
                pos = tis.syn.synPosDS(isSyn,[2 1 3]);
                preClass = tis.syn.preClass(isSyn);
                col = repmat([1 0 0],[length(isSyn) 1]);
                isBip = find(preClass==7);
                hold on
                glob.p.preSyn1 = scatter3(pos(:,1),pos(:,2),pos(:,3),glob.param.markerSize,'markerfacecolor','r',...
                    'marker','o','markeredgecolor','w');
                glob.p.preSyn2 = scatter3(pos(isBip,1),pos(isBip,2),pos(isBip,3),glob.param.markerSize,'markerfacecolor','g',...
                    'marker','o','markeredgealpha',0);
                set(glob.p.preSyn1,'clipping','off')
                set(glob.p.preSyn2,'clipping','off')
            
                hold off
            
                preSyn = tis.syn.pre(isSyn);
                %postText = sprintf('preSyn to %d = %s',targ,num2str(preSyn'));
                postText = sprintf('all Pre = %d.  Bip post = %d',length(isSyn),length(isBip));
            
                showText(app, postText);
            end
        end

        % Button pushed function: pushShrinkMarker
        function pushShrinkMarker_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushShrinkMarker (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            global glob
            
            glob.param.markerSize = glob.param.markerSize ^ .9;
            glob.param.markerSize = max(1,glob.param.markerSize);
            pushPostSyn_Callback(glob.handles.pushPostSyn)
            pushPreSyn_Callback(glob.handles.pushPreSyn)
        end

        % Button pushed function: raiseLight
        function raiseLight_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to raiseLight (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            global glob
            
            [a e] = lightangle(glob.light);
            %[a e] = view;
            lightangle(glob.light,mod(a + 45,360),mod(e + 22.5,360));
        end

        % Value changed function: toggleBipolars
        function toggleBipolars_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to toggleBipolars (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of toggleBipolars
            
            global glob tis
            
            val = get(hObject,'Value')
            
            try
                isHand = isvalid(glob.p.pBipolars);
            catch err
                isHand = 0;
            end
            
            if val
                if isHand
                    glob.p.pBipolars.Visible = 'on';
                else
                    bipCids = tis.cells.type.typeLists.bpc;
                    vert = [];
                    fac = [];
                    for i = 1:length(bipCids)
                        fileName = sprintf('%s%d.mat',glob.fvDir,bipCids(i));
                        load(fileName);
                        fac = cat(1,fac,fv.faces+size(vert,1));
                        vert = cat(1,vert,fv.vertices);
                    end
            
                    fv.vertices = vert;
                    fv.faces = fac;
                    glob.p.pBipolars = renderFVnav(fv,[0 .8 0],.1)
            
                end
            else
                if isHand
                    glob.p.pBipolars.Visible = 'off';
                end
            end
        end

        % Value changed function: toggleBorders
        function toggleBorders_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to toggleBorders (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of toggleBorders
            
            global glob
            
            val = get(hObject,'Value');
            try
                isHand = isvalid(glob.p.pRefINL);
            catch err
                isHand = 0;
            end
            
            if val
                if isHand
                    glob.p.pRefGCL.Visible = 'on';
                    glob.p.pRefINL.Visible = 'on';
                else
                    load([glob.fvDir 'ref_gcl nucEdge.mat'])
                    glob.p.pRefGCL = renderFVnav(fv,[1 1 0],.5)
            
                    load([glob.fvDir 'ref_inl nucEdge.mat'])
                    glob.p.pRefINL = renderFVnav(fv,[1 0 1],.5)
                end
            else
                if isHand
                    glob.p.pRefGCL.Visible = 'off'
                    glob.p.pRefINL.Visible = 'off'
                end
            
            end
        end

        % Value changed function: toggleScaleBar
        function toggleScaleBar_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to toggleScaleBar (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of toggleScaleBar
            global glob
            
            val = get(hObject,'Value')
            try
                isHand = isvalid(glob.p.pRefMicronBar);
            catch err
                isHand = 0;
            end
            
            if val
                if isHand
                    glob.p.pRefMicronBar.Visible = 'on';
                    glob.p.pRefMicronPt1.Visible = 'on';
                    glob.p.pRefMicronPt2.Visible = 'on';
                else
                    load([glob.fvDir 'ref_10 micron bar.mat'])
                    glob.p.pRefMicronBar = renderFVnav(fv,[1 1 1],1)
            
                    load([glob.fvDir 'ref_10 micron point 1.mat'])
                    glob.p.pRefMicronPt1 = renderFVnav(fv,[1 1 1],.5)
            
                    load([glob.fvDir 'ref_10 micron point 2.mat'])
                    glob.p.pRefMicronPt2 = renderFVnav(fv,[1 1 1],.5)
                end
            else
                if isHand
                    glob.p.pRefMicronBar.Visible = 'off';
                    glob.p.pRefMicronPt1.Visible = 'off';
                    glob.p.pRefMicronPt2.Visible = 'off';
                end
            end
        end

        % Button pushed function: viewXY
        function viewXY_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to viewXY (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            view([0 270])
        end

        % Button pushed function: viewXZ
        function viewXZ_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to viewXZ (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            
            view([0 5 ])
        end

        % Button pushed function: viewYZ
        function viewYZ_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to viewYZ (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            view([92 1])
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'cellNavGuide_8';
            app.figure1.Tag = 'figure1';
            app.mainAx = uiaxes(app.figure1);
            app.mainAx.Visible = 'off';
            app.mainAx.Tag = 'mainAx';
            app.edit1 = uieditfield(app.figure1, 'text');
            app.edit1.ValueChangedFcn = createCallbackFcn(app, @edit1_Callback, true);
            app.edit1.Tag = 'edit1';
            app.edit1.Value = 'Edit Text';
            app.textCID = uilabel(app.figure1);
            app.textCID.Tag = 'textCID';
            app.textCID.Text = 'Cell ID';
            app.popupCellType = uidropdown(app.figure1);
            app.popupCellType.Items = {'all', 'unassigned', 'rgc', 'unk', 'bpc', 'amc'};
            app.popupCellType.ValueChangedFcn = createCallbackFcn(app, @popupCellType_Callback, true);
            app.popupCellType.Tag = 'popupCellType';
            app.popupCellType.Value = 'all';
            app.listCIDs = uilistbox(app.figure1);
            app.listCIDs.Items = {'2    cell cid2 vgc', '3    cell cid3 vgc', '4    cell cid4 vgc', '5    cell cid5 vgc init', '10    cell cid10 vgc soma', '11    cell cid11 vgc soma', '13    cell cid13 vgc soma', '14    cell cid14 vgc soma', '91    cid091 bpc frag3', '103    cell cid103 amc', '104    cell cid104 amc', '105    cell cid105 amc', '106    cell cid106 bpc', '112    cid112 bpc frag5 do', '113    cid113 bpc frag3 do', '1000    cell cid1000 init', '1001    cell cid1001 init', '1002    cell cid1002 init', '1003    cell cid1003 init', '1004    cell cid1004 bip', '1005    cell cid1005 amc', '1006    cid1006 rib (cid3 cid1007)', '1007    *cell cid3002 rgc', '1008    cell cid1008 init', '1009    cell cid1009 amc init', '1010    cell cid1010 init', '1011    cell cid1011 init', '1012    cell cid1012', '1013    cid1013 syn cid3', '1014    cell cid1014 init', '1015    cell cid1015 init', '1016    cell cid1016 init', '1017    frag cid1017', '1018    cell cid1018 init', '1019    cell cid1019 init', '1020    cell cid 1020 ? ', '1021    cell cid3047 rgc', '1022    cell cid1022 brh1', '1023    cell cid 1023', '1024    frag cid1024', '1025    frag cid1025', '1026    cell cid1026 rgc init', '1027    cid1027 rib (cid3 cid1028)', '1028    frag cid1028', '1029    frag cid1029', '1030    cid1030 syn cid3', '1031    cid1031 syn cid3', '1032    cid1032 syn cid3', '1033    cid1033 brh1', '1036    cid1036 syn cid3', '1037    cid1037 syn cid3', '1038    cid1038 syn cid3', '1039    cid1039 syn cid3', '1042    cid1042 syn cid3', '1043    cid1043 syn cid3', '1044    cid1044 syn cid3', '1045    cid1045 syn cid3 big engulf', '1046    cid1046 syn cid3', '1047    cid1047 syn cid3', '1051    cid1051 syn cid3', '1052    cell cid1052 rgc init', '1054    cid1054 syn cid3', '1055    cell cid1055 amc init', '1056    cell cid1056 bpc init', '1057    cell cid1057 rgc', '1058    cell cid1058 init', '1059    cell cid1059 bpc init', '1060    cell cid1060 rgc init', '1061    cell cid1061 bpc init', '1062    cell cid1062 rbc init', '1064    cell cid1064 bpc init', '1065    cell cid1065 bpc init', '1066    cell cid1066 amc init', '1067    cell cid1067 rgc init', '1068    cell cid1068 amc init', '1069    cell cid1069 rgc init', '1070    cell cid1070 rgc init', '1072    cid1072 rib (cid3 cid1069 0)', '1074    cell cid1074 bpc init', '1075    cell cid1075 bpc init', '1076    cell cid1076 amc init', '1077    cell cid1077 amc init', '1078    cell cid1078 rgc init', '1079    cell cid1079 amc init', '1081    cell cid1081 rgc init', '1082    cell cid1082 rgc init', '1083    cell cid1083 bpc init', '1084    cell cid1084 bpc init', '1085    cell cid1085 amc init', '1087    cell cid1087 bpc init', '1088    cell cid1088 bpc init', '1089    cell cid1089 bpc init', '1090    cell cid1090 bpc init do (labeled 2005 brh1.2)', '1091    cell cid1091 bpc init do', '1093    cell cid1093 amc init lost quickly', '1094    cell cid1094 amc init do', '1095    cell cid1095 bpc init (labeled 2005 brh1.2.1)', '1096    cell cid1096 bpc init', '1097    cid1097 brh1', '1098    cell cid1098 bpc init do (labeled 2005 brh1.3)', '1099    cell cid1099 bpc init', '1100    cell cid1100 bpc init', '1101    cell cid1101 bpc init closetoedge', '1102    cell cid1102 amc init', '1103    cell cid1103 amc init', '1104    cell cid1104 amc init', '1105    cell cid1105 bpc init', '1106    cell cid1106 amc init', '1107    cell cid1107 rgc init', '1108    cell cid1108 bpc init', '1109    cell cid1109 amc init', '1110    cell cid1110 rgc init', '1111    cell cid1111 bpc init', '1112    cell cid1112 bpc init', '1113    cell cid1113 bpc', '1114    cell cid1114 bpc init', '1115    cell cid1115 bpc init', '1116    cell cid1116 bpc init', '1117    cell cid1117 bpc init', '1118    cell cid1118 rgc init', '1119    cell cid1119 rgc init', '1120    cell cid1120 bpc init', '1121    cell cid1121 bpc init', '1122    cell cid1122 rgc init', '1123    cell cid1123 amc init', '1124    cell cid1124 rgc init', '1126    cell cid1126 bpc init', '1127    cell cid1127 rgc init', '1128    cell cid1128 bpc init do', '1129    cid 1129 brh1', '1130    cell cid1130 bpc init', '1200    frag cid1200', '1201    frag cid1201', '1202    frag cid1202', '1203    frag cid1203', '1204    cell cid1204 bpc', '1205    cell cid1205 rgc', '1400    cell cid1400 bpc frag1', '2001    cell cid2001 amc', '2002    cell cid2002 rgc', '2003    cell cid2003 rgc', '2004    cid2004 syn cid4', '2005    cell cid2005 bpc init', '2007    cell cid2007 bpc', '2008    cell cid2008 bpc', '2009    cell cid2009 bpc', '2010    cell cid2010 bpc', '2011    cell cid2011 bpc', '2012    cell cid2012 bpc', '2013    cell cid2013 bpc', '2014    cell cid2014 bpc', '2015    cell cid2015 bpc', '2016    cell cid2016 bpc', '2017    cell cid2017 bpc', '2018    cell cid2018 bpc', '2019    cell cid2019 bpc', '2020    cell cid2020 bpc', '2021    cell cid2021 bpc', '2022    cell cid2022 bpc', '2023    cell cid2023 bpc', '2024    cell cid2024 bpc', '2026    cell cid2026 bpc', '2027    cell cid2027 bpc', '2028    cell cid2028 bpc', '3001    cell cid3001 amc', '3003    cell cid3003 unk', '3004    cell cid3004 unk', '3005    cell cid3005 amc', '3006    cell cid3006 rgc', '3007    cell cid3007 rgc', '3008    cell cid3008 rgc', '3009    cell cid3009 amc', '3010    cell cid3010 amc', '3011    cell cid3011 amc', '3012    cell cid3012 amc', '3013    cell cid3013 rgc', '3014    cell cid3014 amc', '3015    cell cid3015 amc', '3016    cell cid3016 unk', '3017    cell cid3017 rgc', '3018    cell cid3018 unk', '3019    cell cid3019 amc', '3020    cell cid3020 amc', '3021    cell cid3021 unk', '3022    cell cid3022 amc', '3023    cell cid3023 unk', '3024    cell cid3024 amc', '3025    cell cid3025 amc', '3026    cell cid3026 rgc', '3027    cell cid3027 amc', '3028    cell cid3028 amc', '3029    cell cid3029 amc', '3030    cell cid3030 amc', '3031    cell cid3031 rgc', '3032    cell cid3032 rgc', '3033    cell cid3033 amc', '3034    cell cid3034 amc', '3035    cell cid3035 rgc', '3036    cell cid3036 rgc', '3037    cell cid3037 amc', '3038    cell cid3038 amc', '3039    cell cid3039 rgc', '3040    cell cid3040 amc', '3041    cell cid3041 unk', '3042    cell cid3042 amc', '3043    cell cid3043 unk', '3044    cell cid3044 amc', '3045    cell cid3045 rgc', '3046    cell cid3046 amc', '3048    cell cid3048 unk', '3049    cell cid3049 rgc', '3050    cell cid3050 rgc', '3051    cell cid3051 rgc', '3052    cell cid3052 rgc', '3053    cell cid3053 amc', '3054    cell cid3054 amc', '3055    cell cid3055 bpc', '3056    cell cid3056', '3057    cell cid3057 amc', '3058    cell cid3058 amc', '3059    cell cid3059', '3060    cell cid3060 unk', '3061    cell cid3061 amc', '3062    cell cid3062 unk', '3063    cell cid3063 amc', '3064    cell cid3064 rgc', '3065    cell cid3065 amc', '3066    cell cid3066 rgc', '3067    cell cid3067 rgc', '3068    cell cid3068 amc', '3069    cell cid3069 unk', '3070    cell cid3070 amc', '3071    cell cid3071 amc', '3072    cell cid3072 rgc', '3073    cell cid3073 rgc', '3074    cell cid3074 amc', '3075    cell cid3075 amc', '3076    cell cid3076 amc', '3077    cell cid3077 amc', '3078    cell cid3078 unk', '3079    cell cid3079 rgc', '3080    cell cid3080 amc', '3081    cell cid3081 amc', '3082    cell cid3082 amc', '3084    cell cid3084 amc', '3085    cell cid3085 amc', '3086    cell cid3086 amc', '3087    cell cid3087 amc', '3088    cell cid3088', '3089    cell cid3089 amc', '3090    cell cid3090 amc', '3091    cell cid3091 unk', '3092    cell cid3092 rgc', '3093    cell cid3093 unk', '3094    cell cid3094 rgc', '3095    cell cid3095 rgc', '3096    cell cid3096 amc', '4001    cid4001 brh 1.1.1.1.2.1', '10001    cell cid10001 prc', '11107    cid11107 rgc frag6 do', '11129    cid 11129 brh3', '123666    cell cid 123666 test'};
            app.listCIDs.ValueChangedFcn = createCallbackFcn(app, @listCIDs_Callback, true);
            app.listCIDs.Tag = 'listCIDs';
            app.listCIDs.Value = '2    cell cid2 vgc';
            app.prevCell = uibutton(app.figure1, 'push');
            app.prevCell.ButtonPushedFcn = createCallbackFcn(app, @prevCell_Callback, true);
            app.prevCell.Tag = 'prevCell';
            app.prevCell.Text = 'Previous Cell';
            app.nextCell = uibutton(app.figure1, 'push');
            app.nextCell.ButtonPushedFcn = createCallbackFcn(app, @nextCell_Callback, true);
            app.nextCell.Tag = 'nextCell';
            app.nextCell.Text = 'Next Cell';
            app.displayToggle = uipanel(app.figure1);
            app.displayToggle.Title = 'ON / OFF';
            app.displayToggle.Tag = 'displayToggle';
            app.toggleBipolars = uibutton(app.displayToggle, 'state');
            app.toggleBipolars.ValueChangedFcn = createCallbackFcn(app, @toggleBipolars_Callback, true);
            app.toggleBipolars.Tag = 'toggleBipolars';
            app.toggleBipolars.Text = 'Show Bipolars';
            app.toggleScaleBar = uibutton(app.displayToggle, 'state');
            app.toggleScaleBar.ValueChangedFcn = createCallbackFcn(app, @toggleScaleBar_Callback, true);
            app.toggleScaleBar.Tag = 'toggleScaleBar';
            app.toggleScaleBar.Text = 'Scale Bar';
            app.toggleBorders = uibutton(app.displayToggle, 'state');
            app.toggleBorders.ValueChangedFcn = createCallbackFcn(app, @toggleBorders_Callback, true);
            app.toggleBorders.Tag = 'toggleBorders';
            app.toggleBorders.Text = 'IPL Borders';
            app.panelView = uipanel(app.figure1);
            app.panelView.Title = 'View';
            app.panelView.Tag = 'panelView';
            app.viewXY = uibutton(app.panelView, 'push');
            app.viewXY.ButtonPushedFcn = createCallbackFcn(app, @viewXY_Callback, true);
            app.viewXY.Tag = 'viewXY';
            app.viewXY.Text = 'View XY';
            app.viewXZ = uibutton(app.panelView, 'push');
            app.viewXZ.ButtonPushedFcn = createCallbackFcn(app, @viewXZ_Callback, true);
            app.viewXZ.Tag = 'viewXZ';
            app.viewXZ.Text = 'View XZ';
            app.viewYZ = uibutton(app.panelView, 'push');
            app.viewYZ.ButtonPushedFcn = createCallbackFcn(app, @viewYZ_Callback, true);
            app.viewYZ.Tag = 'viewYZ';
            app.viewYZ.Text = 'View YZ';
            app.raiseLight = uibutton(app.panelView, 'push');
            app.raiseLight.ButtonPushedFcn = createCallbackFcn(app, @raiseLight_Callback, true);
            app.raiseLight.Tag = 'raiseLight';
            app.raiseLight.Text = 'Raise Light';
            app.textCellType = uilabel(app.figure1);
            app.textCellType.Tag = 'textCellType';
            app.textCellType.Text = 'Cell type';
            app.edit2 = uieditfield(app.figure1, 'text');
            app.edit2.ValueChangedFcn = createCallbackFcn(app, @edit2_Callback, true);
            app.edit2.Tag = 'edit2';
            app.edit2.Value = 'Edit Text';
            app.textRefCell = uilabel(app.figure1);
            app.textRefCell.Tag = 'textRefCell';
            app.textRefCell.Text = 'Ref Cell';
            app.panelConnections = uipanel(app.figure1);
            app.panelConnections.Title = 'Connections';
            app.panelConnections.Tag = 'panelConnections';
            app.pushPreCell = uibutton(app.panelConnections, 'state');
            app.pushPreCell.ValueChangedFcn = createCallbackFcn(app, @pushPreCell_Callback, true);
            app.pushPreCell.Tag = 'pushPreCell';
            app.pushPreCell.Text = 'Pre Cells';
            app.pushPostCells = uibutton(app.panelConnections, 'state');
            app.pushPostCells.ValueChangedFcn = createCallbackFcn(app, @pushPostCells_Callback, true);
            app.pushPostCells.Tag = 'pushPostCells';
            app.pushPostCells.Text = 'Post Cells';
            app.pushPreSyn = uibutton(app.panelConnections, 'state');
            app.pushPreSyn.ValueChangedFcn = createCallbackFcn(app, @pushPreSyn_Callback, true);
            app.pushPreSyn.Tag = 'pushPreSyn';
            app.pushPreSyn.Text = 'Pre Syn';
            app.pushPostSyn = uibutton(app.panelConnections, 'state');
            app.pushPostSyn.ValueChangedFcn = createCallbackFcn(app, @pushPostSyn_Callback, true);
            app.pushPostSyn.Tag = 'pushPostSyn';
            app.pushPostSyn.Text = 'Post Syn';
            app.pushShrinkMarker = uibutton(app.panelConnections, 'push');
            app.pushShrinkMarker.ButtonPushedFcn = createCallbackFcn(app, @pushShrinkMarker_Callback, true);
            app.pushShrinkMarker.Tag = 'pushShrinkMarker';
            app.pushShrinkMarker.Text = '(-)';
            app.pushGrowMarker = uibutton(app.panelConnections, 'push');
            app.pushGrowMarker.ButtonPushedFcn = createCallbackFcn(app, @pushGrowMarker_Callback, true);
            app.pushGrowMarker.Tag = 'pushGrowMarker';
            app.pushGrowMarker.Text = '(+)';
            app.panelGetPos = uipanel(app.figure1);
            app.panelGetPos.Title = 'Position';
            app.panelGetPos.Tag = 'panelGetPos';
            app.pushDataTip = uibutton(app.panelGetPos, 'state');
            app.pushDataTip.ValueChangedFcn = createCallbackFcn(app, @pushDataTip_Callback, true);
            app.pushDataTip.Tag = 'pushDataTip';
            app.pushDataTip.Text = 'Gety XYZ';
            app.textXYZ = uilabel(app.panelGetPos);
            app.textXYZ.Tag = 'textXYZ';
            app.textXYZ.Text = '[ 0 , 0 , 0 ]';
            app.textOut = uilabel(app.figure1);
            app.textOut.Tag = 'textOut';
            app.textOut.Text = '';
            app.textOutput = uilabel(app.figure1);
            app.textOutput.Tag = 'textOutput';
            app.textOutput.Text = 'Info';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = cellNavGuide_8_App(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @(app)cellNavGuide_8_OpeningFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end