classdef navCellsOn3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        CellIDcidEditFieldLabel  matlab.ui.control.Label
        CellIDcidEditField       matlab.ui.control.NumericEditField
        Tree                     matlab.ui.container.Tree
        Node                     matlab.ui.container.TreeNode
        Node2                    matlab.ui.container.TreeNode
        Node3                    matlab.ui.container.TreeNode
        Node4                    matlab.ui.container.TreeNode
        CellTypeDropDownLabel    matlab.ui.control.Label
        CellTypeDropDown         matlab.ui.control.DropDown
        PickCellListBoxLabel     matlab.ui.control.Label
        PickCellListBox          matlab.ui.control.ListBox
        IPLBoardersButton        matlab.ui.control.StateButton
        BipolarCellsButton       matlab.ui.control.StateButton
        mainAx                   matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global glob tis
            load('MPN.mat')
            load([WPN 'tis.mat'])
            fvDir = [WPN 'fvLibrary\'];
            glob.fvDir = fvDir;
            glob.MPN = MPN;
            glob.WPN = WPN;
            
            
        end

        % Value changed function: CellIDcidEditField
        function CellIDcidEditFieldValueChanged(app, event)
            global glob
            value = app.CellIDcidEditField.Value;
            
            fileName = sprintf('%s%d.mat',glob.fvDir,value);
            load(fileName);
            glob.p = renderFV(fv,[1 1 1],1);
            glob.p = patch(app.mainAx,fv)
            view([0 0])
            axis off


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CellIDcidEditFieldLabel = uilabel(app.UIFigure);
            app.CellIDcidEditFieldLabel.Text = 'Cell ID (cid)';
            app.CellIDcidEditField = uieditfield(app.UIFigure, 'numeric');
            app.CellIDcidEditField.ValueChangedFcn = createCallbackFcn(app, @CellIDcidEditFieldValueChanged, true);
            app.Tree = uitree(app.UIFigure);
            app.Node = uitreenode(app.Tree);
            app.Node.Text = 'Node';
            app.Node2 = uitreenode(app.Node);
            app.Node2.Text = 'Node2';
            app.Node3 = uitreenode(app.Node);
            app.Node3.Text = 'Node3';
            app.Node4 = uitreenode(app.Node);
            app.Node4.Text = 'Node4';
            app.CellTypeDropDownLabel = uilabel(app.UIFigure);
            app.CellTypeDropDownLabel.Text = 'Cell Type';
            app.CellTypeDropDown = uidropdown(app.UIFigure);
            app.CellTypeDropDown.Items = {'VGlut3', 'Bipolar', 'Bipolar', 'Amacrine', 'RGC'};
            app.CellTypeDropDown.Value = 'VGlut3';
            app.PickCellListBoxLabel = uilabel(app.UIFigure);
            app.PickCellListBoxLabel.Text = 'Pick Cell';
            app.PickCellListBox = uilistbox(app.UIFigure);
            app.IPLBoardersButton = uibutton(app.UIFigure, 'state');
            app.IPLBoardersButton.Text = 'IPL Boarders';
            app.IPLBoardersButton.Value = true;
            app.BipolarCellsButton = uibutton(app.UIFigure, 'state');
            app.BipolarCellsButton.Text = 'Bipolar Cells';
            app.BipolarCellsButton.Value = true;
            app.mainAx = uiaxes(app.UIFigure);
            title(app.mainAx, 'Title')
            xlabel(app.mainAx, 'X')
            ylabel(app.mainAx, 'Y')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = navCellsOn3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end