classdef SortImages < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        OButton                    matlab.ui.control.Button
        AddontoExistingmatsButton  matlab.ui.control.Button
        textOut                    matlab.ui.control.EditField
        DirEditField               matlab.ui.control.EditField
        DirEditFieldLabel          matlab.ui.control.Label
        PickDirButton              matlab.ui.control.Button
        CreatsecmatsforIButton     matlab.ui.control.Button
        ChannelDropDown            matlab.ui.control.DropDown
        ChannelDropDownLabel       matlab.ui.control.Label
        Button_2                   matlab.ui.control.Button
        Button                     matlab.ui.control.Button
        WButton                    matlab.ui.control.Button
        RButton                    matlab.ui.control.Button
        LButton                    matlab.ui.control.Button
        UIAxes                     matlab.ui.control.UIAxes
    end


    methods (Access = private)

        function results = updateImage(app)
            global globSort
            targ = find(globSort.uICount==globSort.ic);
            if globSort.checkChannel
                imIdx = find((globSort.iCount==globSort.ic) & (globSort.chan == globSort.checkChannel));
                I = double(imread([globSort.SPN globSort.nams{imIdx}]));
                app.UIAxes.Colormap = gray(256);
                image(app.UIAxes,uint8(I * 100/mean(I(:))));
            else
                %imIdx = find((globSort.iCount==globSort.ic));
                imIdx = globSort.secs{globSort.ic};
                iminfo = imfinfo([globSort.SPN globSort.nams{imIdx(1)}]);
                W = iminfo.Width;
                Icol = zeros(W,W,3);
                for i = 1:length(imIdx)
                    colMat = [.7 0 .7; 0 .7 .7; 0 0 1; 0 1 0; 1 0 0; .7 .7 0];
                    I = double(imread([globSort.SPN globSort.nams{imIdx(i)}]));
                    I = I - min(I(:));
                    I = I * 20/mean(I(:));
                    Icol(:,:,1) = Icol(:,:,1) + I * colMat(i,1);
                    Icol(:,:,2) = Icol(:,:,2) + I * colMat(i,2);
                    Icol(:,:,3) = Icol(:,:,3) + I * colMat(i,3);
                end
                image(app.UIAxes,uint8(Icol));
                drawnow
                app.UIAxes.Colormap = gray(256);

            end

            titleStr = sprintf('image %d of %d, channel %d, assigned to group %d, unassigned num = %d',...
                targ,length(globSort.uICount),globSort.checkChannel,globSort.groupAssignment(targ),...
                sum(globSort.groupAssignment==0));
            app.UIAxes.Title.String = titleStr;
            app.UIAxes.YLim = [1 size(I,1)];
            app.UIAxes.XLim = [1 size(I,2)];
        end

        function results = nextImage(app)
            global globSort
            targ = find(globSort.uICount==globSort.ic);
            if isempty(targ)
                targ = 1;
            else
                targ = min(targ+1,length(globSort.uICount));
            end
            globSort.ic = globSort.uICount(targ);
            updateImage(app);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global globSort


        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)

        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            global globSort
            targ = find(globSort.uICount==globSort.ic);
            if isempty(targ)
                targ = 1;
            else
                targ = max(targ-1,1);
            end
            globSort.ic = globSort.uICount(targ);
            updateImage(app);
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            global globSort
            targ = find(globSort.uICount==globSort.ic);
            if isempty(targ)
                targ = 1;
            else
                targ = min(targ+1,length(globSort.uICount));
            end
            globSort.ic = globSort.uICount(targ);
            updateImage(app);
        end

        % Value changed function: ChannelDropDown
        function ChannelDropDownValueChanged(app, event)
            value = app.ChannelDropDown.Value;
            global globSort
            globSort.checkChannel = str2num(value);
            updateImage(app)
        end

        % Button pushed function: LButton
        function LButtonPushed(app, event)
            global globSort
            targ = find(globSort.uICount==globSort.ic);
            globSort.groupAssignment(targ) = 1;
            nextImage(app);
        end

        % Button pushed function: RButton
        function RButtonPushed(app, event)
            global globSort
            targ = find(globSort.uICount==globSort.ic);
            globSort.groupAssignment(targ) = 2;
            nextImage(app);
        end

        % Button pushed function: WButton
        function WButtonPushed(app, event)
            global globSort
            targ = find(globSort.uICount==globSort.ic);
            globSort.groupAssignment(targ) = 3;
            nextImage(app);
        end

        % Button pushed function: CreatsecmatsforIButton
        function CreatsecmatsforIButtonPushed(app, event)

            global globSort


            %% set image groups
            imageTags = {'L' 'R' 'W' 'O'};
            for i = 1:length(imageTags)
                imageOrder{i} =  find(globSort.groupAssignment==i); % order of images acquired
            end
            numChan = length(globSort.uChan);


            %% Convert images
            for i = 1:length(imageTags)
                useCs = globSort.uICount(imageOrder{i});
                if ~isempty(useCs)

                    groupDir = [globSort.SPN imageTags{i} '\'];
                    if ~exist(groupDir,'dir'),mkdir(groupDir);end

                    for z = 1:length(useCs)
                        textStr = sprintf('group %d, z %d of %d',i,z,length(useCs));
                        app.textOut.Value = textStr;
                        drawnow
                        useCs(z);
                        imIdxs = globSort.secs{useCs(z)};
                        imChan = globSort.chans{useCs(z)};
                        tiffInfo = imfinfo([globSort.SPN globSort.nams{imIdxs(1)}]);
                        ys = tiffInfo.Height;
                        xs = tiffInfo.Width;
                        I = zeros(ys,xs,numChan);
                        for t = 1:length(imIdxs);
                            I(:,:,imChan(t)+1) = imread([globSort.SPN globSort.nams{imIdxs(t)}]);
                        end

                        fileName = sprintf('%ssec%03.0f.mat',groupDir,z);
                        save(fileName,'I')
                    end
                end
            end
            textStr = 'finished creating .mat files';
            app.textOut.Value = textStr;

        end

        % Button pushed function: PickDirButton
        function PickDirButtonPushed(app, event)
            global globSort

            if exist('.\lastDirName.mat','file')
                load('.\lastDirName.mat');
            else
                lastDirName = '.'
            end
            SPN = uigetdir(lastDirName);
            SPN = [SPN '\'];
            if SPN
                globSort.SPN = SPN;
                lastDirName = SPN;
                save('.\lastDirName.mat','lastDirName');
                app.DirEditField.Value = globSort.SPN;


                %% Parse image names
                dSPN = dir([SPN '*.tif']);
                nams = {dSPN.name};
                for i = 1:length(nams)
                    nam = nams{i};
                    und = regexp(nam,'_');
                    ch = regexp(nam,'ch');
                    ch = ch(end);
                    und = und(end-1:end);
                    chan(i) = str2num(nam(ch+2:ch+3));
                    tags{i} = nam(1:(ch-1));
                end

                c = 0;
                for i = 1:length(tags)
                    isTag = find(strcmp(tags,tags{i}));
                    if i == min(isTag)
                        c = c+1;
                        secs{c} = isTag;
                        chans{c} = chan(isTag);
                    end
                end
                                        
                    
                uICount = 1:length(secs); % list of counts in file names
                numImages = length(secs)
                uChan = unique(chan);
                numChan = length(uChan);


                testStr = sprintf('found %d images, with %d channels',numImages,numChan);
                app.textOut.Value = testStr;

                %% Assign images
                globSort.secs = secs;
                globSort.chans = chans;
                globSort.ic = uICount(1);
                globSort.uICount = uICount;
                globSort.numImages = numImages;
                globSort.iCount = length(nams);
                globSort.chan = chan;
                globSort.uChan = uChan;
                globSort.numChan = numChan;
                globSort.nams = nams;
                globSort.SPN = SPN;
                globSort.checkChannel = 0;
                globSort.groupAssignment = zeros(length(uICount),1);

                %% Set up Gui
                uChan = globSort.uChan;
                for i = 1:length(uChan);
                    uChanS{i} = num2str(uChan(i));
                end
                app.ChannelDropDown.Items = uChanS;
                updateImage(app)
            else
                app.textOut.Value = 'No directory selected';
            end

        end

        % Button pushed function: AddontoExistingmatsButton
        function AddontoExistingmatsButtonPushed(app, event)
            
            global globSort


            uititle = sprintf('from %s to...',globSort.SPN);
            TPN = [uigetdir(globSort.SPN,uititle) '\'];

            %% set image groups
            imageTags = {'L' 'R' 'W'};
            for i = 1:3
                imageOrder{i} =  find(globSort.groupAssignment==i); % order of images acquired
            end
            numChan = length(globSort.uChan);


            %% Convert images
            for i = 1:length(imageTags)
                useCs = globSort.uICount(imageOrder{i});
                if ~isempty(useCs)

                    groupDir = [globSort.SPN imageTags{i} '\'];
                    targDir = [TPN imageTags{i} '\'];
                    if exist(targDir,'dir')
                        tdDir = dir([targDir '*.mat']);
                        nams = {tdDir.name};
                        secNums = 0;
                        for n = 1:length(nams)
                            if strcmp(nams{n}(1:3),'sec')
                                secNum =  str2num(nams{n}(4:6));
                                if ~isempty(secNum)
                                    secNums = [secNums secNum];
                                end
                            end
                        end
                        lastSec = max(secNums);
                    else
                        mkdir(targDir)
                        lastSec = 0;
                    end


                    for z = 1:length(useCs)
                        textStr = sprintf('group %d, z %d of %d',i,z,length(useCs));
                        app.textOut.Value = textStr;
                        drawnow
                        useCs(z);
                        imIdxs = find(globSort.iCount==useCs(z));
                        imChan = globSort.chan(imIdxs);
                        tiffInfo = imfinfo([globSort.SPN globSort.nams{imIdxs(1)}]);
                        ys = tiffInfo.Height;
                        xs = tiffInfo.Width;
                        I = zeros(ys,xs,numChan);
                        for t = 1:length(imIdxs);
                            I(:,:,imChan(t)+1) = imread([globSort.SPN globSort.nams{imIdxs(t)}]);
                        end

                        fileName = sprintf('%ssec%03.0f.mat',targDir,lastSec + z);
                        save(fileName,'I')
                    end
                    
                end
            end
            textStr = 'finished creating .mat files';
            app.textOut.Value = textStr;
        end

        % Button pushed function: OButton
        function OButtonPushed(app, event)
            global globSort
            targ = find(globSort.uICount==globSort.ic);
            globSort.groupAssignment(targ) = 4;
            nextImage(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.LButton = uibutton(app.UIFigure, 'push');
            app.LButton.ButtonPushedFcn = createCallbackFcn(app, @LButtonPushed, true);
            app.LButton.Text = 'L';
            app.RButton = uibutton(app.UIFigure, 'push');
            app.RButton.ButtonPushedFcn = createCallbackFcn(app, @RButtonPushed, true);
            app.RButton.Text = 'R';
            app.WButton = uibutton(app.UIFigure, 'push');
            app.WButton.ButtonPushedFcn = createCallbackFcn(app, @WButtonPushed, true);
            app.WButton.Text = 'W';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '<';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '>';
            app.ChannelDropDownLabel = uilabel(app.UIFigure);
            app.ChannelDropDownLabel.Text = 'Channel';
            app.ChannelDropDown = uidropdown(app.UIFigure);
            app.ChannelDropDown.ValueChangedFcn = createCallbackFcn(app, @ChannelDropDownValueChanged, true);
            app.CreatsecmatsforIButton = uibutton(app.UIFigure, 'push');
            app.CreatsecmatsforIButton.ButtonPushedFcn = createCallbackFcn(app, @CreatsecmatsforIButtonPushed, true);
            app.CreatsecmatsforIButton.Text = 'Creat ''sec#.mat''s for I';
            app.PickDirButton = uibutton(app.UIFigure, 'push');
            app.PickDirButton.ButtonPushedFcn = createCallbackFcn(app, @PickDirButtonPushed, true);
            app.PickDirButton.Text = 'Pick Dir';
            app.DirEditFieldLabel = uilabel(app.UIFigure);
            app.DirEditFieldLabel.Text = 'Dir';
            app.DirEditField = uieditfield(app.UIFigure, 'text');
            app.textOut = uieditfield(app.UIFigure, 'text');
            app.AddontoExistingmatsButton = uibutton(app.UIFigure, 'push');
            app.AddontoExistingmatsButton.ButtonPushedFcn = createCallbackFcn(app, @AddontoExistingmatsButtonPushed, true);
            app.AddontoExistingmatsButton.Text = 'Add onto Existing mats';
            app.OButton = uibutton(app.UIFigure, 'push');
            app.OButton.ButtonPushedFcn = createCallbackFcn(app, @OButtonPushed, true);
            app.OButton.Text = 'O';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SortImages

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end