classdef autoAlignMaps < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        StartAligningButton          matlab.ui.control.Button
        MaxShiftEditField            matlab.ui.control.EditField
        MaxShiftimagefractionEditFieldLabel  matlab.ui.control.Label
        MaxRotationEditField         matlab.ui.control.EditField
        MaxRotationEditFieldLabel    matlab.ui.control.Label
        MergeChannelsfasterCheckBox  matlab.ui.control.CheckBox
        DownsampleEditField          matlab.ui.control.EditField
        DownsampleEditFieldLabel     matlab.ui.control.Label
        textOut                      matlab.ui.control.EditField
        DirEditField                 matlab.ui.control.EditField
        DirEditFieldLabel            matlab.ui.control.Label
        ax                           matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global globBB globAA

            globAA.SPN = globBB.file.SPN;
            app.DirEditField.Value = globAA.SPN;
            app.textOut.Value = 'Read to align. Set parameters and press run.';


        end

        % Button pushed function: StartAligningButton
        function StartAligningButtonPushed(app, event)
            global globAA globBB
            
            regDat.SPN = globAA.SPN;
            regDat.shiftLim = str2num(app.MaxShiftEditField.Value);
            regDat.rLim = str2num(app.MaxRotationEditField.Value);
            regDat.dsamp = str2num(app.DownsampleEditField.Value);
            regDat.mergeChannels = app.MergeChannelsfasterCheckBox.Value;
            regDat.app = app;

            tforms = alignMatSectionsFunc(regDat);
            globBB.show.tform = tforms;


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ax = uiaxes(app.UIFigure);
            app.ax.XTick = [];
            app.ax.YTick = [];
            app.DirEditFieldLabel = uilabel(app.UIFigure);
            app.DirEditFieldLabel.Text = 'Dir';
            app.DirEditField = uieditfield(app.UIFigure, 'text');
            app.textOut = uieditfield(app.UIFigure, 'text');
            app.DownsampleEditFieldLabel = uilabel(app.UIFigure);
            app.DownsampleEditFieldLabel.Text = 'Downsample';
            app.DownsampleEditField = uieditfield(app.UIFigure, 'text');
            app.DownsampleEditField.Value = '16';
            app.MergeChannelsfasterCheckBox = uicheckbox(app.UIFigure);
            app.MergeChannelsfasterCheckBox.Text = 'Merge Channels (faster)';
            app.MaxRotationEditFieldLabel = uilabel(app.UIFigure);
            app.MaxRotationEditFieldLabel.Text = 'Max Rotation';
            app.MaxRotationEditField = uieditfield(app.UIFigure, 'text');
            app.MaxRotationEditField.Value = '30';
            app.MaxShiftimagefractionEditFieldLabel = uilabel(app.UIFigure);
            app.MaxShiftimagefractionEditFieldLabel.Text = 'Max Shift (image fraction)';
            app.MaxShiftEditField = uieditfield(app.UIFigure, 'text');
            app.MaxShiftEditField.Value = '0.3';
            app.StartAligningButton = uibutton(app.UIFigure, 'push');
            app.StartAligningButton.ButtonPushedFcn = createCallbackFcn(app, @StartAligningButtonPushed, true);
            app.StartAligningButton.Text = 'Start Aligning';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = autoAlignMaps

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end