classdef bufoBrainMap_MatReader < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        RenderPanel                  matlab.ui.container.Panel
        renderChan                   matlab.ui.control.DropDown
        AspectEditField              matlab.ui.control.NumericEditField
        AspectEditFieldLabel         matlab.ui.control.Label
        RenderButton                 matlab.ui.control.Button
        InstructionsButton           matlab.ui.control.Button
        ExportPanel                  matlab.ui.container.Panel
        ExportSectionButton          matlab.ui.control.Button
        MosaicGUIButton              matlab.ui.control.Button
        LoadAdjustmentsButton        matlab.ui.control.Button
        SaveAdjustmentsButton        matlab.ui.control.Button
        ApplyBrightContrastCheckBox  matlab.ui.control.CheckBox
        HiddenColorsCheckBox         matlab.ui.control.CheckBox
        ResizeforalignmentCheckBox   matlab.ui.control.CheckBox
        ExportButton                 matlab.ui.control.Button
        ChangeDirectoryButton        matlab.ui.control.Button
        DirectoryEditField           matlab.ui.control.EditField
        textOut                      matlab.ui.control.EditField
        AlignPanel                   matlab.ui.container.Panel
        ReorderSectionsButton        matlab.ui.control.Button
        AutoAlignWindowButton        matlab.ui.control.Button
        rotateStep_dropDown          matlab.ui.control.DropDown
        LButton                      matlab.ui.control.Button
        RButton                      matlab.ui.control.Button
        ResetallButton               matlab.ui.control.Button
        dsVal                        matlab.ui.control.DropDown
        FlipButton                   matlab.ui.control.Button
        ResetsectionButton           matlab.ui.control.Button
        TranslateButton              matlab.ui.control.StateButton
        mergeContrast                matlab.ui.control.DropDown
        mergeType                    matlab.ui.control.DropDown
        DownsampleButton             matlab.ui.control.StateButton
        ShowPreviousButton           matlab.ui.control.StateButton
        DisplayPanel                 matlab.ui.container.Panel
        AutoAllButton                matlab.ui.control.Button
        ApplytostackButton           matlab.ui.control.Button
        Blue                         matlab.ui.control.DropDown
        Green                        matlab.ui.control.DropDown
        Red                          matlab.ui.control.DropDown
        BottomSlider                 matlab.ui.control.Slider
        TopSlider                    matlab.ui.control.Slider
        tweakColor                   matlab.ui.control.DropDown
        ResetButton                  matlab.ui.control.Button
        AutoButton                   matlab.ui.control.Button
        SelectSectionPanel           matlab.ui.container.Panel
        slideSection                 matlab.ui.control.Slider
        editSection                  matlab.ui.control.NumericEditField
        sectionUp                    matlab.ui.control.Button
        sectionDown                  matlab.ui.control.Button
        edit_fileName                matlab.ui.control.EditField
        PickimageButton              matlab.ui.control.Button
        parseFile                    matlab.ui.container.Panel
        SortexportintomatsButton     matlab.ui.control.Button
        MatchStringsCheckBox         matlab.ui.control.CheckBox
        dropDownParseColor           matlab.ui.control.DropDown
        ColorDropDownLabel           matlab.ui.control.Label
        dropDownParseSection         matlab.ui.control.DropDown
        SectionnumberDropDownLabel   matlab.ui.control.Label
        UIAxes                       matlab.ui.control.UIAxes
    end


    methods (Access = private)

        function results = loadImageStack(app)

            global globBB globBI


            %% clean globBB 
            try globBB.show = rmfield(globBB.show,'tform');end

            %% read format

            colVal = app.dropDownParseColor.Value;
            secVal = app.dropDownParseSection.Value;

            useNum = 1;
            nam = globBB.lastFileName;


            if strcmp(nam(end-3:end),'.mat')
                globBB.isMat = 1;
            else
                globBB.isMat = 0;
            end

            slashes = regexp(nam,'\');
            SPN = nam(1:slashes(end));
            nam2 = nam(slashes(end)+1:end);
            numPos = getNums(app,nam2);


            %%Pick parse positions
            if strcmp(colVal,'First')
                colNum = 1;
            elseif strcmp(colVal,'None')
                colNum = 0;
            elseif strcmp(colVal,'Last')
                colNum = length(numPos.nums);
            else
                colNum = str2num(colVal);
            end

            if strcmp(secVal,'First')
                useNum = 1;
            elseif strcmp(secVal,'None')
                useNum = 0;
            elseif strcmp(secVal,'Last')
                useNum = length(numPos.nums);
            else
                useNum = str2num(secVal);
            end

            pickedSec = numPos.nums{useNum};

            num1 = numPos.startStop{useNum};
            searchName = nam2;

            if colNum
                num2 = numPos.startStop{colNum};
                searchName([num1(1):num1(2) num2(1):num2(2)]) = '*';

            else
                num2 = [];
                searchName([num1(1):num1(2) ]) = '*';
            end
            isAst = regexp(searchName,'*');
            nextAst = isAst+1;
            cutAst = intersect(isAst,nextAst);
            allAst = 1:length(searchName);

            if app.MatchStringsCheckBox.Value
                searchName = searchName(setdiff(allAst,cutAst));
            else
                searchName = sprintf('*%s',nam2(end-3:end));
            end


            imageFiles = dir([SPN searchName]);
            imageNames = {imageFiles(:).name}';
            secCol = ones(length(imageNames),1);
            for i = 1:length(imageNames)
                numPosT = getNums(app,imageNames{i});
                secNum(i) = numPosT.nums{useNum};
                if colNum
                    secCol(i) = numPosT.nums{colNum};
                end
            end

            [secNum idx] = sort(secNum,'ascend');
            imageNames = imageNames(idx);
            secCol = secCol(idx);

            globBB.file.imageNames = imageNames;
            globBB.file.secNum = secNum;
            globBB.file.secCol = secCol-min(secCol)+1;
            globBB.file.secs = unique(secNum);
            lookupSec(globBB.file.secs+1) = 1:length(globBB.file.secs);
            globBB.file.secID = lookupSec(secNum+1);

            globBB.file.cols = unique(globBB.file.secCol );
            globBB.file.SPN = SPN;
            globBB.show.secs = globBB.file.secs;
            globBB.show.secNum = pickedSec;
            globBB.show.secID = lookupSec(pickedSec+1);
            globBB.numSec = length(unique(secNum));
            globBB.numCol = length(globBB.file.cols);
            globBB.show.secOrder = [1:globBB.numSec];


            if globBB.isMat
                load([globBB.file.SPN imageNames{1}]);
                [ys xs zs] = size(I);
                globBB.numCol = zs;
                globBB.file.cols = 1:zs;
            end


            app.slideSection.Limits = [1 max(globBB.numSec,2)];

            cols = globBB.file.cols;
            if length(cols) == 1;
                globBB.show.colMix = [1 1 1];
            elseif length(cols) == 2;
                globBB.show.colMix = [1 2 1];
            else
                globBB.show.colMix = [1 2 3];
            end

            colMix = globBB.show.colMix;
            colStr = mat2cell(num2str(globBB.file.cols(:)),ones(length(globBB.file.cols(:)),1));
            app.Red.Items = colStr;
            app.Red.Value = colStr(colMix(1));
            app.Green.Items = colStr;
            app.Green.Value = colStr(colMix(2));
            app.Blue.Items = colStr;
            app.Blue.Value = colStr(colMix(3));


            if globBB.ramStack


                if globBB.isMat
                    load([globBB.file.SPN imageNames{i}]);
                    globBB.file.Isize = [size(I,1),size(I,2)];
                    globBB.numCol = size(I,3);
                    globBI.Istack = zeros(size(I,1),size(I,2),globBB.numCol,globBB.numSec,'double');
                    for i = 1:length(secNum)
                        load([globBB.file.SPN imageNames{i}]);
                        app.textOut.Value = sprintf('loading image %d of %d',i,length(secNum));
                        drawnow
                        globBI.Istack(:,:,1:size(I,3),globBB.file.secID(i)) = double(I);
                    end

                else

                    I = imread([globBB.file.SPN imageNames{i}]);
                    globBB.file.Isize = size(I);
                    app.UIAxes.XLim = [1 globBB.file.Isize(2)];
                    app.UIAxes.YLim = [1 globBB.file.Isize(1)];

                    globBI.Istack = zeros(size(I,1),size(I,2),globBB.numCol,globBB.numSec,'double');
                    for i = 1:length(secNum)
                         app.textOut.Value = sprintf('loading image %d of %d',i,length(secNum));
                        drawnow
                        I = imread([globBB.file.SPN imageNames{i}]);
                        globBI.Istack(:,:,globBB.file.secCol(i),globBB.file.secID(i)) = double(I);
                    end
                end
                globBB.show.globalMax = max(globBI.Istack(:));
                globBB.show.con = ones(globBB.numSec,globBB.numCol);
                globBB.show.bright = ones(globBB.numSec,globBB.numCol);
                globBB.show.shift = zeros(globBB.numSec,2);
                globBB.show.rotate = zeros(globBB.numSec,1);
                globBB.show.flip = zeros(globBB.numSec,1,'logical');


            end

            %%load alignment
            if exist([globBB.file.SPN 'tforms.mat'],'file')
                load([globBB.file.SPN 'tforms.mat']);
                for i = 1:length(tforms)
                    T = tforms{i}.T;
                    globBB.show.rotate(i)  = acosd(T(1,1));
                    globBB.show.shift(i,:) = T(3,[2 1]);
                    globBB.show.tform{i} = tforms{i};
                end

            end


            globBB.Isize = [size(globBI.Istack,1) size(globBI.Istack,2) size(globBI.Istack,4)];

            showSection(app)

        end

        function numPos = getNums(app,nam)
            namNum = isstrprop(nam,'digit');
            bin = 0;
            reading = 0;
            nums = {};
            startStop = {};
            for i = 1:length(namNum)
                if ~reading & namNum(i);
                    reading = 1;
                    bin = bin+1;
                    numStr = nam(i);
                    sS = i;
                elseif reading & namNum(i)
                    numStr = [numStr nam(i)];
                elseif reading & ~namNum(i)
                    nums{bin} = str2num(numStr);
                    reading = 0;
                    sS = [sS i-1];
                    startStop{bin} = sS;
                end
                if reading & (i == length(namNum))
                    nums{bin} = str2num(numStr);
                    reading = 0;
                    sS = [sS i];
                    startStop{bin} = sS;
                end
            end
            numPos.nums = nums;
            numPos.startStop = startStop;

        end

        function results = showSection(app)

            global globBB globBI

            secNum = globBB.show.secNum;

            secI = globBB.show.secID;
            secI = globBB.show.secOrder(secI);


            secTarg = find(globBB.file.secID == secI);

            app.textOut.Value = sprintf('Showing %s',globBB.file.imageNames{secTarg(1)});

            if globBB.ramStack
                I1 = globBI.Istack(:,:,:,secI);
            else
                secI = find(globBB.file.secNum == secNum);
                secCol = globBB.file.secCol(secI);
            end
            app.editSection.Value = globBB.show.secNum;
            if app.DownsampleButton.Value
                dsVal = str2num(app.dsVal.Value);
            else
                dsVal = 1;
            end


            Icol1 = I1(:,:,globBB.show.colMix) * 256/globBB.show.globalMax;
            if  app.DownsampleButton.Value
                dsVal = str2num(app.dsVal.Value);
                Icol1 = imresize(Icol1,1/dsVal,'box');
            end

            Icol1(:,:,1) = (Icol1(:,:,1) - globBB.show.bright(secI,1) ) * globBB.show.con(secI,1);
            Icol1(:,:,2) = (Icol1(:,:,2) - globBB.show.bright(secI,2) ) * globBB.show.con(secI,2);
            Icol1(:,:,3) = (Icol1(:,:,3) - globBB.show.bright(secI,3) ) * globBB.show.con(secI,3);

            %%Translate

            if globBB.show.flip(secI), Icol1 = fliplr(Icol1); end

            %             Icol1 = imrotate(Icol1,globBB.show.rotate(secI),'nearest','crop');
            %             Icol1 = imtranslate(Icol1,[globBB.show.shift(secI,:)/dsVal],'nearest','Outputview','same');
            if isfield(globBB.show,'tform')
                if length(globBB.show.tform) >= secI
                    Icol1 = imwarp(Icol1,globBB.show.tform{secI},'OutputView',imref2d(size(Icol1)));
                end
            end

            app.UIAxes.YLim = [1 size(Icol1,1)];
            app.UIAxes.XLim = [1 size(Icol1,2)];


            %%Show previous
            showPrevious = app.ShowPreviousButton.Value;

            if showPrevious
                if secI == 1
                    app.textOut.Value = 'At first section, no Previous section available';
                    Icol = Icol1;
                else
                    secI2 = secI-1;
                    secI2 = globBB.show.secOrder(secI2);

                    I2 = globBI.Istack(:,:,:,secI2);
                    Icol2 = I2(:,:,globBB.show.colMix) * 256/globBB.show.globalMax;
                    if  app.DownsampleButton.Value
                        dsVal = str2num(app.dsVal.Value);
                        Icol2 = imresize(Icol2,1/dsVal,'box');
                    end
                    Icol2(:,:,1) = (Icol2(:,:,1) - globBB.show.bright(secI2,1) ) * globBB.show.con(secI2,1);
                    Icol2(:,:,2) = (Icol2(:,:,2) - globBB.show.bright(secI2,2) ) * globBB.show.con(secI2,2);
                    Icol2(:,:,3) = (Icol2(:,:,3) - globBB.show.bright(secI2,3) ) * globBB.show.con(secI2,3);

                    if globBB.show.flip(secI2), Icol2 = fliplr(Icol2); end
                    
                    %Icol2 = imrotate(Icol2,globBB.show.rotate(secI2),'nearest','crop');
                    %Icol2 = imtranslate(Icol2,[globBB.show.shift(secI2,:)/dsVal],'nearest','Outputview','same');
                    Icol2 = imwarp(Icol2,globBB.show.tform{secI2},'OutputView',imref2d(size(Icol2)));


                    mergeCon = app.mergeContrast.Value;
                    switch mergeCon
                        case 'Norm to max'
                            Icol1 = Icol1 * 255/max(Icol1(:));
                            Icol2 = Icol2 * 255/max(Icol2(:));
                        case 'Norm to mean'
                            Icol1 = Icol1 * 100/mean(Icol1(:));
                            Icol2 = Icol2 * 100/mean(Icol2(:));
                    end

                    mergeType = app.mergeType.Value;
                    switch mergeType
                        case 'Sum'
                            Icol = Icol1 + Icol2;
                        case 'Difference'
                            Icol = Icol1*2-Icol2;
                        case 'Color'
                            Icol = repmat(mean(Icol1,3),[1 1 3]) * 3;
                            Icol(:,:,2) = mean(Icol2,3) * 3;
                    end % case merge

                end % if sec 1
            else % if dont show previous
                Icol = Icol1;
            end % if show previous


            image(app.UIAxes,uint8(Icol))
            drawnow

        end

        function txt = displayCoordinates(app, ~, info)
            global glob
            X = info.Position(2);
            Y = info.Position(1);
            Z = info.Position(3);
            %c_info = getCursorInfo(glob.dcm)

            anc2sub = (glob.em.res/ 1000);
            umPos = [X Y Z] ;
            umPosStr = sprintf('%.0f  %.0f  %.0f',umPos(end,2),umPos(end,1),umPos(end,3));
            disp(umPos)

            VASTpos = round([Y X Z] ./ anc2sub);
            VASTposStr = sprintf('%.0f \r%.0f \r%.0f um',umPos(end,2),umPos(end,1),umPos(end,3));

            set(glob.handles.textOut,'String',VASTpos);
            tag = get(info.Target,'Tag');

            txt = umPosStr;

            clipboard('copy',VASTpos)
        end


        function results = applySlider(app)
            global globBB

            topVal = app.TopSlider.Value;
            bottomVal = app.BottomSlider.Value;
            rangeVal = topVal-bottomVal;
            con = 256/rangeVal;
            bright = bottomVal/255 * 255;
            globBB.show.bright(globBB.show.secID, globBB.show.tweakMix) = bright;
            globBB.show.con(globBB.show.secID, globBB.show.tweakMix) = con;

            showSection(app)
        end


    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global globBB globBI
            
            globBB.ramStack = 1;
            colormap(app.UIAxes, gray(256));
            globBB.show.colMix = [1 1 1];
            globBB.show.con = [1 1 1];
            globBB.show.bright = [0 0 0];
            globBB.show.tweakMix = [1 2 3];
            globBB.draw.on = 0;
            globBB.show.downSample = 0;
            globBB.show.secOrder = [1:100000];

            rSteps = {'0.2' '1' '10' '45'};
            app.rotateStep_dropDown.Items = rSteps;
            app.rotateStep_dropDown.Value = '10';
            'hi'
        end

        % Button pushed function: PickimageButton
        function PickimageButtonPushed(app, event)

            global globBB

            app.textOut.Value = 'Loading images';
            drawnow

            getLastFileName = '.\lastFileName.mat';
            if exist(getLastFileName,'file')
                
                try load(getLastFileName)
                catch 
                    lastFileName = '.\';
                end
                    try
                    [SFN SPN] = uigetfile(lastFileName,'MultiSelect','on');
                catch ME
                    [SFN SPN] = uigetfile('*.*','MultiSelect','on');
                end
            else
                [SFN SPN] = uigetfile('*.*','MultiSelect','on');
            end
            if SPN
                try
                    lastFileName = [SPN SFN{1}];
                catch
                    lastFileName = [SPN SFN];
                end
                save(getLastFileName,['lastFileName'])

                app.edit_fileName.Value = lastFileName;
                globBB.lastFileName = lastFileName;

                SPN2 = [SPN(1:end-1) '_bufoExp\'];
                globBB.file.SPN2 = SPN2;
                baseName = SFN(1:end-4);
                TFN = baseName;
                try
                    globBB.file.TPN = [SPN2 TFN{1} '\'];
                catch 
                    globBB.file.TPN = [SPN2 TFN '\'];
                end
                app.DirectoryEditField.Value = globBB.file.TPN;

                loadImageStack(app)

                app.textOut.Value = 'Finished Loading Images';
            else
                app.textOut.Value = 'No files selected.';
            end
        end

        % Button pushed function: sectionDown
        function sectionDownButtonPushed(app, event)
            global globBB

            secID = globBB.show.secID;
            secID = max(secID-1,1);

            globBB.show.secNum = globBB.show.secs(secID);
            globBB.show.secID = secID;
            app.slideSection.Value = secID;

            showSection(app)
        end

        % Button pushed function: sectionUp
        function sectionUpButtonPushed(app, event)

            global globBB
            secID = globBB.show.secID;
            secID = min(secID+1,length(globBB.show.secs));
            globBB.show.secNum = globBB.show.secs(secID);
            globBB.show.secID = secID;
            app.slideSection.Value = secID;

            showSection(app)
        end

        % Callback function
        function TopSliderValueChanging(app, event)

        end

        % Value changed function: tweakColor
        function tweakColorValueChanged(app, event)
            global globBB
            tweakColor = app.tweakColor.Value;
            if strcmp(tweakColor,'All')
                globBB.show.tweakMix = [1 2 3];
            elseif strcmp(tweakColor,'Red')
                globBB.show.tweakMix = 1;
            elseif strcmp(tweakColor,'Green')
                globBB.show.tweakMix = 2;
            elseif strcmp(tweakColor,'Blue')
                globBB.show.tweakMix = 3;
            end
        end

        % Callback function
        function BottomSliderValueChanging(app, event)


        end

        % Value changed function: BottomSlider
        function BottomSliderValueChanged(app, event)
            applySlider(app)

        end

        % Value changed function: TopSlider
        function TopSliderValueChanged(app, event)

            applySlider(app)
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)

            app.BottomSlider.Value = 0;
            app.TopSlider.Value = 255;

            applySlider(app)

        end

        % Button pushed function: AutoButton
        function AutoButtonPushed(app, event)
            global globBB globBI

            secI = globBB.show.secID;
            secI = globBB.show.secOrder(secI);
            I = globBI.Istack(:,:,:,secI);
            Icol = I(:,:,globBB.show.colMix) * 255/globBB.show.globalMax;

            tweakMix = globBB.show.tweakMix;
            for i = 1:length(tweakMix)
                maxC = max(max(Icol(:,:,tweakMix(i))));
                globBB.show.bright(secI, tweakMix(i)) = 0;
                globBB.show.con(secI,tweakMix(i)) = 255/maxC;
            end
            showSection(app)

        end

        % Value changed function: Blue
        function BlueValueChanged(app, event)
            value = app.Blue.Value;
            global globBB
            globBB.show.colMix(3) = str2num(value);
            showSection(app)
        end

        % Value changed function: Green
        function GreenValueChanged(app, event)
            value = app.Green.Value;
            global globBB
            globBB.show.colMix(2) = str2num(value);
            showSection(app)
        end

        % Value changed function: Red
        function RedValueChanged(app, event)
            value = app.Red.Value;
            global globBB
            globBB.show.colMix(1) = str2num(value);
            showSection(app)
        end

        % Value changed function: ShowPreviousButton
        function ShowPreviousButtonValueChanged(app, event)
            showSection(app)
        end

        % Value changed function: mergeType
        function mergeTypeValueChanged(app, event)
            showSection(app)

        end

        % Value changed function: mergeContrast
        function mergeContrastValueChanged(app, event)
            showSection(app)

        end

        % Value changed function: DownsampleButton
        function DownsampleButtonValueChanged(app, event)
            showSection(app)

        end

        % Button pushed function: ApplytostackButton
        function ApplytostackButtonPushed(app, event)
            global globBB
            tweakMix = globBB.show.tweakMix;
            globBB.show.bright(:, tweakMix) = repmat(globBB.show.bright(globBB.show.secID, tweakMix),[globBB.numSec 1]);
            globBB.show.con(:,tweakMix) =  repmat(globBB.show.con(globBB.show.secID, tweakMix),[globBB.numSec 1]);
            showSection(app)
        end

        % Value changed function: TranslateButton
        function TranslateButtonValueChanged(app, event)
            %app.RotateButton.Value = 0;


        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)

        end

        % Window button motion function: UIFigure
        function UIFigureWindowButtonMotion(app, event)
            global globBB
            inbounds = 0;
            if  globBB.draw.on == 1;
                pt = app.UIAxes.CurrentPoint;

                if app.DownsampleButton.Value
                    dsVal = str2num(app.dsVal.Value);
                else
                    dsVal = 1;
                end

                if 0 %if use current point
                    y = pt(1,1)* dsVal;
                    x = pt(1,2)* dsVal;
                    globBB.draw.pathPT = cat(1,globBB.draw.pathPT , [y x]);

                else % if use end of path
                    y = globBB.draw.pathPT(end,1);
                    x = globBB.draw.pathPT(end,2);
                end

                if (y>0) & (x>0) & (y<=globBB.file.Isize(1)) & (x<=globBB.file.Isize(2));
                    inbounds = 1;
                end
            end
            if inbounds
                
                if     app.TranslateButton.Value & globBB.draw.on == 1;
                    if 0
                    id = globBB.show.secID;
                    xshift = (globBB.draw.pathPT(end,2) - globBB.draw.startPT(2));
                    yshift = (globBB.draw.pathPT(end,1) - globBB.draw.startPT(1));
                    T = globBB.show.tform{id}.T;
                    T(3,1) = T(3,1) + yshift;
                    T(3,2) = T(3,2) + xshift;
                    T
                    globBB.show.tform{id}.T = T;
                    globBB.show.shift(globBB.show.secID,2) = globBB.draw.startShift(2) + xshift;
                    globBB.show.shift(globBB.show.secID,1) = globBB.draw.startShift(1)+ yshift;
                    showSection(app)
                    end
%                 elseif app.RotateButton.Value & globBB.draw.on == 1
%                     startPT = globBB.draw.startPT;
%                     stopPT = globBB.draw.pathPT(end,:);
%                     startShift = globBB.draw.startShift;
%                     Isize = globBB.file.Isize;
%                     cent = [Isize(1) Isize(2)]/2 + startShift;
%                     startA = atan2((startPT(1)-cent(1)),(startPT(2)-cent(2)));
%                     stopA = atan2((stopPT(1)-cent(1)),(stopPT(2)-cent(2)));
%                     newA = stopA - startA;
%                     globBB.show.rotate(globBB.show.secID)= globBB.show.rotate(globBB.show.secID) +   newA * 180/pi;
%                     showSection(app)
                end

            end

        end

        % Window button up function: UIFigure
        function UIFigureWindowButtonUp(app, event)
            global globBB
            inbounds = 0;
            if globBB.draw.on == 1;

                pt = app.UIAxes.CurrentPoint;

                if app.DownsampleButton.Value
                    dsVal = str2num(app.dsVal.Value);
                else
                    dsVal = 1;
                end

                y = pt(1,1)*dsVal;
                x = pt(1,2)*dsVal;
                globBB.draw.stopPT = [y x];
                globBB.draw.pathPT = cat(1,globBB.draw.pathPT, [y x]);

                if (y>0) & (x>0) & (y<=globBB.file.Isize(1)) & (x<=globBB.file.Isize(2));
                    inbounds = 1;
                end
            end
            if inbounds
                if     app.TranslateButton.Value

                     id = globBB.show.secID;
                    xshift = (globBB.draw.stopPT(2) - globBB.draw.startPT(2));
                    yshift = (globBB.draw.stopPT(1) - globBB.draw.startPT(1));
                    T = globBB.show.tform{id}.T;
                    T(3,1) = T(3,1) + yshift;
                    T(3,2) = T(3,2) + xshift;
                    T
                    globBB.show.tform{id}.T = T;
                    globBB.show.shift(globBB.show.secID,2) = globBB.draw.startShift(2) + (globBB.draw.stopPT(2) - globBB.draw.startPT(2));
                    globBB.show.shift(globBB.show.secID,1) = globBB.draw.startShift(1)+ (globBB.draw.stopPT(1) - globBB.draw.startPT(1));
                    globBB.draw.stopPt = [0 0];
                    globBB.draw.startPt = [0 0];
                    showSection(app)
%                 elseif app.RotateButton.Value & (globBB.draw.on == 1)
% 
%                     startPT = globBB.draw.startPT;
%                     stopPT = globBB.draw.stopPT;
%                     startShift = globBB.draw.startShift;
%                     Isize = globBB.file.Isize;
%                     cent = [Isize(1) Isize(2)]/2 + startShift;
%                     startA = atan2((startPT(1)-cent(1)),(startPT(2)-cent(2)));
%                     stopA = atan2((stopPT(1)-cent(1)),(stopPT(2)-cent(2)));
%                     newA = stopA - startA;
%                     globBB.show.rotate(globBB.show.secID)=newA * 180/pi;
%                     showSection(app)

                end
            end
            globBB.draw.on = 0;

        end

        % Button down function: UIFigure
        function UIFigureButtonDown(app, event)


        end

        % Window button down function: UIFigure
        function UIFigureWindowButtonDown(app, event)
            global globBB

            if isfield(globBB,'file')
                pt = app.UIAxes.CurrentPoint;
                if app.DownsampleButton.Value
                    dsVal = str2num(app.dsVal.Value);
                else
                    dsVal = 1;
                end
                y = pt(1,1)* dsVal;
                x = pt(1,2)* dsVal;
                if (y>0) & (x>0) & (y<=globBB.file.Isize(1)) & (x<=globBB.file.Isize(2));
                    globBB.draw.on = 1;
                    globBB.draw.startPT = [y x];
                    globBB.draw.pathPT = [y x];
                    globBB.draw.startShift = globBB.show.shift(globBB.show.secID,:);
                end
            end

        end

        % Callback function
        function RotateButtonValueChanged(app, event)
            value = app.RotateButton.Value;
            app.TranslateButton.Value = 0;
        end

        % Callback function
        function FlipButtonValueChanged(app, event)
            value = app.FlipButton.Value;
            global globBB
            globBB.show.flip(globBB.show.secID) = ~globBB.show.flip(globBB.show.secID);
        end

        % Button pushed function: FlipButton
        function FlipButtonPushed(app, event)
            global globBB
            globBB.show.flip(globBB.show.secID) = ~globBB.show.flip(globBB.show.secID);
            showSection(app)
        end

        % Button pushed function: ResetsectionButton
        function ResetsectionButtonPushed(app, event)
            global globBB
            secID = globBB.show.secID;
            globBB.show.roatate(secID) = 0;
            globBB.show.flip(secID) = 0;
            globBB.show.shift(secID,:) = 0;
            showSection(app)
        end

        % Value changed function: dsVal
        function dsValValueChanged(app, event)
            showSection(app)
        end

        % Button pushed function: ChangeDirectoryButton
        function ChangeDirectoryButtonPushed(app, event)
            global globBB
            
            try TPN = uigetdir(globBB.file.TPN,'MultiSelect','on')
            catch err
                TPN = uigetdir;
            end
            if ~isempty(TPN)
                globBB.file.TPN = TPN;
                app.DirectoryEditField.Value = TPN;
            end
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            global globBB globBI

            app.textOut.Value = 'Exporting stack.';
            drawnow

            TPN = globBB.file.TPN;
            baseName = TPN(1:end-1);
            for r = 1:99;
                TPN = sprintf('%s_v%02.0f\\',baseName,r);
                if ~exist(TPN,'dir')
                    mkdir(TPN)
                    break
                end
            end
            globBB.file.TPN = TPN;
            app.DirectoryEditField.Value =TPN;
            if ~exist(TPN,'dir'),mkdir(TPN);end


            doExpand = app.ResizeforalignmentCheckBox.Value;
            doContrast = app.ApplyBrightContrastCheckBox.Value;
            doAllColors = app.HiddenColorsCheckBox.Value;


            if doExpand

                oldSize = globBB.Isize;
                rotBuf = max(oldSize) * 0.25;

                minShift = min(globBB.show.shift(:,[2 1]),[],1);
                maxShift = max(globBB.show.shift(:,[2 1]),[],1);

                lowX = ceil(minShift(2) * -1 + rotBuf);
                lowY = ceil(minShift(1) * -1 + rotBuf);
                highX = maxShift(2) + rotBuf;
                highY = maxShift(1) + rotBuf;

                newSize = ceil([lowY + oldSize(1) + highY lowX + oldSize(2) + highX]);

            end


            if doAllColors
                app.textOut = 'under construction';
            else

                for i = 1:globBB.numSec
                    secI = i;
                    secI = globBB.show.secOrder(secI);
                    I1 = globBI.Istack(:,:,:,secI);
                    Icol1 = I1(:,:,globBB.show.colMix) * 256/globBB.show.globalMax;

                    if doContrast
                        Icol1(:,:,1) = (Icol1(:,:,1) - globBB.show.bright(secI,1) ) * globBB.show.con(secI,1);
                        Icol1(:,:,2) = (Icol1(:,:,2) - globBB.show.bright(secI,2) ) * globBB.show.con(secI,2);
                        Icol1(:,:,3) = (Icol1(:,:,3) - globBB.show.bright(secI,3) ) * globBB.show.con(secI,3);
                    end

                    %%Translate
                    if globBB.show.flip(secI), Icol1 = fliplr(Icol1); end


                    if sum(abs(globBB.show.rotate(secI))) | sum(abs(globBB.show.shift(secI,:)))
                        if doExpand
                            It = Icol1;
                            Icol1 = zeros(newSize(1),newSize(2),size(It,3));
                            Icol1(lowY+1:lowY+size(It,1),lowX+1:lowX+size(It,2),:) = It;
                            Icol1 = imrotate(Icol1,globBB.show.rotate(secI),'nearest','crop');
                            Icol1 = imtranslate(Icol1,[globBB.show.shift(secI,:)],'nearest','Outputview','same');

                        else
                            Icol1 = imrotate(Icol1,globBB.show.rotate(secI),'nearest','crop');
                            Icol1 = imtranslate(Icol1,[globBB.show.shift(secI,:)],'nearest','Outputview','same');

                        end
                    end
                    fileName = sprintf('%03.0f.tif',secI);
                    imwrite(uint8(Icol1),[TPN '\' fileName]);
                end

            end

            app.textOut.Value = 'Finished Exporting.';


        end

        % Value changed function: DirectoryEditField
        function DirectoryEditFieldValueChanged(app, event)
            value = app.DirectoryEditField.Value;
            global globBB
            globBB.file.TPN = value{1}
        end

        % Value changed function: slideSection
        function slideSectionValueChanged(app, event)
            global globBB
            value = round(app.slideSection.Value);
            globBB.show.secID = value;
            globBB.show.secNum = globBB.show.secs(value);
            app.editSection.Value = globBB.show.secNum;
            showSection(app);
        end

        % Button pushed function: ResetallButton
        function ResetallButtonPushed(app, event)
            global globBB

            globBB.show.shift = globBB.show.shift * 0;
            globBB.show.rotate = globBB.show.rotate * 0;
            showSection(app)
        end

        % Button pushed function: SaveAdjustmentsButton
        function SaveAdjustmentsButtonPushed(app, event)
            global globBB

            %base = globBB.file.imageNames{1};
            base = 'brainMapAdjustments';
            for r = 1:999
                fileName = sprintf('%s%s_v%02.0f.mat',globBB.file.SPN,base,r);
                if ~exist(fileName,'file')
                    save(fileName,"globBB")
                    break
                end
            end


        end

        % Button pushed function: LoadAdjustmentsButton
        function LoadAdjustmentsButtonPushed(app, event)
            global globBB
            [TFN TPN] = uigetfile(globBB.file.SPN,'MultiSelect','on');
            if ~isempty(TFN)
            load([TPN TFN])
% 
%             app.edit_fileName.Value = globBB.lastFileName;
%             app.DirectoryEditField.Value = globBB.file.TPN;
% 
%             loadImageStack(app)
%             load([TPN TFN])
            
            showSection(app)
            end

        end

        % Button pushed function: RenderButton
        function RenderButtonPushed(app, event)

            global globBB globBI
            app.textOut.Value = 'Under Construction';
            drawnow

            if app.DownsampleButton.Value
                dsVal = str2num(app.dsVal.Value);
            else
                dsVal = 8;
            end
            vSize = globBB.Isize/dsVal;
            V = zeros(vSize(1),vSize(2),3,globBB.numSec,'uint8');

            for i = 1:globBB.numSec

                secI = i;
                secI = globBB.show.secOrder(secI);

                I1r = globBI.Istack(:,:,:,secI);
                I1 = imresize(I1r,1/dsVal);

                Icol1 = I1(:,:,globBB.show.colMix) * 256/globBB.show.globalMax;
                if  app.DownsampleButton.Value
                    dsVal = str2num(app.dsVal.Value);
                    Icol1 = imresize(Icol1,1/dsVal,'box');
                end

                Icol1(:,:,1) = (Icol1(:,:,1) - globBB.show.bright(secI,1) ) * globBB.show.con(secI,1);
                Icol1(:,:,2) = (Icol1(:,:,2) - globBB.show.bright(secI,2) ) * globBB.show.con(secI,2);
                Icol1(:,:,3) = (Icol1(:,:,3) - globBB.show.bright(secI,3) ) * globBB.show.con(secI,3);

                %%Translate

                if globBB.show.flip(secI), Icol1 = fliplr(Icol1); end
                
                Icol1 = imrotate(Icol1,globBB.show.rotate(secI),'nearest','crop');
                Icol1 = imtranslate(Icol1,[globBB.show.shift(secI,:)/dsVal],'nearest','Outputview','same');

                V(:,:,:,secI) = Icol1;

            end

            chan = app.renderChan.Value;
            if strcmp(chan,'A')
                V2 = squeeze(mean(V,3));
            else
                chan = str2num(chan);
                V2 = squeeze(V(:,:,chan,:));
            end
            aspect = app.AspectEditField.Value;
            volshow(V2,'Renderer','MaximumIntensityProjection','ScaleFactors',[1 1 aspect],...
                'BackgroundColor',[0 0 0])
            'bark'
        end

        % Button pushed function: AutoAllButton
        function AutoAllButtonPushed(app, event)
            global globBB globBI

            app.textOut.Value = 'Running auto contrast';
            drawnow
            for i = 1:globBB.numSec

                secI = i;
                secI = globBB.show.secOrder(secI);

                I1 = globBI.Istack(:,:,:,secI);
                Icol1 = I1(:,:,globBB.show.colMix) * 256/globBB.show.globalMax;

                for c = 1:3

                    minC = min(min(Icol1(:,:,c)));
                    maxC = max(max(Icol1(:,:,c)));
                    rangeC = maxC-minC;

                    globBB.show.bright(i,c) = (minC*-1) ;
                    globBB.show.con(i,c) = 255/rangeC;

                end


            end

            showSection(app)
            app.textOut.Value = 'Finished auto contrast';
            drawnow
        end

        % Button pushed function: InstructionsButton
        function showInstructions(app, event)
            app.textOut.Value = 'Showing instructions';
            bufoBrainMaps_Instructions
        end

        % Button pushed function: RButton
        function rotateRight(app, event)
            global globBB
            id =  globBB.show.secID;
            r = str2num(app.rotateStep_dropDown.Value)
            globBB.show.rotate(id) = globBB.show.rotate(id) + r;
  
            Ts = [1 0 0; 0 1 0; -globBB.Isize(1)/2 -globBB.Isize(2)/2 1];
            Ts2 = [1 0 0; 0 1 0; globBB.Isize(1)/2 globBB.Isize(2)/2 1];
            Tr = [cosd(r) sind(r) 0; -sind(r) cosd(r) 0; 0 0 1];
            T = globBB.show.tform{id}.T;
            
            Tn = T*Ts;
            Tn = Tn * Tr;
            Tn = Tn * Ts2;

            globBB.show.tform{id}.T = Tn;
            showSection(app)

        end

        % Button pushed function: LButton
        function rotateLeft(app, event)
            global globBB
            id =  globBB.show.secID;
            r = str2num(app.rotateStep_dropDown.Value) * -1;
            globBB.show.rotate(id) = globBB.show.rotate(id) + r;

            Ts = [1 0 0; 0 1 0; -globBB.Isize(1)/2 -globBB.Isize(2)/2 1];
            Ts2 = [1 0 0; 0 1 0; globBB.Isize(1)/2 globBB.Isize(2)/2 1];
            Tr = [cosd(r) sind(r) 0; -sind(r) cosd(r) 0; 0 0 1];
            T = globBB.show.tform{id}.T;

            Tn = T*Ts;
            Tn = Tn * Tr;
            Tn = Tn * Ts2;

            globBB.show.tform{id}.T = Tn;

            showSection(app)
          
        end

        % Button pushed function: SortexportintomatsButton
        function SortexportintomatsButtonPushed(app, event)
            SortImages
        end

        % Button pushed function: AutoAlignWindowButton
        function AutoAlignWindowButtonPushed(app, event)
            autoAlignMaps
        end

        % Button pushed function: ReorderSectionsButton
        function ReorderSectionsButtonPushed(app, event)
            reorderSectionsMap
        end

        % Button pushed function: MosaicGUIButton
        function MosaicGUIButtonPushed(app, event)
            makeMosaic
        end

        % Button pushed function: ExportSectionButton
        function exportSectionButton(app, event)
            global globBB globBI

            app.textOut.Value = 'Exporting plane.';
            drawnow

            TPN = globBB.file.TPN;
            for r = 1:999;
                TFN = sprintf('v%03.0f.png',r);
                if ~exist([TPN '\' TFN],'file')
                    break
                end
            end
            if ~exist(TPN,'dir'),mkdir(TPN);end
            app.textOut.Value = sprintf('Exporting plane as file %s.',TFN);
            
            doExpand = app.ResizeforalignmentCheckBox.Value;
            doContrast = app.ApplyBrightContrastCheckBox.Value;
            doAllColors = app.HiddenColorsCheckBox.Value;


            if doExpand

                oldSize = globBB.Isize;
                rotBuf = max(oldSize) * 0.25;

                minShift = min(globBB.show.shift(:,[2 1]),[],1);
                maxShift = max(globBB.show.shift(:,[2 1]),[],1);

                lowX = ceil(minShift(2) * -1 + rotBuf);
                lowY = ceil(minShift(1) * -1 + rotBuf);
                highX = maxShift(2) + rotBuf;
                highY = maxShift(1) + rotBuf;

                newSize = ceil([lowY + oldSize(1) + highY lowX + oldSize(2) + highX]);

            end


            if doAllColors
                app.textOut = 'under construction';
            else

                    secI = globBB.show.secID;
                    secI = globBB.show.secOrder(secI);
                    I1 = globBI.Istack(:,:,:,secI);
                    Icol1 = I1(:,:,globBB.show.colMix) * 256/globBB.show.globalMax;

                    if doContrast
                        Icol1(:,:,1) = (Icol1(:,:,1) - globBB.show.bright(secI,1) ) * globBB.show.con(secI,1);
                        Icol1(:,:,2) = (Icol1(:,:,2) - globBB.show.bright(secI,2) ) * globBB.show.con(secI,2);
                        Icol1(:,:,3) = (Icol1(:,:,3) - globBB.show.bright(secI,3) ) * globBB.show.con(secI,3);
                    end

                    %%Translate
                    if globBB.show.flip(secI), Icol1 = fliplr(Icol1); end


                    if sum(abs(globBB.show.rotate(secI))) | sum(abs(globBB.show.shift(secI,:)))
                        if doExpand
                            It = Icol1;
                            Icol1 = zeros(newSize(1),newSize(2),size(It,3));
                            Icol1(lowY+1:lowY+size(It,1),lowX+1:lowX+size(It,2),:) = It;
                            Icol1 = imrotate(Icol1,globBB.show.rotate(secI),'nearest','crop');
                            Icol1 = imtranslate(Icol1,[globBB.show.shift(secI,:)],'nearest','Outputview','same');

                        else
                            Icol1 = imrotate(Icol1,globBB.show.rotate(secI),'nearest','crop');
                            Icol1 = imtranslate(Icol1,[globBB.show.shift(secI,:)],'nearest','Outputview','same');

                        end
                    end
                    imwrite(uint8(Icol1),[TPN '\' TFN]);
                

            end

            app.textOut.Value = sprintf('Finished Exporting file %s.',[TPN '\' TFN]);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @UIFigureWindowButtonDown, true);
            app.UIFigure.WindowButtonUpFcn = createCallbackFcn(app, @UIFigureWindowButtonUp, true);
            app.UIFigure.WindowButtonMotionFcn = createCallbackFcn(app, @UIFigureWindowButtonMotion, true);
            app.UIFigure.ButtonDownFcn = createCallbackFcn(app, @UIFigureButtonDown, true);
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.parseFile = uipanel(app.UIFigure);
            app.parseFile.Title = 'Parse File';
            app.SectionnumberDropDownLabel = uilabel(app.parseFile);
            app.SectionnumberDropDownLabel.Text = 'Section number';
            app.dropDownParseSection = uidropdown(app.parseFile);
            app.dropDownParseSection.Items = {'None', 'First', '1', '2', '3', '4', '5', 'Last'};
            app.dropDownParseSection.Value = 'First';
            app.ColorDropDownLabel = uilabel(app.parseFile);
            app.ColorDropDownLabel.Text = 'Color';
            app.dropDownParseColor = uidropdown(app.parseFile);
            app.dropDownParseColor.Items = {'None', 'First', '1', '2', '3', '4', '5', 'Last'};
            app.dropDownParseColor.Value = 'Last';
            app.MatchStringsCheckBox = uicheckbox(app.parseFile);
            app.MatchStringsCheckBox.Text = 'Match Strings';
            app.SortexportintomatsButton = uibutton(app.parseFile, 'push');
            app.SortexportintomatsButton.ButtonPushedFcn = createCallbackFcn(app, @SortexportintomatsButtonPushed, true);
            app.SortexportintomatsButton.Text = 'Sort export into .mats';
            app.PickimageButton = uibutton(app.UIFigure, 'push');
            app.PickimageButton.ButtonPushedFcn = createCallbackFcn(app, @PickimageButtonPushed, true);
            app.PickimageButton.Text = 'Pick image';
            app.edit_fileName = uieditfield(app.UIFigure, 'text');
            app.SelectSectionPanel = uipanel(app.UIFigure);
            app.SelectSectionPanel.Title = 'Select Section';
            app.sectionDown = uibutton(app.SelectSectionPanel, 'push');
            app.sectionDown.ButtonPushedFcn = createCallbackFcn(app, @sectionDownButtonPushed, true);
            app.sectionDown.Text = '<';
            app.sectionUp = uibutton(app.SelectSectionPanel, 'push');
            app.sectionUp.ButtonPushedFcn = createCallbackFcn(app, @sectionUpButtonPushed, true);
            app.sectionUp.Text = '>';
            app.editSection = uieditfield(app.SelectSectionPanel, 'numeric');
            app.slideSection = uislider(app.SelectSectionPanel);
            app.slideSection.ValueChangedFcn = createCallbackFcn(app, @slideSectionValueChanged, true);
            app.DisplayPanel = uipanel(app.UIFigure);
            app.DisplayPanel.Title = 'Display';
            app.AutoButton = uibutton(app.DisplayPanel, 'push');
            app.AutoButton.ButtonPushedFcn = createCallbackFcn(app, @AutoButtonPushed, true);
            app.AutoButton.Text = 'Auto';
            app.ResetButton = uibutton(app.DisplayPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.tweakColor = uidropdown(app.DisplayPanel);
            app.tweakColor.Items = {'All', 'Red', 'Green', 'Blue'};
            app.tweakColor.ValueChangedFcn = createCallbackFcn(app, @tweakColorValueChanged, true);
            app.tweakColor.Value = 'All';
            app.TopSlider = uislider(app.DisplayPanel);
            app.TopSlider.Limits = [0 255];
            app.TopSlider.ValueChangedFcn = createCallbackFcn(app, @TopSliderValueChanged, true);
            app.TopSlider.Value = 255;
            app.BottomSlider = uislider(app.DisplayPanel);
            app.BottomSlider.Limits = [0 255];
            app.BottomSlider.ValueChangedFcn = createCallbackFcn(app, @BottomSliderValueChanged, true);
            app.Red = uidropdown(app.DisplayPanel);
            app.Red.Items = {'1', '2', '3'};
            app.Red.ValueChangedFcn = createCallbackFcn(app, @RedValueChanged, true);
            app.Red.Value = '1';
            app.Green = uidropdown(app.DisplayPanel);
            app.Green.Items = {'1', '2', '3'};
            app.Green.ValueChangedFcn = createCallbackFcn(app, @GreenValueChanged, true);
            app.Green.Value = '2';
            app.Blue = uidropdown(app.DisplayPanel);
            app.Blue.Items = {'1', '2', '3'};
            app.Blue.ValueChangedFcn = createCallbackFcn(app, @BlueValueChanged, true);
            app.Blue.Value = '3';
            app.ApplytostackButton = uibutton(app.DisplayPanel, 'push');
            app.ApplytostackButton.ButtonPushedFcn = createCallbackFcn(app, @ApplytostackButtonPushed, true);
            app.ApplytostackButton.Text = 'Apply to stack';
            app.AutoAllButton = uibutton(app.DisplayPanel, 'push');
            app.AutoAllButton.ButtonPushedFcn = createCallbackFcn(app, @AutoAllButtonPushed, true);
            app.AutoAllButton.Text = 'Auto All';
            app.AlignPanel = uipanel(app.UIFigure);
            app.AlignPanel.Title = 'Align';
            app.ShowPreviousButton = uibutton(app.AlignPanel, 'state');
            app.ShowPreviousButton.ValueChangedFcn = createCallbackFcn(app, @ShowPreviousButtonValueChanged, true);
            app.ShowPreviousButton.Text = 'Show Previous';
            app.DownsampleButton = uibutton(app.AlignPanel, 'state');
            app.DownsampleButton.ValueChangedFcn = createCallbackFcn(app, @DownsampleButtonValueChanged, true);
            app.DownsampleButton.Text = 'Downsample';
            app.mergeType = uidropdown(app.AlignPanel);
            app.mergeType.Items = {'Sum', 'Difference', 'Color'};
            app.mergeType.ValueChangedFcn = createCallbackFcn(app, @mergeTypeValueChanged, true);
            app.mergeType.Value = 'Sum';
            app.mergeContrast = uidropdown(app.AlignPanel);
            app.mergeContrast.Items = {'No adjust', 'Norm to max', 'Norm to mean'};
            app.mergeContrast.ValueChangedFcn = createCallbackFcn(app, @mergeContrastValueChanged, true);
            app.mergeContrast.Value = 'No adjust';
            app.TranslateButton = uibutton(app.AlignPanel, 'state');
            app.TranslateButton.ValueChangedFcn = createCallbackFcn(app, @TranslateButtonValueChanged, true);
            app.TranslateButton.Text = 'Translate';
            app.ResetsectionButton = uibutton(app.AlignPanel, 'push');
            app.ResetsectionButton.ButtonPushedFcn = createCallbackFcn(app, @ResetsectionButtonPushed, true);
            app.ResetsectionButton.Text = 'Reset section';
            app.FlipButton = uibutton(app.AlignPanel, 'push');
            app.FlipButton.ButtonPushedFcn = createCallbackFcn(app, @FlipButtonPushed, true);
            app.FlipButton.Text = 'Flip';
            app.dsVal = uidropdown(app.AlignPanel);
            app.dsVal.Items = {'1', '2', '4', '8', '16', '32', '64'};
            app.dsVal.ValueChangedFcn = createCallbackFcn(app, @dsValValueChanged, true);
            app.dsVal.Value = '1';
            app.ResetallButton = uibutton(app.AlignPanel, 'push');
            app.ResetallButton.ButtonPushedFcn = createCallbackFcn(app, @ResetallButtonPushed, true);
            app.ResetallButton.Text = 'Reset all';
            app.RButton = uibutton(app.AlignPanel, 'push');
            app.RButton.ButtonPushedFcn = createCallbackFcn(app, @rotateRight, true);
            app.RButton.Text = 'R';
            app.LButton = uibutton(app.AlignPanel, 'push');
            app.LButton.ButtonPushedFcn = createCallbackFcn(app, @rotateLeft, true);
            app.LButton.Text = 'L';
            app.rotateStep_dropDown = uidropdown(app.AlignPanel);
            app.AutoAlignWindowButton = uibutton(app.AlignPanel, 'push');
            app.AutoAlignWindowButton.ButtonPushedFcn = createCallbackFcn(app, @AutoAlignWindowButtonPushed, true);
            app.AutoAlignWindowButton.Text = 'Auto Align Window';
            app.ReorderSectionsButton = uibutton(app.AlignPanel, 'push');
            app.ReorderSectionsButton.ButtonPushedFcn = createCallbackFcn(app, @ReorderSectionsButtonPushed, true);
            app.ReorderSectionsButton.Text = 'Reorder Sections';
            app.textOut = uieditfield(app.UIFigure, 'text');
            app.ExportPanel = uipanel(app.UIFigure);
            app.ExportPanel.Title = 'Export';
            app.DirectoryEditField = uieditfield(app.ExportPanel, 'text');
            app.DirectoryEditField.ValueChangedFcn = createCallbackFcn(app, @DirectoryEditFieldValueChanged, true);
            app.ChangeDirectoryButton = uibutton(app.ExportPanel, 'push');
            app.ChangeDirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @ChangeDirectoryButtonPushed, true);
            app.ChangeDirectoryButton.Text = 'Change Directory';
            app.ExportButton = uibutton(app.ExportPanel, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            app.ResizeforalignmentCheckBox = uicheckbox(app.ExportPanel);
            app.ResizeforalignmentCheckBox.Text = 'Resize for alignment';
            app.HiddenColorsCheckBox = uicheckbox(app.ExportPanel);
            app.HiddenColorsCheckBox.Text = 'Hidden Colors';
            app.ApplyBrightContrastCheckBox = uicheckbox(app.ExportPanel);
            app.ApplyBrightContrastCheckBox.Text = 'Apply Bright/Contrast';
            app.ApplyBrightContrastCheckBox.Value = true;
            app.SaveAdjustmentsButton = uibutton(app.ExportPanel, 'push');
            app.SaveAdjustmentsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveAdjustmentsButtonPushed, true);
            app.SaveAdjustmentsButton.Text = 'Save Adjustments';
            app.LoadAdjustmentsButton = uibutton(app.ExportPanel, 'push');
            app.LoadAdjustmentsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadAdjustmentsButtonPushed, true);
            app.LoadAdjustmentsButton.Text = 'Load Adjustments';
            app.MosaicGUIButton = uibutton(app.ExportPanel, 'push');
            app.MosaicGUIButton.ButtonPushedFcn = createCallbackFcn(app, @MosaicGUIButtonPushed, true);
            app.MosaicGUIButton.Text = 'Mosaic GUI';
            app.ExportSectionButton = uibutton(app.ExportPanel, 'push');
            app.ExportSectionButton.ButtonPushedFcn = createCallbackFcn(app, @exportSectionButton, true);
            app.ExportSectionButton.Text = 'Export Section';
            app.InstructionsButton = uibutton(app.UIFigure, 'push');
            app.InstructionsButton.ButtonPushedFcn = createCallbackFcn(app, @showInstructions, true);
            app.InstructionsButton.Text = 'Instructions';
            app.RenderPanel = uipanel(app.UIFigure);
            app.RenderPanel.Title = 'Render';
            app.RenderButton = uibutton(app.RenderPanel, 'push');
            app.RenderButton.ButtonPushedFcn = createCallbackFcn(app, @RenderButtonPushed, true);
            app.RenderButton.Text = 'Render';
            app.AspectEditFieldLabel = uilabel(app.RenderPanel);
            app.AspectEditFieldLabel.Text = 'Aspect';
            app.AspectEditField = uieditfield(app.RenderPanel, 'numeric');
            app.AspectEditField.Value = 1;
            app.renderChan = uidropdown(app.RenderPanel);
            app.renderChan.Items = {'1', '2', '3', '4', 'A'};
            app.renderChan.Value = '1';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = bufoBrainMap_MatReader

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end