classdef makeMosaic < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        SPNedit              matlab.ui.control.EditField
        textOut              matlab.ui.control.EditField
        RefreshButton        matlab.ui.control.Button
        AppendNameCheckBox   matlab.ui.control.CheckBox
        CollectionDirButton  matlab.ui.control.Button
        CollectDirEditField  matlab.ui.control.EditField
        CollectMosaicButton  matlab.ui.control.Button
        DefaultSaveButton    matlab.ui.control.Button
        ColumsButtonGroup    matlab.ui.container.ButtonGroup
        SingleRowButton      matlab.ui.control.RadioButton
        ColumnsEditField     matlab.ui.control.NumericEditField
        Button_2             matlab.ui.control.RadioButton
        SingleColumnButton   matlab.ui.control.RadioButton
        SquareButton         matlab.ui.control.RadioButton
        TileSizeButtonGroup  matlab.ui.container.ButtonGroup
        CustomTileSizeEdit   matlab.ui.control.NumericEditField
        CustomButton         matlab.ui.control.RadioButton
        Button               matlab.ui.control.RadioButton
        FullButton           matlab.ui.control.RadioButton
        RemoveButton         matlab.ui.control.Button
        OnlyUseButton        matlab.ui.control.Button
        InsertButton         matlab.ui.control.StateButton
        MovetofrontButton    matlab.ui.control.Button
        MovetoendButton      matlab.ui.control.Button
        move                 matlab.ui.control.StateButton
        ResetButton          matlab.ui.control.Button
        ReverseButton        matlab.ui.control.Button
        ax                   matlab.ui.control.UIAxes
    end


    methods (Access = private)

        function results = updateMosaic(app)
            global globBB globRO globBI


            %% Get Colum number
            numZc = length(globRO.IDsInPos);
            tileText = app.ColumsButtonGroup.SelectedObject.Text;
            switch lower(tileText)
                case 'square'
                    numCol = ceil(sqrt(numZc));
                    numRow = ceil(numZc/numCol);
                    globRO.numCol = numCol;
                    globRO.numRow = numRow;
                case 'single column'
                    numCol = 1;
                    numRow = ceil(numZc/numCol);
                    globRO.numCol = numCol;
                    globRO.numRow = numRow;
                case 'single row'
                    numRow= 1;
                    numCol = ceil(numZc/numRow);
                    globRO.numCol = numCol;
                    globRO.numRow = numRow;
                case ''
                    val = app.ColumnsEditField.Value;
                    val = max(val,1);
                    numCol = ceil(val);
                    numRow = ceil(numZc/numCol);
                    globRO.numCol = numCol;
                    globRO.numRow = numRow;
            end


            %% get tile size
            tileText = app.TileSizeButtonGroup.SelectedObject.Text;
            oldTileSize = globRO.tileSize;
            switch lower(tileText)
                case 'full'
                    globRO.tileSize = globRO.fullYs;
                case '256'
                    globRO.tileSize = 256;
                case 'custom'
                    val = app.CustomTileSizeEdit.Value;
                    if val>0
                        globRO.tileSize = val;
                    else
                        globRO.tileSize= 512;
                        app.CustomTileSizeEdit.Value = 512;
                    end
            end
            if oldTileSize ~= globRO.tileSize
                makeIs(app)
            end

            %% Draw
            ax = app.ax;
            hold(app.ax,'off')
            app.ax.YDir = 'reverse';

            bSize = globRO.bSize;
            tileSize = globRO.tileSize;
            globRO.xs = tileSize * globRO.numCol;
            globRO.ys = tileSize * globRO.numRow;
            Imos = zeros(globRO.ys, globRO.xs,3);
            for i = 1:numZc
                secI =  globRO.IDsInPos(i);
                Icol1 = globRO.Is{secI};
                [c r] = ind2sub([globRO.numCol globRO.numRow],i);
                Imos((r-1)*tileSize+bSize+1:r*tileSize-bSize,...
                    (c-1)*tileSize+bSize+1:c*tileSize-bSize,:) = Icol1;
%                 image(ax,uint8(Imos))
%                 drawnow

            end
            
            globRO.Imos = Imos;
            image(ax,uint8(Imos))
            squareAx = max(globRO.ys, globRO.xs);
            ylim(ax,[1 squareAx])
            xlim(ax,[1 squareAx])
            drawnow
        end

        function results = makeIs(app)
            global globRO globBI globBB

            tileSize = globRO.tileSize;
            globRO.xs = tileSize * globRO.numCol;
            globRO.ys = tileSize * globRO.numRow;
            thumbSize = tileSize- (globRO.bSize * 2);
            globRO.thumbSize = thumbSize;
            numZ = globRO.numZ;

            globRO.Is = {};
            for i = 1:numZ
                secI =  i;
                I1 = globBI.Istack(:,:,:,secI);
                I1 = imresize(I1,[thumbSize thumbSize],'box');
                Icol1 = I1(:,:,globBB.show.colMix) * 256/globBB.show.globalMax;
                Icol1(:,:,1) = (Icol1(:,:,1) - globBB.show.bright(secI,1) ) * globBB.show.con(secI,1);
                Icol1(:,:,2) = (Icol1(:,:,2) - globBB.show.bright(secI,2) ) * globBB.show.con(secI,2);
                Icol1(:,:,3) = (Icol1(:,:,3) - globBB.show.bright(secI,3) ) * globBB.show.con(secI,3);
                if globBB.show.flip(secI), Icol1 = fliplr(Icol1); end

                globRO.Is{i} = Icol1;
            end
        end

        function results = func3(app)

        end
        
        function results = func4(app)
            
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            global globBB globRO globBI
            clear globRO
            global globRO

            app.textOut.Value = 'loading';

            numZ = size(globBI.Istack,4);
            globRO.fullXs = size(globBI.Istack,2);
            globRO.fullYs = size(globBI.Istack,1);
            globRO.numZ = numZ;
            globRO.IDsInPos = 1:globRO.numZ;
            numCol = ceil(sqrt(numZ));
            numRow = ceil(numZ/numCol);


            globRO.numCol = numCol;
            globRO.numRow = numRow;
            globRO.draw.pathPT = [];
            globRO.draw.on = 0;
            globRO.selecting = 0;
            globRO.selected = [];
            globRO.inserting = 0;
           

            bSize = 4;
            globRO.XS = 2048;
            globRO.bSize = bSize;
            I = globBI.Istack;
            Isize = size(I);
            tileSize = ceil(globRO.XS/globRO.numCol);
            globRO.tileSize = tileSize;

            makeIs(app)

            updateMosaic(app)

            if exist('.\collectDir.mat','file')
                load('.\collectDir.mat')
                globRO.collectDir = collectDir;
            else
                globRO.collectDir = globBB.file.SPN;
            end
             app.CollectDirEditField.Value = globRO.collectDir;
            
             app.SPNedit.Value = globBB.file.SPN;
             app.textOut.Value = 'Finished loading';


        end

        % Window button motion function: UIFigure
        function UIFigureWindowButtonMotion(app, event)


        end

        % Value changed function: move
        function moveValueChanged(app, event)
            value = app.move.Value;
            global globRO
            globRO.selecting = value;
            if value
                globRO.inserting = 0;
                app.InsertButton.Value = 0;
            end
        end

        % Button down function: UIFigure
        function UIFigureButtonDown(app, event)

         
        end

        % Window button down function: UIFigure
        function UIFigureWindowButtonDown(app, event)
            global globRO
            app.ax.CurrentPoint
                pt = app.ax.CurrentPoint;
                r = ceil(pt(1,2)/globRO.tileSize)
                c = ceil(pt(1,1)/globRO.tileSize)
            if globRO.selecting

               
                selected = globRO.selected;
                addPoint = 1;
                if (r>0) & (c>0) & (r<=globRO.numRow) & (c<=globRO.numCol)  %if valid
                    if ~isempty(selected)
                        
                        notSelected = find(~((selected(:,1)==r) & (selected(:,2)==c)));
                        if length(notSelected) < size(selected,1) %new selection
                            addPoint = 0;
                        end
                    end

                    if addPoint
                        globRO.selected = cat(1,selected,[r c]);
                    else
                        globRO.selected = selected(notSelected,:);
                    end

                    try globRO.scatSelect.delete,end
                        hold(app.ax,'on')
                        sC = globRO.selected(:,2)*globRO.tileSize-globRO.tileSize/2;
                        sR = globRO.selected(:,1)*globRO.tileSize-globRO.tileSize/2;
                        globRO.scatSelect = scatter(app.ax,sC,sR,500,'y','filled','markerfacealpha',.3,'linewidth',2,'MarkerEdgeColor','b')
                        app.ax.YDir = 'reverse';
                end
            elseif globRO.inserting


                selected = globRO.selected;
                if ~isempty(selected)

                    cutPos = sub2ind([globRO.numCol globRO.numRow],c,r);
                    cutGroup1 = [1:cutPos];
                    cutGroup2 = [cutPos+1:length(globRO.IDsInPos)];

                    linPos = sub2ind([globRO.numCol globRO.numRow],selected(:,2),selected(:,1));
                    cutGroup1d = setdiff(cutGroup1,linPos);
                    cutGroup2d = setdiff(cutGroup2,linPos);

                    newOrder = [cutGroup1d(:); linPos(:); cutGroup2d(:) ];
                    globRO.IDsInPos = globRO.IDsInPos(newOrder);
                    globRO.selected = [];
                end
                updateMosaic(app)
                app.InsertButton.Value = 0;

            end

        
        end

        % Button pushed function: MovetoendButton
        function MovetoendButtonPushed(app, event)
            global globRO

            selected = globRO.selected;
            if ~isempty(selected)
                    linPos = sub2ind([globRO.numCol globRO.numRow],selected(:,2),selected(:,1));
                notLinPos = setdiff([1:length(globRO.IDsInPos)],linPos);
                newOrder = [notLinPos(:); linPos(:)];
                globRO.IDsInPos = globRO.IDsInPos(newOrder);
                globRO.selected = [];
            end
            updateMosaic(app)
        end

        % Button pushed function: MovetofrontButton
        function MovetofrontButtonPushed(app, event)
            global globRO

            selected = globRO.selected;
            if ~isempty(selected)
                                   linPos = sub2ind([globRO.numCol globRO.numRow],selected(:,2),selected(:,1));
                notLinPos = setdiff([1:length(globRO.IDsInPos)],linPos);
                newOrder = [linPos(:); notLinPos(:) ];
                globRO.IDsInPos = globRO.IDsInPos(newOrder);
                globRO.selected = [];
            end
            updateMosaic(app)
        end

        % Value changed function: InsertButton
        function InsertButtonValueChanged(app, event)
            value = app.InsertButton.Value;
            global globRO
            globRO.inserting = value;
            if value
                globRO.selecting = 0;
                app.move.Value = 0;
            end


        end

        % Button pushed function: ReverseButton
        function ReverseButtonPushed(app, event)
            global globRO
            globRO.IDsInPos(:) = flipud(globRO.IDsInPos(:));
            updateMosaic(app)

        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            global globRO
            globRO.IDsInPos = [1:globRO.numZ];
            updateMosaic(app)
        end

        % Callback function
        function UpdateBrainmaporderButtonPushed(app, event)
                global globRO globBB
                globBB.show.secOrder = globRO.IDsInPos;
                

        end

        % Callback function
        function ReordersavedmatsButtonPushed(app, event)
            global globBB globRO
            
            SPN = globBB.file.SPN;
            nams = globBB.file.imageNames;
            for i = 1:length(nams)
                backUpNam{i} = ['backupNam_' nams{i}];
            end
            for i = 1:length(nams)
                movefile([SPN nams{i}], [SPN backUpNam{i}]);
            end
            for i = 1:length(nams)
                reorderedSourceFile = backUpNam{globRO.IDsInPos(i)};
                movefile([SPN reorderedSourceFile], [SPN nams{i}]);
            end


        end

        % Button pushed function: OnlyUseButton
        function OnlyUseButtonPushed(app, event)

            global globRO

            selected = globRO.selected;
            if ~isempty(selected)
                linPos = sub2ind([globRO.numCol globRO.numRow],selected(:,2),selected(:,1));
                newOrder = linPos(:);
                globRO.IDsInPos = globRO.IDsInPos(newOrder);
                globRO.selected = [];
            end
            updateMosaic(app)


        end

        % Button pushed function: RemoveButton
        function RemoveButtonPushed(app, event)
             global globRO

            selected = globRO.selected;
            if ~isempty(selected)
                linPos = sub2ind([globRO.numCol globRO.numRow],selected(:,2),selected(:,1));
                notLinPos = setdiff([1:length(globRO.IDsInPos)],linPos);
                newOrder = [notLinPos(:) ];
                globRO.IDsInPos = globRO.IDsInPos(newOrder);
                globRO.selected = [];
            end
            updateMosaic(app)
        end

        % Button down function: TileSizeButtonGroup
        function TileSizeButtonGroupButtonDown(app, event)
            updateMosaic(app)

        end

        % Selection changed function: TileSizeButtonGroup
        function TileSizeButtonGroupSelectionChanged(app, event)
            selectedButton = app.TileSizeButtonGroup.SelectedObject;
            updateMosaic(app)

        end

        % Selection changed function: ColumsButtonGroup
        function ColumsButtonGroupSelectionChanged(app, event)
            selectedButton = app.ColumsButtonGroup.SelectedObject;
            updateMosaic(app)

        end

        % Value changed function: ColumnsEditField
        function ColumnsEditFieldValueChanged(app, event)
            value = app.ColumnsEditField.Value;
            updateMosaic(app)

        end

        % Value changed function: CustomTileSizeEdit
        function CustomTileSizeEditValueChanged(app, event)
            value = app.CustomTileSizeEdit.Value;
            updateMosaic(app)

        end

        % Button down function: ColumsButtonGroup
        function ColumsButtonGroupButtonDown(app, event)
            updateMosaic(app)

        end

        % Button pushed function: DefaultSaveButton
        function DefaultSaveButtonPushed(app, event)
            global globBB globRO
            fileName = [globBB.file.SPN 'mosaic.tif'];
            imwrite(uint8(globRO.Imos),fileName)

        end

        % Button pushed function: CollectMosaicButton
        function CollectMosaicButtonPushed(app, event)
            app.textOut.Value = 'saving mosaic';
            drawnow
            global globBB globRO
            SPN = globBB.file.SPN;
            slashes = regexp(SPN,'\');
            imageName = sprintf('%s--%s.png',SPN(slashes(end-2)+1:slashes(end-1)-1),...
                SPN(slashes(end-1)+1:slashes(end)-1));
            fileName = [globRO.collectDir imageName];
            imwrite(uint8(globRO.Imos),fileName);
            app.textOut.Value = 'saved';
            drawnow

        end

        % Button pushed function: CollectionDirButton
        function CollectionDirButtonPushed(app, event)
            global globRO
            collectDir = uigetdir(globRO.collectDir);
            collectDir = [collectDir '\'];
            if exist(collectDir,'dir')
                globRO.collectDir = collectDir;
                save('.\collectDir.mat','collectDir')
                app.CollectDirEditField.Value = collectDir;
            end
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            startupFcn(app)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @UIFigureWindowButtonDown, true);
            app.UIFigure.WindowButtonMotionFcn = createCallbackFcn(app, @UIFigureWindowButtonMotion, true);
            app.UIFigure.ButtonDownFcn = createCallbackFcn(app, @UIFigureButtonDown, true);
            app.ax = uiaxes(app.UIFigure);
            app.ax.XTick = [];
            app.ax.YTick = [];
            app.ReverseButton = uibutton(app.UIFigure, 'push');
            app.ReverseButton.ButtonPushedFcn = createCallbackFcn(app, @ReverseButtonPushed, true);
            app.ReverseButton.Text = 'Reverse';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.move = uibutton(app.UIFigure, 'state');
            app.move.ValueChangedFcn = createCallbackFcn(app, @moveValueChanged, true);
            app.move.Text = 'Select';
            app.MovetoendButton = uibutton(app.UIFigure, 'push');
            app.MovetoendButton.ButtonPushedFcn = createCallbackFcn(app, @MovetoendButtonPushed, true);
            app.MovetoendButton.Text = 'Move to end';
            app.MovetofrontButton = uibutton(app.UIFigure, 'push');
            app.MovetofrontButton.ButtonPushedFcn = createCallbackFcn(app, @MovetofrontButtonPushed, true);
            app.MovetofrontButton.Text = 'Move to front';
            app.InsertButton = uibutton(app.UIFigure, 'state');
            app.InsertButton.ValueChangedFcn = createCallbackFcn(app, @InsertButtonValueChanged, true);
            app.InsertButton.Text = 'Insert';
            app.OnlyUseButton = uibutton(app.UIFigure, 'push');
            app.OnlyUseButton.ButtonPushedFcn = createCallbackFcn(app, @OnlyUseButtonPushed, true);
            app.OnlyUseButton.Text = 'Only Use';
            app.RemoveButton = uibutton(app.UIFigure, 'push');
            app.RemoveButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveButtonPushed, true);
            app.RemoveButton.Text = 'Remove';
            app.TileSizeButtonGroup = uibuttongroup(app.UIFigure);
            app.TileSizeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @TileSizeButtonGroupSelectionChanged, true);
            app.TileSizeButtonGroup.Title = 'Tile Size';
            app.TileSizeButtonGroup.ButtonDownFcn = createCallbackFcn(app, @TileSizeButtonGroupButtonDown, true);
            app.FullButton = uiradiobutton(app.TileSizeButtonGroup);
            app.FullButton.Text = 'Full';
            app.Button = uiradiobutton(app.TileSizeButtonGroup);
            app.Button.Text = '256';
            app.CustomButton = uiradiobutton(app.TileSizeButtonGroup);
            app.CustomButton.Text = 'Custom';
            app.CustomButton.Value = true;
            app.CustomTileSizeEdit = uieditfield(app.TileSizeButtonGroup, 'numeric');
            app.CustomTileSizeEdit.ValueChangedFcn = createCallbackFcn(app, @CustomTileSizeEditValueChanged, true);
            app.CustomTileSizeEdit.Value = 512;
            app.ColumsButtonGroup = uibuttongroup(app.UIFigure);
            app.ColumsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ColumsButtonGroupSelectionChanged, true);
            app.ColumsButtonGroup.Title = 'Colums';
            app.ColumsButtonGroup.ButtonDownFcn = createCallbackFcn(app, @ColumsButtonGroupButtonDown, true);
            app.SquareButton = uiradiobutton(app.ColumsButtonGroup);
            app.SquareButton.Text = 'Square';
            app.SquareButton.Value = true;
            app.SingleColumnButton = uiradiobutton(app.ColumsButtonGroup);
            app.SingleColumnButton.Text = 'Single Column';
            app.Button_2 = uiradiobutton(app.ColumsButtonGroup);
            app.Button_2.Text = '';
            app.ColumnsEditField = uieditfield(app.ColumsButtonGroup, 'numeric');
            app.ColumnsEditField.ValueChangedFcn = createCallbackFcn(app, @ColumnsEditFieldValueChanged, true);
            app.ColumnsEditField.Value = 4;
            app.SingleRowButton = uiradiobutton(app.ColumsButtonGroup);
            app.SingleRowButton.Text = 'Single Row';
            app.DefaultSaveButton = uibutton(app.UIFigure, 'push');
            app.DefaultSaveButton.ButtonPushedFcn = createCallbackFcn(app, @DefaultSaveButtonPushed, true);
            app.DefaultSaveButton.Text = 'Default Save';
            app.CollectMosaicButton = uibutton(app.UIFigure, 'push');
            app.CollectMosaicButton.ButtonPushedFcn = createCallbackFcn(app, @CollectMosaicButtonPushed, true);
            app.CollectMosaicButton.Text = 'Collect Mosaic';
            app.CollectDirEditField = uieditfield(app.UIFigure, 'text');
            app.CollectionDirButton = uibutton(app.UIFigure, 'push');
            app.CollectionDirButton.ButtonPushedFcn = createCallbackFcn(app, @CollectionDirButtonPushed, true);
            app.CollectionDirButton.Text = 'Collection Dir';
            app.AppendNameCheckBox = uicheckbox(app.UIFigure);
            app.AppendNameCheckBox.Text = 'Append Name';
            app.AppendNameCheckBox.Value = true;
            app.RefreshButton = uibutton(app.UIFigure, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Text = 'Refresh';
            app.textOut = uieditfield(app.UIFigure, 'text');
            app.SPNedit = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = makeMosaic

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end