classdef reorderSectionsMap < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        ReordersavedmatsButton     matlab.ui.control.Button
        InsertButton               matlab.ui.control.StateButton
        MovetofrontButton          matlab.ui.control.Button
        MovetoendButton            matlab.ui.control.Button
        move                       matlab.ui.control.StateButton
        UpdateBrainmaporderButton  matlab.ui.control.Button
        ResetButton                matlab.ui.control.Button
        SmallerButton              matlab.ui.control.Button
        BiggerButton               matlab.ui.control.Button
        PageDownButton             matlab.ui.control.Button
        PageUpButton               matlab.ui.control.Button
        ReverseButton              matlab.ui.control.Button
        ax                         matlab.ui.control.UIAxes
    end


    methods (Access = private)

        function results = updateMosaic(app)
            global globBB globRO globBI

            ax = app.ax;
            hold(app.ax,'off')
            app.ax.YDir = 'reverse';

            bSize = 4;
            tileSize = globRO.tileSize;
            Imos = zeros(globRO.ys, globRO.xs,3);
            for i = 1:globRO.numZ
                secI =  globRO.IDsInPos(i);
                Icol1 = globRO.Is{secI};
                [c r] = ind2sub([globRO.numCol globRO.numRow],i);
                Imos((r-1)*tileSize+bSize+1:r*tileSize-bSize,...
                    (c-1)*tileSize+bSize+1:c*tileSize-bSize,:) = Icol1;
%                 image(ax,uint8(Imos))
%                 drawnow

            end

            image(ax,uint8(Imos))
            ylim(ax,[1 size(Imos,1)])
            xlim(ax,[1 size(Imos,2)])
            drawnow
        end

        function results = func2(app)

        end

        function results = func3(app)

        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global globBB globRO globBI
            clear globRO
            global globRO

            numZ = size(globBI.Istack,4);
            globRO.numZ = numZ;
            globRO.IDsInPos = 1:globRO.numZ;
            numCol = ceil(sqrt(numZ));
            numRow = ceil(numZ/numCol);


            globRO.numCol = numCol;
            globRO.numRow = numRow;
            globRO.xs = 2048;
            globRO.draw.pathPT = [];
            globRO.draw.on = 0;
            globRO.selecting = 0;
            globRO.selected = [];
            globRO.inserting = 0;


            bSize = 4;
            globRO.bSize = bSize;
            I = globBI.Istack;
            Isize = size(I);
            tileSize = ceil(globRO.xs/globRO.numCol);
            globRO.xs = tileSize * globRO.numCol;
            globRO.ys = tileSize * globRO.numRow;
            globRO.tileSize = tileSize;
            thumbSize = tileSize- (bSize * 2);
            globRO.thumbSize = thumbSize;

            globRO.Is = {};
            for i = 1:numZ
                secI =  i;
                I1 = globBI.Istack(:,:,:,secI);
                I1 = imresize(I1,[thumbSize thumbSize],'box');
                Icol1 = I1(:,:,globBB.show.colMix) * 256/globBB.show.globalMax;
                Icol1(:,:,1) = (Icol1(:,:,1) - globBB.show.bright(secI,1) ) * globBB.show.con(secI,1);
                Icol1(:,:,2) = (Icol1(:,:,2) - globBB.show.bright(secI,2) ) * globBB.show.con(secI,2);
                Icol1(:,:,3) = (Icol1(:,:,3) - globBB.show.bright(secI,3) ) * globBB.show.con(secI,3);
                if globBB.show.flip(secI), Icol1 = fliplr(Icol1); end

                globRO.Is{i} = Icol1;
            end


            updateMosaic(app)


        end

        % Window button motion function: UIFigure
        function UIFigureWindowButtonMotion(app, event)


        end

        % Value changed function: move
        function moveValueChanged(app, event)
            value = app.move.Value;
            global globRO
            globRO.selecting = value;
            if value
                globRO.inserting = 0;
                app.InsertButton.Value = 0;
            end
        end

        % Button down function: UIFigure
        function UIFigureButtonDown(app, event)

         
        end

        % Window button down function: UIFigure
        function UIFigureWindowButtonDown(app, event)
            global globRO
            app.ax.CurrentPoint
                pt = app.ax.CurrentPoint;
                r = ceil(pt(1,2)/globRO.tileSize)
                c = ceil(pt(1,1)/globRO.tileSize)
            if globRO.selecting

               
                selected = globRO.selected;
                addPoint = 1;
                if (r>0) & (c>0) & (r<=globRO.numRow) & (c<=globRO.numCol)  %if valid
                    if ~isempty(selected)
                        
                        notSelected = find(~((selected(:,1)==r) & (selected(:,2)==c)));
                        if length(notSelected) < size(selected,1) %new selection
                            addPoint = 0;
                        end
                    end

                    if addPoint
                        globRO.selected = cat(1,selected,[r c]);
                    else
                        globRO.selected = selected(notSelected,:);
                    end

                    try globRO.scatSelect.delete,end
                        hold(app.ax,'on')
                        sC = globRO.selected(:,2)*globRO.tileSize-globRO.tileSize/2;
                        sR = globRO.selected(:,1)*globRO.tileSize-globRO.tileSize/2;
                        globRO.scatSelect = scatter(app.ax,sC,sR,500,'y','filled','markerfacealpha',.3,'linewidth',2,'MarkerEdgeColor','b')
                        app.ax.YDir = 'reverse';
                end
            elseif globRO.inserting


                selected = globRO.selected;
                if ~isempty(selected)

                    cutPos = sub2ind([globRO.numCol globRO.numRow],c,r);
                    cutGroup1 = [1:cutPos];
                    cutGroup2 = [cutPos+1:length(globRO.IDsInPos)];

                    linPos = sub2ind([globRO.numCol globRO.numRow],selected(:,2),selected(:,1));
                    cutGroup1d = setdiff(cutGroup1,linPos);
                    cutGroup2d = setdiff(cutGroup2,linPos);

                    newOrder = [cutGroup1d(:); linPos(:); cutGroup2d(:) ];
                    globRO.IDsInPos = globRO.IDsInPos(newOrder);
                    globRO.selected = [];
                end
                updateMosaic(app)
                app.InsertButton.Value = 0;

            end

        
        end

        % Button pushed function: MovetoendButton
        function MovetoendButtonPushed(app, event)
            global globRO

            selected = globRO.selected;
            if ~isempty(selected)
                    linPos = sub2ind([globRO.numCol globRO.numRow],selected(:,2),selected(:,1));
                notLinPos = setdiff([1:length(globRO.IDsInPos)],linPos);
                newOrder = [notLinPos(:); linPos(:)];
                globRO.IDsInPos = globRO.IDsInPos(newOrder);
                globRO.selected = [];
            end
            updateMosaic(app)
        end

        % Button pushed function: MovetofrontButton
        function MovetofrontButtonPushed(app, event)
            global globRO

            selected = globRO.selected;
            if ~isempty(selected)
                                   linPos = sub2ind([globRO.numCol globRO.numRow],selected(:,2),selected(:,1));
                notLinPos = setdiff([1:length(globRO.IDsInPos)],linPos);
                newOrder = [linPos(:); notLinPos(:) ];
                globRO.IDsInPos = globRO.IDsInPos(newOrder);
                globRO.selected = [];
            end
            updateMosaic(app)
        end

        % Value changed function: InsertButton
        function InsertButtonValueChanged(app, event)
            value = app.InsertButton.Value;
            global globRO
            globRO.inserting = value;
            if value
                globRO.selecting = 0;
                app.move.Value = 0;
            end


        end

        % Button pushed function: ReverseButton
        function ReverseButtonPushed(app, event)
            global globRO
            globRO.IDsInPos(:) = flipud(globRO.IDsInPos(:));
            updateMosaic(app)

        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            global globRO
            globRO.IDsInPos = [1:length(globRO.IDsInPos)];
            updateMosaic(app)
        end

        % Button pushed function: UpdateBrainmaporderButton
        function UpdateBrainmaporderButtonPushed(app, event)
                global globRO globBB
                globBB.show.secOrder = globRO.IDsInPos;
                

        end

        % Button pushed function: ReordersavedmatsButton
        function ReordersavedmatsButtonPushed(app, event)
            global globBB globRO
            disp('reordering mats')
            SPN = globBB.file.SPN;
            nams = globBB.file.imageNames;
            for i = 1:length(nams)
                backUpNam{i} = ['backupNam_' nams{i}];
            end
            for i = 1:length(nams)
                movefile([SPN nams{i}], [SPN backUpNam{i}]);
            end
            for i = 1:length(nams)
                reorderedSourceFile = backUpNam{globRO.IDsInPos(i)};
                movefile([SPN reorderedSourceFile], [SPN nams{i}]);
            end


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @UIFigureWindowButtonDown, true);
            app.UIFigure.WindowButtonMotionFcn = createCallbackFcn(app, @UIFigureWindowButtonMotion, true);
            app.UIFigure.ButtonDownFcn = createCallbackFcn(app, @UIFigureButtonDown, true);
            app.ax = uiaxes(app.UIFigure);
            app.ax.XTick = [];
            app.ax.YTick = [];
            app.ReverseButton = uibutton(app.UIFigure, 'push');
            app.ReverseButton.ButtonPushedFcn = createCallbackFcn(app, @ReverseButtonPushed, true);
            app.ReverseButton.Text = 'Reverse';
            app.PageUpButton = uibutton(app.UIFigure, 'push');
            app.PageUpButton.Text = 'Page Up';
            app.PageDownButton = uibutton(app.UIFigure, 'push');
            app.PageDownButton.Text = 'Page Down';
            app.BiggerButton = uibutton(app.UIFigure, 'push');
            app.BiggerButton.Text = 'Bigger';
            app.SmallerButton = uibutton(app.UIFigure, 'push');
            app.SmallerButton.Text = 'Smaller';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.UpdateBrainmaporderButton = uibutton(app.UIFigure, 'push');
            app.UpdateBrainmaporderButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateBrainmaporderButtonPushed, true);
            app.UpdateBrainmaporderButton.Text = 'Update Brainmap order';
            app.move = uibutton(app.UIFigure, 'state');
            app.move.ValueChangedFcn = createCallbackFcn(app, @moveValueChanged, true);
            app.move.Text = 'Select';
            app.MovetoendButton = uibutton(app.UIFigure, 'push');
            app.MovetoendButton.ButtonPushedFcn = createCallbackFcn(app, @MovetoendButtonPushed, true);
            app.MovetoendButton.Text = 'Move to end';
            app.MovetofrontButton = uibutton(app.UIFigure, 'push');
            app.MovetofrontButton.ButtonPushedFcn = createCallbackFcn(app, @MovetofrontButtonPushed, true);
            app.MovetofrontButton.Text = 'Move to front';
            app.InsertButton = uibutton(app.UIFigure, 'state');
            app.InsertButton.ValueChangedFcn = createCallbackFcn(app, @InsertButtonValueChanged, true);
            app.InsertButton.Text = 'Insert';
            app.ReordersavedmatsButton = uibutton(app.UIFigure, 'push');
            app.ReordersavedmatsButton.ButtonPushedFcn = createCallbackFcn(app, @ReordersavedmatsButtonPushed, true);
            app.ReordersavedmatsButton.Text = 'Reorder saved mats';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = reorderSectionsMap

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end