classdef mixNewChannel < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        LinearunmixingvaluesPanel     matlab.ui.container.Panel
        AutofindvaluesButton          matlab.ui.control.Button
        RxbDown                       matlab.ui.control.Button
        RxbUp                         matlab.ui.control.Button
        RxaDown                       matlab.ui.control.Button
        RxaUp                         matlab.ui.control.Button
        RxbEditField                  matlab.ui.control.NumericEditField
        RxbEditFieldLabel             matlab.ui.control.Label
        RxaEditField                  matlab.ui.control.NumericEditField
        RxaEditFieldLabel             matlab.ui.control.Label
        ScaleButton                   matlab.ui.control.StateButton
        textOut                       matlab.ui.control.EditField
        StatusEditFieldLabel          matlab.ui.control.Label
        InputchannelsEditField        matlab.ui.control.EditField
        InputchannelsEditFieldLabel   matlab.ui.control.Label
        MixesDropDown                 matlab.ui.control.DropDown
        MixesDropDownLabel            matlab.ui.control.Label
        RunStackButton                matlab.ui.control.Button
        PreviewButton                 matlab.ui.control.Button
        NewchannelID46EditField       matlab.ui.control.NumericEditField
        NewchannelID46EditFieldLabel  matlab.ui.control.Label
        ChannelmixequationEditField   matlab.ui.control.EditField
        ChannelmixequationEditFieldLabel  matlab.ui.control.Label
        PreviewplanePanel             matlab.ui.container.Panel
    end


    methods (Access = private)

        function results = showPreview(app)
            global globTT globNM

            app.textOut.Value = 'remixing channels';
            toCol = globTT.remix.col + 3 * globTT.remix.ab;
            isEasy = sum(unique(toCol)>0) == 6;
            pAx = {};
            pAx{1} = subplot(1,3,1,'Parent',globNM.app.PreviewplanePanel);
            pAx{2} = subplot(1,3,2,'Parent',globNM.app.PreviewplanePanel);
            pAx{3} = subplot(1,3,3,'Parent',globNM.app.PreviewplanePanel);
            t = globTT.active.ID;

            for i = globTT.active.slice;%1:size(globTT.I.tab{t},4);
                %Io = cat(3,globTT.I.tab{t}(:,:,:,i),globTT.I.tab{t+3}(:,:,:,i));
                Io = globTT.I.tab{t}(:,:,:,i);

                
                inputs = globNM.param.input;
                for c = 1:length(inputs)
                    eval(sprintf('c%d = Io(:,:,%d);',c,inputs(c)));
                end
                eval(sprintf(globNM.param.eqStr));


                %                      if app.ScaleButton.Value
                %                          nc = nc-min(nc(:));
                %                          nc = nc*240/max(nc(:));
                %                          nc = nc+ 15;
                %                          nc = uint8(nc);
                %                      end


                if exist('Y','var');
                    colT(:,:,1) = myNorms(Y,2);
                    colT(:,:,2) = myNorms(X,2);
                    colT(:,:,3) = myNorms(Y,2);
                    image(pAx{1},myNorms(Y,2));
                    image(pAx{2},myNorms(X,2));
                    image(pAx{3},uint8(colT));
                    colormap(pAx{1},gray(256))
                    colormap(pAx{2},gray(256))
                    drawnow
                else
                    if exist('c1','var')
                        image(pAx{1},c1);
                        colormap(pAx{1},gray(256))
                    end
                    if exist('c2','var')
                        image(pAx{2},c2);
                        colormap(pAx{2},gray(256))
                    end
                    if exist('nc','var')
                        image(pAx{3},nc);
                        colormap(pAx{3},gray(256))
                    end
                    drawnow

                end

                %globTT.I.tab{t}(:,:,:,i) = In(:,:,1:3,:);
                %globTT.I.tab{t+3}(:,:,:,i) = In(:,:,4:6,:);
            end


            %showImage(app)
            app.textOut.Value = 'finished remixing channels';

        end


        function results = runStack(app)
            global globTT globNM
            globTT.app.main.updateChannelInfo(globTT.app)
            pAx = {};
            pAx{1} = subplot(1,3,1,'Parent',globNM.app.PreviewplanePanel);
            pAx{2} = subplot(1,3,2,'Parent',globNM.app.PreviewplanePanel);
            pAx{3} = subplot(1,3,3,'Parent',globNM.app.PreviewplanePanel);

            app.textOut.Value = 'remixing channels';drawnow
            toCol = globTT.remix.col + 3 * globTT.remix.ab;
            isEasy = sum(unique(toCol)>0) == 6;
            pass = 1;
            if globNM.unMix.do
                if ~isfield(globNM.unMix,'res')
                    showPreview(app)
                end
            end

            t = globTT.active.ID;
            for i = 1:size(globTT.I.tab{t},4);
                app.textOut.Value = sprintf('mixing plane %d of %d',i,size(globTT.I.tab{t},4));
                drawnow
                Io = globTT.I.tab{t}(:,:,:,i);
                targC = globNM.param.new;

                inputs = globNM.param.input;
                for c = 1:length(inputs)
                    eval(sprintf('c%d = Io(:,:,%d);',c,inputs(c)));
                end
                eval(sprintf(globNM.param.eqStr));


                %
                %                     if app.ScaleButton.Value
                %                         nc = nc-min(nc(:));
                %                         nc = nc*256/max(nc(:));
                %                     end


                if exist('Y','var')
                    nc = Y;
                    colT(:,:,1) = myNorms(Y,2);
                    colT(:,:,2) = myNorms(X,2);
                    colT(:,:,3) = myNorms(Y,2);
                    image(pAx{1},myNorms(Y,2));
                    image(pAx{2},myNorms(X,2));
                    image(pAx{3},uint8(colT));
                    colormap(pAx{1},gray(256))
                    colormap(pAx{2},gray(256))
                    drawnow
                else
                    if exist('c1','var')
                        image(pAx{1},c1);
                        colormap(pAx{1},gray(256))
                    end
                    if exist('c2','var')
                        image(pAx{2},c2);
                        colormap(pAx{2},gray(256))
                    end
                    if exist('nc','var')
                        image(pAx{3},nc);
                        colormap(pAx{3},gray(256))
                    end
                    drawnow

                end

                if targC<=3
                    globTT.I.tab{t+0}(:,:,targC,i) = nc;
                    globTT.I.tab{t+1}(:,:,targC,i) = nc;
                    globTT.I.tab{t+2}(:,:,targC,i) = nc;
                else
                    globTT.I.tab{t+3}(:,:,targC-3,i) = nc;
                    globTT.I.tab{t+4}(:,:,targC-3,i) = nc;
                    globTT.I.tab{t+5}(:,:,targC-3,i) = nc;
                end
            end


            %showImage(app)
            globTT.app.main.updateChannelInfo(globTTapp)
            app.textOut.Value = 'finished remixing channels';
        end
    end

        methods (Access = public)

            function results = previewNewMix(app)
                global globTT globNM


            end

            function results = parseNewMixEquation(app)

                global globNM
                globNM.param.eqStr = app.ChannelmixequationEditField.Value;
                globNM.param.input = str2num(app.InputchannelsEditField.Value);
                globNM.param.new = app.NewchannelID46EditField.Value;
                globNM.param.mixDrop = app.MixesDropDown.Value;

                globNM.unMix.R.Rxa = globNM.unMix.manual.Rxa;
                globNM.unMix.R.Rxb = globNM.unMix.manual.Rxb;
                globNM.unMix.R.Rya = 1 -globNM.unMix.manual.Rxa;
                globNM.unMix.R.Ryb = 1 -globNM.unMix.manual.Rxb;

            end

        end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            global globNM
            globNM.app = app;
            globNM.app.PreviewplanePanel.AutoResizeChildren = 'off';
            globNM.pAx = subplot(1,1,1,'Parent',globNM.app.PreviewplanePanel);
            globNM.unMix.do = 0;
            globNM.unMix.manual.Rxa = 1;
            globNM.unMix.manual.Rxb =0;

        end

        % Button pushed function: PreviewButton
        function runPreview(app, event)
            parseNewMixEquation(app)
            showPreview(app)

        end

        % Value changed function: ScaleButton
        function doScale(app, event)
            value = app.ScaleButton.Value;
            showPreview(app)
        end

        % Button pushed function: RunStackButton
        function runStackButton(app, event)
            parseNewMixEquation(app)
            runStack(app)
        end

        % Value changed function: MixesDropDown
        function changeDrop(app, event)
            global globNM
            value = lower(app.MixesDropDown.Value);
            parseNewMixEquation(app)
            globNM.unMix.do = 0;
            if strcmp(value,'custom')

            elseif strcmp(value,'equal')
                app.ChannelmixequationEditField.Value = ...
                    sprintf('nc = c1;');
            elseif strcmp(value,'equal')
                app.ChannelmixequationEditField.Value = ...
                    'nc = ((c1-c2)./(c1+c2)+1 ) * 255/2;';;
            elseif strcmp(value,'linear unmix 1')
                app.ChannelmixequationEditField.Value = ...
                    '[Y X] = linUnMixRes(c1,c2,globNM.unMix);';
                globNM.unMix.do = 1;
                globNM.unMix.outID = 1;
            elseif  strcmp(value,'linear unmix 2')
                app.ChannelmixequationEditField.Value = ...
                    '[Y X] = linUnMixRes(c1,c2,globNM.unMix);';
                globNM.unMix.do = 1;
                globNM.unMix.outID = 2;
            end


        end

        % Value changed function: ChannelmixequationEditField
        function eqChange(app, event)
            value = app.ChannelmixequationEditField.Value;
            app.MixesDropDown.Value = 'Custom';
        end

        % Button pushed function: RxaUp
        function RxaUpCall(app, event)
            global globNM
            globNM.unMix.manual.Rxa = globNM.unMix.manual.Rxa +.05;
            if globNM.unMix.manual.Rxa >1
                globNM.unMix.manual.Rxa = 1;
            end
            app.RxaEditField.Value = globNM.unMix.manual.Rxa;
        end

        % Button pushed function: RxaDown
        function RxaDownCall(app, event)
            global globNM
            globNM.unMix.manual.Rxa = globNM.unMix.manual.Rxa -.05;
            if globNM.unMix.manual.Rxa <0
                globNM.unMix.manual.Rxa = 0;
            end
            app.RxaEditField.Value = globNM.unMix.manual.Rxa;
        end

        % Button pushed function: RxbUp
        function RxbUpCall(app, event)
            global globNM
            globNM.unMix.manual.Rxb = globNM.unMix.manual.Rxb +.05;
            if globNM.unMix.manual.Rxb >1
                globNM.unMix.manual.Rxb = 1;
            end
            app.RxbEditField.Value = globNM.unMix.manual.Rxb;
        end

        % Button pushed function: RxbDown
        function RxbDownCall(app, event)
            global globNM
            globNM.unMix.manual.Rxb = globNM.unMix.manual.Rxb -.05;
            if globNM.unMix.manual.Rxb <0
                globNM.unMix.manual.Rxb = 0;
            end
            app.RxbEditField.Value = globNM.unMix.manual.Rxb;
        end

        % Value changed function: RxaEditField
        function RxaChange(app, event)
            value = app.RxaEditField.Value;
            if value < 0;
                value = 0;
            elseif value > 1
                value = 1
            end
            app.RxaEditField.Value = 1;
            globNM.unMix.manual.Rxa = value;
        end

        % Value changed function: RxbEditField
        function RcbChange(app, event)
            value = app.RxbEditField.Value;
            if value < 0;
                value = 0;
            elseif value > 1
                value = 1
            end
            app.RxbEditField.Value = 1;
            globNM.unMix.manual.Rxb = value;
        end

        % Button pushed function: AutofindvaluesButton
        function findUnMixValues(app, event)
            global globTT globNM
            i = globTT.active.slice;
            t = globTT.active.ID;
            %Io = cat(3,globTT.I.tab{t}(:,:,:,i),globTT.I.tab{t+3}(:,:,:,i));
            Io = globTT.I.tab{t}(:,:,:,i);
            In = Io * 0;

            for c = 1:length(globNM.param.input)
                eval(sprintf('c%d = Io(:,:,%d);',c,globNM.param.input(c)));
            end

            [W res]  = linUnMix01(c1,c2,globNM.unMix);
            globNM.app.RxbEditField.Value = res.Rxb;
            globNM.app.RxaEditField.Value = res.Rxa;
            globNM.unMix.manual.Rxa = res.Rxa;
            globNM.unMix.manual.Rxb = res.Rxb;

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PreviewplanePanel = uipanel(app.UIFigure);
            app.PreviewplanePanel.Title = 'Preview plane';
            app.ChannelmixequationEditFieldLabel = uilabel(app.UIFigure);
            app.ChannelmixequationEditFieldLabel.Text = 'Channel mix equation';
            app.ChannelmixequationEditField = uieditfield(app.UIFigure, 'text');
            app.ChannelmixequationEditField.ValueChangedFcn = createCallbackFcn(app, @eqChange, true);
            app.ChannelmixequationEditField.Value = '((c1-c2)./(c1+c2)+1 ) * 255/2';
            app.NewchannelID46EditFieldLabel = uilabel(app.UIFigure);
            app.NewchannelID46EditFieldLabel.Text = 'New channel ID (4-6)';
            app.NewchannelID46EditField = uieditfield(app.UIFigure, 'numeric');
            app.NewchannelID46EditField.Value = 4;
            app.PreviewButton = uibutton(app.UIFigure, 'push');
            app.PreviewButton.ButtonPushedFcn = createCallbackFcn(app, @runPreview, true);
            app.PreviewButton.Text = 'Preview';
            app.RunStackButton = uibutton(app.UIFigure, 'push');
            app.RunStackButton.ButtonPushedFcn = createCallbackFcn(app, @runStackButton, true);
            app.RunStackButton.Text = 'Run Stack';
            app.MixesDropDownLabel = uilabel(app.UIFigure);
            app.MixesDropDownLabel.Text = 'Mixes';
            app.MixesDropDown = uidropdown(app.UIFigure);
            app.MixesDropDown.Items = {'Custom', 'Equal', 'Polarity', 'linear unmix 1', 'linear unmix 2'};
            app.MixesDropDown.ValueChangedFcn = createCallbackFcn(app, @changeDrop, true);
            app.MixesDropDown.Value = 'Custom';
            app.InputchannelsEditFieldLabel = uilabel(app.UIFigure);
            app.InputchannelsEditFieldLabel.Text = 'Input channels';
            app.InputchannelsEditField = uieditfield(app.UIFigure, 'text');
            app.InputchannelsEditField.Value = '1 2';
            app.StatusEditFieldLabel = uilabel(app.UIFigure);
            app.StatusEditFieldLabel.Text = 'Status';
            app.textOut = uieditfield(app.UIFigure, 'text');
            app.ScaleButton = uibutton(app.UIFigure, 'state');
            app.ScaleButton.ValueChangedFcn = createCallbackFcn(app, @doScale, true);
            app.ScaleButton.Text = 'Scale';
            app.ScaleButton.Value = true;
            app.LinearunmixingvaluesPanel = uipanel(app.UIFigure);
            app.LinearunmixingvaluesPanel.Title = 'Linear unmixing values';
            app.RxaEditFieldLabel = uilabel(app.LinearunmixingvaluesPanel);
            app.RxaEditFieldLabel.Text = 'Rxa';
            app.RxaEditField = uieditfield(app.LinearunmixingvaluesPanel, 'numeric');
            app.RxaEditField.ValueChangedFcn = createCallbackFcn(app, @RxaChange, true);
            app.RxaEditField.Value = 1;
            app.RxbEditFieldLabel = uilabel(app.LinearunmixingvaluesPanel);
            app.RxbEditFieldLabel.Text = 'Rxb';
            app.RxbEditField = uieditfield(app.LinearunmixingvaluesPanel, 'numeric');
            app.RxbEditField.ValueChangedFcn = createCallbackFcn(app, @RcbChange, true);
            app.RxaUp = uibutton(app.LinearunmixingvaluesPanel, 'push');
            app.RxaUp.ButtonPushedFcn = createCallbackFcn(app, @RxaUpCall, true);
            app.RxaDown = uibutton(app.LinearunmixingvaluesPanel, 'push');
            app.RxaDown.ButtonPushedFcn = createCallbackFcn(app, @RxaDownCall, true);
            app.RxbUp = uibutton(app.LinearunmixingvaluesPanel, 'push');
            app.RxbUp.ButtonPushedFcn = createCallbackFcn(app, @RxbUpCall, true);
            app.RxbDown = uibutton(app.LinearunmixingvaluesPanel, 'push');
            app.RxbDown.ButtonPushedFcn = createCallbackFcn(app, @RxbDownCall, true);
            app.AutofindvaluesButton = uibutton(app.LinearunmixingvaluesPanel, 'push');
            app.AutofindvaluesButton.ButtonPushedFcn = createCallbackFcn(app, @findUnMixValues, true);
            app.AutofindvaluesButton.Text = 'Auto find values';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mixNewChannel

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end