classdef toadalTweaker5 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        MixnewchannelButton      matlab.ui.control.Button
        ViewAllButton            matlab.ui.control.StateButton
        SaveButton               matlab.ui.control.Button
        MixImagesButton          matlab.ui.control.StateButton
        SendchanneltocolorPanel  matlab.ui.container.Panel
        rmC6                     matlab.ui.control.EditField
        rmC5                     matlab.ui.control.EditField
        rmC4                     matlab.ui.control.EditField
        rmC3                     matlab.ui.control.EditField
        rmC2                     matlab.ui.control.EditField
        rmC1                     matlab.ui.control.EditField
        RemixButton              matlab.ui.control.Button
        rmAB6                    matlab.ui.control.StateButton
        rmAB5                    matlab.ui.control.StateButton
        rmAB4                    matlab.ui.control.StateButton
        rmAB3                    matlab.ui.control.StateButton
        rmAB2                    matlab.ui.control.StateButton
        rmAB1                    matlab.ui.control.StateButton
        rmT6                     matlab.ui.control.Button
        rmT5                     matlab.ui.control.Button
        rmT4                     matlab.ui.control.Button
        rmT3                     matlab.ui.control.Button
        rmT2                     matlab.ui.control.Button
        rmT1                     matlab.ui.control.Button
        FlipRFButton             matlab.ui.control.Button
        twkR                     matlab.ui.control.StateButton
        twkB                     matlab.ui.control.StateButton
        twkG                     matlab.ui.control.StateButton
        FlipABButton             matlab.ui.control.Button
        FilterPanel              matlab.ui.container.Panel
        ResampleButton           matlab.ui.control.Button
        ResetButton              matlab.ui.control.Button
        SigLabel_2               matlab.ui.control.Label
        SigLabel                 matlab.ui.control.Label
        mexHatSig2               matlab.ui.control.NumericEditField
        mexHat3DButton           matlab.ui.control.StateButton
        MexHatButton             matlab.ui.control.Button
        mexHatSig1               matlab.ui.control.NumericEditField
        gauss3DButton            matlab.ui.control.StateButton
        GaussianButton           matlab.ui.control.Button
        gaussSig                 matlab.ui.control.NumericEditField
        BinrollingZLabel         matlab.ui.control.Label
        edit_autoConBin          matlab.ui.control.NumericEditField
        AutoConButton            matlab.ui.control.Button
        medFiltZ                 matlab.ui.control.NumericEditField
        MedianButton             matlab.ui.control.Button
        YXZLabel                 matlab.ui.control.Label
        medFiltX                 matlab.ui.control.NumericEditField
        medFiltY                 matlab.ui.control.NumericEditField
        TweakPanel               matlab.ui.container.Panel
        ApplytofileButton        matlab.ui.control.Button
        GLabel                   matlab.ui.control.Label
        edit_gamma               matlab.ui.control.NumericEditField
        slideGamma               matlab.ui.control.Slider
        slideTopCon              matlab.ui.control.Slider
        slideBottomCon           matlab.ui.control.Slider
        histAx                   matlab.ui.control.UIAxes
        textOut                  matlab.ui.control.TextArea
        edit_file                matlab.ui.control.EditField
        edit_folder              matlab.ui.control.EditField
        OifReadPanel             matlab.ui.container.Panel
        z1Button                 matlab.ui.control.StateButton
        InfoButton               matlab.ui.control.Button
        ChannelMixingLabel       matlab.ui.control.Label
        toggle_shouldWrite_3     matlab.ui.control.StateButton
        toggle_shouldWrite_2     matlab.ui.control.StateButton
        toggle_shouldWrite       matlab.ui.control.StateButton
        BLabel                   matlab.ui.control.Label
        ALabel                   matlab.ui.control.Label
        edit_chanB3              matlab.ui.control.NumericEditField
        edit_chanB2              matlab.ui.control.NumericEditField
        edit_chanB1              matlab.ui.control.NumericEditField
        edit_chanA3              matlab.ui.control.NumericEditField
        edit_chanA2              matlab.ui.control.NumericEditField
        edit_chanA1              matlab.ui.control.NumericEditField
        ReadButton               matlab.ui.control.Button
        mainTab                  matlab.ui.container.TabGroup
        RawATab                  matlab.ui.container.Tab
        rawAAxes                 matlab.ui.control.UIAxes
        FiltATab                 matlab.ui.container.Tab
        filtAAxes                matlab.ui.control.UIAxes
        MaxATab                  matlab.ui.container.Tab
        maxAAxes                 matlab.ui.control.UIAxes
        RawBTab                  matlab.ui.container.Tab
        rawBAxes                 matlab.ui.control.UIAxes
        FiltBTab                 matlab.ui.container.Tab
        filtBAxes                matlab.ui.control.UIAxes
        MaxBTab                  matlab.ui.container.Tab
        maxBAxes                 matlab.ui.control.UIAxes
        InfoTab                  matlab.ui.container.Tab
        list_imageInfo           matlab.ui.control.ListBox
        zSlider                  matlab.ui.control.Slider
        ChooseFileButton         matlab.ui.control.Button
        ChooseFolderButton       matlab.ui.control.Button
    end


    methods (Access = private)

        function fInfo = parseFileInfo(app,fileName)

            %             A = textread(fileName)
            %
            %             allData = textread(fileName, '%s', 'delimiter', '\n');
            % % Make allData cells empty if numerical
            % numericalArray = cellfun(@(s) sscanf(s,'%f').' ,allData, 'un', 0);
            % % Get Header
            % header = allData(cellfun('isempty',numericalArray));
            % % Get Data
            % data = vertcat(numericalArray{:});


            if 1
                [fid err] = fopen(fileName); % open the file
                c = 0;
                clear textInfo
                while ~feof(fid) % feof(fid) is true when the file ends
                    c = c+1;
                    textInfo{c} = fgetl(fid); % read one line
                end
                fclose(fid); % close the file
            end

            fInfo.textInfo = textInfo;


        end

        function results = updateSlider(app)
            global globTT
            %             activeSliceNum = size(globTT.I.tab{globTT.active.ID},4);
            %             if activeSliceNum >1
            %                 app.zSlider.Limits = [1 activeSliceNum];
            %             end

            activeSliceNum = size(globTT.I.tab{1},4);
            app.zSlider.Limits = [1 max(activeSliceNum,2)];
            try globTT.app.imMix.zSlider.Limits = [1 activeSliceNum]; end

        end

        function results = showImage(app)
            global globTT
            id = globTT.active.ID;
            %I = eval(sprintf('globTT.I.%s(:,:,:,%d);',globTT.active.I,globTT.active.slice));
            if size(globTT.I.tab{id},4) >= globTT.active.slice;
                globTT.I.showRaw =  globTT.I.tab{id}(:,:,:,globTT.active.slice);
            else
                globTT.I.showRaw =  globTT.I.tab{id}(:,:,:,1);
            end
            globTT.I.showFilt = globTT.I.showRaw;
            for c = 1:3
                F = globTT.I.showFilt(:,:,c);
                F = F + globTT.twk.bright(id,c);
                F = F * globTT.twk.con(id,c);
                F(F<0) = 0;
                maxA = max(F(:));
                conA =  maxA/(maxA ^ globTT.twk.gamma(id,c));

                F = F.^globTT.twk.gamma(id,c) * conA; %gamma presserving max value.
                globTT.I.showFilt(:,:,c) = F;
            end
            
            if ~app.ViewAllButton.Value
                
                useCol = find([app.twkR.Value app.twkG.Value app.twkB.Value]);
                if length(useCol) == 1
                    globTT.I.showFilt = repmat(globTT.I.showFilt(:,:,useCol),[1 1 3]);
                elseif length(useCol) == 2
                   dontShow = setdiff([1 2 3],useCol);
                   globTT.I.showFilt(:,:,dontShow) = 0;
                end
                
                
            end
            
            
            image(globTT.active.ax,uint8(globTT.I.showFilt));
            pause(.001)
        end

        function results = showHist(app)
            global globTT
            id = globTT.active.ID;
            cHist = globTT.I.hist{id};
            hRange = globTT.I.hRange{id};
            maxY = max(cHist(:));

            cla(app.histAx)
            app.histAx.NextPlot = 'add';
            plot(app.histAx,hRange,cHist(:,1),'r');
            plot(app.histAx,hRange,cHist(:,2),'g');
            plot(app.histAx,hRange,cHist(:,3),'b');
            plot(app.histAx,[globTT.twk.bottom(id,1) globTT.twk.bottom(id,1)],[ 0 maxY/3],'r')
            plot(app.histAx,[globTT.twk.bottom(id,2) globTT.twk.bottom(id,2)],[ maxY/3 2*maxY/3],'g')
            plot(app.histAx,[globTT.twk.bottom(id,3) globTT.twk.bottom(id,3)],[ 2*maxY/3 maxY],'b')
            plot(app.histAx,[globTT.twk.top(id,1) globTT.twk.top(id,1)],[ 0 maxY/3],'r')
            plot(app.histAx,[globTT.twk.top(id,2) globTT.twk.top(id,2)],[ maxY/3 2*maxY/3],'g')
            plot(app.histAx,[globTT.twk.top(id,3) globTT.twk.top(id,3)],[ 2*maxY/3 maxY],'b')

            app.histAx.XLim = [min(hRange)-5 max(hRange)+5];
            app.histAx.YLim = [0 max(cHist(:))];
            pause(.0001)
        end

        function results = pickTab(app,tabID)
            global globTT
            
            globTT.active.ID = tabID;

            if tabID == 2
                app.filtAAxes.XLim = globTT.active.ax.XLim;
                app.filtAAxes.YLim = globTT.active.ax.YLim;
                globTT.active.I = 'filtA';
                globTT.active.ax = app.filtAAxes;
            elseif tabID == 1
                app.rawAAxes.XLim = globTT.active.ax.XLim;
                app.rawAAxes.YLim = globTT.active.ax.YLim;
                globTT.active.I = 'rawA';
                globTT.active.ax = app.rawAAxes;
            elseif tabID ==4
                app.rawBAxes.XLim = globTT.active.ax.XLim;
                app.rawBAxes.YLim = globTT.active.ax.YLim;
                globTT.active.I = 'rawB';
                globTT.active.ax = app.rawBAxes;
            elseif tabID == 6
                app.maxBAxes.XLim = globTT.active.ax.XLim;
                app.maxBAxes.YLim = globTT.active.ax.YLim;
                globTT.active.I = 'maxB';
                globTT.active.ax = app.maxBAxes;
            elseif tabID == 3
                app.maxAAxes.XLim = globTT.active.ax.XLim;
                app.maxAAxes.YLim = globTT.active.ax.YLim;
                globTT.active.I = 'maxA';
                globTT.active.ax = app.maxAAxes;
            elseif tabID == 5
                app.filtBAxes.XLim = globTT.active.ax.XLim;
                app.filtBAxes.YLim = globTT.active.ax.YLim;
                globTT.active.I = 'filtB';
                globTT.active.ax = app.filtBAxes;
            end
            
            showImage(app)
        end
    end
    
    methods (Access = public)
        
        function results = pickTabP(app,tabID)
             global globTT
            
            globTT.active.ID = tabID;

            if tabID == 2
                app.filtAAxes.XLim = globTT.active.ax.XLim;
                app.filtAAxes.YLim = globTT.active.ax.YLim;
                globTT.active.I = 'filtA';
                globTT.active.ax = app.filtAAxes;
            elseif tabID == 1
                app.rawAAxes.XLim = globTT.active.ax.XLim;
                app.rawAAxes.YLim = globTT.active.ax.YLim;
                globTT.active.I = 'rawA';
                globTT.active.ax = app.rawAAxes;
            elseif tabID ==4
                app.rawBAxes.XLim = globTT.active.ax.XLim;
                app.rawBAxes.YLim = globTT.active.ax.YLim;
                globTT.active.I = 'rawB';
                globTT.active.ax = app.rawBAxes;
            elseif tabID == 6
                app.maxBAxes.XLim = globTT.active.ax.XLim;
                app.maxBAxes.YLim = globTT.active.ax.YLim;
                globTT.active.I = 'maxB';
                globTT.active.ax = app.maxBAxes;
            elseif tabID == 3
                app.maxAAxes.XLim = globTT.active.ax.XLim;
                app.maxAAxes.YLim = globTT.active.ax.YLim;
                globTT.active.I = 'maxA';
                globTT.active.ax = app.maxAAxes;
            elseif tabID == 5
                app.filtBAxes.XLim = globTT.active.ax.XLim;
                app.filtBAxes.YLim = globTT.active.ax.YLim;
                globTT.active.I = 'filtB';
                globTT.active.ax = app.filtBAxes;
            end
            
            showImage(app)
            
        end
        
        function results = updateChannelInfo(app,chaN)

            global globTT

            for i = 1:length(globTT.I.tab);

                globTT.I.max(i) = max(globTT.I.tab{i}(:));
                hRange = [0:globTT.I.max(i)];
                clear cHist
                for c = 1:3
                    vals = globTT.I.tab{i}(:,:,c,:);
                    cHist(:,c) = hist(vals(:),hRange);
                end
                globTT.I.hist{i} = cHist;
                globTT.I.hRange{i} = hRange;
            end


        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global globTT

            
            globTT.app.main = app;
            %%Try previous settings
            try load('.\toadalTweakerDat.mat');
                globTT.dir = ttDat.dir;
            catch ME
                globTT.dir.tryPath = 'C:\';
                globTT.dir.TFN = '';
                globTT.dir.saveFolder = 'C:\';
                globTT.dir.saveName = 'image_tt.png';
            end
            app.edit_folder.Value = globTT.dir.tryPath;
            app.edit_file.Value = globTT.dir.TFN;


            %% Housekeeping
            globTT.active.I = 'rawA';
            globTT.active.ID = 1;
            globTT.active.slice = 1;
            globTT.imMix.alpha = [1 1 1 1 1 1];
    
            
            %% Set default tweaks
            tabNum = 6;
            globTT.twk.top = ones(tabNum,3)*10^10;
            globTT.twk.bottom = zeros(tabNum,3);
            globTT.twk.bright = zeros(tabNum,3);
            globTT.twk.con = ones(tabNum,3);
            globTT.twk.gamma = ones(tabNum,3);
            globTT.remix.col = [1 2 3 1 2 3];
            globTT.remix.ab = [0 0 0 1 1 1];
            
            
            %% lookups
            globTT.lu.flipAB = [4 5 6 1 2 3];
            globTT.lu.flipRF = [2 1 2 5 4 5];
            globTT.lu.filt = [2 2 2 5 5 5];
            globTT.lu.xrgb = {'R' 'G' 'B' 'X'};
            

            %%Format
            axis(app.rawAAxes,'off');
            axis(app.filtAAxes,'off');
            axis(app.rawBAxes,'off');
            axis(app.filtBAxes,'off');
            axis(app.maxAAxes,'off');
            axis(app.maxBAxes,'off');
            set(app.rawAAxes,'box','off');


            %% Make image mixer
            globTT.app.imMix = tt_imageMixer;
            globTT.app.imMix.UIFigure.Visible = 'off'
            
            %% Make image saver
            globTT.app.saveImage = tt_saveImage;
            globTT.app.saveImage.UIFigure.Visible = 'off'
        end

        % Button pushed function: ChooseFolderButton
        function chooseFolder(app, event)
            global globTT
            if exist(globTT.dir.tryPath,'dir')
                TPN =  uigetdir(globTT.dir.tryPath,'choose folder');
            else
                TPN =  uigetdir('C:\','choose folder');
            end
            if exist(TPN,'dir')
                TPN = [TPN '\'];
                globTT.dir.TPN = TPN;
                app.edit_folder.Value = TPN;
                globTT.dir.tryPath = TPN;
                ttDat.dir = globTT.dir;
                save('.\toadalTweakerDat.mat','ttDat');
                app.textOut.Value = ' ';
            else
                app.textOut.Value = 'Folder does not exist'
            end
        end

        % Button pushed function: ChooseFileButton
        function chooseFile(app, event)
            global globTT
            if exist(globTT.dir.tryPath,'dir')
                [TFN TPN] =  uigetfile([globTT.dir.tryPath '\*'],'choose file');
            else
                [TFN TPN] =  uigetfile('C:\','choose file');
            end
            if ~isempty(TFN)
                globTT.dir.TPN = TPN;
                globTT.dir.TFN = TFN;
                app.edit_folder.Value = TPN;
                app.edit_file.Value = TFN;
                globTT.dir.tryPath = TPN;
                ttDat.dir = globTT.dir;
                save('.\toadalTweakerDat.mat','ttDat');
                app.textOut.Value = ' ';

            else
                app.textOut.Value = 'File does not exist'
            end
        end

        % Button pushed function: InfoButton
        function getFileInfo(app, event)
            global globTT
            fileName = [globTT.dir.TPN globTT.dir.TFN];
            globTT.info.fInfo = parseFileInfo(app,fileName);
            app.list_imageInfo.Items =  globTT.info.fInfo.textInfo;
            app.mainTab.SelectedTab = app.InfoTab;


        end

        % Button pushed function: ReadButton
        function readOif(app, event)
            global globTT
            app.textOut.Value = sprintf('Reading oif %s', globTT.dir.TPN);
            pause(.01)
            tt_oif2Stack_MultiChan(app,globTT.dir.TPN)
            app.textOut.Value = sprintf('Finished reading oif %s', globTT.dir.TPN);
            pause(.01)
            updateSlider(app)
            globTT.active.ax = app.rawAAxes;
            globTT.twk.top = repmat(globTT.I.max',[1 3]);

            showImage(app)
            showHist(app)
        end

        % Value changed function: toggle_shouldWrite
        function toggle_shouldWrite_callback(app, event)
            value = app.toggle_shouldWrite.Value;
            %app.oif.shouldWrite = value;
        end

        % Button down function: RawATab
        function pickRawA(app, event)
            pickTab(app,1)
        end

        % Button down function: FiltATab
        function pickFiltA(app, event)
            global globTT
            pickTab(app,2)
        end

        % Value changing function: zSlider
        function zSliderChanging(app, event)
            global globTT
            changingValue = round(event.Value);
            
            if 1 %changingValue ~= globTT.active.slice;
                globTT.active.slice = changingValue;
                showImage(app)
            end

        end

        % Button down function: RawBTab
        function pickRawB(app, event)
            pickTab(app,4)
        end

        % Callback function
        function rawAAxesButtonDown(app, event)
            'hi'
        end

        % Button down function: FiltBTab
        function pickFiltB(app, event)
            pickTab(app,5)
        end

        % Button down function: MaxBTab
        function pickMaxB(app, event)
            pickTab(app,6)
        end

        % Button down function: MaxATab
        function pickMaxA(app, event)
            pickTab(app,3)
        end

        % Value changing function: slideTopCon
        function conTopSlide(app, event)
            global globTT
            id = globTT.active.ID;
            chan = find([app.twkR.Value app.twkG.Value app.twkB.Value]);
            changingValue = event.Value;
            globTT.twk.top(id,chan) = changingValue * globTT.I.max(id);
            globTT.twk.con(id,chan) =  globTT.I.max(id)./(globTT.twk.top(id,chan) - globTT.twk.bottom(id,chan));
            showImage(app)
            showHist(app)

        end

        % Value changing function: slideBottomCon
        function conBottomSlide(app, event)
            changingValue = event.Value;
            changingValue = changingValue^2; %to give finer controll at the bottom values

            global globTT
            id = globTT.active.ID;
            chan = find([app.twkR.Value app.twkG.Value app.twkB.Value]);
            globTT.twk.bottom(id,chan) = changingValue * globTT.I.max(id);
            globTT.twk.con(id,chan) =  globTT.I.max(id)./(globTT.twk.top(id,chan) - globTT.twk.bottom(id,chan));
            globTT.twk.bright(id,chan) = 0 - globTT.twk.bottom(id,chan);
            showImage(app)
            showHist(app)
        end

        % Value changing function: slideGamma
        function gammaSlide(app, event)
            changingValue = event.Value;
            gamma = changingValue*2;

            global globTT
            id = globTT.active.ID;
            chan = find([app.twkR.Value app.twkG.Value app.twkB.Value]);
            globTT.twk.gamma(id,chan) = gamma;
            app.edit_gamma.Value = gamma;
            pause(.0001)
            showImage(app)
            showHist(app)

        end

        % Value changed function: edit_gamma
        function edit_gammaFunction(app, event)
            global globTT
            value = app.edit_gamma.Value;
            id = globTT.active.ID;
            chan = find([app.twkR.Value app.twkG.Value app.twkB.Value]);
            globTT.twk.gamma(id,chan) = value;
            limits = app.slideGamma;
            app.slideGamma.Value = value/2;
            showImage(app)
            showHist(app)

        end

        % Button pushed function: AutoConButton
        function autoCon(app, event)

            tt_AutoConPlusGammaRunning(app)

            showImage(app)
            showHist(app)
        end

        % Button pushed function: FlipABButton
        function flipAB(app, event)
            global globTT
            id = globTT.active.ID;
            id = globTT.lu.flipAB(id);
            app.mainTab.SelectedTab = app.mainTab.Children(id);
            pickTab(app,id)
            
        end

        % Button pushed function: MedianButton
        function medianFunc(app, event)
            global globTT

            app.textOut.Value = 'Running median filter';
            kSize = [app.medFiltY.Value app.medFiltX.Value app.medFiltZ.Value];
            tt_filtStack(app,'median',kSize)
            app.textOut.Value = 'Finished median filter';
           
            showImage(app)

        end

        % Button pushed function: FlipRFButton
        function flipRawFilt(app, event)
             global globTT
            id = globTT.active.ID;
            id = globTT.lu.flipRF(id);
            app.mainTab.SelectedTab = app.mainTab.Children(id);
            pickTab(app,id)
        end

        % Button pushed function: GaussianButton
        function gaussFilt(app, event)
             global globTT

            app.textOut.Value = 'Running gaussian filter';
            kSize = [app.gaussSig.Value 0 app.gauss3DButton.Value];
            tt_filtStack(app,'gaussian',kSize)
            app.textOut.Value = 'Finished gaussian filter';
           
            showImage(app)
        end

        % Button pushed function: MexHatButton
        function mexHatFunction(app, event)
            global globTT

            app.textOut.Value = 'Running Mexican hat filter';
            kSize = [app.mexHatSig1.Value app.mexHatSig2.Value app.mexHat3DButton.Value];
            tt_filtStack(app,'mexhat',kSize)
            app.textOut.Value = 'Finished Mexican hat filter';
           
            showImage(app)
        end

        % Value changed function: MixImagesButton
        function mixImages(app, event)
            global globTT
            value = app.MixImagesButton.Value;
            
            try 0;%globTT.app.imMix.UIFigure.Visible = 'off';
            catch ME
                globTT.app.imMix = tt_imageMixer;
            end

            if value
                globTT.app.imMix.UIFigure.Visible = 'on'
            end
        end

        % Button pushed function: SaveButton
        function saveImage(app, event)
            global globTT
            try 
                a  = globTT.app.saveImage.Visible;
            catch ME
                globTT.app.saveImage = tt_saveImage;
            end
            globTT.app.saveImage.UIFigure.Visible = 'on';
            globTT.save.tab = globTT.active.ID;

        end

        % Value changed function: rmAB1
        function rmAB1f(app, event)
            global globTT
            value = app.rmAB1.Value;
            if value
                app.rmAB1.Text = 'B';               
                globTT.remix.ab(1) = 1;
            else
                app.rmAB1.Text = 'A';               
                globTT.remix.ab(1) = 0;
            end  
            pause(.01)
        end

        % Value changed function: rmAB2
        function rmAB2f(app, event)
            global globTT
            value = app.rmAB2.Value;
            if value
                app.rmAB2.Text = 'B';
                globTT.remix.ab(2) = 1;
            else
                app.rmAB2.Text = 'A';                
                globTT.remix.ab(2) = 0;

            end  
        end

        % Value changed function: rmAB3
        function rmAB3f(app, event)
            global globTT
            value = app.rmAB3.Value;
           if value
                app.rmAB3.Text = 'B';               
                globTT.remix.ab(3) = 1;
            else
                app.rmAB3.Text = 'A';               
                globTT.remix.ab(3) = 0;
            end  
        end

        % Value changed function: rmAB4
        function rmAB4f(app, event)
            global globTT
            value = app.rmAB4.Value;
            if value
                app.rmAB4.Text = 'B';               
                globTT.remix.ab(4) = 1;
            else
                app.rmAB4.Text = 'A';               
                globTT.remix.ab(4)= 0;
            end  
        end

        % Value changed function: rmAB5
        function rmAB5f(app, event)
            global globTT
            value = app.rmAB5.Value;
            if value
                app.rmAB5.Text = 'B';               
                globTT.remix.ab(5) = 1;
            else
                app.rmAB5.Text = 'A';               
                globTT.remix.ab(5) = 0;
            end  
        end

        % Button pushed function: rmT1
        function rmT1f(app, event)
            global globTT
            globTT.remix.col(1) = mod(globTT.remix.col(1) + 1,4);
            app.rmC1.Value = globTT.lu.xrgb{globTT.remix.col(1)+1};
        end

        % Button pushed function: rmT2
        function rmT2f(app, event)
             global globTT
            globTT.remix.col(2) = mod(globTT.remix.col(2) + 1,4);
            app.rmC2.Value = globTT.lu.xrgb{globTT.remix.col(2)+1};
        end

        % Button pushed function: rmT3
        function rmT3f(app, event)
             global globTT
            globTT.remix.col(3) = mod(globTT.remix.col(3) + 1,4);
            app.rmC3.Value = globTT.lu.xrgb{globTT.remix.col(3)+1};
        end

        % Button pushed function: rmT4
        function rmT4f(app, event)
             global globTT
            globTT.remix.col(4) = mod(globTT.remix.col(4) + 1,4);
            app.rmC4.Value = globTT.lu.xrgb{globTT.remix.col(4)+1};
        end

        % Button pushed function: rmT5
        function rmT5f(app, event)
             global globTT
            globTT.remix.col(5) = mod(globTT.remix.col(5) + 1,4);
            app.rmC5.Value = globTT.lu.xrgb{globTT.remix.col(5)+1};
        end

        % Button pushed function: rmT6
        function rmT6f(app, event)
             global globTT
            globTT.remix.col(6) = mod(globTT.remix.col(6) + 1,4);
            app.rmC6.Value = globTT.lu.xrgb{globTT.remix.col(6)+1};
        end

        % Value changed function: rmAB6
        function rmAB6f(app, event)
             global globTT
            value = app.rmAB6.Value;
            if value
                app.rmAB6.Text = 'B';               
                globTT.remix.ab(6) = 1;
            else
                app.rmAB6.Text = 'A';               
                globTT.remix.ab(6) = 0;
            end  
        end

        % Button pushed function: RemixButton
        function remix(app, event)
           tic
            global globTT
            toc
            app.textOut.Value = 'remixing channels';
            toCol = globTT.remix.col + 3 * globTT.remix.ab;
            isEasy = sum(unique(toCol)>0) == 6;
            for t = 1:3
            for i = 1:size(globTT.I.tab{t},4);
                Io = cat(3,globTT.I.tab{t}(:,:,:,i),globTT.I.tab{t+3}(:,:,:,i));
                In = Io * 0;
                if isEasy
                    In(:,:,toCol,:) = Io;
                else
                   for c = 1:length(toCol)
                       In(:,:,toCol(c),:) =  In(:,:,toCol(c),:) + Io(:,:,c,:);
                   end
                end
                globTT.I.tab{t}(:,:,:,i) = In(:,:,1:3,:);
                globTT.I.tab{t+3}(:,:,:,i) = In(:,:,4:6,:);
            end
            end
            
            showImage(app)
            app.textOut.Value = 'finished remixing channels';

        end

        % Button pushed function: ApplytofileButton
        function applyToFileFunc(app, event)
            global globTT
            id = globTT.active.ID;

            I = globTT.I.tab{id};
            for c = 1:3

                F = I(:,:,c,:);
                F = F + globTT.twk.bright(id,c);
                F = F * globTT.twk.con(id,c);
                F(F<0) = 0;
                maxA = max(F(:));
                conA =  maxA/(maxA ^ globTT.twk.gamma(id,c));

                F = F.^globTT.twk.gamma(id,c) * conA; %gamma presserving max value.
                I(:,:,c,:) = F;
            end
            globTT.I.tab{id} = I;
            
            globTT.twk.top = ones(id,3)*10^10;
            globTT.twk.bottom = zeros(id,3);
            globTT.twk.bright = zeros(id,3);
            globTT.twk.con = ones(id,3);
            globTT.twk.gamma = ones(id,3);
            
            
        end

        % Button pushed function: ResetButton
        function resetF(app, event)
            global globTT
            id = globTT.active.ID;
            idF = globTT.lu.filt(id);
            idR = idF - 1;
            globTT.I.tab{idF} = globTT.I.tab{idR};
            showImage(app)
        end

        % Value changed function: ViewAllButton
        function viewAllF(app, event)
            value = app.ViewAllButton.Value;
            showImage(app)
        end

        % Value changed function: twkG
        function twkGf(app, event)
            value = app.twkG.Value;
            showImage(app)

        end

        % Value changed function: twkR
        function twkRf(app, event)
            value = app.twkR.Value;
            showImage(app)
        end

        % Value changed function: twkB
        function twkBf(app, event)
            value = app.twkB.Value;
            showImage(app)
        end

        % Button pushed function: MixnewchannelButton
        function openMixNewChannel(app, event)
            mixNewChannel
        end

        % Button pushed function: ResampleButton
        function openResampleWindow(app, event)
            tt_resample
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ChooseFolderButton = uibutton(app.UIFigure, 'push');
            app.ChooseFolderButton.ButtonPushedFcn = createCallbackFcn(app, @chooseFolder, true);
            app.ChooseFolderButton.Text = 'Choose Folder';
            app.ChooseFileButton = uibutton(app.UIFigure, 'push');
            app.ChooseFileButton.ButtonPushedFcn = createCallbackFcn(app, @chooseFile, true);
            app.ChooseFileButton.Text = 'Choose File';
            app.zSlider = uislider(app.UIFigure);
            app.zSlider.ValueChangingFcn = createCallbackFcn(app, @zSliderChanging, true);
            app.mainTab = uitabgroup(app.UIFigure);
            app.RawATab = uitab(app.mainTab);
            app.RawATab.Title = 'Raw A';
            app.RawATab.ButtonDownFcn = createCallbackFcn(app, @pickRawA, true);
            app.rawAAxes = uiaxes(app.RawATab);
            xlabel(app.rawAAxes, 'X')
            ylabel(app.rawAAxes, 'Y')
            zlabel(app.rawAAxes, 'Z')
            app.FiltATab = uitab(app.mainTab);
            app.FiltATab.Title = 'Filt A';
            app.FiltATab.ButtonDownFcn = createCallbackFcn(app, @pickFiltA, true);
            app.filtAAxes = uiaxes(app.FiltATab);
            xlabel(app.filtAAxes, 'X')
            ylabel(app.filtAAxes, 'Y')
            zlabel(app.filtAAxes, 'Z')
            app.MaxATab = uitab(app.mainTab);
            app.MaxATab.Title = 'Max A';
            app.MaxATab.ButtonDownFcn = createCallbackFcn(app, @pickMaxA, true);
            app.maxAAxes = uiaxes(app.MaxATab);
            xlabel(app.maxAAxes, 'X')
            ylabel(app.maxAAxes, 'Y')
            zlabel(app.maxAAxes, 'Z')
            app.RawBTab = uitab(app.mainTab);
            app.RawBTab.Title = 'Raw B';
            app.RawBTab.ButtonDownFcn = createCallbackFcn(app, @pickRawB, true);
            app.rawBAxes = uiaxes(app.RawBTab);
            xlabel(app.rawBAxes, 'X')
            ylabel(app.rawBAxes, 'Y')
            zlabel(app.rawBAxes, 'Z')
            app.FiltBTab = uitab(app.mainTab);
            app.FiltBTab.Title = 'Filt B';
            app.FiltBTab.ButtonDownFcn = createCallbackFcn(app, @pickFiltB, true);
            app.filtBAxes = uiaxes(app.FiltBTab);
            xlabel(app.filtBAxes, 'X')
            ylabel(app.filtBAxes, 'Y')
            zlabel(app.filtBAxes, 'Z')
            app.MaxBTab = uitab(app.mainTab);
            app.MaxBTab.Title = 'Max B';
            app.MaxBTab.ButtonDownFcn = createCallbackFcn(app, @pickMaxB, true);
            app.maxBAxes = uiaxes(app.MaxBTab);
            xlabel(app.maxBAxes, 'X')
            ylabel(app.maxBAxes, 'Y')
            zlabel(app.maxBAxes, 'Z')
            app.InfoTab = uitab(app.mainTab);
            app.InfoTab.Title = 'Info';
            app.list_imageInfo = uilistbox(app.InfoTab);
            app.OifReadPanel = uipanel(app.UIFigure);
            app.OifReadPanel.Title = 'Oif Read';
            app.ReadButton = uibutton(app.OifReadPanel, 'push');
            app.ReadButton.ButtonPushedFcn = createCallbackFcn(app, @readOif, true);
            app.ReadButton.Text = 'Read';
            app.edit_chanA1 = uieditfield(app.OifReadPanel, 'numeric');
            app.edit_chanA1.Value = 1;
            app.edit_chanA2 = uieditfield(app.OifReadPanel, 'numeric');
            app.edit_chanA2.Value = 2;
            app.edit_chanA3 = uieditfield(app.OifReadPanel, 'numeric');
            app.edit_chanA3.Value = 3;
            app.edit_chanB1 = uieditfield(app.OifReadPanel, 'numeric');
            app.edit_chanB1.Value = 4;
            app.edit_chanB2 = uieditfield(app.OifReadPanel, 'numeric');
            app.edit_chanB2.Value = 5;
            app.edit_chanB3 = uieditfield(app.OifReadPanel, 'numeric');
            app.edit_chanB3.Value = 6;
            app.ALabel = uilabel(app.OifReadPanel);
            app.ALabel.Text = 'A';
            app.BLabel = uilabel(app.OifReadPanel);
            app.BLabel.Text = 'B';
            app.toggle_shouldWrite = uibutton(app.OifReadPanel, 'state');
            app.toggle_shouldWrite.ValueChangedFcn = createCallbackFcn(app, @toggle_shouldWrite_callback, true);
            app.toggle_shouldWrite.Text = 'Write';
            app.toggle_shouldWrite_2 = uibutton(app.OifReadPanel, 'state');
            app.toggle_shouldWrite_2.Text = 'Filter';
            app.toggle_shouldWrite_2.Value = true;
            app.toggle_shouldWrite_3 = uibutton(app.OifReadPanel, 'state');
            app.toggle_shouldWrite_3.Text = 'Con';
            app.toggle_shouldWrite_3.Value = true;
            app.ChannelMixingLabel = uilabel(app.OifReadPanel);
            app.ChannelMixingLabel.Text = 'Channel Mixing';
            app.InfoButton = uibutton(app.OifReadPanel, 'push');
            app.InfoButton.ButtonPushedFcn = createCallbackFcn(app, @getFileInfo, true);
            app.InfoButton.Text = 'Info';
            app.z1Button = uibutton(app.OifReadPanel, 'state');
            app.z1Button.Text = 'z=1';
            app.edit_folder = uieditfield(app.UIFigure, 'text');
            app.edit_file = uieditfield(app.UIFigure, 'text');
            app.textOut = uitextarea(app.UIFigure);
            app.TweakPanel = uipanel(app.UIFigure);
            app.TweakPanel.Title = 'Tweak';
            app.histAx = uiaxes(app.TweakPanel);
            app.histAx.YTick = [];
            app.slideBottomCon = uislider(app.TweakPanel);
            app.slideBottomCon.Limits = [0 1];
            app.slideBottomCon.MajorTicks = [];
            app.slideBottomCon.ValueChangingFcn = createCallbackFcn(app, @conBottomSlide, true);
            app.slideBottomCon.MinorTicks = [];
            app.slideTopCon = uislider(app.TweakPanel);
            app.slideTopCon.Limits = [0 1];
            app.slideTopCon.MajorTicks = [];
            app.slideTopCon.ValueChangingFcn = createCallbackFcn(app, @conTopSlide, true);
            app.slideTopCon.MinorTicks = [];
            app.slideTopCon.Value = 1;
            app.slideGamma = uislider(app.TweakPanel);
            app.slideGamma.Limits = [0 1];
            app.slideGamma.MajorTicks = [];
            app.slideGamma.ValueChangingFcn = createCallbackFcn(app, @gammaSlide, true);
            app.slideGamma.MinorTicks = [];
            app.slideGamma.Value = 1;
            app.edit_gamma = uieditfield(app.TweakPanel, 'numeric');
            app.edit_gamma.ValueDisplayFormat = '%1.02f';
            app.edit_gamma.ValueChangedFcn = createCallbackFcn(app, @edit_gammaFunction, true);
            app.edit_gamma.Value = 1;
            app.GLabel = uilabel(app.TweakPanel);
            app.GLabel.Text = 'G';
            app.ApplytofileButton = uibutton(app.TweakPanel, 'push');
            app.ApplytofileButton.ButtonPushedFcn = createCallbackFcn(app, @applyToFileFunc, true);
            app.ApplytofileButton.Text = 'Apply to file';
            app.FilterPanel = uipanel(app.UIFigure);
            app.FilterPanel.Title = 'Filter';
            app.medFiltY = uieditfield(app.FilterPanel, 'numeric');
            app.medFiltY.Value = 3;
            app.medFiltX = uieditfield(app.FilterPanel, 'numeric');
            app.medFiltX.Value = 3;
            app.YXZLabel = uilabel(app.FilterPanel);
            app.YXZLabel.Text = 'YXZ ';
            app.MedianButton = uibutton(app.FilterPanel, 'push');
            app.MedianButton.ButtonPushedFcn = createCallbackFcn(app, @medianFunc, true);
            app.MedianButton.Text = 'Median';
            app.medFiltZ = uieditfield(app.FilterPanel, 'numeric');
            app.medFiltZ.Value = 1;
            app.AutoConButton = uibutton(app.FilterPanel, 'push');
            app.AutoConButton.ButtonPushedFcn = createCallbackFcn(app, @autoCon, true);
            app.AutoConButton.Text = 'Auto Con';
            app.edit_autoConBin = uieditfield(app.FilterPanel, 'numeric');
            app.BinrollingZLabel = uilabel(app.FilterPanel);
            app.BinrollingZLabel.Text = 'Bin rolling Z';
            app.gaussSig = uieditfield(app.FilterPanel, 'numeric');
            app.gaussSig.Value = 3;
            app.GaussianButton = uibutton(app.FilterPanel, 'push');
            app.GaussianButton.ButtonPushedFcn = createCallbackFcn(app, @gaussFilt, true);
            app.GaussianButton.Text = 'Gaussian';
            app.gauss3DButton = uibutton(app.FilterPanel, 'state');
            app.gauss3DButton.Text = '3D';
            app.mexHatSig1 = uieditfield(app.FilterPanel, 'numeric');
            app.mexHatSig1.Value = 3;
            app.MexHatButton = uibutton(app.FilterPanel, 'push');
            app.MexHatButton.ButtonPushedFcn = createCallbackFcn(app, @mexHatFunction, true);
            app.MexHatButton.Text = 'Mex Hat';
            app.mexHat3DButton = uibutton(app.FilterPanel, 'state');
            app.mexHat3DButton.Text = '3D';
            app.mexHatSig2 = uieditfield(app.FilterPanel, 'numeric');
            app.mexHatSig2.Value = 20;
            app.SigLabel = uilabel(app.FilterPanel);
            app.SigLabel.Text = 'Sig';
            app.SigLabel_2 = uilabel(app.FilterPanel);
            app.SigLabel_2.Text = 'Sig';
            app.ResetButton = uibutton(app.FilterPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @resetF, true);
            app.ResetButton.Text = 'Reset';
            app.ResampleButton = uibutton(app.FilterPanel, 'push');
            app.ResampleButton.ButtonPushedFcn = createCallbackFcn(app, @openResampleWindow, true);
            app.ResampleButton.Text = 'Resample';
            app.FlipABButton = uibutton(app.UIFigure, 'push');
            app.FlipABButton.ButtonPushedFcn = createCallbackFcn(app, @flipAB, true);
            app.FlipABButton.Text = 'Flip A/B';
            app.twkG = uibutton(app.UIFigure, 'state');
            app.twkG.ValueChangedFcn = createCallbackFcn(app, @twkGf, true);
            app.twkG.Text = 'G';
            app.twkG.Value = true;
            app.twkB = uibutton(app.UIFigure, 'state');
            app.twkB.ValueChangedFcn = createCallbackFcn(app, @twkBf, true);
            app.twkB.Text = 'B';
            app.twkB.Value = true;
            app.twkR = uibutton(app.UIFigure, 'state');
            app.twkR.ValueChangedFcn = createCallbackFcn(app, @twkRf, true);
            app.twkR.Text = 'R';
            app.twkR.Value = true;
            app.FlipRFButton = uibutton(app.UIFigure, 'push');
            app.FlipRFButton.ButtonPushedFcn = createCallbackFcn(app, @flipRawFilt, true);
            app.FlipRFButton.Text = 'Flip R/F';
            app.SendchanneltocolorPanel = uipanel(app.UIFigure);
            app.SendchanneltocolorPanel.Title = 'Send channel to color';
            app.rmT1 = uibutton(app.SendchanneltocolorPanel, 'push');
            app.rmT1.ButtonPushedFcn = createCallbackFcn(app, @rmT1f, true);
            app.rmT1.Text = '>';
            app.rmT2 = uibutton(app.SendchanneltocolorPanel, 'push');
            app.rmT2.ButtonPushedFcn = createCallbackFcn(app, @rmT2f, true);
            app.rmT2.Text = '>';
            app.rmT3 = uibutton(app.SendchanneltocolorPanel, 'push');
            app.rmT3.ButtonPushedFcn = createCallbackFcn(app, @rmT3f, true);
            app.rmT3.Text = '>';
            app.rmT4 = uibutton(app.SendchanneltocolorPanel, 'push');
            app.rmT4.ButtonPushedFcn = createCallbackFcn(app, @rmT4f, true);
            app.rmT4.Text = '>';
            app.rmT5 = uibutton(app.SendchanneltocolorPanel, 'push');
            app.rmT5.ButtonPushedFcn = createCallbackFcn(app, @rmT5f, true);
            app.rmT5.Text = '>';
            app.rmT6 = uibutton(app.SendchanneltocolorPanel, 'push');
            app.rmT6.ButtonPushedFcn = createCallbackFcn(app, @rmT6f, true);
            app.rmT6.Text = '>';
            app.rmAB1 = uibutton(app.SendchanneltocolorPanel, 'state');
            app.rmAB1.ValueChangedFcn = createCallbackFcn(app, @rmAB1f, true);
            app.rmAB1.Text = 'A';
            app.rmAB2 = uibutton(app.SendchanneltocolorPanel, 'state');
            app.rmAB2.ValueChangedFcn = createCallbackFcn(app, @rmAB2f, true);
            app.rmAB2.Text = 'A';
            app.rmAB3 = uibutton(app.SendchanneltocolorPanel, 'state');
            app.rmAB3.ValueChangedFcn = createCallbackFcn(app, @rmAB3f, true);
            app.rmAB3.Text = 'A';
            app.rmAB4 = uibutton(app.SendchanneltocolorPanel, 'state');
            app.rmAB4.ValueChangedFcn = createCallbackFcn(app, @rmAB4f, true);
            app.rmAB4.Text = {'B'; ''};
            app.rmAB4.Value = true;
            app.rmAB5 = uibutton(app.SendchanneltocolorPanel, 'state');
            app.rmAB5.ValueChangedFcn = createCallbackFcn(app, @rmAB5f, true);
            app.rmAB5.Text = 'B';
            app.rmAB5.Value = true;
            app.rmAB6 = uibutton(app.SendchanneltocolorPanel, 'state');
            app.rmAB6.ValueChangedFcn = createCallbackFcn(app, @rmAB6f, true);
            app.rmAB6.Text = 'B';
            app.rmAB6.Value = true;
            app.RemixButton = uibutton(app.SendchanneltocolorPanel, 'push');
            app.RemixButton.ButtonPushedFcn = createCallbackFcn(app, @remix, true);
            app.RemixButton.Text = 'Remix';
            app.rmC1 = uieditfield(app.SendchanneltocolorPanel, 'text');
            app.rmC1.Value = 'R';
            app.rmC2 = uieditfield(app.SendchanneltocolorPanel, 'text');
            app.rmC2.Value = 'G';
            app.rmC3 = uieditfield(app.SendchanneltocolorPanel, 'text');
            app.rmC3.Value = 'B';
            app.rmC4 = uieditfield(app.SendchanneltocolorPanel, 'text');
            app.rmC4.Value = 'R';
            app.rmC5 = uieditfield(app.SendchanneltocolorPanel, 'text');
            app.rmC5.Value = 'G';
            app.rmC6 = uieditfield(app.SendchanneltocolorPanel, 'text');
            app.rmC6.Value = 'B';
            app.MixImagesButton = uibutton(app.UIFigure, 'state');
            app.MixImagesButton.ValueChangedFcn = createCallbackFcn(app, @mixImages, true);
            app.MixImagesButton.Text = 'Mix Images';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @saveImage, true);
            app.SaveButton.Text = 'Save';
            app.ViewAllButton = uibutton(app.UIFigure, 'state');
            app.ViewAllButton.ValueChangedFcn = createCallbackFcn(app, @viewAllF, true);
            app.ViewAllButton.Text = 'View All';
            app.MixnewchannelButton = uibutton(app.UIFigure, 'push');
            app.MixnewchannelButton.ButtonPushedFcn = createCallbackFcn(app, @openMixNewChannel, true);
            app.MixnewchannelButton.Text = 'Mix new channel';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = toadalTweaker5

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end