classdef tt_imageMixer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        textOut          matlab.ui.control.EditField
        AlphaLabel       matlab.ui.control.Label
        zSlider          matlab.ui.control.Slider
        ChoosePanel      matlab.ui.container.Panel
        MaxBButton       matlab.ui.control.StateButton
        FiltBButton      matlab.ui.control.StateButton
        RawBButton       matlab.ui.control.StateButton
        MaxAButton       matlab.ui.control.StateButton
        FiltAButton      matlab.ui.control.StateButton
        RawAButton       matlab.ui.control.StateButton
        SaveButton       matlab.ui.control.Button
        alphaSlider      matlab.ui.control.Slider
        dropDownAlphaID  matlab.ui.control.DropDown
        UIAxes           matlab.ui.control.UIAxes
    end


    methods (Access = private)

        function Im = showImage(app)
            global globTT
            globTT.imMix.useID = find([app.RawAButton.Value app.FiltAButton.Value app.MaxAButton.Value ...
                app.RawBButton.Value app.FiltBButton.Value app.MaxBButton.Value]);

            try Im = zeros(size(globTT.I.tab{3}));
            catch ME, Im = []; end
            if ~isempty(Im)
                for i = 1:length(globTT.imMix.useID)
                    id = globTT.imMix.useID(i);


                    %I = eval(sprintf('globTT.I.%s(:,:,:,%d);',globTT.active.I,globTT.active.slice));
                    if size(globTT.I.tab{id},4) >= globTT.active.slice;
                        showRaw =  globTT.I.tab{id}(:,:,:,globTT.active.slice);
                    else
                        showRaw =  globTT.I.tab{id}(:,:,:,1);
                    end
                    showFilt = showRaw;
                    for c = 1:3
                        F = showFilt(:,:,c);
                        F = F + globTT.twk.bright(id,c);
                        F = F * globTT.twk.con(id,c);
                        F(F<0) = 0;
                        maxA = max(F(:));
                        conA =  maxA/(maxA ^ globTT.twk.gamma(id,c));

                        F = F.^globTT.twk.gamma(id,c) * conA; %gamma presserving max value.
                        showFilt(:,:,c) = F;
                    end
                    Im  = Im + showFilt * globTT.imMix.alpha(id);
                end

                globTT.I.imMix = Im;
                image(app.UIAxes,uint8(Im));
                pause(.001)
            else
                app.textOut.Value = 'No image'
            end
        end


        function results = updateAlphaSlider(app)
            global globTT
            app.alphaSlider.Value = globTT.imMix.alpha(globTT.imMix.alphaID);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global globTT
            app.dropDownAlphaID.Value = '3';
            globTT.imMix.alphaID = 3;
            app.alphaSlider.Value = globTT.imMix.alpha(3);
            app.zSlider.Limits = globTT.app.main.zSlider.Limits;
            showImage(app);
        end

        % Value changed function: RawAButton
        function onRawA(app, event)
            global globTT
            % globTT.imMix.alpha(1) = double(app.RawAButton.Value);
            updateAlphaSlider(app)
            showImage(app);
        end

        % Value changed function: FiltAButton
        function onFiltA(app, event)
            global globTT
            % globTT.imMix.alpha(1) = double(app.FiltAButton.Value);
            updateAlphaSlider(app)
            showImage(app);
        end

        % Value changed function: MaxAButton
        function onMaxA(app, event)
            global globTT
            %globTT.imMix.alpha(1) = double(app.MaxAButton.Value);
            updateAlphaSlider(app)
            showImage(app);
        end

        % Value changed function: RawBButton
        function onRawB(app, event)
            global globTT
            %globTT.imMix.alpha(1) = double(app.RawBButton.Value);
            updateAlphaSlider(app)
            showImage(app);
        end

        % Value changed function: FiltBButton
        function onFiltB(app, event)
            global globTT
            % globTT.imMix.alpha(1) = double(app.FiltBButton.Value);
            updateAlphaSlider(app)
            showImage(app);
        end

        % Value changed function: MaxBButton
        function onMaxB(app, event)
            global globTT
            % globTT.imMix.alpha(1) = double(app.MaxBButton.Value);
            updateAlphaSlider(app)
            showImage(app);
        end

        % Value changing function: zSlider
        function zSliderChanging(app, event)
            global globTT
            changingValue = round(event.Value);

            if 1 %changingValue ~= globTT.active.slice;
                globTT.active.slice = changingValue;
                showImage(app);
            end
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)

        end

        % Value changed function: dropDownAlphaID
        function pickAlphaID(app, event)
            global globTT
            value = str2num(app.dropDownAlphaID.Value);
            globTT.imMix.alphaID = value;
            app.alphaSlider.Value = globTT.imMix.alpha(value);

        end

        % Value changing function: alphaSlider
        function setAlpha(app, event)
            global globTT
            changingValue = event.Value;
            if 1 %changingValue ~= globTT.active.slice;
                globTT.imMix.alpha(globTT.imMix.alphaID) = changingValue;
                showImage(app);
            end
        end

        % Button pushed function: SaveButton
        function saveImage(app, event)
            global globTT

            Im = showImage(app);
            globTT.save.I = Im;
            globTT.save.tab = 0;
            globTT.save.tab
            try
                a  = globTT.app.saveImage.Visible;
            catch ME
                globTT.app.saveImage = tt_saveImage;
            end
            globTT.app.saveImage.UIFigure.Visible = 'on';

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.dropDownAlphaID = uidropdown(app.UIFigure);
            app.dropDownAlphaID.Items = {'Raw A', 'Filt A', 'Max A', 'Raw B', 'Filt B', 'Max B'};
            app.dropDownAlphaID.ItemsData = {'1', '2', '3', '4', '5', '6'};
            app.dropDownAlphaID.ValueChangedFcn = createCallbackFcn(app, @pickAlphaID, true);
            app.dropDownAlphaID.Value = '1';
            app.alphaSlider = uislider(app.UIFigure);
            app.alphaSlider.Limits = [0 1];
            app.alphaSlider.ValueChangingFcn = createCallbackFcn(app, @setAlpha, true);
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @saveImage, true);
            app.SaveButton.Text = 'Save';
            app.ChoosePanel = uipanel(app.UIFigure);
            app.ChoosePanel.Title = 'Choose';
            app.RawAButton = uibutton(app.ChoosePanel, 'state');
            app.RawAButton.ValueChangedFcn = createCallbackFcn(app, @onRawA, true);
            app.RawAButton.Text = 'Raw A';
            app.FiltAButton = uibutton(app.ChoosePanel, 'state');
            app.FiltAButton.ValueChangedFcn = createCallbackFcn(app, @onFiltA, true);
            app.FiltAButton.Text = 'Filt A';
            app.MaxAButton = uibutton(app.ChoosePanel, 'state');
            app.MaxAButton.ValueChangedFcn = createCallbackFcn(app, @onMaxA, true);
            app.MaxAButton.Text = 'Max A';
            app.MaxAButton.Value = true;
            app.RawBButton = uibutton(app.ChoosePanel, 'state');
            app.RawBButton.ValueChangedFcn = createCallbackFcn(app, @onRawB, true);
            app.RawBButton.Text = 'Raw B';
            app.FiltBButton = uibutton(app.ChoosePanel, 'state');
            app.FiltBButton.ValueChangedFcn = createCallbackFcn(app, @onFiltB, true);
            app.FiltBButton.Text = 'Filt B';
            app.MaxBButton = uibutton(app.ChoosePanel, 'state');
            app.MaxBButton.ValueChangedFcn = createCallbackFcn(app, @onMaxB, true);
            app.MaxBButton.Text = 'Max B';
            app.MaxBButton.Value = true;
            app.zSlider = uislider(app.UIFigure);
            app.zSlider.ValueChangingFcn = createCallbackFcn(app, @zSliderChanging, true);
            app.AlphaLabel = uilabel(app.UIFigure);
            app.AlphaLabel.Text = 'Alpha';
            app.textOut = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = tt_imageMixer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end