classdef tt_resample < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        textOut                    matlab.ui.control.EditField
        Easydownsampleby05Button   matlab.ui.control.Button
        ResampletypeDropDown       matlab.ui.control.DropDown
        ResampletypeDropDownLabel  matlab.ui.control.Label
        ZfactorEditField           matlab.ui.control.NumericEditField
        ZfactorEditField_2Label    matlab.ui.control.Label
        YfactorEditField           matlab.ui.control.NumericEditField
        YfactorEditFieldLabel      matlab.ui.control.Label
        XfactorEditField           matlab.ui.control.NumericEditField
        XfactorEditFieldLabel      matlab.ui.control.Label
        RunButton                  matlab.ui.control.Button
    end


    methods (Access = public)

        function results = runResample(app)

            global globTT globRS

            app.textOut.Value = 'downsampling into filtered tab';drawnow

            t = (globTT.active.ID<3)*3+1;
            [ys xs cs zs] = size(globTT.I.tab{t});
            nys = round(ys * globRS.Yf);
            nxs = round(xs * globRS.Xf);
            nzs = round(zs * globRS.Zf);
            if globRS.Zf == 1

                In = zeros(nys,nxs,3,nzs);

                for i = 1:size(globTT.I.tab{t},4);
                    app.textOut.Value = sprintf('resampling plane %d of %d',i,size(globTT.I.tab{t},4));
                    drawnow
                    Io = globTT.I.tab{t}(:,:,:,i);
                    targT = t+1;


                    Irs = imresize(Io,[nys nxs],globRS.rsType);
                    In(:,:,:,i) = Irs;
                end
                    globTT.I.tab{targT} = In;


            else %3D resample
                for i = 1:3
                    app.textOut.Value = sprintf('resampling plane %d of %d',i,size(globTT.I.tab{t},4));
                    drawnow
                    Io = squeeze(globTT.I.tab{t}(:,:,i,:));
                    targT = t+1;


                    if strcmp(globRS.rsType,'bicubic')
                        meth = 'cubic';
                    else
                        meth = globRS.rsType;
                    end
                    Irs = imresize3(Io,[nys nxs nzs],'method',meth);
                    globTT.I.tab{targT}(:,:,i,:) = Irs;
                end

            end

            %showImage(app)
            globTT.app.main.updateChannelInfo(globTT.app)
            app.textOut.Value = 'finished remixing channels';


        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global globRS
            globRS.Yf = .5;
            globRS.Xf = .5;
            globRS.Zf = 1;
            globRS.rsType = 'bicubic';
        end

        % Button pushed function: RunButton
        function runButton(app, event)

            global globRS

            globRS.Yf = app.YfactorEditField.Value;
            globRS.Xf = app.XfactorEditField.Value;
            globRS.Zf = app.ZfactorEditField.Value;
            globRS.rsType = app.ResampletypeDropDown.Value;

            runResample(app)

        end

        % Button pushed function: Easydownsampleby05Button
        function easyDownsample(app, event)
            global globRS
            globRS.Yf = .5;
            globRS.Xf = .5;
            globRS.Zf = 1;
            globRS.rsType = 'bicubic';

            runResample(app)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @runButton, true);
            app.RunButton.Text = 'Run';
            app.XfactorEditFieldLabel = uilabel(app.UIFigure);
            app.XfactorEditFieldLabel.Text = 'X-factor';
            app.XfactorEditField = uieditfield(app.UIFigure, 'numeric');
            app.XfactorEditField.Value = 1;
            app.YfactorEditFieldLabel = uilabel(app.UIFigure);
            app.YfactorEditFieldLabel.Text = 'Y-factor';
            app.YfactorEditField = uieditfield(app.UIFigure, 'numeric');
            app.YfactorEditField.Value = 1;
            app.ZfactorEditField_2Label = uilabel(app.UIFigure);
            app.ZfactorEditField_2Label.Text = 'Z-factor';
            app.ZfactorEditField = uieditfield(app.UIFigure, 'numeric');
            app.ZfactorEditField.Value = 1;
            app.ResampletypeDropDownLabel = uilabel(app.UIFigure);
            app.ResampletypeDropDownLabel.Text = 'Resample type';
            app.ResampletypeDropDown = uidropdown(app.UIFigure);
            app.ResampletypeDropDown.Items = {'nearest', 'bilinear', 'bicubic'};
            app.ResampletypeDropDown.Value = 'bicubic';
            app.Easydownsampleby05Button = uibutton(app.UIFigure, 'push');
            app.Easydownsampleby05Button.ButtonPushedFcn = createCallbackFcn(app, @easyDownsample, true);
            app.Easydownsampleby05Button.Text = 'Easy downsample by 0.5';
            app.textOut = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = tt_resample

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end