classdef tt_saveImage < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        HideButton          matlab.ui.control.Button
        textOut             matlab.ui.control.EditField
        SavePlaneButton     matlab.ui.control.Button
        addTime             matlab.ui.control.StateButton
        SaveStackButton     matlab.ui.control.Button
        NameFileLabel       matlab.ui.control.Label
        edit_file           matlab.ui.control.EditField
        edit_folder         matlab.ui.control.EditField
        ChooseFolderButton  matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global globTT
            globTT.save.overWrite = 0;
            app.addTime.Value = 1;
            app.edit_folder.Value = globTT.dir.saveFolder;
            app.edit_file.Value = globTT.dir.saveName;
            
        end

        % Button pushed function: ChooseFolderButton
        function chooseDir(app, event)
            global globTT
            SPN = uigetdir(globTT.dir.saveFolder);
            if exist(SPN,'dir')
                SPN = [SPN '\'];
                app.edit_folder.Value = SPN;
                globTT.dir.saveFolder = SPN;
                ttDat.dir = globTT.dir;
                save('.\toadalTweakerDat.mat','ttDat');
            end
             
            
        end

        % Value changed function: edit_file
        function saveName(app, event)
            global globTT
            globTT.dir.saveName = app.edit_file.Value;
            ttDat.dir = globTT.dir;
            save('.\toadalTweakerDat.mat','ttDat');
        end

        % Button pushed function: SavePlaneButton
        function savePlane(app, event)
            app.textOut.Value = 'Saving 2D image...';
            pause(.01)
            global globTT
            globTT.save.doStack = 0;
            tt_saveImageFunction
            app.textOut.Value = 'Image saved';

        end

        % Button pushed function: SaveStackButton
        function saveStack(app, event)
            app.textOut.Value = 'Saving stack...';
            pause(.01)
             global globTT
            globTT.save.doStack = 1;
            tt_saveImageFunction
            app.textOut.Value = 'Stack saved';
        end

        % Button pushed function: HideButton
        function hide(app, event)
            app.UIFigure.Visible  = 'off';
        end

        % Value changed function: addTime
        function toggleOverwrite(app, event)
            global globTT
            value = app.addTime.Value;
            globTT.save.overWrite = ~value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ChooseFolderButton = uibutton(app.UIFigure, 'push');
            app.ChooseFolderButton.ButtonPushedFcn = createCallbackFcn(app, @chooseDir, true);
            app.ChooseFolderButton.Text = 'Choose Folder';
            app.edit_folder = uieditfield(app.UIFigure, 'text');
            app.edit_file = uieditfield(app.UIFigure, 'text');
            app.edit_file.ValueChangedFcn = createCallbackFcn(app, @saveName, true);
            app.NameFileLabel = uilabel(app.UIFigure);
            app.NameFileLabel.Text = 'Name File';
            app.SaveStackButton = uibutton(app.UIFigure, 'push');
            app.SaveStackButton.ButtonPushedFcn = createCallbackFcn(app, @saveStack, true);
            app.SaveStackButton.Text = 'Save Stack';
            app.addTime = uibutton(app.UIFigure, 'state');
            app.addTime.ValueChangedFcn = createCallbackFcn(app, @toggleOverwrite, true);
            app.addTime.Text = 'Add time rather than save over';
            app.addTime.Value = true;
            app.SavePlaneButton = uibutton(app.UIFigure, 'push');
            app.SavePlaneButton.ButtonPushedFcn = createCallbackFcn(app, @savePlane, true);
            app.SavePlaneButton.Text = 'Save Plane';
            app.textOut = uieditfield(app.UIFigure, 'text');
            app.HideButton = uibutton(app.UIFigure, 'push');
            app.HideButton.ButtonPushedFcn = createCallbackFcn(app, @hide, true);
            app.HideButton.Text = 'Hide';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = tt_saveImage

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end