classdef mixNewChannel < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        ScaleButton                   matlab.ui.control.StateButton
        textOut                       matlab.ui.control.EditField
        StatusEditFieldLabel          matlab.ui.control.Label
        InputchannelsEditField        matlab.ui.control.EditField
        InputchannelsEditFieldLabel   matlab.ui.control.Label
        MixesDropDown                 matlab.ui.control.DropDown
        MixesDropDownLabel            matlab.ui.control.Label
        RunStackButton                matlab.ui.control.Button
        PreviewButton                 matlab.ui.control.Button
        NewchannelID46EditField       matlab.ui.control.NumericEditField
        NewchannelID46EditFieldLabel  matlab.ui.control.Label
        ChannelmixequationEditField   matlab.ui.control.EditField
        ChannelmixequationEditFieldLabel  matlab.ui.control.Label
        PreviewplanePanel             matlab.ui.container.Panel
    end

    
    methods (Access = private)
        
        function results = showPreview(app)
            global globTT globNM

            app.textOut.Value = 'remixing channels';
            toCol = globTT.remix.col + 3 * globTT.remix.ab;
            isEasy = sum(unique(toCol)>0) == 6;
            for t = 1
                for i = globTT.active.slice;%1:size(globTT.I.tab{t},4);
                    Io = cat(3,globTT.I.tab{t}(:,:,:,i),globTT.I.tab{t+3}(:,:,:,i));
                    In = Io * 0;
                   
                    for c = 1:length(globNM.param.input)
                        eval(sprintf('c%d = Io(:,:,%d);',c,globNM.param.input(c)));
                    end
                    if globNM.unMix.do
                        eval(sprintf('[nc umRes] = %s;',globNM.param.eqStr));
                        globNM.unMix.res = umRes;
                    else
                        eval(sprintf('nc = %s;',globNM.param.eqStr));
                    end
                        
                     app.PreviewplanePanel.AutoResizeChildren = 'off';
                     pAx = subplot(1,1,1,'Parent',app.PreviewplanePanel);
                     if app.ScaleButton.Value
                         nc = nc-min(nc(:));
                         nc = nc*240/max(nc(:));
                         nc = nc+ 15;
                         nc = uint8(nc);
                     end
                     image(pAx,nc);
                     colormap(pAx,gray(256))

                    %globTT.I.tab{t}(:,:,:,i) = In(:,:,1:3,:);
                    %globTT.I.tab{t+3}(:,:,:,i) = In(:,:,4:6,:);
                end
            end

            %showImage(app)
            app.textOut.Value = 'finished remixing channels';

        end


        function results = runStack(app)
            global globTT globNM

            app.textOut.Value = 'remixing channels';drawnow
            toCol = globTT.remix.col + 3 * globTT.remix.ab;
            isEasy = sum(unique(toCol)>0) == 6;
            pass = 1;
                    if globNM.unMix.do
                        if ~isfield(globNM.unMix,'res')
                            showPreview(app)
                        end
                    end

            for t = 1:3
                for i = 1:size(globTT.I.tab{t},4);
                    app.textOut.Value = sprintf('mixing plane %d of %d',i,size(globTT.I.tab{t},4));
                    drawnow
                    Io = cat(3,globTT.I.tab{t}(:,:,:,i),globTT.I.tab{t+3}(:,:,:,i));
                    In = Io * 0;
                    targC = globNM.param.new;
                    for c = 1:length(globNM.param.input)
                        eval(sprintf('c%d = Io(:,:,%d);',c,globNM.param.input(c)));
                    end
                    if globNM.unMix.do
                        nc = linUnMixRes(c1,c2,globNM.unMix);
                    else
                        eval(sprintf('nc = %s;',globNM.param.eqStr));
                    end
                    app.PreviewplanePanel.AutoResizeChildren = 'off';
                    pAx = subplot(1,1,1,'Parent',app.PreviewplanePanel);
                    if app.ScaleButton.Value
                        nc = nc-min(nc(:));
                        nc = nc*256/max(nc(:));
                    end
                    image(pAx,nc);
                    colormap(pAx,gray(256))

                    if targC<=3
                        globTT.I.tab{t}(:,:,targC,i) = nc;
                    else
                        globTT.I.tab{t+3}(:,:,targC-3,i) = nc;
                    end
                end
            end

            %showImage(app)
            app.textOut.Value = 'finished remixing channels';
        end
    end
    
    methods (Access = public)
        
        function results = previewNewMix(app)
            global globTT globNM
        

        end
        
        function results = parseNewMixEquation(app)
            
            global globNM
            globNM.param.eqStr = app.ChannelmixequationEditField.Value;
            globNM.param.input = str2num(app.InputchannelsEditField.Value);
            globNM.param.new = app.NewchannelID46EditField.Value;
            globNM.param.mixDrop = app.MixesDropDown.Value;


        end


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)


        end

        % Button pushed function: PreviewButton
        function runPreview(app, event)
            parseNewMixEquation(app)
            showPreview(app)

        end

        % Value changed function: ScaleButton
        function doScale(app, event)
            value = app.ScaleButton.Value;
            showPreview(app)
        end

        % Button pushed function: RunStackButton
        function runStackButton(app, event)
            parseNewMixEquation(app)
            runStack(app)
        end

        % Value changed function: MixesDropDown
        function changeDrop(app, event)
            global globNM
            value = lower(app.MixesDropDown.Value);
            parseNewMixEquation(app)
            globNM.unMix.do = 0;
            if strcmp(value,'custom')

            elseif strcmp(value,'equal')
                app.ChannelmixequationEditField.Value = ...
                    sprintf('c%d',globNM.param.input(1));
            elseif strcmp(value,'equal')
                app.ChannelmixequationEditField.Value = ...
                    '((c1-c2)./(c1+c2)+1 ) * 255/2';
            elseif strcmp(value,'linear unmix 1')
                app.ChannelmixequationEditField.Value = ...
                    'linUnMix01(c1,c2,1)';
                globNM.unMix.do = 1;
                globNM.unMix.outID = 1;
            elseif  strcmp(value,'linear unmix 2')
                app.ChannelmixequationEditField.Value = ...
                    'linUnMix01(c1,c2,2)';
                globNM.unMix.do = 1;
                globNM.unMix.outID = 2;
            end


        end

        % Value changed function: ChannelmixequationEditField
        function eqChange(app, event)
            value = app.ChannelmixequationEditField.Value;
            app.MixesDropDown.Value = 'Custom';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PreviewplanePanel = uipanel(app.UIFigure);
            app.PreviewplanePanel.Title = 'Preview plane';
            app.ChannelmixequationEditFieldLabel = uilabel(app.UIFigure);
            app.ChannelmixequationEditFieldLabel.Text = 'Channel mix equation';
            app.ChannelmixequationEditField = uieditfield(app.UIFigure, 'text');
            app.ChannelmixequationEditField.ValueChangedFcn = createCallbackFcn(app, @eqChange, true);
            app.ChannelmixequationEditField.Value = '((c1-c2)./(c1+c2)+1 ) * 255/2';
            app.NewchannelID46EditFieldLabel = uilabel(app.UIFigure);
            app.NewchannelID46EditFieldLabel.Text = 'New channel ID (4-6)';
            app.NewchannelID46EditField = uieditfield(app.UIFigure, 'numeric');
            app.NewchannelID46EditField.Value = 4;
            app.PreviewButton = uibutton(app.UIFigure, 'push');
            app.PreviewButton.ButtonPushedFcn = createCallbackFcn(app, @runPreview, true);
            app.PreviewButton.Text = 'Preview';
            app.RunStackButton = uibutton(app.UIFigure, 'push');
            app.RunStackButton.ButtonPushedFcn = createCallbackFcn(app, @runStackButton, true);
            app.RunStackButton.Text = 'Run Stack';
            app.MixesDropDownLabel = uilabel(app.UIFigure);
            app.MixesDropDownLabel.Text = 'Mixes';
            app.MixesDropDown = uidropdown(app.UIFigure);
            app.MixesDropDown.Items = {'Custom', 'Equal', 'Polarity', 'linear unmix 1', 'linear unmix 2'};
            app.MixesDropDown.ValueChangedFcn = createCallbackFcn(app, @changeDrop, true);
            app.MixesDropDown.Value = 'Custom';
            app.InputchannelsEditFieldLabel = uilabel(app.UIFigure);
            app.InputchannelsEditFieldLabel.Text = 'Input channels';
            app.InputchannelsEditField = uieditfield(app.UIFigure, 'text');
            app.InputchannelsEditField.Value = '1 2';
            app.StatusEditFieldLabel = uilabel(app.UIFigure);
            app.StatusEditFieldLabel.Text = 'Status';
            app.textOut = uieditfield(app.UIFigure, 'text');
            app.ScaleButton = uibutton(app.UIFigure, 'state');
            app.ScaleButton.ValueChangedFcn = createCallbackFcn(app, @doScale, true);
            app.ScaleButton.Text = 'Scale';
            app.ScaleButton.Value = true;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mixNewChannel

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end