classdef testAccuracyDCI_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TestpopulationsandsamplesPanel  matlab.ui.container.Panel
        SuccessratePanel                matlab.ui.container.Panel
        Nominal95Label                  matlab.ui.control.Label
        Test0of0Label                   matlab.ui.control.Label
        ItterativeresampleEditField     matlab.ui.control.NumericEditField
        ItterativeresampleEditFieldLabel  matlab.ui.control.Label
        ItterativenormalEditField       matlab.ui.control.NumericEditField
        ItterativenormalEditFieldLabel  matlab.ui.control.Label
        BootstrapEditField              matlab.ui.control.NumericEditField
        BootstrapEditFieldLabel         matlab.ui.control.Label
        StandardEditField               matlab.ui.control.NumericEditField
        StandardEditFieldLabel          matlab.ui.control.Label
        ItterativemodelmuchslowerPanel  matlab.ui.container.Panel
        ResampleddistributionsmediansCheckBox  matlab.ui.control.CheckBox
        NormaldistributionsmeansCheckBox  matlab.ui.control.CheckBox
        Group2testvariablesPanel        matlab.ui.container.Panel
        standarddeviationEditField_4    matlab.ui.control.EditField
        standarddeviationEditField_4Label  matlab.ui.control.Label
        meanEditField_4                 matlab.ui.control.EditField
        meanEditField_4Label            matlab.ui.control.Label
        samplesizeEditField_4           matlab.ui.control.EditField
        samplesizeEditField_4Label      matlab.ui.control.Label
        matchButton_3                   matlab.ui.control.StateButton
        matchButton_2                   matlab.ui.control.StateButton
        matchButton                     matlab.ui.control.StateButton
        Group1testvariablesPanel        matlab.ui.container.Panel
        standarddeviationEditField_3    matlab.ui.control.EditField
        standarddeviationEditField_3Label  matlab.ui.control.Label
        meanEditField_3                 matlab.ui.control.EditField
        meanEditField_3Label            matlab.ui.control.Label
        samplesizeEditField_3           matlab.ui.control.EditField
        samplesizeEditField_3Label      matlab.ui.control.Label
        ResultsPanel                    matlab.ui.container.Panel
        XaxisDropDown                   matlab.ui.control.DropDown
        XaxisDropDownLabel              matlab.ui.control.Label
        RunButton                       matlab.ui.control.Button
        textOut                         matlab.ui.control.EditField
        OthertestvariablesPanel         matlab.ui.container.Panel
        BootstraptypeDropDown           matlab.ui.control.DropDown
        BootstraptypeDropDownLabel      matlab.ui.control.Label
        DistributiontypeDropDown        matlab.ui.control.DropDown
        DistributiontypeDropDownLabel   matlab.ui.control.Label
    end


    methods (Access = private)

        function results = runTests(app)
            global globDR globTA

            getTestVariables(app);
            param = globDR.param;
            param1 = param;
            param1.useMean = 1;
            param2 = param;
            param2.useMean = 0;
            
            bootParam = globDR.bootParam;

            v = globTA.v;

            if v.matchN
                n2sL = 1;
            else
                n2sL = length(n2s);
            end

            if v.matchStd
                std2L = 1;
            else
                std2L = length(std2s)
            end

            globTA.lengths =  [ 1 length(v.m2s) length(v.n1s) n2sL length(v.std1s) length(v.std2s)];


            xdatType = app.XaxisDropDown.Value;
            xdatId = find(strcmp({'NA' 'mean 2' 'sample size 1' 'sample size 2' 'std 1' 'std 2'},xdatType));

            globTA.allRes = zeros(7,length(v.m2s),length(v.n1s),n2sL,length(v.std1s),std2L,2);
            globTA.expNum = length(v.m2s) * length(v.n1s) * n2sL * length(v.std1s) * std2L;
            globTA.pRes = {};
            globTA.resName = {};
            globTA.useMask = globTA.allRes; %keep track of which fields are used

            c = 0;
            m1 = v.m1s(1);
            for im2 = 1:length(v.m2s)
                m2 = v.m2s(im2);
                for in1 = 1:length(v.n1s)
                    n1 = v.n1s(in1);
                    for in2 = 1:n2sL;
                        if v.matchN
                            n2 = n1;
                        else
                            n2 = v.n2s(in2);
                        end

                        for istd1 = 1:length(v.std1s)
                            std1 = v.std1s(istd1);
                            for istd2 = 1:length(v.std2s)
                                
                                if v.matchStd
                                    std2 = std1;
                                else
                                    std2 = v.std2s(istd2);
                                end

                                c = c+1;
                                app.textOut.Value = sprintf('running experiment %d of %d',c,globTA.expNum);
                                %% Make data distributions
                                bn = 100000;
                                dType = lower(app.DistributiontypeDropDown.Value);
                                if strcmp(dType,'normal')
                                    dat1 = randn(n1,1)*std1 + m1;
                                    dat2 = randn(n2,1)*std2 + m2;
                                    dat1m = randn(bn,1)*std1 + m1;
                                    dat2m = randn(bn,1)*std2 + m2;
                                elseif strcmp(dType,'flat')
                                    f = 3.46;
                                    dat1 = (rand(n1,1)-.5)*std1*f + m1;
                                    dat2= (rand(n2,1)-.5)*std2*f+ m2;
                                    dat1m = (rand(bn,1)-.5)*std1*f + m1;
                                    dat2m= (rand(bn,1)-.5)*std2*f + m2;
                                elseif strcmp(dType,'bimodal 1.5')
                                    s = 1.5;
                                    f = 0.663
                                    dat1 = randn(n1,1)*std1*f + (rand(n1,1)>.5) * s * std1 - s *std1/2 + m1;
                                    dat2 = randn(n2,1)*std2*f  + (rand(n2,1)>.5) * s * std2 - s *std2/2 + m2;
                                    dat1m = randn(bn,1)*std1*f + (rand(bn,1)>.5) * s *std1 - s *std1/2 + m1;
                                    dat2m = randn(bn,1)*std2*f + (rand(bn,1)>.5) * s *std2 - s * std2/2 + m2;
                                elseif strcmp(dType,'bimodal 1.7')
                                    s = 1.7;
                                    f = 0.527;
                                    dat1 = randn(n1,1)*std1*f + (rand(n1,1)>.5) * s * std1 - s *std1/2 + m1;
                                     dat2 = randn(n2,1)*std2*f  + (rand(n2,1)>.5) * s * std2 - s *std2/2 + m2;
                                     dat1m = randn(bn,1)*std1*f + (rand(bn,1)>.5) * s *std1 - s *std1/2 + m1;
                                     dat2m = randn(bn,1)*std2*f + (rand(bn,1)>.5) * s *std2 - s * std2/2 + m2;
                                elseif strcmp(dType,'bimodal 2.0')
                                     s = 2;
                                     f = 0;
                                     dat1 = randn(n1,1)*std1*f + (rand(n1,1)>.5) * s * std1 - s *std1/2 + m1;
                                     dat2 = randn(n2,1)*std2*f  + (rand(n2,1)>.5) * s * std2 - s *std2/2 + m2;
                                     dat1m = randn(bn,1)*std1*f + (rand(bn,1)>.5) * s *std1 - s *std1/2 + m1;
                                     dat2m = randn(bn,1)*std2*f + (rand(bn,1)>.5) * s *std2 - s * std2/2 + m2;
                                end
                                sourceDif = m2 - m1;


                                %% Plot population
                                app.TestpopulationsandsamplesPanel.AutoResizeChildren = 'off';
                                
                                minM = min([m1-5*std1 m2-5*std2]);
                                maxM = max([m1+5*std1 m2+5*std2]);
                                step = (maxM-minM)/100;
                                hRange = [minM:step:maxM];
                                h1 = hist(dat1m,hRange);
                                h2 = hist(dat2m,hRange);
                                tp{1} = subplot(2,1,1,'Parent',app.TestpopulationsandsamplesPanel);
                                cla(tp{1})
                                tp{1}.NextPlot = 'add';
                                plot(tp{1},hRange,h1,'k')
                                plot(tp{1},hRange,h2,'b')

                                %% Plot Sample
                                tp{2} = subplot(2,1,2,'Parent',app.TestpopulationsandsamplesPanel);
                                cla(tp{2})
                                tp{2}.NextPlot = 'add';
                                scatter(tp{2},dat1*0+1,dat1,'k')
                                scatter(tp{2},dat2*0+2,dat2,'b')
                                tp{2}.XLim = [0 3];
                                tp{2}.XTickLabel = {'' '1' '2' ''};
                                drawnow


                                %% Run test

                                res = standardDifCI(dat1,dat2,param1.ciFrac);

                                if  globTA.runNormalReps
                                    res1 = differenceRangeA2(dat1,dat2,param1);
                                    globTA.allRes(6,im2,in1,in2,istd1,istd2,:) = res1.bestRange;
                                end
                                    
                                if globTA.runResampleReps 
                                    res2 = differenceRangeA2(dat1,dat2,param2);
                                    globTA.allRes(7,im2,in1,in2,istd1,istd2,:) = res2.bestRange;
                                end

                                res3 = bootDifCI(dat1,dat2,bootParam.ciFrac,bootParam.reps);

                                updateResults_public(globDR.app)
                            

                                globTA.allRes(1,im2,in1,in2,istd1,istd2,:) = sourceDif;
                                globTA.allRes(2,im2,in1,in2,istd1,istd2,:) = mean(dat2)-mean(dat1);
                                globTA.allRes(3,im2,in1,in2,istd1,istd2,:) = median(dat2) - median(dat1);
                                globTA.allRes(4,im2,in1,in2,istd1,istd2,:) = res.ciD;
                                globTA.allRes(5,im2,in1,in2,istd1,istd2,:) = res3.ciMean;

                                globTA.useMask(1,im2,in1,in2,istd1,istd2,1) = 1;

                                updatePlots(app);

                            end
                        end
                    end
                end
            end

            app.textOut.Value = 'Finished running tests';
            drawnow

        end

        function results = updatePlots(app)
            global globTA globDR


            xdatType = app.XaxisDropDown.Value;
            xdatId = find(strcmp({'NA' 'mean 2' 'sample size 1' 'sample size 2' 'std 1' 'std 2'},xdatType));
            res = globTA.allRes;

            globTA.pRes = {};
            globTA.resName = {};

            lengths = globTA.lengths;
            lengths(xdatId) = 1;
            v = globTA.v;
            c = 0;
            m1 = v.m1s(1);
            for im2 = 1:lengths(2)
                m2 = v.m2s(im2);
                for in1 = 1:lengths(3)
                    n1 = v.n1s(in1);
                    for in2 = 1:lengths(4);
                        if v.matchN
                            n2 = n1;
                        else
                            n2 = v.n2s(in2);
                        end
                        for istd1 = 1:lengths(5)
                            std1 = v.std1s(istd1);
                            for istd2 = 1:lengths(6)
                                if v.matchStd
                                    std2 = std1;
                                else
                                    std2 = v.std2s(istd2);
                                end

                                c = c+1;
                                   %% record hits
%                                    numTests = sum(tested(:));
%             for c = 1:length(checkT)
%                 t = checkT(c);
%                 pass = (globTA.allRes(1,:,:,:,:,:,1) >= globTA.allRes(t,:,:,:,:,:,1) )... 
%                     &  (globTA.allRes(1,:,:,:,:,:,1) <= globTA.allRes(t,:,:,:,:,:,2) );
%                 hits(c) = sum(pass(:))/numTests;
%             end


                                %%Make plots
                                if xdatId == 2
                                    globTA.pRes{c} = globTA.allRes(:,:,in1,in2,istd1,istd2,:);
                                    globTA.xrange = v.m2s;
                                elseif xdatId == 3
                                    globTA.pRes{c}  = globTA.allRes(:,im2,:,in2,istd1,istd2,:);
                                    globTA.xrange = v.n1s;
                                elseif xdatId == 4
                                    globTA.pRes{c}  = globTA.allRes(:,im2,in1,:,istd1,istd2,:);
                                    globTA.xrange = v.n2s;
                                elseif xdatId == 5
                                    globTA.pRes{c}  = globTA.allRes(:,im2,in1,in2,:,istd2,:);
                                    globTA.xrange = v.std1s;
                                else
                                    globTA.pRes{c}  = globTA.allRes(:,im2,in1,in2,istd1,:,:);
                                    globTA.xrange = v.std2s;
                                end

                                globTA.resName{c} = sprintf('n1-%d, n2-%d, m1-%0.1f, m2-%0.1f, std1-%0.1f, std2-%0.1f',n1,n2,m1,m2,std1,std2);

                            end
                        end
                    end
                end
            end


            plotNum = length(globTA.resName);

            cNum = ceil(plotNum/6);
            if cNum > 4
                rNum = ceil(plotNum/4);
                cNum = ceil(plotNum/rNum);
            else
                rNum = ceil(plotNum/cNum);
            end

            %% Display success rate

            useInd = find(globTA.useMask>0);
            [s1 s2 s3 s4 s5 s6 s7]  = ind2sub(size(globTA.useMask),useInd);

            checkT = [4 5 6 7];
            trueV = globTA.allRes(sub2ind(size(globTA.allRes), s1*0+1,s2,s3,s4,s5,s6,s7*1));
            numTests = sum(globTA.useMask(:));
            for ct = 1:length(checkT)
                t = checkT(ct);
                pass = (globTA.allRes(1,:,:,:,:,:,1) >= globTA.allRes(t,:,:,:,:,:,1) )... 
                    &  (globTA.allRes(1,:,:,:,:,:,1) <= globTA.allRes(t,:,:,:,:,:,2) );
                pass = pass & (globTA.allRes(t,:,:,:,:,:,1) ~= globTA.allRes(t,:,:,:,:,:,2));
                hits(ct) = sum(pass(:))/numTests;
            end

            useRes = globTA.allRes(globTA.useMask>0);
            
            app.ItterativeresampleEditField.Value = hits(4)*100;
            app.ItterativenormalEditField.Value = hits(3)*100;
            app.StandardEditField.Value = hits(1)*100;
            app.BootstrapEditField.Value = hits(2)*100;

            app.Nominal95Label.Text = sprintf('Nominal ci = %0.2f%%',globDR.param.ciFrac);
            app.Test0of0Label.Text = sprintf('Test %d of %d',numTests,globTA.expNum);


            %% Display plots

            for i = 1:length(globTA.resName)
                app.ResultsPanel.AutoResizeChildren = 'off';
                ax{i} = subplot(rNum,cNum,i,'Parent',app.ResultsPanel);
                cla(ax{i})
                ax{i}.NextPlot = "add";
                pRes = globTA.pRes{i};
                pRes = squeeze(pRes);
                title(ax{i},globTA.resName{i});
                ymax = max(pRes(:));
                ymin = min(pRes(:));
                ymax = max(ymax,ymin+.00001);

                classCol = [0 0 0;  .8 0 0; 0 0 .8 ; 1 0 0; 0 0 1; 1 0 0; 0 0 1];
                classNames = {'sourceDif' 'observedDifMean' 'observedDifMed',...
                    'standardDifference','bootDifference','testNorm','testNonPara'};
                lineStyles = {'-','-','-','--','--',':',':'};
                %lineStyles = {'-','-','-','-','-','-','-'};

                lineWidth = [ 1 1 1 1 1 1 1 ] * 1.5;

                for p = 1:size(pRes,1)
                    vals = pRes(p,:,1);
                    if sum(abs(vals));
                        plot(ax{i},globTA.xrange,vals,'color',classCol(p,:), ...
                            'LineStyle',lineStyles(p),'LineWidth',lineWidth(p));
                    end
                end
                for p = 1:size(pRes,1)
                    vals = pRes(p,:,2);
                    if sum(abs(vals));
                        plot(ax{i},globTA.xrange,vals,'color',classCol(p,:), ...
                            'LineStyle',lineStyles(p),'LineWidth',lineWidth(p));
                    end
                end
                xticks(ax{i},globTA.xrange)
                xmag = max(globTA.xrange) - min(globTA.xrange);
                xlim(ax{i},[min(globTA.xrange)-xmag/10 max(globTA.xrange)+xmag/10]);
                ylim([ymin ymax])
                ylim([-200 400])

            end
            drawnow


        end

        function results = getTestVariables(app)
            global globTA
            v.std1s = eval(['[' app.standarddeviationEditField_3.Value ']']);
            v.m1s = eval(['[' app.meanEditField_3.Value ']']);
            v.n1s = eval(['[' app.samplesizeEditField_3.Value ']']);
            v.std2s = eval(['[' app.standarddeviationEditField_4.Value ']']);
            v.m2s = eval(['[' app.meanEditField_4.Value ']']);
            v.n2s = eval(['[' app.samplesizeEditField_4.Value ']']);
            v.matchN = app.matchButton.Value;
            v.matchM = app.matchButton_2.Value;
            v.matchStd = app.matchButton_3.Value;
            v.distributionType = app.DistributiontypeDropDown.Value;
            globTA.v = v;

        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            global globDR globTA


        end

        % Button pushed function: RunButton
        function run(app, event)
            global globDR globTA
            getModelParameters(globDR.app)

            globTA.runNormalReps = app.NormaldistributionsmeansCheckBox.Value;
            globTA.runResampleReps = app.ResampleddistributionsmediansCheckBox.Value;

            runTests(app);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.OthertestvariablesPanel = uipanel(app.UIFigure);
            app.OthertestvariablesPanel.Title = 'Other test variables';
            app.DistributiontypeDropDownLabel = uilabel(app.OthertestvariablesPanel);
            app.DistributiontypeDropDownLabel.Text = 'Distribution type';
            app.DistributiontypeDropDown = uidropdown(app.OthertestvariablesPanel);
            app.DistributiontypeDropDown.Items = {'Normal', 'Flat', 'Bimodal 1.5', 'Bimodal 1.7', 'Bimodal 2.0'};
            app.DistributiontypeDropDown.Value = 'Normal';
            app.BootstraptypeDropDownLabel = uilabel(app.OthertestvariablesPanel);
            app.BootstraptypeDropDownLabel.Text = 'Bootstrap type';
            app.BootstraptypeDropDown = uidropdown(app.OthertestvariablesPanel);
            app.BootstraptypeDropDown.Items = {'Single'};
            app.BootstraptypeDropDown.Value = 'Single';
            app.textOut = uieditfield(app.UIFigure, 'text');
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @run, true);
            app.RunButton.Text = 'Run';
            app.ResultsPanel = uipanel(app.UIFigure);
            app.ResultsPanel.Title = 'Results';
            app.XaxisDropDownLabel = uilabel(app.ResultsPanel);
            app.XaxisDropDownLabel.Text = 'X axis';
            app.XaxisDropDown = uidropdown(app.ResultsPanel);
            app.XaxisDropDown.Items = {'mean 2', 'sample size 1', 'sample size 2', 'std 1', 'std 2'};
            app.XaxisDropDown.Value = 'mean 2';
            app.Group1testvariablesPanel = uipanel(app.UIFigure);
            app.Group1testvariablesPanel.Title = 'Group 1 test variables';
            app.samplesizeEditField_3Label = uilabel(app.Group1testvariablesPanel);
            app.samplesizeEditField_3Label.Text = 'sample size';
            app.samplesizeEditField_3 = uieditfield(app.Group1testvariablesPanel, 'text');
            app.samplesizeEditField_3.Value = '10 50 100';
            app.meanEditField_3Label = uilabel(app.Group1testvariablesPanel);
            app.meanEditField_3Label.Text = 'mean';
            app.meanEditField_3 = uieditfield(app.Group1testvariablesPanel, 'text');
            app.meanEditField_3.Value = '100';
            app.standarddeviationEditField_3Label = uilabel(app.Group1testvariablesPanel);
            app.standarddeviationEditField_3Label.Text = 'standard deviation';
            app.standarddeviationEditField_3 = uieditfield(app.Group1testvariablesPanel, 'text');
            app.standarddeviationEditField_3.Value = '100';
            app.Group2testvariablesPanel = uipanel(app.UIFigure);
            app.Group2testvariablesPanel.Title = 'Group 2 test variables';
            app.matchButton = uibutton(app.Group2testvariablesPanel, 'state');
            app.matchButton.Text = 'match';
            app.matchButton.Value = true;
            app.matchButton_2 = uibutton(app.Group2testvariablesPanel, 'state');
            app.matchButton_2.Text = 'match';
            app.matchButton_3 = uibutton(app.Group2testvariablesPanel, 'state');
            app.matchButton_3.Text = 'match';
            app.matchButton_3.Value = true;
            app.samplesizeEditField_4Label = uilabel(app.Group2testvariablesPanel);
            app.samplesizeEditField_4Label.Text = 'sample size';
            app.samplesizeEditField_4 = uieditfield(app.Group2testvariablesPanel, 'text');
            app.samplesizeEditField_4.Value = '6';
            app.meanEditField_4Label = uilabel(app.Group2testvariablesPanel);
            app.meanEditField_4Label.Text = 'mean';
            app.meanEditField_4 = uieditfield(app.Group2testvariablesPanel, 'text');
            app.meanEditField_4.Value = '100:10:200';
            app.standarddeviationEditField_4Label = uilabel(app.Group2testvariablesPanel);
            app.standarddeviationEditField_4Label.Text = 'standard deviation';
            app.standarddeviationEditField_4 = uieditfield(app.Group2testvariablesPanel, 'text');
            app.standarddeviationEditField_4.Value = '10';
            app.ItterativemodelmuchslowerPanel = uipanel(app.UIFigure);
            app.ItterativemodelmuchslowerPanel.Title = 'Itterative model (much slower)';
            app.NormaldistributionsmeansCheckBox = uicheckbox(app.ItterativemodelmuchslowerPanel);
            app.NormaldistributionsmeansCheckBox.Text = 'Normal distributions (means)';
            app.ResampleddistributionsmediansCheckBox = uicheckbox(app.ItterativemodelmuchslowerPanel);
            app.ResampleddistributionsmediansCheckBox.Text = 'Resampled distributions (medians)';
            app.SuccessratePanel = uipanel(app.UIFigure);
            app.SuccessratePanel.Title = 'Success rate (%)';
            app.StandardEditFieldLabel = uilabel(app.SuccessratePanel);
            app.StandardEditFieldLabel.Text = 'Standard';
            app.StandardEditField = uieditfield(app.SuccessratePanel, 'numeric');
            app.BootstrapEditFieldLabel = uilabel(app.SuccessratePanel);
            app.BootstrapEditFieldLabel.Text = 'Bootstrap';
            app.BootstrapEditField = uieditfield(app.SuccessratePanel, 'numeric');
            app.ItterativenormalEditFieldLabel = uilabel(app.SuccessratePanel);
            app.ItterativenormalEditFieldLabel.Text = 'Itterative normal';
            app.ItterativenormalEditField = uieditfield(app.SuccessratePanel, 'numeric');
            app.ItterativeresampleEditFieldLabel = uilabel(app.SuccessratePanel);
            app.ItterativeresampleEditFieldLabel.Text = 'Itterative resample';
            app.ItterativeresampleEditField = uieditfield(app.SuccessratePanel, 'numeric');
            app.Test0of0Label = uilabel(app.SuccessratePanel);
            app.Test0of0Label.Text = 'Test 0 of 0';
            app.Nominal95Label = uilabel(app.SuccessratePanel);
            app.Nominal95Label.Text = 'Nominal 95%';
            app.TestpopulationsandsamplesPanel = uipanel(app.UIFigure);
            app.TestpopulationsandsamplesPanel.Title = 'Test populations and samples';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = testAccuracyDCI_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end