classdef makeRandomData_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        PopulatedatatablesButton      matlab.ui.control.Button
        RandomparametersButton        matlab.ui.control.Button
        Group2Panel                   matlab.ui.container.Panel
        NEditField_2                  matlab.ui.control.EditField
        NEditField_2Label             matlab.ui.control.Label
        StandarddeviationEditField_2  matlab.ui.control.EditField
        StandarddeviationEditField_2Label  matlab.ui.control.Label
        MeanEditField_2               matlab.ui.control.EditField
        MeanEditField_2Label          matlab.ui.control.Label
        Group1Panel                   matlab.ui.container.Panel
        NEditField                    matlab.ui.control.EditField
        NEditFieldLabel               matlab.ui.control.Label
        StandarddeviationEditField    matlab.ui.control.EditField
        StandarddeviationEditFieldLabel  matlab.ui.control.Label
        MeanEditField                 matlab.ui.control.EditField
        MeanEditFieldLabel            matlab.ui.control.Label
    end

    
    methods (Access = private)
        
        function results = updateDefault(app)

            global globPD
            default = globPD.default;

            app.NEditField.Value = num2str(default.n1);
            app.NEditField_2.Value = num2str(default.n2);
            app.StandarddeviationEditField.Value = num2str(default.std1);
            app.StandarddeviationEditField_2.Value = num2str(default.std2);
            app.MeanEditField.Value = num2str(default.m1);
            app.MeanEditField_2.Value = num2str(default.m2);
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global globDR globPD

            default.m1 = 100;
            default.m2 = 125;
            default.n1 = 10;
            default.n2 = 6;
            default.std1 = 10;
            default.std2 = 10;

            globPD.default = default;
            globPD.param = default;

            updateDefault(app)


        end

        % Button pushed function: PopulatedatatablesButton
        function populate(app, event)
            global globDR globPD

            n1 = str2num(app.NEditField.Value);
            n2 = str2num(app.NEditField_2.Value);
            std1 = str2num(app.StandarddeviationEditField.Value);
            std2 = str2num(app.StandarddeviationEditField_2.Value);
            m1 = str2num(app.MeanEditField.Value);
            m2 = str2num(app.MeanEditField_2.Value);

            dat1 = randn(n1,1)*std1 + m1;
            dat2 = randn(n2,1)*std2 + m2;

            globDR.dat1 = dat1;
            globDR.dat2 = dat2;


            dat1s = num2str(dat1);
            dat1c = mat2cell(dat1s,ones(size(dat1s,1),1),size(dat1s,2));
            dat2s = num2str(dat2);
            dat2c = mat2cell(dat2s,ones(size(dat2s,1),1),size(dat2s,2));


            globDR.app.Datagroup1TextArea.Value = dat1c;
            globDR.app.Datagroup2TextArea.Value = dat2c;


            updateAx1_public(globDR.app)
            app.delete;


        end

        % Button pushed function: RandomparametersButton
        function ramdomParam(app, event)
            app.NEditField.Value = num2str(ceil(rand*100));
            app.NEditField_2.Value = num2str(ceil(rand*100));
            app.StandarddeviationEditField.Value = num2str(rand*100);
            app.StandarddeviationEditField_2.Value = num2str(rand*100);
            app.MeanEditField.Value = num2str(100);
            app.MeanEditField_2.Value = num2str(rand*200-100);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Group1Panel = uipanel(app.UIFigure);
            app.Group1Panel.Title = 'Group 1';
            app.MeanEditFieldLabel = uilabel(app.Group1Panel);
            app.MeanEditFieldLabel.Text = 'Mean';
            app.MeanEditField = uieditfield(app.Group1Panel, 'text');
            app.StandarddeviationEditFieldLabel = uilabel(app.Group1Panel);
            app.StandarddeviationEditFieldLabel.Text = 'Standard deviation';
            app.StandarddeviationEditField = uieditfield(app.Group1Panel, 'text');
            app.NEditFieldLabel = uilabel(app.Group1Panel);
            app.NEditFieldLabel.Text = 'N';
            app.NEditField = uieditfield(app.Group1Panel, 'text');
            app.Group2Panel = uipanel(app.UIFigure);
            app.Group2Panel.Title = 'Group 2';
            app.MeanEditField_2Label = uilabel(app.Group2Panel);
            app.MeanEditField_2Label.Text = 'Mean';
            app.MeanEditField_2 = uieditfield(app.Group2Panel, 'text');
            app.StandarddeviationEditField_2Label = uilabel(app.Group2Panel);
            app.StandarddeviationEditField_2Label.Text = 'Standard deviation';
            app.StandarddeviationEditField_2 = uieditfield(app.Group2Panel, 'text');
            app.NEditField_2Label = uilabel(app.Group2Panel);
            app.NEditField_2Label.Text = 'N';
            app.NEditField_2 = uieditfield(app.Group2Panel, 'text');
            app.RandomparametersButton = uibutton(app.UIFigure, 'push');
            app.RandomparametersButton.ButtonPushedFcn = createCallbackFcn(app, @ramdomParam, true);
            app.RandomparametersButton.Text = 'Random parameters';
            app.PopulatedatatablesButton = uibutton(app.UIFigure, 'push');
            app.PopulatedatatablesButton.ButtonPushedFcn = createCallbackFcn(app, @populate, true);
            app.PopulatedatatablesButton.Text = 'Populate data tables';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = makeRandomData_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end